classdef Kv < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        ProtocolTab                     matlab.ui.container.Tab
        ModelParametersImportButton     matlab.ui.control.Button
        ModelParametersSaveButton       matlab.ui.control.Button
        DataClearButton                 matlab.ui.control.Button
        ModelCalibrationButton          matlab.ui.control.Button
        ClampingvoltagemaxmVEditField   matlab.ui.control.NumericEditField
        ClampingvoltagemaxmVEditFieldLabel  matlab.ui.control.Label
        ClampingvoltagestepmVEditField  matlab.ui.control.NumericEditField
        ClampingvoltagestepmVEditFieldLabel  matlab.ui.control.Label
        DatauploadButton                matlab.ui.control.Button
        NernstpotentialEkmVEditField    matlab.ui.control.NumericEditField
        NernstpotentialEkmVLabel        matlab.ui.control.Label
        ProtocolVisualizationUpdateButton  matlab.ui.control.Button
        ClampingtimemsEditField         matlab.ui.control.NumericEditField
        ClampingtimemsEditFieldLabel    matlab.ui.control.Label
        ClampingvoltageminmVEditField   matlab.ui.control.NumericEditField
        ClampingvoltageminmVEditFieldLabel  matlab.ui.control.Label
        HoldingpotentialmVEditField     matlab.ui.control.NumericEditField
        HoldingpotentialmVEditFieldLabel  matlab.ui.control.Label
        HoldingtimemsEditField          matlab.ui.control.NumericEditField
        HoldingtimemsEditFieldLabel     matlab.ui.control.Label
        UIAxesBottomLeft                matlab.ui.control.UIAxes
        UIAxesTopLeft                   matlab.ui.control.UIAxes
        IKtofKv42Tab                    matlab.ui.container.Tab
        IncludeCheckBox_1               matlab.ui.control.CheckBox
        HighlightButton_1               matlab.ui.control.Button
        p14Knob_1                       matlab.ui.control.Knob
        p14KnobLabel                    matlab.ui.control.Label
        p13Knob_1                       matlab.ui.control.Knob
        p13KnobLabel                    matlab.ui.control.Label
        p12Knob_1                       matlab.ui.control.Knob
        p12KnobLabel                    matlab.ui.control.Label
        p11Knob_1                       matlab.ui.control.Knob
        p11KnobLabel                    matlab.ui.control.Label
        p10Knob_1                       matlab.ui.control.Knob
        p10KnobLabel                    matlab.ui.control.Label
        p9Knob_1                        matlab.ui.control.Knob
        p9Knob_5Label                   matlab.ui.control.Label
        p8Knob_1                        matlab.ui.control.Knob
        p8Knob_5Label                   matlab.ui.control.Label
        p7Knob_1                        matlab.ui.control.Knob
        p7Knob_5Label                   matlab.ui.control.Label
        p6Knob_1                        matlab.ui.control.Knob
        p6Knob_5Label                   matlab.ui.control.Label
        p5Knob_1                        matlab.ui.control.Knob
        p5Knob_6Label                   matlab.ui.control.Label
        p4Knob_1                        matlab.ui.control.Knob
        p4Knob_6Label                   matlab.ui.control.Label
        p3Knob_1                        matlab.ui.control.Knob
        p3Knob_6Label                   matlab.ui.control.Label
        p2Knob_1                        matlab.ui.control.Knob
        p2Knob_6Label                   matlab.ui.control.Label
        p1Knob_1                        matlab.ui.control.Knob
        p1Knob_6Label                   matlab.ui.control.Label
        GKtopKnob                       matlab.ui.control.Knob
        GKtopKnobLabel                  matlab.ui.control.Label
        GKtoKnob                        matlab.ui.control.Knob
        GKtoKnobLabel                   matlab.ui.control.Label
        fKtoKnob                        matlab.ui.control.Knob
        fKtoKnobLabel                   matlab.ui.control.Label
        ResetButton_1                   matlab.ui.control.Button
        ShowEquationsButton             matlab.ui.control.Button
        TextArea_1                      matlab.ui.control.TextArea
        IKslow1Kv15Tab                  matlab.ui.container.Tab
        IncludeCheckBox_2               matlab.ui.control.CheckBox
        HighlightButton_2               matlab.ui.control.Button
        p10Knob_2                       matlab.ui.control.Knob
        p10Knob_2Label                  matlab.ui.control.Label
        p9Knob_2                        matlab.ui.control.Knob
        p9Knob_2Label                   matlab.ui.control.Label
        p8Knob_2                        matlab.ui.control.Knob
        p8Knob_2Label                   matlab.ui.control.Label
        p7Knob_2                        matlab.ui.control.Knob
        p7Knob_2Label                   matlab.ui.control.Label
        p6Knob_2                        matlab.ui.control.Knob
        p6Knob_2Label                   matlab.ui.control.Label
        p5Knob_2                        matlab.ui.control.Knob
        p5Knob_2Label                   matlab.ui.control.Label
        p4Knob_2                        matlab.ui.control.Knob
        p4Knob_2Label                   matlab.ui.control.Label
        p3Knob_2                        matlab.ui.control.Knob
        p3Knob_2Label                   matlab.ui.control.Label
        p2Knob_2                        matlab.ui.control.Knob
        p2Knob_2Label                   matlab.ui.control.Label
        p1Knob_2                        matlab.ui.control.Knob
        p1Knob_2Label                   matlab.ui.control.Label
        GKslow1pKnob                    matlab.ui.control.Knob
        GKslo1pLabel                    matlab.ui.control.Label
        GKslow1Knob                     matlab.ui.control.Knob
        GKslow1Label                    matlab.ui.control.Label
        fKslow1Knob                     matlab.ui.control.Knob
        fKslow1KnobLabel                matlab.ui.control.Label
        ResetButton_2                   matlab.ui.control.Button
        ShowEquationsButton_2           matlab.ui.control.Button
        TextArea_2                      matlab.ui.control.TextArea
        IKslow2Kv21Tab                  matlab.ui.container.Tab
        IncludeCheckBox_3               matlab.ui.control.CheckBox
        p10Text_3                       matlab.ui.control.NumericEditField
        p9Text_3                        matlab.ui.control.NumericEditField
        GKslow2Text                     matlab.ui.control.NumericEditField
        Image                           matlab.ui.control.Image
        p10Knob_3                       matlab.ui.control.Knob
        p10Knob_3Label                  matlab.ui.control.Label
        p9Knob_3                        matlab.ui.control.Knob
        p9Knob_3Label                   matlab.ui.control.Label
        GKslow2Knob                     matlab.ui.control.Knob
        GKslow2Label                    matlab.ui.control.Label
        ResetButton_3                   matlab.ui.control.Button
        HighlightButton_3               matlab.ui.control.Button
        TextArea_3                      matlab.ui.control.TextArea
        IKssTab                         matlab.ui.container.Tab
        IncludeCheckBox_4               matlab.ui.control.CheckBox
        p3_2Text_4                      matlab.ui.control.NumericEditField
        p3_1Text_4                      matlab.ui.control.NumericEditField
        p6Text_4                        matlab.ui.control.NumericEditField
        p4_2Text_4                      matlab.ui.control.NumericEditField
        p4_1Text_4                      matlab.ui.control.NumericEditField
        p5Text_4                        matlab.ui.control.NumericEditField
        GKssText                        matlab.ui.control.NumericEditField
        Image2                          matlab.ui.control.Image
        p6Knob_4                        matlab.ui.control.Knob
        p6Knob_4Label                   matlab.ui.control.Label
        p5Knob_4                        matlab.ui.control.Knob
        p5Knob_4Label                   matlab.ui.control.Label
        p4Knob_4                        matlab.ui.control.Knob
        p4Knob_4Label                   matlab.ui.control.Label
        GKssKnob                        matlab.ui.control.Knob
        GKssLabel                       matlab.ui.control.Label
        TextArea_4                      matlab.ui.control.TextArea
        HightlightButton_4              matlab.ui.control.Button
        ResetButton_4                   matlab.ui.control.Button
        IKtosKv14Tab                    matlab.ui.container.Tab
        IKurTab                         matlab.ui.container.Tab
        IncludeCheckBox_5               matlab.ui.control.CheckBox
        p10Knob_5                       matlab.ui.control.Knob
        p10Knob_7Label                  matlab.ui.control.Label
        p9Knob_5                        matlab.ui.control.Knob
        p9Knob_7Label                   matlab.ui.control.Label
        GKurKnob                        matlab.ui.control.Knob
        GKurKnobLabel                   matlab.ui.control.Label
        IK1Tab                          matlab.ui.container.Tab
        ResetButton_6                   matlab.ui.control.Button
        IncludeCheckBox_6               matlab.ui.control.CheckBox
        p10Knob_6                       matlab.ui.control.Knob
        p10Knob_4Label                  matlab.ui.control.Label
        p9Knob_6                        matlab.ui.control.Knob
        p9Knob_4Label                   matlab.ui.control.Label
        p8Knob_6                        matlab.ui.control.Knob
        p8Knob_3Label                   matlab.ui.control.Label
        p7Knob_6                        matlab.ui.control.Knob
        p7Knob_3Label                   matlab.ui.control.Label
        p6Knob_6                        matlab.ui.control.Knob
        p6Knob_5Label_2                 matlab.ui.control.Label
        p5Knob_6                        matlab.ui.control.Knob
        p5Knob_5Label                   matlab.ui.control.Label
        p4Knob_6                        matlab.ui.control.Knob
        p4Knob_5Label                   matlab.ui.control.Label
        p3Knob_6                        matlab.ui.control.Knob
        p3Knob_3Label                   matlab.ui.control.Label
        p2Knob_6                        matlab.ui.control.Knob
        p2Knob_3Label                   matlab.ui.control.Label
        p1Knob_6                        matlab.ui.control.Knob
        p1Knob_3Label                   matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        SelectVoltageDropDown           matlab.ui.control.DropDown
        SelectVoltageDropDownLabel      matlab.ui.control.Label
        UIAxesBottomRight               matlab.ui.control.UIAxes
        UIAxesTopRight                  matlab.ui.control.UIAxes
        UIAxesMiddleRight               matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    properties (Access = private)
        % currents
        yksum_set
        ykto_set
        ykslow1_set
        ykslow2_set
        ykss_set
        yk1_set
        
        % apps for dialog windows
        KtoDialogApp
        Kslow1DialogApp
        
        % warning window
        WarnAppNan
        WarnAppExpVolt
        
        % calibration window
        CalibApp
        
        % default parameter values
        Kto_default
        Kslow1_default
        Kslow2_default
        Kss_default
        k1_default
        
        % current highlighting
        highlight_idx
        
        % current flags to be included
        ykto_idx
        ykslow1_idx
        ykslow2_idx
        ykss_idx
        yk1_idx
    end
    
    properties (Access = public)
        % declared as public properties in case to be used in Calib Window
        % experimental data
        exp_t
        exp_yksum_set_orig
        exp_yksum_set
        exp_volts
        exp_num_volts
        exp_idx
        
        % time space
        t % entire time
        hold_t % holding time
        pulse_t % voltage-clamping time
        
        % voltages
        volts
        num_volts
        volt_idx
    end
    
    methods (Access = private)
        function [t, hold_t, pulse_t] = gen_time_space(app)
            hold_len = app.HoldingtimemsEditField.Value;
            time_len = hold_len + app.ClampingtimemsEditField.Value;
            
            hold_t = 0:hold_len;
            pulse_t = (hold_len+1):time_len;
            pulse_t_shift = pulse_t - pulse_t(1);
            
            t = [hold_t, pulse_t];
            pulse_t = pulse_t_shift;
        end
        
        function [volts, num_volts] = gen_volts(app)
            minv = app.ClampingvoltageminmVEditField.Value;
            stepv = app.ClampingvoltagestepmVEditField.Value;
            maxv = app.ClampingvoltagemaxmVEditField.Value;
            
            if stepv == 0
                volts = minv;
            else
                volts = minv:stepv:maxv;
            end
            num_volts = length(volts);
        end
        
        function [current_trc] = gen_ikto(app, v)
            if app.ykto_idx == 0
                current_trc = zeros(length(app.t), 1);
            else
                param = zeros(17, 1);
                param(1) = app.p1Knob_1.Value;
                param(2) = app.p2Knob_1.Value;
                param(3) = app.p3Knob_1.Value;
                param(4) = app.p4Knob_1.Value;
                param(5) = app.p5Knob_1.Value;
                param(6) = app.p6Knob_1.Value;
                param(7) = app.p7Knob_1.Value;
                param(8) = app.p8Knob_1.Value;
                param(9) = app.p9Knob_1.Value;
                param(10) = app.p10Knob_1.Value;
                param(11) = app.p11Knob_1.Value;
                param(12) = app.p12Knob_1.Value;
                param(13) = app.p13Knob_1.Value;
                param(14) = app.p14Knob_1.Value;
                param(15) = app.fKtoKnob.Value;
                param(16) = app.GKtoKnob.Value;
                param(17) = app.GKtopKnob.Value;
                
                tt = cell(1, 3);
                tt{1} = app.t;
                tt{2} = app.hold_t;
                tt{3} = app.pulse_t;
                current_trc = ikto(param, app.HoldingpotentialmVEditField.Value, ...
                    v, tt, app.NernstpotentialEkmVEditField.Value);                
            end
        end
        
        function [current_trc] = gen_ikslow1(app, v)
            if app.ykslow1_idx == 0
                current_trc = zeros(length(app.t), 1);
            else
                param = zeros(13,1);
                
                % shared
                param(1) = app.p1Knob_2.Value;
                param(2) = app.p2Knob_2.Value;
                param(3) = app.p3Knob_2.Value;
                param(4) = app.p4Knob_2.Value;
                param(5) = app.p5Knob_2.Value;
                param(6) = app.p6Knob_2.Value;
                param(7) = app.p7Knob_2.Value;
                param(8) = app.p8Knob_2.Value;
                
                % unique
                param(9) = app.p9Knob_2.Value;
                param(10) = app.p10Knob_2.Value;
                param(11) = app.fKslow1Knob.Value;
                param(12) = app.GKslow1Knob.Value;
                param(13) = app.GKslow1pKnob.Value;
                
                tt = cell(1, 3);
                tt{1} = app.t;
                tt{2} = app.hold_t;
                tt{3} = app.pulse_t;
                current_trc = ikslow1(param, app.HoldingpotentialmVEditField.Value, ...
                    v, tt, app.NernstpotentialEkmVEditField.Value);                
            end            
        end
        
        function [current_trc] = gen_ikslow2(app, v)
            if app.ykslow2_idx == 0
                current_trc = zeros(length(app.t), 1);
            else
                param = zeros(11,1);
                
                % shared
                param(1) = app.p1Knob_2.Value;
                param(2) = app.p2Knob_2.Value;
                param(3) = app.p3Knob_2.Value;
                param(4) = app.p4Knob_2.Value;
                param(5) = app.p5Knob_2.Value;
                param(6) = app.p6Knob_2.Value;
                param(7) = app.p7Knob_2.Value;
                param(8) = app.p8Knob_2.Value;
                
                % unique
                param(9) = app.p9Knob_3.Value;
                param(10) = app.p10Knob_3.Value;
                param(11) = app.GKslow2Knob.Value;
                
                tt = cell(1, 3);
                tt{1} = app.t;
                tt{2} = app.hold_t;
                tt{3} = app.pulse_t;            
                current_trc = ikslow2(param, app.HoldingpotentialmVEditField.Value, ...
                    v, tt, app.NernstpotentialEkmVEditField.Value);                
            end
        end
        
        function [current_trc] = gen_ikss(app, v)
            if app.ykss_idx == 0
                current_trc = zeros(length(app.t), 1);
            else
                param = zeros(7,1);
                
                % shared
                param(1) = app.p1Knob_2.Value;
                param(2) = app.p3Knob_2.Value; % referred as p3 in equation window
                param(3) = app.p4Knob_2.Value;
                
                % unique
                param(4) = app.p4Knob_4.Value;
                param(5) = app.p5Knob_4.Value;
                param(6) = app.p6Knob_4.Value;
                param(7) = app.GKssKnob.Value;
                
                tt = cell(1, 3);
                tt{1} = app.t;
                tt{2} = app.hold_t;
                tt{3} = app.pulse_t;
                current_trc = ikss(param, app.HoldingpotentialmVEditField.Value, ...
                    v, tt, app.NernstpotentialEkmVEditField.Value);                
            end
        end
        
        function [current_trc] = gen_ik1(app, v)
            if app.yk1_idx == 0
                current_trc = zeros(length(app.t), 1);
            else
                param = zeros(10, 1);
                param(1) = app.p1Knob_6.Value;
                param(2) = app.p2Knob_6.Value;
                param(3) = app.p3Knob_6.Value;
                param(4) = app.p4Knob_6.Value;
                param(5) = app.p5Knob_6.Value;
                param(6) = app.p6Knob_6.Value;
                param(7) = app.p7Knob_6.Value;
                param(8) = app.p8Knob_6.Value;
                param(9) = app.p9Knob_6.Value;
                param(10) = app.p10Knob_6.Value;
                
                tt = cell(1, 3);
                tt{1} = app.t;
                tt{2} = app.hold_t;
                tt{3} = app.pulse_t;
                current_trc = ik1(param, app.HoldingpotentialmVEditField.Value, ...
                    v, tt, app.NernstpotentialEkmVEditField.Value);  
            end
        end
        
        function [current_trc_set] = gen_currents_over_volts(app, current_generator)
            % wrapper function looping over voltages
            current_trc_set = zeros(length(app.t), app.num_volts);
            for i=1:app.num_volts
                current_trc = current_generator(app, app.volts(i));
                if any(isnan(current_trc_set))
                    % warning message for NaN
                    app.WarnAppNan = warn_message_nan();
                else
                    current_trc_set(:,i) = current_trc;
                end
            end
        end
        
        function plot_protocol(app)
            yhold = ones(length(app.hold_t), 1)*app.HoldingpotentialmVEditField.Value;
            ypulse = ones(length(app.pulse_t), 1)*app.volts(1);
            y = [yhold; ypulse];
            plot(app.UIAxesTopLeft, app.t, y, 'LineWidth',1.5)
            
            hold(app.UIAxesTopLeft, "on")
            for i=2:app.num_volts
                ypulse = ones(length(app.pulse_t), 1)*app.volts(i);
                y = [yhold; ypulse];
                plot(app.UIAxesTopLeft, app.t, y, 'LineWidth',1.5)
            end
            hold(app.UIAxesTopLeft, "off")
        end
        
        function plot_sum_currents_allvolts(app)
            % top-right axes; IKsum
            plot(app.UIAxesTopRight, app.t, app.yksum_set(:,1))
            hold(app.UIAxesTopRight, "on")
            for i=2:app.num_volts
                plot(app.UIAxesTopRight, app.t, app.yksum_set(:,i))
            end
            hold(app.UIAxesTopRight, "off")
            axis(app.UIAxesTopRight, "tight")
        end
        
        function plot_currents_onevolt(app, volt_idx, highlight_idx)
            % check given voltage is out of voltage range of exp_volts
            if app.exp_idx == true
                if ~ismember(app.volts(volt_idx), app.exp_volts)
                    volt_idx = 1;
                    app.SelectVoltageDropDown.Value = app.SelectVoltageDropDown.Items(1);
                    app.WarnAppExpVolt = warn_message_exp_volt(app);
                end
            end
            
            % MiddleRight; individual K+ currents for one voltage
            plot_list = [];
            legend_list = [];
            
            % figure out first current to be plotted
            current_idx_set = [app.ykto_idx, app.ykslow1_idx, app.ykslow2_idx, app.ykss_idx, app.yk1_idx];
            active_current_idx = find(current_idx_set == 1);
            
            % first plot before hold on
            if isempty(active_current_idx)
                plot(app.UIAxesMiddleRight, app.t, zeros(length(app.t), 1))
            else
                switch active_current_idx(1)
                    case 1
                        plot_list = [plot_list, plot(app.UIAxesMiddleRight, app.t, app.ykto_set(:,volt_idx))];
                        legend_list = [legend_list, "I_{Kto}"];
                    case 2
                        plot_list = [plot_list, plot(app.UIAxesMiddleRight, app.t, app.ykslow1_set(:,volt_idx))];
                        legend_list = [legend_list, "I_{Kslow1}"];
                    case 3
                        plot_list = [plot_list, plot(app.UIAxesMiddleRight, app.t, app.ykslow2_set(:,volt_idx))];
                        legend_list = [legend_list, "I_{Kslow2}"];
                    case 4
                        plot_list = [plot_list, plot(app.UIAxesMiddleRight, app.t, app.ykss_set(:,volt_idx))];
                        legend_list = [legend_list, "I_{Kss}"];
                    case 5
                        plot_list = [plot_list, plot(app.UIAxesMiddleRight, app.t, app.yk1_set(:,volt_idx))];
                        legend_list = [legend_list, "I_{K1}"];                        
                end
                
                hold(app.UIAxesMiddleRight, "on")
                for i = 2:length(active_current_idx)
                    switch active_current_idx(i)
                        case 1
                            plot_list = [plot_list, plot(app.UIAxesMiddleRight, app.t, app.ykto_set(:,volt_idx))];
                            legend_list = [legend_list, "I_{Kto}"];
                        case 2
                            plot_list = [plot_list, plot(app.UIAxesMiddleRight, app.t, app.ykslow1_set(:,volt_idx))];
                            legend_list = [legend_list, "I_{Kslow1}"];
                        case 3
                            plot_list = [plot_list, plot(app.UIAxesMiddleRight, app.t, app.ykslow2_set(:,volt_idx))];
                            legend_list = [legend_list, "I_{Kslow2}"];
                        case 4
                            plot_list = [plot_list, plot(app.UIAxesMiddleRight, app.t, app.ykss_set(:,volt_idx))];
                            legend_list = [legend_list, "I_{Kss}"];
                        case 5
                            plot_list = [plot_list, plot(app.UIAxesMiddleRight, app.t, app.yk1_set(:,volt_idx))];
                            legend_list = [legend_list, "I_{K1}"];
                    end
                end
                hold(app.UIAxesMiddleRight, "off")
                
                % legned
                legend(app.UIAxesMiddleRight, legend_list, "Location","northeast")
                
                % highlighting
                if any(active_current_idx == highlight_idx)
                    set(plot_list(highlight_idx), "LineWidth",2)                
                end
            end
            
            % IKsum for one voltage
            if app.exp_idx == true
                plot(app.UIAxesBottomRight, app.exp_t, app.exp_yksum_set(:,volt_idx), "LineWidth",2)
                hold(app.UIAxesBottomRight, "on")
                plot(app.UIAxesBottomRight, app.t, app.yksum_set(:,volt_idx), "LineWidth",2, "Color","red")
                hold(app.UIAxesBottomRight, "off")
                axis(app.UIAxesBottomRight, "tight")
                legend(app.UIAxesBottomRight, "Experimental Data", "Model Prediction", "Location","northeast")
            else
                plot(app.UIAxesBottomRight, app.t, app.yksum_set(:,volt_idx), "LineWidth",2, "Color","red")
                axis(app.UIAxesBottomRight, "tight")
                legend(app.UIAxesBottomRight, "Model Prediction", "Location","northeast")                
            end
        end
        
        function reset_kto(app)
            app.highlight_idx = false;
            
            app.p1Knob_1.Value = app.Kto_default(1);
            app.p2Knob_1.Value = app.Kto_default(2);
            app.p3Knob_1.Value = app.Kto_default(3);
            app.p4Knob_1.Value = app.Kto_default(4);
            app.p5Knob_1.Value = app.Kto_default(5);
            app.p6Knob_1.Value = app.Kto_default(6);
            app.p7Knob_1.Value = app.Kto_default(7);
            app.p8Knob_1.Value = app.Kto_default(8);
            app.p9Knob_1.Value = app.Kto_default(9);
            app.p10Knob_1.Value = app.Kto_default(10);
            app.p11Knob_1.Value = app.Kto_default(11);
            app.p12Knob_1.Value = app.Kto_default(12);
            app.p13Knob_1.Value = app.Kto_default(13);
            app.p14Knob_1.Value = app.Kto_default(14);
            app.fKtoKnob.Value = app.Kto_default(15);
            app.GKtoKnob.Value = app.Kto_default(16);
            app.GKtopKnob.Value = app.Kto_default(17);
        end
        
        function reset_kslow1(app)
            app.highlight_idx = false;
            
            % shared
            app.p1Knob_2.Value = app.Kslow1_default(1);
            app.p2Knob_2.Value = app.Kslow1_default(2);
            app.p3Knob_2.Value = app.Kslow1_default(3);
            app.p4Knob_2.Value = app.Kslow1_default(4);
            app.p5Knob_2.Value = app.Kslow1_default(5);
            app.p6Knob_2.Value = app.Kslow1_default(6);
            app.p7Knob_2.Value = app.Kslow1_default(7);
            app.p8Knob_2.Value = app.Kslow1_default(8);
            
            % unique
            app.p9Knob_2.Value = app.Kslow1_default(9);
            app.p10Knob_2.Value = app.Kslow1_default(10);
            app.fKslow1Knob.Value = app.Kslow1_default(11);
            app.GKslow1Knob.Value = app.Kslow1_default(12);
            app.GKslow1pKnob.Value = app.Kslow1_default(13);
        end
        
        function reset_kslow2(app)
            app.highlight_idx = false;
            
            % shared; reset only unique parameters or all relevant ones?
%             app.p1Knob_2.Value = app.Kslow1_default(1);
%             app.p2Knob_2.Value = app.Kslow1_default(2);
%             app.p3Knob_2.Value = app.Kslow1_default(3);
%             app.p4Knob_2.Value = app.Kslow1_default(4);
%             app.p5Knob_2.Value = app.Kslow1_default(5);
%             app.p6Knob_2.Value = app.Kslow1_default(6);
%             app.p7Knob_2.Value = app.Kslow1_default(7);
%             app.p8Knob_2.Value = app.Kslow1_default(8);
            
            % unique
            app.p9Knob_3.Value = app.Kslow2_default(1);
            app.p9Text_3.Value = app.Kslow2_default(1);
            
            app.p10Knob_3.Value = app.Kslow2_default(2);
            app.p10Text_3.Value = app.Kslow2_default(2);
            
            app.GKslow2Knob.Value = app.Kslow2_default(3);  
            app.GKslow2Text.Value = app.Kslow2_default(3);
        end
        
        function reset_kss(app)
            app.highlight_idx = false;
            
            % shared; reset only unique parameters or all relevant ones?
%             app.p1Knob_2.Value = app.Kslow1_default(1);
%             app.p3Knob_2.Value = app.Kslow1_default(3);
%             app.p3_1Text_4.Value = app.Kslow1_default(3);
%             app.p3_2Text_4.Value = app.Kslow2_default(3);
%             app.p4Knob_2.Value = app.Kslow1_default(4);
            
            % unique
            app.p4Knob_4.Value = app.Kss_default(1);
            app.p4_1Text_4.Value = app.Kss_default(1);
            app.p4_2Text_4.Value = app.Kss_default(1);
            
            app.p5Knob_4.Value = app.Kss_default(2);
            app.p5Text_4.Value = app.Kss_default(2);
            
            app.p6Knob_4.Value = app.Kss_default(3);
            app.p6Text_4.Value = app.Kss_default(3);
            
            app.GKssKnob.Value = app.Kss_default(4);
            app.GKssText.Value = app.Kss_default(4);
        end
        
        function reset_k1(app)
            app.highlight_idx = false;
            
            app.p1Knob_6.Value = app.k1_default(1);
            app.p2Knob_6.Value = app.k1_default(2);
            app.p3Knob_6.Value = app.k1_default(3);
            app.p4Knob_6.Value = app.k1_default(4);
            app.p5Knob_6.Value = app.k1_default(5);
            app.p6Knob_6.Value = app.k1_default(6);
            app.p7Knob_6.Value = app.k1_default(7);
            app.p8Knob_6.Value = app.k1_default(8);
            app.p9Knob_6.Value = app.k1_default(9);
            app.p10Knob_6.Value = app.k1_default(10);
        end
        
        function update_sum_current(app)
            for i=1:app.num_volts
                app.yksum_set(:,i) = app.ykto_set(:,i) + app.ykslow1_set(:,i) + ...
                    app.ykslow2_set(:,i) + app.ykss_set(:,i) + app.yk1_set(:,i);
            end
        end
    end
    
    methods (Access = public)
        function update_kto_dialog(app, param)
            % update knobs
            app.p1Knob_1.Value = param(1);
            app.p2Knob_1.Value = param(2);
            app.p3Knob_1.Value = param(3);
            app.p4Knob_1.Value = param(4);
            app.p5Knob_1.Value = param(5);
            app.p6Knob_1.Value = param(6);
            app.p7Knob_1.Value = param(7);
            app.p8Knob_1.Value = param(8);
            app.p9Knob_1.Value = param(9);
            app.p10Knob_1.Value = param(10);
            app.p11Knob_1.Value = param(11);
            app.p12Knob_1.Value = param(12);
            app.p13Knob_1.Value = param(13);
            app.p14Knob_1.Value = param(14);
            app.fKtoKnob.Value = param(15);
            app.GKtoKnob.Value = param(16);
            app.GKtopKnob.Value = param(17);
            
            % generate new IKto and update plots
            app.ykto_set = gen_currents_over_volts(app, @gen_ikto);
            update_sum_current(app);
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, app.highlight_idx);
        end
        
        function update_kslow1_dialog(app, param)
            % shared
            app.p1Knob_2.Value = param(1);
            app.p2Knob_2.Value = param(2);            
            app.p3Knob_2.Value = param(3);
            app.p4Knob_2.Value = param(4);
            app.p5Knob_2.Value = param(5);
            app.p6Knob_2.Value = param(6);
            app.p7Knob_2.Value = param(7);
            app.p8Knob_2.Value = param(8);
            
            % unique
            app.p9Knob_2.Value = param(9);
            app.p10Knob_2.Value = param(10);
            app.fKslow1Knob.Value = param(11);
            app.GKslow1Knob.Value = param(12);
            app.GKslow1pKnob.Value = param(13);
            
            app.ykslow1_set = gen_currents_over_volts(app, @gen_ikslow1);
            app.ykslow2_set = gen_currents_over_volts(app, @gen_ikslow2);
            app.ykss_set = gen_currents_over_volts(app, @gen_ikss);
            
            update_sum_current(app);
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 2);            
        end
        
        
        function update_calib_window(app, sol)
            % IKto tuning parameters: p1, p2, p3, p5, p6, p10, p13, p14, GKto, GKtop
            % update knobs
            app.p1Knob_1.Value = sol(1);
            app.p2Knob_1.Value = sol(2);
            app.p3Knob_1.Value = sol(3);
            app.p5Knob_1.Value = sol(4);
            app.p6Knob_1.Value = sol(5);
            app.p10Knob_1.Value = sol(6);
            app.p13Knob_1.Value = sol(7);
            app.p14Knob_1.Value = sol(8);
            app.GKtoKnob.Value = sol(9);
            app.GKtopKnob.Value = sol(10);
            
            % IKslow1 tuning parameters: p1, p2, p3, p5, p8, p9, GKslow1, GKslow1p
            % update knobs
            app.p1Knob_2.Value = sol(11);
            app.p2Knob_2.Value = sol(12);
            app.p3Knob_2.Value = sol(13);
            app.p5Knob_2.Value = sol(14);
            app.p8Knob_2.Value = sol(15);
            app.p9Knob_2.Value = sol(16);
            app.GKslow1Knob.Value = sol(17);
            app.GKslow1pKnob.Value = sol(18);
            
            % IKslow2 tuning parameters: p9, GKslow2
            % update knobs
            app.p9Knob_3.Value = sol(19);
            app.GKslow2Knob.Value = sol(20);
            
            % IKss tuning parameters: p6, GKss
            % update knobs
            app.p6Knob_4.Value = sol(21);
            app.GKssKnob.Value = sol(22);
            
            % generate individual K+ currents
            app.ykto_set = gen_currents_over_volts(app, @gen_ikto);
            app.ykslow1_set = gen_currents_over_volts(app, @gen_ikslow1);
            app.ykslow2_set = gen_currents_over_volts(app, @gen_ikslow2);
            app.ykss_set = gen_currents_over_volts(app, @gen_ikss);
            
            % calculate and plot Iksum
            update_sum_current(app);
            plot_sum_currents_allvolts(app);
            
            % plot individual K+ currents
            app.volt_idx = 1;
            app.highlight_idx = false;
            plot_currents_onevolt(app, app.volt_idx, app.highlight_idx);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % assign default parameter values IKto
            app.Kto_default = zeros(17, 1);
            app.Kto_default(1) = 33; 
            app.Kto_default(2) = 15.5; 
            app.Kto_default(3) = 20; 
            app.Kto_default(4) = 16; 
            app.Kto_default(5) = 8; 
            app.Kto_default(6) = 7; 
            app.Kto_default(7) = 0.03577; 
            app.Kto_default(8) = 0.06237; 
            app.Kto_default(9) = 0.18064; 
            app.Kto_default(10) = 0.3956; 
            app.Kto_default(11) = 0.000152; 
            app.Kto_default(12) = 0.067083; 
            app.Kto_default(13) = 0.00095; 
            app.Kto_default(14) = 0.051335; 
            app.Kto_default(15) = 0.2087704319; 
            app.Kto_default(16) = 0.14067; 
            app.Kto_default(17) = 0.387;
            
            % assign default parameter values IKslow1
            app.Kslow1_default = zeros(13, 1);
            app.Kslow1_default(1) = 22.5;
            app.Kslow1_default(2) = 45.2;
            app.Kslow1_default(3) = 40.0;
            app.Kslow1_default(4) = 7.7;
            app.Kslow1_default(5) = 5.7;
            app.Kslow1_default(6) = 6.1;
            app.Kslow1_default(7) = 0.0629;
            app.Kslow1_default(8) = 2.058;
            app.Kslow1_default(9) = 803.0;
            app.Kslow1_default(10) = 18.0;
            app.Kslow1_default(11) = 0.9214774521;
            app.Kslow1_default(12) = 0.05766;
            app.Kslow1_default(13) = 0.07496;     
            
            % assign default parameter values IKslow2
            app.Kslow2_default = zeros(3, 1);
            app.Kslow2_default(1) = 5334;
            app.Kslow2_default(2) = 4912;
            app.Kslow2_default(3) = 0.05766;
            
            % assign default parameter values IKss
            app.Kss_default = zeros(4, 1);
            app.Kss_default(1) = 0.0862;
            app.Kss_default(2) = 1235.5;
            app.Kss_default(3) = 13.17;
            app.Kss_default(4) = 0.0428;
            
            % assign default parameter values ik1
            app.k1_default = zeros(10, 1);
            app.k1_default(1) = 59.215;
            app.k1_default(2) = 5.476;
            app.k1_default(3) = 594.31;
            app.k1_default(4) = 4.753;
            app.k1_default(5) = 1.02;
            app.k1_default(6) = 0.2385;
            app.k1_default(7) = 0.8;
            app.k1_default(8) = 0.08032;
            app.k1_default(9) = 0.06175;
            app.k1_default(10) = 0.5143;
            
            % generate time space
            [app.t, app.hold_t, app.pulse_t] = gen_time_space(app);
            
            % generate clamping voltages based on protocol infomation
            [app.volts, app.num_volts] = gen_volts(app);
            
            % voltage drop-down menu
            volt_dd_items = strings(1, app.num_volts);
            for i=1:app.num_volts
                volt_dd_items(i) = strcat(num2str(app.volts(i)), " mV");
            end
            app.SelectVoltageDropDown.Items = volt_dd_items;
            
            % plot default voltage clamp protocol
            plot_protocol(app);
            
            % generate individual K+ currents
            app.ykto_idx = app.IncludeCheckBox_1.Value;
            app.ykslow1_idx = app.IncludeCheckBox_2.Value;
            app.ykslow2_idx = app.IncludeCheckBox_3.Value;
            app.ykss_idx = app.IncludeCheckBox_4.Value;
            app.yk1_idx = app.IncludeCheckBox_6.Value;
            
            app.ykto_set = gen_currents_over_volts(app, @gen_ikto);
            app.ykslow1_set = gen_currents_over_volts(app, @gen_ikslow1);
            app.ykslow2_set = gen_currents_over_volts(app, @gen_ikslow2);
            app.ykss_set = gen_currents_over_volts(app, @gen_ikss);
            app.yk1_set = gen_currents_over_volts(app, @gen_ik1);
            
            % calculate and plot Iksum
            update_sum_current(app);
            plot_sum_currents_allvolts(app);
            
            % plot individual K+ currents
            app.highlight_idx = false;
            app.volt_idx = 1;
            plot_currents_onevolt(app, app.volt_idx, app.highlight_idx);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {874, 874};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {495, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.KtoDialogApp)
            delete(app.Kslow1DialogApp)
            delete(app.WarnAppNan)
            delete(app.WarnAppExpVolt)
            delete(app.CalibApp)
            delete(app)
        end

        % Button pushed function: ProtocolVisualizationUpdateButton
        function ProtocolVisualizationUpdateButtonPushed(app, event)
            % update time space
            [app.t, app.hold_t, app.pulse_t] = gen_time_space(app);
            
            % update voltages
            [app.volts, app.num_volts] = gen_volts(app);
            
            % voltage drop-down menu
            volt_dd_items = strings(1, app.num_volts);
            for i=1:app.num_volts
                volt_dd_items(i) = strcat(num2str(app.volts(i)), " mV");
            end
            app.SelectVoltageDropDown.Items = volt_dd_items;
            
            % update protocl visualization
            plot_protocol(app);
            
            % update experimental data
%             if app.exp_num_volts < app.num_volts
%                 app.WarnApp1 = warn_message_exp_volt();
%             else
%                 app.exp_yksum_set = app.exp_yksum_set(:, (app.exp_num_volts-app.num_volts+1):end);
%                 [~, app.exp_num_volts] = size(app.exp_yksum_set);
%             end

%             plot(app.UIAxesBottomLeft, app.exp_t, app.exp_yksum_set(:,1))
%             hold(app.UIAxesBottomLeft, "on")
%             for i=2:app.num_volts
%                 plot(app.UIAxesBottomLeft, app.exp_t, app.exp_yksum_set(:,i))
%             end
%             hold(app.UIAxesBottomLeft, "off")
%             axis(app.UIAxesBottomLeft, "tight")
            
            % update and plot currents with new protocol
            app.ykto_set = gen_currents_over_volts(app, @gen_ikto);
            app.ykslow1_set = gen_currents_over_volts(app, @gen_ikslow1);
            app.ykslow2_set = gen_currents_over_volts(app, @gen_ikslow2);
            app.ykss_set = gen_currents_over_volts(app, @gen_ikss);
            app.yksum_set = app.ykto_set + app.ykslow1_set + app.ykslow2_set + app.ykss_set;
            
            app.volt_idx = 1;
            app.highlight_idx = false;
            app.SelectVoltageDropDown.Value = volt_dd_items(1);
            plot_currents_onevolt(app, app.volt_idx, app.highlight_idx);
            plot_sum_currents_allvolts(app);
        end

        % Button pushed function: DatauploadButton
        function DatauploadButtonPushed(app, event)
            [file,path] = uigetfile(["*.xlsx";"*.csv"], "File Selector");
            
            % when close file selection window withou selecting file
            if (isequal(file, 0) || isequal(path, 0))
                return
            end
            
            % load experimental data
            exp_data = table2array(readtable(fullfile(path, file)));
            app.exp_t = exp_data(:,1);
            app.exp_yksum_set_orig = exp_data(:,2:end);
            
            % meta info
            app.exp_idx = true;
            [~, app.exp_num_volts] = size(app.exp_yksum_set_orig);
            
            % estimate voltages of experimental data
            min_volt = app.ClampingvoltageminmVEditField.Value;
            volt_step = app.ClampingvoltagestepmVEditField.Value;
            app.exp_volts = min_volt:volt_step:(min_volt + (app.exp_num_volts-1)*volt_step);
            
            % select data for calibration
            if app.exp_num_volts <= app.num_volts
                app.exp_yksum_set = app.exp_yksum_set_orig;
            else
                app.exp_yksum_set = app.exp_yksum_set_orig(:, 1:app.num_volts);
            end
            
            % visualize experimental data
            plot(app.UIAxesBottomLeft, app.exp_t, app.exp_yksum_set_orig(:,1))
            hold(app.UIAxesBottomLeft, "on")
            for i=2:app.exp_num_volts
                plot(app.UIAxesBottomLeft, app.exp_t, app.exp_yksum_set_orig(:,i))
            end
            hold(app.UIAxesBottomLeft, "off")
            axis(app.UIAxesBottomLeft, "tight")
            
            plot_currents_onevolt(app, app.volt_idx, app.highlight_idx);
        end

        % Button pushed function: DataClearButton
        function DataClearButtonPushed(app, event)
            cla(app.UIAxesBottomLeft)
            app.exp_idx = false;
            
            app.highlight_idx = false;
            plot_currents_onevolt(app, app.volt_idx, app.highlight_idx);
        end

        % Button pushed function: ModelCalibrationButton
        function ModelCalibrationButtonPushed(app, event)
            if app.exp_idx == true
                % open model calibration window
                app.CalibApp = calib_window(app);
            end
        end

        % Button pushed function: ModelParametersSaveButton
        function ModelParametersSaveButtonPushed(app, event)
            [file, path] = uiputfile("model_parameter.xlsx", "Save File Specifier");
            save_path = fullfile(path, file);
            
            % when closing window without action
            if (isequal(file, 0) || isequal(path, 0))
                return
            end
            
            % header
            current_names = ["IKto", "IKslow1", "IKslow2", "IKss"];
            writematrix(current_names, save_path, "Sheet","Parameters", "Range","A1:D1");            
            
            % ikto
            p = zeros(17, 1);
            p(1) = app.p1Knob_1.Value;
            p(2) = app.p2Knob_1.Value;
            p(3) = app.p3Knob_1.Value;
            p(4) = app.p4Knob_1.Value;
            p(5) = app.p5Knob_1.Value;
            p(6) = app.p6Knob_1.Value;
            p(7) = app.p7Knob_1.Value;
            p(8) = app.p8Knob_1.Value;
            p(9) = app.p9Knob_1.Value;
            p(10) = app.p10Knob_1.Value;
            p(11) = app.p11Knob_1.Value;
            p(12) = app.p12Knob_1.Value;
            p(13) = app.p13Knob_1.Value;
            p(14) = app.p14Knob_1.Value;
            p(15) = app.fKtoKnob.Value;
            p(16) = app.GKtoKnob.Value;
            p(17) = app.GKtopKnob.Value;
            writematrix(p, save_path, "Sheet","Parameters", "Range","A2");
            
            % ikslow1
            p = zeros(13, 1);
            p(1) = app.p1Knob_2.Value;
            p(2) = app.p2Knob_2.Value;
            p(3) = app.p3Knob_2.Value;
            p(4) = app.p4Knob_2.Value;
            p(5) = app.p5Knob_2.Value;
            p(6) = app.p6Knob_2.Value;
            p(7) = app.p7Knob_2.Value;
            p(8) = app.p8Knob_2.Value;
            p(9) = app.p9Knob_2.Value;
            p(10) = app.p10Knob_2.Value;
            p(11) = app.fKslow1Knob.Value;
            p(12) = app.GKslow1Knob.Value;
            p(13) = app.GKslow1pKnob.Value;
            writematrix(p, save_path, "Sheet","Parameters", "Range","B2");
            
            % ikslow2
            p = zeros(3, 1);
            p(1) = app.p9Knob_3.Value;
            p(2) = app.p10Knob_3.Value;
            p(3) = app.GKslow2Knob.Value;
            writematrix(p, save_path, "Sheet","Parameters", "Range","C2");
            
            % ikss
            p = zeros(4, 1);
            p(1) = app.p4Knob_4.Value;
            p(2) = app.p5Knob_4.Value;
            p(3) = app.p6Knob_4.Value;
            p(4) = app.GKssKnob.Value;
            writematrix(p, save_path, "Sheet","Parameters", "Range","D2");
        end

        % Button pushed function: ModelParametersImportButton
        function ModelParametersImportButtonPushed(app, event)
            [file, path] = uigetfile(["*.xlsx";"*.csv"], "File Selector");
    
            % when closing window without action
            if (isequal(file, 0) || isequal(path, 0))
                return
            end
            
            % read parameters saved in Excel format
            param = readtable(fullfile(path, file));
            var_names = param.Properties.VariableNames;
            
            if any(contains(var_names, 'IKto'))
                app.ykto_idx = 1;
                app.IncludeCheckBox_1.Value = 1;
                
                pkto = param.IKto;
                
                % tuning parameters of IKto:
                % p1, p2, p3, p5, p6, p10, p13, p14, p16, p17
                app.p1Knob_1.Value = pkto(1);
                app.p2Knob_1.Value = pkto(2);
                app.p3Knob_1.Value = pkto(3);
                app.p4Knob_1.Value = pkto(4);
                app.p5Knob_1.Value = pkto(5);
                app.p6Knob_1.Value = pkto(6);
                app.p7Knob_1.Value = pkto(7);
                app.p8Knob_1.Value = pkto(8);
                app.p9Knob_1.Value = pkto(9);
                app.p10Knob_1.Value = pkto(10);
                app.p11Knob_1.Value = pkto(11);
                app.p12Knob_1.Value = pkto(12);
                app.p13Knob_1.Value = pkto(13);
                app.p14Knob_1.Value = pkto(14);
                app.fKtoKnob.Value = pkto(15);
                app.GKtoKnob.Value = pkto(16);
                app.GKtopKnob.Value = pkto(17);
            else
                app.ykto_idx = 0;
                app.IncludeCheckBox_1.Value = 0;
            end
            
            if any(contains(var_names, 'IKslow1'))
                app.ykslow1_idx = 1;
                app.IncludeCheckBox_2.Value = 1;
                
                pkslow1 = param.IKslow1;
                pkslow1 = pkslow1(~isnan(pkslow1));

                % tuning parameters of IKslow1:
                % p1, p2, p3, p5, p8, p9, p12, p13
                app.p1Knob_2.Value = pkslow1(1);
                app.p2Knob_2.Value = pkslow1(2);
                app.p3Knob_2.Value = pkslow1(3);
                app.p4Knob_2.Value = pkslow1(4);
                app.p5Knob_2.Value = pkslow1(5);
                app.p6Knob_2.Value = pkslow1(6);
                app.p7Knob_2.Value = pkslow1(7);
                app.p8Knob_2.Value = pkslow1(8);
                app.p9Knob_2.Value = pkslow1(9);
                app.p10Knob_2.Value = pkslow1(10);
                app.fKslow1Knob.Value = pkslow1(11);
                app.GKslow1Knob.Value = pkslow1(12);
                app.GKslow1pKnob.Value = pkslow1(13);                
            elseif any(contains(var_names, 'IKslow'))
                app.ykslow1_idx = 1;
                app.IncludeCheckBox_2.Value = 1;
                
                pkslow1 = param.IKslow;
                pkslow1 = pkslow1(~isnan(pkslow1));
                
                % tuning parameters of IKslow1:
                % p1, p2, p3, p5, p8, p9, p12, p13
                app.p1Knob_2.Value = pkslow1(1);
                app.p2Knob_2.Value = pkslow1(2);
                app.p3Knob_2.Value = pkslow1(3);
                app.p4Knob_2.Value = pkslow1(4);
                app.p5Knob_2.Value = pkslow1(5);
                app.p6Knob_2.Value = pkslow1(6);
                app.p7Knob_2.Value = pkslow1(7);
                app.p8Knob_2.Value = pkslow1(8);
                app.p9Knob_2.Value = pkslow1(9);
                app.p10Knob_2.Value = pkslow1(10);
                app.fKslow1Knob.Value = pkslow1(11);
                app.GKslow1Knob.Value = pkslow1(12);
                app.GKslow1pKnob.Value = pkslow1(13);
            else
                app.ykslow1_idx = 0;
                app.IncludeCheckBox_2.Value = 0;
            end
            
            if any(contains(var_names, 'IKslow2'))
                app.ykslow2_idx = 1;
                app.IncludeCheckBox_3.Value = 1;
                
                pkslow2 = param.IKslow2;
                pkslow2 = pkslow2(~isnan(pkslow2));
                
                % tuning parameters of IKslow2: p10. p11
                app.p9Knob_3.Value = pkslow2(1);
                app.p10Knob_3.Value = pkslow2(2);
                app.GKslow2Knob.Value = pkslow2(3);
            else
                app.ykslow2_idx = 0;
                app.IncludeCheckBox_3.Value = 0;
            end
            
            if any(contains(var_names, 'IKss'))
                app.ykss_idx = 1;
                app.IncludeCheckBox_4.Value = 1;
                
                pkss = param.IKss;
                pkss = pkss(~isnan(pkss));
                
                % tuning parameters of IKss: p6, p7
                app.p4Knob_4.Value = pkss(1);
                app.p5Knob_4.Value = pkss(2);
                app.p6Knob_4.Value = pkss(3);
                app.GKssKnob.Value = pkss(4);
            else
                app.ykss_idx = 0;
                app.IncludeCheckBox_4.Value = 0;
            end                        
            
            % update currents
            app.ykto_set = gen_currents_over_volts(app, @gen_ikto);
            app.ykslow1_set = gen_currents_over_volts(app, @gen_ikslow1);
            app.ykslow2_set = gen_currents_over_volts(app, @gen_ikslow2);
            app.ykss_set = gen_currents_over_volts(app, @gen_ikss);
            update_sum_current(app);
            
            % update plots
            plot_currents_onevolt(app, app.volt_idx, app.highlight_idx);
            plot_sum_currents_allvolts(app);
        end

        % Value changed function: SelectVoltageDropDown
        function SelectVoltageDropDownValueChanged(app, event)
            value = app.SelectVoltageDropDown.Value;
            volt = split(value, " ");
            volt = str2double(volt(1));
            app.volt_idx = find(app.volts == volt);
            
            plot_currents_onevolt(app, app.volt_idx, app.highlight_idx);
        end

        % Button pushed function: ShowEquationsButton
        function ShowEquationsButton_1Pushed(app, event)
            % open IKto dialog
            ikto_dialog(app);
        end

        % Value changed function: IncludeCheckBox_1
        function IncludeCheckBox_1ValueChanged(app, event)
            value = app.IncludeCheckBox_1.Value;
            app.ykto_idx = value;
            
            app.ykto_set = gen_currents_over_volts(app, @gen_ikto);
            update_sum_current(app);
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, app.highlight_idx);            
        end

        % Button pushed function: HighlightButton_1
        function HighlightButton_1Pushed(app, event)
            % hightlight IKto (index 1)
            app.highlight_idx = 1;
            plot_currents_onevolt(app, app.volt_idx, app.highlight_idx);
        end

        % Button pushed function: ResetButton_1
        function ResetButton_1_1Pushed(app, event)
            % reset parameters of IKto to default
            reset_kto(app);
            
            app.ykto_set = gen_currents_over_volts(app, @gen_ikto);
            update_sum_current(app);
            
            plot_sum_currents_allvolts(app);
            
            app.highlight_idx = false;
            plot_currents_onevolt(app, app.volt_idx, app.highlight_idx);
        end

        % Value changing function: p1Knob_1
        function p1Knob_1ValueChanging(app, event)
            changingValue = event.Value;
            app.p1Knob_1.Value = changingValue;
            
            app.ykto_set = gen_currents_over_volts(app, @gen_ikto);
            update_sum_current(app);
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, app.highlight_idx);
        end

        % Value changing function: p2Knob_1
        function p2Knob_1ValueChanging(app, event)
            changingValue = event.Value;
            app.p2Knob_1.Value = changingValue;
            
            app.ykto_set = gen_currents_over_volts(app, @gen_ikto);
            update_sum_current(app);
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, app.highlight_idx);
        end

        % Value changing function: p3Knob_1
        function p3Knob_1ValueChanging(app, event)
            changingValue = event.Value;
            app.p3Knob_1.Value = changingValue;
            
            app.ykto_set = gen_currents_over_volts(app, @gen_ikto);
            update_sum_current(app);
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, app.highlight_idx);
        end

        % Value changing function: p4Knob_1
        function p4Knob_1ValueChanging(app, event)
            changingValue = event.Value;
            app.p4Knob_1.Value = changingValue;
            
            app.ykto_set = gen_currents_over_volts(app, @gen_ikto);
            update_sum_current(app);
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, app.highlight_idx);
        end

        % Value changing function: p5Knob_1
        function p5Knob_1ValueChanging(app, event)
            changingValue = event.Value;
            app.p5Knob_1.Value = changingValue;
            
            app.ykto_set = gen_currents_over_volts(app, @gen_ikto);
            update_sum_current(app);
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, app.highlight_idx);
        end

        % Value changing function: p6Knob_1
        function p6Knob_1ValueChanging(app, event)
            changingValue = event.Value;
            app.p6Knob_1.Value = changingValue;
            
            app.ykto_set = gen_currents_over_volts(app, @gen_ikto);
            update_sum_current(app);
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, app.highlight_idx);
        end

        % Value changing function: p7Knob_1
        function p7Knob_1ValueChanging(app, event)
            changingValue = event.Value;
            app.p7Knob_1.Value = changingValue;
            
            app.ykto_set = gen_currents_over_volts(app, @gen_ikto);
            update_sum_current(app);
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, app.highlight_idx);
        end

        % Value changing function: p8Knob_1
        function p8Knob_1ValueChanging(app, event)
            changingValue = event.Value;
            app.p8Knob_1.Value = changingValue;
            
            app.ykto_set = gen_currents_over_volts(app, @gen_ikto);
            update_sum_current(app);
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, app.highlight_idx);
        end

        % Value changing function: p9Knob_1
        function p9Knob_1ValueChanging(app, event)
            changingValue = event.Value;
            app.p9Knob_1.Value = changingValue;
            
            app.ykto_set = gen_currents_over_volts(app, @gen_ikto);
            update_sum_current(app);
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, app.highlight_idx);
        end

        % Value changing function: p10Knob_1
        function p10Knob_1ValueChanging(app, event)
            changingValue = event.Value;
            app.p10Knob_1.Value = changingValue;
            
            app.ykto_set = gen_currents_over_volts(app, @gen_ikto);
            update_sum_current(app);
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, app.highlight_idx);
        end

        % Value changing function: p11Knob_1
        function p11Knob_1ValueChanging(app, event)
            changingValue = event.Value;
            app.p11Knob_1.Value = changingValue;
            
            app.ykto_set = gen_currents_over_volts(app, @gen_ikto);
            update_sum_current(app);
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, app.highlight_idx);
        end

        % Value changing function: p12Knob_1
        function p12Knob_1ValueChanging(app, event)
            changingValue = event.Value;
            app.p12Knob_1.Value = changingValue;
            
            app.ykto_set = gen_currents_over_volts(app, @gen_ikto);
            update_sum_current(app);
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, app.highlight_idx);
        end

        % Value changing function: p13Knob_1
        function p13Knob_1ValueChanging(app, event)
            changingValue = event.Value;
            app.p13Knob_1.Value = changingValue;
            
            app.ykto_set = gen_currents_over_volts(app, @gen_ikto);
            update_sum_current(app);
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, app.highlight_idx);
        end

        % Value changing function: p14Knob_1
        function p14Knob_1ValueChanging(app, event)
            changingValue = event.Value;
            app.p14Knob_1.Value = changingValue;
            
            app.ykto_set = gen_currents_over_volts(app, @gen_ikto);
            update_sum_current(app);
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, app.highlight_idx);
        end

        % Value changing function: fKtoKnob
        function fKtoKnobValueChanging(app, event)
            changingValue = event.Value;
            app.fKtoKnob.Value = changingValue;
            
            app.ykto_set = gen_currents_over_volts(app, @gen_ikto);
            update_sum_current(app);
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, app.highlight_idx);
        end

        % Value changing function: GKtoKnob
        function GKtoKnobValueChanging2(app, event)
            changingValue = event.Value;
            app.GKtoKnob.Value = changingValue;
            
            app.ykto_set = gen_currents_over_volts(app, @gen_ikto);
            update_sum_current(app);
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, app.highlight_idx);
        end

        % Value changing function: GKtopKnob
        function GKtopKnobValueChanging(app, event)
            changingValue = event.Value;
            app.GKtopKnob.Value = changingValue;
            
            app.ykto_set = gen_currents_over_volts(app, @gen_ikto);
            update_sum_current(app);
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, app.highlight_idx);
        end

        % Value changed function: IncludeCheckBox_2
        function IncludeCheckBox_2ValueChanged(app, event)
            value = app.IncludeCheckBox_2.Value;
            app.ykslow1_idx = value;
            
            app.ykslow1_set = gen_currents_over_volts(app, @gen_ikslow1);
            update_sum_current(app);
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, app.highlight_idx);              
        end

        % Button pushed function: ShowEquationsButton_2
        function ShowEquationsButton_2Pushed(app, event)
            % open IKslow1 dialog
            app.Kslow1DialogApp = ikslow1_dialog(app);
        end

        % Button pushed function: HighlightButton_2
        function HighlightButton_2Pushed(app, event)
            % highlight IKslow1 (index2)
            app.highlight_idx = 2;
            plot_currents_onevolt(app, app.volt_idx, app.highlight_idx);
        end

        % Button pushed function: ResetButton_2
        function ResetButton_2Pushed(app, event)
            % reset parameters of IKslow1 to default
            reset_kslow1(app);
            
            app.ykslow1_set = gen_currents_over_volts(app, @gen_ikslow1);
            app.ykslow2_set = gen_currents_over_volts(app, @gen_ikslow2);
            app.ykss_set = gen_currents_over_volts(app, @gen_ikss);

            update_sum_current(app);
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, app.highlight_idx); 
        end

        % Value changing function: p1Knob_2
        function p1Knob_2ValueChanging(app, event)
            changingValue = event.Value;
            
            % update IKslow1 knob
            app.p1Knob_2.Value = changingValue;
            
            app.ykslow1_set = gen_currents_over_volts(app, @gen_ikslow1);
            app.ykslow2_set = gen_currents_over_volts(app, @gen_ikslow2);
            app.ykss_set = gen_currents_over_volts(app, @gen_ikss);
            
            % update IKsum
            update_sum_current(app);
            
            % update figures
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, app.highlight_idx);             
        end

        % Value changing function: p2Knob_2
        function p2Knob_2ValueChanging(app, event)
            changingValue = event.Value;

            % update IKslow1 knob
            app.p2Knob_2.Value = changingValue;
            
            app.ykslow1_set = gen_currents_over_volts(app, @gen_ikslow1);
            app.ykslow2_set = gen_currents_over_volts(app, @gen_ikslow2);
           
            % update IKsum
            update_sum_current(app);
            
            % update figures
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, app.highlight_idx);
        end

        % Value changing function: p3Knob_2
        function p3Knob_2ValueChanging(app, event)
            changingValue = event.Value;
            
            % update IKslow1 knob and text box in IKss
            app.p3Knob_2.Value = changingValue;
            app.p3_1Text_4.Value = changingValue;
            app.p3_2Text_4.Value = changingValue;
            
            app.ykslow1_set = gen_currents_over_volts(app, @gen_ikslow1);
            app.ykslow2_set = gen_currents_over_volts(app, @gen_ikslow2);            
            app.ykss_set = gen_currents_over_volts(app, @gen_ikss);
            
            % update IKsum
            update_sum_current(app);
            
            % update figures
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, app.highlight_idx);                    
        end

        % Value changing function: p4Knob_2
        function p4Knob_2ValueChanging(app, event)
            changingValue = event.Value;
            
            % update IKslow1 knob
            app.p4Knob_2.Value = changingValue;

            app.ykslow1_set = gen_currents_over_volts(app, @gen_ikslow1);
            app.ykslow2_set = gen_currents_over_volts(app, @gen_ikslow2);
            app.ykss_set = gen_currents_over_volts(app, @gen_ikss);
            
            % update IKsum
            update_sum_current(app);
            
            % update figures
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, app.highlight_idx);                    
        end

        % Value changing function: p5Knob_2
        function p5Knob_2ValueChanging(app, event)
            changingValue = event.Value;
            
            % update IKslow1 knob
            app.p5Knob_2.Value = changingValue;
            app.ykslow1_set = gen_currents_over_volts(app, @gen_ikslow1);
            app.ykslow2_set = gen_currents_over_volts(app, @gen_ikslow2);
           
            % update IKsum
            update_sum_current(app);
            
            % update figures
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, app.highlight_idx);            
        end

        % Value changing function: p6Knob_2
        function p6Knob_2ValueChanging(app, event)
            changingValue = event.Value;
            
            % update IKslow1 knob
            app.p6Knob_2.Value = changingValue;
            app.ykslow1_set = gen_currents_over_volts(app, @gen_ikslow1);
            app.ykslow2_set = gen_currents_over_volts(app, @gen_ikslow2);
           
            % update IKsum
            update_sum_current(app);
            
            % update figures
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, app.highlight_idx);            
        end

        % Value changing function: p7Knob_2
        function p7Knob_2ValueChanging(app, event)
            changingValue = event.Value;
            
            % update IKslow1 knob
            app.p7Knob_2.Value = changingValue;
            app.ykslow1_set = gen_currents_over_volts(app, @gen_ikslow1);
            app.ykslow2_set = gen_currents_over_volts(app, @gen_ikslow2);
           
            % update IKsum
            update_sum_current(app);
            
            % update figures
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, app.highlight_idx);            
        end

        % Value changing function: p8Knob_2
        function p8Knob_2ValueChanging(app, event)
            changingValue = event.Value;
            
            % update IKslow1 knob
            app.p8Knob_2.Value = changingValue;
            app.ykslow1_set = gen_currents_over_volts(app, @gen_ikslow1);
            app.ykslow2_set = gen_currents_over_volts(app, @gen_ikslow2);
           
            % update IKsum
            update_sum_current(app);
            
            % update figures
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, app.highlight_idx);            
        end

        % Value changing function: p9Knob_2
        function p9Knob_2ValueChanging(app, event)
            changingValue = event.Value;
            
            % update IKslow1 knob
            app.p9Knob_2.Value = changingValue;
            app.ykslow1_set = gen_currents_over_volts(app, @gen_ikslow1);
            
            % update IKsum
            update_sum_current(app);
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, app.highlight_idx);
        end

        % Value changing function: p10Knob_2
        function p10Knob_2ValueChanging(app, event)
            changingValue = event.Value;
            
            % update IKslow1 knob
            app.p10Knob_2.Value = changingValue;
            app.ykslow1_set = gen_currents_over_volts(app, @gen_ikslow1);
            
            % update IKsum
            update_sum_current(app);
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, app.highlight_idx);
        end

        % Value changing function: fKslow1Knob
        function fKslow1KnobValueChanging(app, event)
            changingValue = event.Value;
            
            % update IKslow1 knob
            app.fKslow1Knob.Value = changingValue;
            app.ykslow1_set = gen_currents_over_volts(app, @gen_ikslow1);
            
            % update IKsum
            update_sum_current(app);
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, app.highlight_idx);
        end

        % Value changing function: GKslow1Knob
        function GKslow1KnobValueChanging(app, event)
            changingValue = event.Value;
            
            % update IKslow1 knob
            app.GKslow1Knob.Value = changingValue;
            app.ykslow1_set = gen_currents_over_volts(app, @gen_ikslow1);
            
            % update IKsum
            update_sum_current(app);
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, app.highlight_idx);
        end

        % Value changing function: GKslow1pKnob
        function GKslow1pKnobValueChanging(app, event)
            changingValue = event.Value;
            
            % update IKslow1 knob
            app.GKslow1pKnob.Value = changingValue;
            app.ykslow1_set = gen_currents_over_volts(app, @gen_ikslow1);
            
            % update IKsum
            update_sum_current(app);
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, app.highlight_idx);
        end

        % Value changed function: IncludeCheckBox_3
        function IncludeCheckBox_3ValueChanged(app, event)
            value = app.IncludeCheckBox_3.Value;
            app.ykslow2_idx = value;
            
            app.ykslow2_set = gen_currents_over_volts(app, @gen_ikslow2);
            update_sum_current(app);
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, app.highlight_idx);            
        end

        % Button pushed function: HighlightButton_3
        function HighlightButton_3Pushed(app, event)
            % highlight IKslow2 (index3)
            app.highlight_idx = 3;
            plot_currents_onevolt(app, app.volt_idx, app.highlight_idx);
        end

        % Button pushed function: ResetButton_3
        function ResetButton_3Pushed(app, event)
            % reset parameters of IKslow2 to default
            reset_kslow2(app);
            
            app.ykslow2_set = gen_currents_over_volts(app, @gen_ikslow2);
            update_sum_current(app);
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, app.highlight_idx);             
        end

        % Value changing function: p9Knob_3
        function p9Knob_3ValueChanging(app, event)
            changingValue = event.Value;
            
            % update knob and text box
            app.p9Knob_3.Value = changingValue;
            app.p9Text_3.Value = changingValue;
            
            app.ykslow2_set = gen_currents_over_volts(app, @gen_ikslow2);
            
            % update IKsum
            update_sum_current(app);
            
            % update figures
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, app.highlight_idx);
        end

        % Value changed function: p9Text_3
        function p9Text_3ValueChanged(app, event)
            value = app.p9Text_3.Value;
            
            % update knob and text box
            app.p9Knob_3.Value = value;
            app.p9Text_3.Value = value;
            
            app.ykslow2_set = gen_currents_over_volts(app, @gen_ikslow2);
            
            % update IKsum
            update_sum_current(app);
            
            % update figures
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, app.highlight_idx);            
        end

        % Value changing function: p10Knob_3
        function p10Knob_3ValueChanging(app, event)
            changingValue = event.Value;
            
            % update knob and text box
            app.p10Knob_3.Value = changingValue;
            app.p10Text_3.Value = changingValue;
            
            app.ykslow2_set = gen_currents_over_volts(app, @gen_ikslow2);
            
            % update IKsum
            update_sum_current(app);
            
            % update figures
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, app.highlight_idx);
        end

        % Value changed function: p10Text_3
        function p10Text_3ValueChanged(app, event)
            value = app.p10Text_3.Value;
            
            % update knob and text box
            app.p10Knob_3.Value = value;
            app.p10Text_3.Value = value;
            
            app.ykslow2_set = gen_currents_over_volts(app, @gen_ikslow2);
            
            % update IKsum
            update_sum_current(app);
            
            % update figures
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, app.highlight_idx);
        end

        % Value changing function: GKslow2Knob
        function GKslow2KnobValueChanging(app, event)
            changingValue = event.Value;

            % update knob and text box
            app.GKslow2Knob.Value = changingValue;
            app.GKslow2Text.Value = changingValue;
            
            app.ykslow2_set = gen_currents_over_volts(app, @gen_ikslow2);
            
            % update IKsum
            update_sum_current(app);
            
            % update figures
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, app.highlight_idx);
        end

        % Value changed function: GKslow2Text
        function GKslow2TextValueChanged(app, event)
            value = app.GKslow2Text.Value;
            
            % update knob and text box
            app.GKslow2Knob.Value = value;
            app.GKslow2Text.Value = value;
            
            app.ykslow2_set = gen_currents_over_volts(app, @gen_ikslow2);
            
            % update IKsum
            update_sum_current(app);
            
            % update figures
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, app.highlight_idx);            
        end

        % Value changed function: IncludeCheckBox_4
        function IncludeCheckBox_4ValueChanged(app, event)
            value = app.IncludeCheckBox_4.Value;
            app.ykss_idx = value;
            
            app.ykss_set = gen_currents_over_volts(app, @gen_ikss);
            update_sum_current(app);
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, app.highlight_idx);  
        end

        % Button pushed function: HightlightButton_4
        function HightlightButton_4Pushed(app, event)
            % highlight IKslow2 (index4)
            app.highlight_idx = 4;
            plot_currents_onevolt(app, app.volt_idx, app.highlight_idx);
        end

        % Button pushed function: ResetButton_4
        function ResetButton_4Pushed(app, event)
            % reset parameters of IKss to default
            reset_kss(app);
            
            app.ykss_set = gen_currents_over_volts(app, @gen_ikss);
            update_sum_current(app);
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, app.highlight_idx);             
        end

        % Value changed function: p3_1Text_4
        function p3_1Text_4ValueChanged(app, event)
            value = app.p3_1Text_4.Value;
            
            % update IKslow1 knob and text box in IKss
            app.p3Knob_2.Value = value;
            app.p3_1Text_4.Value = value;
            app.p3_2Text_4.Value = value;
            
            app.ykslow1_set = gen_currents_over_volts(app, @gen_ikslow1);
            app.ykslow2_set = gen_currents_over_volts(app, @gen_ikslow2);            
            app.ykss_set = gen_currents_over_volts(app, @gen_ikss);
            
            % update IKsum
            update_sum_current(app);
            
            % update figures
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, app.highlight_idx);              
        end

        % Value changed function: p3_2Text_4
        function p3_2Text_4ValueChanged(app, event)
            value = app.p3_2Text_4.Value;
            
            % update IKslow1 knob and text box in IKss
            app.p3Knob_2.Value = value;
            app.p3_1Text_4.Value = value;
            app.p3_2Text_4.Value = value;
            
            app.ykslow1_set = gen_currents_over_volts(app, @gen_ikslow1);
            app.ykslow2_set = gen_currents_over_volts(app, @gen_ikslow2);            
            app.ykss_set = gen_currents_over_volts(app, @gen_ikss);
            
            % update IKsum
            update_sum_current(app);
            
            % update figures
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, app.highlight_idx);
        end

        % Value changing function: p4Knob_4
        function p4Knob_4ValueChanging(app, event)
            changingValue = event.Value;
            
            % update knob and text box
            app.p4Knob_4.Value = changingValue;
            app.p4_1Text_4.Value = changingValue;
            app.p4_2Text_4.Value = changingValue;
            
            app.ykss_set = gen_currents_over_volts(app, @gen_ikss);
            
            % update IKsum
            update_sum_current(app);
            
            % update figures
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, app.highlight_idx);
        end

        % Value changed function: p4_1Text_4
        function p4_1Text_4ValueChanged(app, event)
            value = app.p4_1Text_4.Value;
            
            % update knob and text box
            app.p4Knob_4.Value = value;
            app.p4_1Text_4.Value = value;
            app.p4_2Text_4.Value = value;
            
            app.ykss_set = gen_currents_over_volts(app, @gen_ikss);
            
            % update IKsum
            update_sum_current(app);
            
            % update figures
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, app.highlight_idx);
        end

        % Value changed function: p4_2Text_4
        function p4_2Text_4ValueChanged(app, event)
            value = app.p4_2Text_4.Value;
            
            % update knob and text box
            app.p4Knob_4.Value = value;
            app.p4_1Text_4.Value = value;
            app.p4_2Text_4.Value = value;
            
            app.ykss_set = gen_currents_over_volts(app, @gen_ikss);
            
            % update IKsum
            update_sum_current(app);
            
            % update figures
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, app.highlight_idx);            
        end

        % Value changing function: p5Knob_4
        function p5Knob_4ValueChanging(app, event)
            changingValue = event.Value;
            
            % update knob and text box
            app.p5Knob_4.Value = changingValue;
            app.p5Text_4.Value = changingValue;
            
            app.ykss_set = gen_currents_over_volts(app, @gen_ikss);
            
            % update IKsum
            update_sum_current(app);
            
            % update figures
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, app.highlight_idx);
        end

        % Value changed function: p5Text_4
        function p5Text_4ValueChanged(app, event)
            value = app.p5Text_4.Value;
            
            % update knob and text box
            app.p5Knob_4.Value = value;
            app.p5Text_4.Value = value;
            
            app.ykss_set = gen_currents_over_volts(app, @gen_ikss);
            
            % update IKsum
            update_sum_current(app);
            
            % update figures
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, app.highlight_idx);
        end

        % Value changing function: p6Knob_4
        function p6Knob_4ValueChanging(app, event)
            changingValue = event.Value;
            
            % update knob and text box
            app.p6Knob_4.Value = changingValue;
            app.p6Text_4.Value = changingValue;
            
            app.ykss_set = gen_currents_over_volts(app, @gen_ikss);
            
            % update IKsum
            update_sum_current(app);
            
            % update figures
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, app.highlight_idx);
        end

        % Value changed function: p6Text_4
        function p6Text_4ValueChanged(app, event)
            value = app.p6Text_4.Value;
            
            % update knob and text box
            app.p6Knob_4.Value = value;
            app.p6Text_4.Value = value;
            
            app.ykss_set = gen_currents_over_volts(app, @gen_ikss);
            
            % update IKsum
            update_sum_current(app);
            
            % update figures
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, app.highlight_idx);
        end

        % Value changing function: GKssKnob
        function GKssKnobValueChanging(app, event)
            changingValue = event.Value;
            
            % update knob and text box
            app.GKssKnob.Value = changingValue;
            app.GKssText.Value = changingValue;
            
            app.ykss_set = gen_currents_over_volts(app, @gen_ikss);
            
            % update IKsum
            update_sum_current(app);
            
            % update figures
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, app.highlight_idx);
        end

        % Value changed function: GKssText
        function GKssTextValueChanged(app, event)
            value = app.GKssText.Value;

            % update knob and text box
            app.GKssKnob.Value = value;
            app.GKssText.Value = value;
            
            app.ykss_set = gen_currents_over_volts(app, @gen_ikss);
            
            % update IKsum
            update_sum_current(app);
            
            % update figures
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, app.highlight_idx);            
        end

        % Button pushed function: ResetButton_6
        function ResetButton_6Pushed(app, event)
            reset_k1(app);
            
            app.yk1_set = gen_currents_over_volts(app, @gen_ik1);
            update_sum_current(app);
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, app.highlight_idx);
        end

        % Value changing function: p1Knob_6
        function p1Knob_6ValueChanging(app, event)
            changingValue = event.Value;
            
            app.p1Knob_6.Value = changingValue;
            
            app.yk1_set = gen_currents_over_volts(app, @gen_ik1);
            update_sum_current(app);
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, app.highlight_idx);
        end

        % Value changing function: p2Knob_6
        function p2Knob_6ValueChanging(app, event)
            changingValue = event.Value;
            
            app.p2Knob_6.Value = changingValue;
            
            app.yk1_set = gen_currents_over_volts(app, @gen_ik1);
            update_sum_current(app);
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, app.highlight_idx);            
        end

        % Value changing function: p3Knob_6
        function p3Knob_6ValueChanging(app, event)
            changingValue = event.Value;
           
            app.p3Knob_6.Value = changingValue;
            
            app.yk1_set = gen_currents_over_volts(app, @gen_ik1);
            update_sum_current(app);
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, app.highlight_idx);            
        end

        % Value changing function: p4Knob_6
        function p4Knob_6ValueChanging(app, event)
            changingValue = event.Value;
            
            app.p4Knob_6.Value = changingValue;
            
            app.yk1_set = gen_currents_over_volts(app, @gen_ik1);
            update_sum_current(app);
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, app.highlight_idx);            
        end

        % Value changing function: p5Knob_6
        function p5Knob_6ValueChanging(app, event)
            changingValue = event.Value;
            
            app.p5Knob_6.Value = changingValue;
            
            app.yk1_set = gen_currents_over_volts(app, @gen_ik1);
            update_sum_current(app);
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, app.highlight_idx);            
        end

        % Value changing function: p6Knob_6
        function p6Knob_6ValueChanging(app, event)
            changingValue = event.Value;
            
            app.p6Knob_6.Value = changingValue;
            
            app.yk1_set = gen_currents_over_volts(app, @gen_ik1);
            update_sum_current(app);
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, app.highlight_idx);            
        end

        % Value changing function: p7Knob_6
        function p7Knob_6ValueChanging(app, event)
            changingValue = event.Value;
            
            app.p4Knob_6.Value = changingValue;
            
            app.yk1_set = gen_currents_over_volts(app, @gen_ik1);
            update_sum_current(app);
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, app.highlight_idx);            
        end

        % Value changing function: p8Knob_6
        function p8Knob_6ValueChanging(app, event)
            changingValue = event.Value;
            
            app.p8Knob_6.Value = changingValue;
            
            app.yk1_set = gen_currents_over_volts(app, @gen_ik1);
            update_sum_current(app);
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, app.highlight_idx);            
        end

        % Value changing function: p9Knob_6
        function p9Knob_6ValueChanging(app, event)
            changingValue = event.Value;
            
            app.p9Knob_6.Value = changingValue;
            
            app.yk1_set = gen_currents_over_volts(app, @gen_ik1);
            update_sum_current(app);
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, app.highlight_idx);            
        end

        % Value changing function: p10Knob_6
        function p10Knob_6ValueChanging(app, event)
            changingValue = event.Value;
            
            app.p10Knob_6.Value = changingValue;
            
            app.yk1_set = gen_currents_over_volts(app, @gen_ik1);
            update_sum_current(app);
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, app.highlight_idx);            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.ProtocolTab = uitab(app.TabGroup);
            app.ProtocolTab.Title = 'Protocol';
            app.UIAxesTopLeft = uiaxes(app.ProtocolTab);
            title(app.UIAxesTopLeft, 'Protocol')
            xlabel(app.UIAxesTopLeft, 'Time (ms)')
            ylabel(app.UIAxesTopLeft, 'Voltage (mV)')
            zlabel(app.UIAxesTopLeft, 'Z')
            app.UIAxesBottomLeft = uiaxes(app.ProtocolTab);
            title(app.UIAxesBottomLeft, 'Sum of K^{+} Currents (Experimental Data)')
            xlabel(app.UIAxesBottomLeft, 'Time (ms)')
            ylabel(app.UIAxesBottomLeft, 'Current (pA/pF)')
            zlabel(app.UIAxesBottomLeft, 'Z')
            app.HoldingtimemsEditFieldLabel = uilabel(app.ProtocolTab);
            app.HoldingtimemsEditFieldLabel.Text = 'Holding time (ms)';
            app.HoldingtimemsEditField = uieditfield(app.ProtocolTab, 'numeric');
            app.HoldingtimemsEditField.Value = 120;
            app.HoldingpotentialmVEditFieldLabel = uilabel(app.ProtocolTab);
            app.HoldingpotentialmVEditFieldLabel.Text = 'Holding potential (mV)';
            app.HoldingpotentialmVEditField = uieditfield(app.ProtocolTab, 'numeric');
            app.HoldingpotentialmVEditField.Value = -70;
            app.ClampingvoltageminmVEditFieldLabel = uilabel(app.ProtocolTab);
            app.ClampingvoltageminmVEditFieldLabel.Text = 'Clamping voltage min (mV)';
            app.ClampingvoltageminmVEditField = uieditfield(app.ProtocolTab, 'numeric');
            app.ClampingvoltageminmVEditField.Value = -50;
            app.ClampingtimemsEditFieldLabel = uilabel(app.ProtocolTab);
            app.ClampingtimemsEditFieldLabel.Text = 'Clamping time (ms)';
            app.ClampingtimemsEditField = uieditfield(app.ProtocolTab, 'numeric');
            app.ClampingtimemsEditField.ValueDisplayFormat = '%.0f';
            app.ClampingtimemsEditField.Value = 4500;
            app.ProtocolVisualizationUpdateButton = uibutton(app.ProtocolTab, 'push');
            app.ProtocolVisualizationUpdateButton.ButtonPushedFcn = createCallbackFcn(app, @ProtocolVisualizationUpdateButtonPushed, true);
            app.ProtocolVisualizationUpdateButton.Text = 'Protocol Visualization & Update';
            app.NernstpotentialEkmVLabel = uilabel(app.ProtocolTab);
            app.NernstpotentialEkmVLabel.Text = 'Nernst potential Ek (mV)';
            app.NernstpotentialEkmVEditField = uieditfield(app.ProtocolTab, 'numeric');
            app.NernstpotentialEkmVEditField.Value = -91.1;
            app.DatauploadButton = uibutton(app.ProtocolTab, 'push');
            app.DatauploadButton.ButtonPushedFcn = createCallbackFcn(app, @DatauploadButtonPushed, true);
            app.DatauploadButton.Text = 'Data upload';
            app.ClampingvoltagestepmVEditFieldLabel = uilabel(app.ProtocolTab);
            app.ClampingvoltagestepmVEditFieldLabel.Text = 'Clamping voltage step (mV)';
            app.ClampingvoltagestepmVEditField = uieditfield(app.ProtocolTab, 'numeric');
            app.ClampingvoltagestepmVEditField.Value = 10;
            app.ClampingvoltagemaxmVEditFieldLabel = uilabel(app.ProtocolTab);
            app.ClampingvoltagemaxmVEditFieldLabel.Text = 'Clamping voltage max (mV)';
            app.ClampingvoltagemaxmVEditField = uieditfield(app.ProtocolTab, 'numeric');
            app.ClampingvoltagemaxmVEditField.Value = 50;
            app.ModelCalibrationButton = uibutton(app.ProtocolTab, 'push');
            app.ModelCalibrationButton.ButtonPushedFcn = createCallbackFcn(app, @ModelCalibrationButtonPushed, true);
            app.ModelCalibrationButton.Text = 'Model Calibration';
            app.DataClearButton = uibutton(app.ProtocolTab, 'push');
            app.DataClearButton.ButtonPushedFcn = createCallbackFcn(app, @DataClearButtonPushed, true);
            app.DataClearButton.Text = 'Data Clear';
            app.ModelParametersSaveButton = uibutton(app.ProtocolTab, 'push');
            app.ModelParametersSaveButton.ButtonPushedFcn = createCallbackFcn(app, @ModelParametersSaveButtonPushed, true);
            app.ModelParametersSaveButton.Text = 'Model Parameters Save';
            app.ModelParametersImportButton = uibutton(app.ProtocolTab, 'push');
            app.ModelParametersImportButton.ButtonPushedFcn = createCallbackFcn(app, @ModelParametersImportButtonPushed, true);
            app.ModelParametersImportButton.Text = 'Model Parameters Import';
            app.IKtofKv42Tab = uitab(app.TabGroup);
            app.IKtofKv42Tab.Title = 'IKto,f (Kv 4.2)';
            app.TextArea_1 = uitextarea(app.IKtofKv42Tab);
            app.TextArea_1.Value = {'IKto: Transient outward K+ current '};
            app.ShowEquationsButton = uibutton(app.IKtofKv42Tab, 'push');
            app.ShowEquationsButton.ButtonPushedFcn = createCallbackFcn(app, @ShowEquationsButton_1Pushed, true);
            app.ShowEquationsButton.Text = 'Show Equations';
            app.ResetButton_1 = uibutton(app.IKtofKv42Tab, 'push');
            app.ResetButton_1.ButtonPushedFcn = createCallbackFcn(app, @ResetButton_1_1Pushed, true);
            app.ResetButton_1.Text = 'Reset';
            app.fKtoKnobLabel = uilabel(app.IKtofKv42Tab);
            app.fKtoKnobLabel.Text = 'fKto';
            app.fKtoKnob = uiknob(app.IKtofKv42Tab, 'continuous');
            app.fKtoKnob.Limits = [0 1];
            app.fKtoKnob.ValueChangingFcn = createCallbackFcn(app, @fKtoKnobValueChanging, true);
            app.fKtoKnob.Value = 0.2087704319;
            app.GKtoKnobLabel = uilabel(app.IKtofKv42Tab);
            app.GKtoKnobLabel.Text = 'GKto';
            app.GKtoKnob = uiknob(app.IKtofKv42Tab, 'continuous');
            app.GKtoKnob.Limits = [0 1];
            app.GKtoKnob.ValueChangingFcn = createCallbackFcn(app, @GKtoKnobValueChanging2, true);
            app.GKtoKnob.Value = 0.14067;
            app.GKtopKnobLabel = uilabel(app.IKtofKv42Tab);
            app.GKtopKnobLabel.Text = 'GKto,p';
            app.GKtopKnob = uiknob(app.IKtofKv42Tab, 'continuous');
            app.GKtopKnob.Limits = [0 1];
            app.GKtopKnob.ValueChangingFcn = createCallbackFcn(app, @GKtopKnobValueChanging, true);
            app.GKtopKnob.Value = 0.387;
            app.p1Knob_6Label = uilabel(app.IKtofKv42Tab);
            app.p1Knob_6Label.Text = 'p1';
            app.p1Knob_1 = uiknob(app.IKtofKv42Tab, 'continuous');
            app.p1Knob_1.Limits = [-50 70];
            app.p1Knob_1.MajorTicks = [-70 -60 -50 -40 -30 -20 -10 0 10 20 30 40 50 60 70];
            app.p1Knob_1.ValueChangingFcn = createCallbackFcn(app, @p1Knob_1ValueChanging, true);
            app.p1Knob_1.Value = 33;
            app.p2Knob_6Label = uilabel(app.IKtofKv42Tab);
            app.p2Knob_6Label.Text = 'p2';
            app.p2Knob_1 = uiknob(app.IKtofKv42Tab, 'continuous');
            app.p2Knob_1.Limits = [-50 70];
            app.p2Knob_1.ValueChangingFcn = createCallbackFcn(app, @p2Knob_1ValueChanging, true);
            app.p2Knob_1.Value = 15.5;
            app.p3Knob_6Label = uilabel(app.IKtofKv42Tab);
            app.p3Knob_6Label.Text = 'p3';
            app.p3Knob_1 = uiknob(app.IKtofKv42Tab, 'continuous');
            app.p3Knob_1.Limits = [-50 70];
            app.p3Knob_1.ValueChangingFcn = createCallbackFcn(app, @p3Knob_1ValueChanging, true);
            app.p3Knob_1.Value = 20;
            app.p4Knob_6Label = uilabel(app.IKtofKv42Tab);
            app.p4Knob_6Label.Text = 'p4';
            app.p4Knob_1 = uiknob(app.IKtofKv42Tab, 'continuous');
            app.p4Knob_1.Limits = [-10 50];
            app.p4Knob_1.ValueChangingFcn = createCallbackFcn(app, @p4Knob_1ValueChanging, true);
            app.p4Knob_1.Value = 16;
            app.p5Knob_6Label = uilabel(app.IKtofKv42Tab);
            app.p5Knob_6Label.Text = 'p5';
            app.p5Knob_1 = uiknob(app.IKtofKv42Tab, 'continuous');
            app.p5Knob_1.Limits = [0 50];
            app.p5Knob_1.ValueChangingFcn = createCallbackFcn(app, @p5Knob_1ValueChanging, true);
            app.p5Knob_1.Value = 8;
            app.p6Knob_5Label = uilabel(app.IKtofKv42Tab);
            app.p6Knob_5Label.Text = 'p6';
            app.p6Knob_1 = uiknob(app.IKtofKv42Tab, 'continuous');
            app.p6Knob_1.Limits = [0 30];
            app.p6Knob_1.ValueChangingFcn = createCallbackFcn(app, @p6Knob_1ValueChanging, true);
            app.p6Knob_1.Value = 7;
            app.p7Knob_5Label = uilabel(app.IKtofKv42Tab);
            app.p7Knob_5Label.Text = 'p7';
            app.p7Knob_1 = uiknob(app.IKtofKv42Tab, 'continuous');
            app.p7Knob_1.Limits = [0 1];
            app.p7Knob_1.ValueChangingFcn = createCallbackFcn(app, @p7Knob_1ValueChanging, true);
            app.p7Knob_1.Value = 0.03577;
            app.p8Knob_5Label = uilabel(app.IKtofKv42Tab);
            app.p8Knob_5Label.Text = 'p8';
            app.p8Knob_1 = uiknob(app.IKtofKv42Tab, 'continuous');
            app.p8Knob_1.Limits = [0 1];
            app.p8Knob_1.ValueChangingFcn = createCallbackFcn(app, @p8Knob_1ValueChanging, true);
            app.p8Knob_1.Value = 0.06237;
            app.p9Knob_5Label = uilabel(app.IKtofKv42Tab);
            app.p9Knob_5Label.Text = 'p9';
            app.p9Knob_1 = uiknob(app.IKtofKv42Tab, 'continuous');
            app.p9Knob_1.Limits = [0 1];
            app.p9Knob_1.ValueChangingFcn = createCallbackFcn(app, @p9Knob_1ValueChanging, true);
            app.p9Knob_1.Value = 0.18064;
            app.p10KnobLabel = uilabel(app.IKtofKv42Tab);
            app.p10KnobLabel.Text = 'p10';
            app.p10Knob_1 = uiknob(app.IKtofKv42Tab, 'continuous');
            app.p10Knob_1.Limits = [0 10];
            app.p10Knob_1.ValueChangingFcn = createCallbackFcn(app, @p10Knob_1ValueChanging, true);
            app.p10Knob_1.Value = 0.3956;
            app.p11KnobLabel = uilabel(app.IKtofKv42Tab);
            app.p11KnobLabel.Text = 'p11';
            app.p11Knob_1 = uiknob(app.IKtofKv42Tab, 'continuous');
            app.p11Knob_1.Limits = [0 0.005];
            app.p11Knob_1.ValueChangingFcn = createCallbackFcn(app, @p11Knob_1ValueChanging, true);
            app.p11Knob_1.Value = 0.000152;
            app.p12KnobLabel = uilabel(app.IKtofKv42Tab);
            app.p12KnobLabel.Text = 'p12';
            app.p12Knob_1 = uiknob(app.IKtofKv42Tab, 'continuous');
            app.p12Knob_1.Limits = [0 0.3];
            app.p12Knob_1.ValueChangingFcn = createCallbackFcn(app, @p12Knob_1ValueChanging, true);
            app.p12Knob_1.Value = 0.067083;
            app.p13KnobLabel = uilabel(app.IKtofKv42Tab);
            app.p13KnobLabel.Text = 'p13';
            app.p13Knob_1 = uiknob(app.IKtofKv42Tab, 'continuous');
            app.p13Knob_1.Limits = [0 0.005];
            app.p13Knob_1.ValueChangingFcn = createCallbackFcn(app, @p13Knob_1ValueChanging, true);
            app.p13Knob_1.Value = 0.00095;
            app.p14KnobLabel = uilabel(app.IKtofKv42Tab);
            app.p14KnobLabel.Text = 'p14';
            app.p14Knob_1 = uiknob(app.IKtofKv42Tab, 'continuous');
            app.p14Knob_1.Limits = [0 0.5];
            app.p14Knob_1.ValueChangingFcn = createCallbackFcn(app, @p14Knob_1ValueChanging, true);
            app.p14Knob_1.Value = 0.051335;
            app.HighlightButton_1 = uibutton(app.IKtofKv42Tab, 'push');
            app.HighlightButton_1.ButtonPushedFcn = createCallbackFcn(app, @HighlightButton_1Pushed, true);
            app.HighlightButton_1.Text = 'Highlight';
            app.IncludeCheckBox_1 = uicheckbox(app.IKtofKv42Tab);
            app.IncludeCheckBox_1.ValueChangedFcn = createCallbackFcn(app, @IncludeCheckBox_1ValueChanged, true);
            app.IncludeCheckBox_1.Text = 'Include';
            app.IncludeCheckBox_1.Value = true;
            app.IKslow1Kv15Tab = uitab(app.TabGroup);
            app.IKslow1Kv15Tab.Title = 'IKslow1 (Kv 1.5)';
            app.TextArea_2 = uitextarea(app.IKslow1Kv15Tab);
            app.TextArea_2.Value = {'IKslow1: Rapidly activating, slowly inactivating K+ current'};
            app.ShowEquationsButton_2 = uibutton(app.IKslow1Kv15Tab, 'push');
            app.ShowEquationsButton_2.ButtonPushedFcn = createCallbackFcn(app, @ShowEquationsButton_2Pushed, true);
            app.ShowEquationsButton_2.Text = 'Show Equations';
            app.ResetButton_2 = uibutton(app.IKslow1Kv15Tab, 'push');
            app.ResetButton_2.ButtonPushedFcn = createCallbackFcn(app, @ResetButton_2Pushed, true);
            app.ResetButton_2.Text = 'Reset';
            app.fKslow1KnobLabel = uilabel(app.IKslow1Kv15Tab);
            app.fKslow1KnobLabel.Text = 'fKslow1';
            app.fKslow1Knob = uiknob(app.IKslow1Kv15Tab, 'continuous');
            app.fKslow1Knob.Limits = [0 1];
            app.fKslow1Knob.ValueChangingFcn = createCallbackFcn(app, @fKslow1KnobValueChanging, true);
            app.fKslow1Knob.Value = 0.9214774521;
            app.GKslow1Label = uilabel(app.IKslow1Kv15Tab);
            app.GKslow1Label.Text = 'GKslow1';
            app.GKslow1Knob = uiknob(app.IKslow1Kv15Tab, 'continuous');
            app.GKslow1Knob.Limits = [0 1];
            app.GKslow1Knob.ValueChangingFcn = createCallbackFcn(app, @GKslow1KnobValueChanging, true);
            app.GKslow1Knob.Value = 0.05766;
            app.GKslo1pLabel = uilabel(app.IKslow1Kv15Tab);
            app.GKslo1pLabel.Text = 'GKslo1,p';
            app.GKslow1pKnob = uiknob(app.IKslow1Kv15Tab, 'continuous');
            app.GKslow1pKnob.Limits = [0 1];
            app.GKslow1pKnob.ValueChangingFcn = createCallbackFcn(app, @GKslow1pKnobValueChanging, true);
            app.GKslow1pKnob.Value = 0.07496;
            app.p1Knob_2Label = uilabel(app.IKslow1Kv15Tab);
            app.p1Knob_2Label.Text = 'p1';
            app.p1Knob_2 = uiknob(app.IKslow1Kv15Tab, 'continuous');
            app.p1Knob_2.Limits = [-70 50];
            app.p1Knob_2.ValueChangingFcn = createCallbackFcn(app, @p1Knob_2ValueChanging, true);
            app.p1Knob_2.Value = 22.5;
            app.p2Knob_2Label = uilabel(app.IKslow1Kv15Tab);
            app.p2Knob_2Label.Text = 'p2';
            app.p2Knob_2 = uiknob(app.IKslow1Kv15Tab, 'continuous');
            app.p2Knob_2.Limits = [-70 50];
            app.p2Knob_2.ValueChangingFcn = createCallbackFcn(app, @p2Knob_2ValueChanging, true);
            app.p2Knob_2.Value = 45.2;
            app.p3Knob_2Label = uilabel(app.IKslow1Kv15Tab);
            app.p3Knob_2Label.Text = 'p3';
            app.p3Knob_2 = uiknob(app.IKslow1Kv15Tab, 'continuous');
            app.p3Knob_2.Limits = [-70 50];
            app.p3Knob_2.ValueChangingFcn = createCallbackFcn(app, @p3Knob_2ValueChanging, true);
            app.p3Knob_2.Value = 40;
            app.p4Knob_2Label = uilabel(app.IKslow1Kv15Tab);
            app.p4Knob_2Label.Text = 'p4';
            app.p4Knob_2 = uiknob(app.IKslow1Kv15Tab, 'continuous');
            app.p4Knob_2.Limits = [0 10];
            app.p4Knob_2.ValueChangingFcn = createCallbackFcn(app, @p4Knob_2ValueChanging, true);
            app.p4Knob_2.Value = 7.7;
            app.p5Knob_2Label = uilabel(app.IKslow1Kv15Tab);
            app.p5Knob_2Label.Text = 'p5';
            app.p5Knob_2 = uiknob(app.IKslow1Kv15Tab, 'continuous');
            app.p5Knob_2.Limits = [0 50];
            app.p5Knob_2.ValueChangingFcn = createCallbackFcn(app, @p5Knob_2ValueChanging, true);
            app.p5Knob_2.Value = 5.7;
            app.p6Knob_2Label = uilabel(app.IKslow1Kv15Tab);
            app.p6Knob_2Label.Text = 'p6';
            app.p6Knob_2 = uiknob(app.IKslow1Kv15Tab, 'continuous');
            app.p6Knob_2.Limits = [0 50];
            app.p6Knob_2.ValueChangingFcn = createCallbackFcn(app, @p6Knob_2ValueChanging, true);
            app.p6Knob_2.Value = 6.1;
            app.p7Knob_2Label = uilabel(app.IKslow1Kv15Tab);
            app.p7Knob_2Label.Text = 'p7';
            app.p7Knob_2 = uiknob(app.IKslow1Kv15Tab, 'continuous');
            app.p7Knob_2.Limits = [0 1];
            app.p7Knob_2.ValueChangingFcn = createCallbackFcn(app, @p7Knob_2ValueChanging, true);
            app.p7Knob_2.Value = 0.0629;
            app.p8Knob_2Label = uilabel(app.IKslow1Kv15Tab);
            app.p8Knob_2Label.Text = 'p8';
            app.p8Knob_2 = uiknob(app.IKslow1Kv15Tab, 'continuous');
            app.p8Knob_2.ValueChangingFcn = createCallbackFcn(app, @p8Knob_2ValueChanging, true);
            app.p8Knob_2.Value = 2.058;
            app.p9Knob_2Label = uilabel(app.IKslow1Kv15Tab);
            app.p9Knob_2Label.Text = 'p9';
            app.p9Knob_2 = uiknob(app.IKslow1Kv15Tab, 'continuous');
            app.p9Knob_2.Limits = [0 1000];
            app.p9Knob_2.ValueChangingFcn = createCallbackFcn(app, @p9Knob_2ValueChanging, true);
            app.p9Knob_2.Value = 803;
            app.p10Knob_2Label = uilabel(app.IKslow1Kv15Tab);
            app.p10Knob_2Label.Text = 'p10';
            app.p10Knob_2 = uiknob(app.IKslow1Kv15Tab, 'continuous');
            app.p10Knob_2.Limits = [0 50];
            app.p10Knob_2.ValueChangingFcn = createCallbackFcn(app, @p10Knob_2ValueChanging, true);
            app.p10Knob_2.Value = 18;
            app.HighlightButton_2 = uibutton(app.IKslow1Kv15Tab, 'push');
            app.HighlightButton_2.ButtonPushedFcn = createCallbackFcn(app, @HighlightButton_2Pushed, true);
            app.HighlightButton_2.Text = 'Highlight';
            app.IncludeCheckBox_2 = uicheckbox(app.IKslow1Kv15Tab);
            app.IncludeCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @IncludeCheckBox_2ValueChanged, true);
            app.IncludeCheckBox_2.Text = 'Include';
            app.IncludeCheckBox_2.Value = true;
            app.IKslow2Kv21Tab = uitab(app.TabGroup);
            app.IKslow2Kv21Tab.Title = 'IKslow2 (Kv 2.1)';
            app.TextArea_3 = uitextarea(app.IKslow2Kv21Tab);
            app.TextArea_3.Value = {'IKslow2: Rapidly activating, slowly inactivating K+ current'};
            app.HighlightButton_3 = uibutton(app.IKslow2Kv21Tab, 'push');
            app.HighlightButton_3.ButtonPushedFcn = createCallbackFcn(app, @HighlightButton_3Pushed, true);
            app.HighlightButton_3.Text = 'Highlight';
            app.ResetButton_3 = uibutton(app.IKslow2Kv21Tab, 'push');
            app.ResetButton_3.ButtonPushedFcn = createCallbackFcn(app, @ResetButton_3Pushed, true);
            app.ResetButton_3.Text = 'Reset';
            app.GKslow2Label = uilabel(app.IKslow2Kv21Tab);
            app.GKslow2Label.Text = 'GKslow2';
            app.GKslow2Knob = uiknob(app.IKslow2Kv21Tab, 'continuous');
            app.GKslow2Knob.Limits = [0 0.5];
            app.GKslow2Knob.ValueChangingFcn = createCallbackFcn(app, @GKslow2KnobValueChanging, true);
            app.GKslow2Knob.Value = 0.05766;
            app.p9Knob_3Label = uilabel(app.IKslow2Kv21Tab);
            app.p9Knob_3Label.Text = 'p9';
            app.p9Knob_3 = uiknob(app.IKslow2Kv21Tab, 'continuous');
            app.p9Knob_3.Limits = [5000 10000];
            app.p9Knob_3.ValueChangingFcn = createCallbackFcn(app, @p9Knob_3ValueChanging, true);
            app.p9Knob_3.Value = 5334;
            app.p10Knob_3Label = uilabel(app.IKslow2Kv21Tab);
            app.p10Knob_3Label.Text = 'p10';
            app.p10Knob_3 = uiknob(app.IKslow2Kv21Tab, 'continuous');
            app.p10Knob_3.Limits = [0 5000];
            app.p10Knob_3.ValueChangingFcn = createCallbackFcn(app, @p10Knob_3ValueChanging, true);
            app.p10Knob_3.Value = 4912;
            app.Image = uiimage(app.IKslow2Kv21Tab);
            app.Image.ImageSource = 'ikslow2.png';
            app.GKslow2Text = uieditfield(app.IKslow2Kv21Tab, 'numeric');
            app.GKslow2Text.Limits = [0 0.5];
            app.GKslow2Text.ValueChangedFcn = createCallbackFcn(app, @GKslow2TextValueChanged, true);
            app.GKslow2Text.Value = 0.05766;
            app.p9Text_3 = uieditfield(app.IKslow2Kv21Tab, 'numeric');
            app.p9Text_3.Limits = [5000 10000];
            app.p9Text_3.ValueChangedFcn = createCallbackFcn(app, @p9Text_3ValueChanged, true);
            app.p9Text_3.Value = 5334;
            app.p10Text_3 = uieditfield(app.IKslow2Kv21Tab, 'numeric');
            app.p10Text_3.Limits = [0 10000];
            app.p10Text_3.ValueChangedFcn = createCallbackFcn(app, @p10Text_3ValueChanged, true);
            app.p10Text_3.Value = 4912;
            app.IncludeCheckBox_3 = uicheckbox(app.IKslow2Kv21Tab);
            app.IncludeCheckBox_3.ValueChangedFcn = createCallbackFcn(app, @IncludeCheckBox_3ValueChanged, true);
            app.IncludeCheckBox_3.Text = 'Include';
            app.IncludeCheckBox_3.Value = true;
            app.IKssTab = uitab(app.TabGroup);
            app.IKssTab.Title = 'IKss';
            app.ResetButton_4 = uibutton(app.IKssTab, 'push');
            app.ResetButton_4.ButtonPushedFcn = createCallbackFcn(app, @ResetButton_4Pushed, true);
            app.ResetButton_4.Text = 'Reset';
            app.HightlightButton_4 = uibutton(app.IKssTab, 'push');
            app.HightlightButton_4.ButtonPushedFcn = createCallbackFcn(app, @HightlightButton_4Pushed, true);
            app.HightlightButton_4.Text = 'Hightlight';
            app.TextArea_4 = uitextarea(app.IKssTab);
            app.TextArea_4.Value = {'IKss: Noninactivating steady-state K+ current'};
            app.GKssLabel = uilabel(app.IKssTab);
            app.GKssLabel.Text = 'GKss';
            app.GKssKnob = uiknob(app.IKssTab, 'continuous');
            app.GKssKnob.Limits = [0 1];
            app.GKssKnob.ValueChangingFcn = createCallbackFcn(app, @GKssKnobValueChanging, true);
            app.GKssKnob.Value = 0.0428;
            app.p4Knob_4Label = uilabel(app.IKssTab);
            app.p4Knob_4Label.Text = 'p4';
            app.p4Knob_4 = uiknob(app.IKssTab, 'continuous');
            app.p4Knob_4.Limits = [0 1];
            app.p4Knob_4.ValueChangingFcn = createCallbackFcn(app, @p4Knob_4ValueChanging, true);
            app.p4Knob_4.Value = 0.0862;
            app.p5Knob_4Label = uilabel(app.IKssTab);
            app.p5Knob_4Label.Text = 'p5';
            app.p5Knob_4 = uiknob(app.IKssTab, 'continuous');
            app.p5Knob_4.Limits = [0 2000];
            app.p5Knob_4.ValueChangingFcn = createCallbackFcn(app, @p5Knob_4ValueChanging, true);
            app.p5Knob_4.Value = 1235.5;
            app.p6Knob_4Label = uilabel(app.IKssTab);
            app.p6Knob_4Label.Text = 'p6';
            app.p6Knob_4 = uiknob(app.IKssTab, 'continuous');
            app.p6Knob_4.ValueChangingFcn = createCallbackFcn(app, @p6Knob_4ValueChanging, true);
            app.p6Knob_4.Value = 13.17;
            app.Image2 = uiimage(app.IKssTab);
            app.Image2.ImageSource = 'ikss.png';
            app.GKssText = uieditfield(app.IKssTab, 'numeric');
            app.GKssText.Limits = [0 0.5];
            app.GKssText.ValueChangedFcn = createCallbackFcn(app, @GKssTextValueChanged, true);
            app.GKssText.Value = 0.0428;
            app.p5Text_4 = uieditfield(app.IKssTab, 'numeric');
            app.p5Text_4.Limits = [0 2000];
            app.p5Text_4.ValueChangedFcn = createCallbackFcn(app, @p5Text_4ValueChanged, true);
            app.p5Text_4.Value = 1235.5;
            app.p4_1Text_4 = uieditfield(app.IKssTab, 'numeric');
            app.p4_1Text_4.Limits = [0 1];
            app.p4_1Text_4.ValueChangedFcn = createCallbackFcn(app, @p4_1Text_4ValueChanged, true);
            app.p4_1Text_4.Value = 0.0862;
            app.p4_2Text_4 = uieditfield(app.IKssTab, 'numeric');
            app.p4_2Text_4.Limits = [0 1];
            app.p4_2Text_4.ValueChangedFcn = createCallbackFcn(app, @p4_2Text_4ValueChanged, true);
            app.p4_2Text_4.Value = 0.0862;
            app.p6Text_4 = uieditfield(app.IKssTab, 'numeric');
            app.p6Text_4.Limits = [0 100];
            app.p6Text_4.ValueChangedFcn = createCallbackFcn(app, @p6Text_4ValueChanged, true);
            app.p6Text_4.Value = 13.17;
            app.p3_1Text_4 = uieditfield(app.IKssTab, 'numeric');
            app.p3_1Text_4.Limits = [-70 50];
            app.p3_1Text_4.ValueChangedFcn = createCallbackFcn(app, @p3_1Text_4ValueChanged, true);
            app.p3_1Text_4.Value = 40;
            app.p3_2Text_4 = uieditfield(app.IKssTab, 'numeric');
            app.p3_2Text_4.Limits = [-70 50];
            app.p3_2Text_4.ValueChangedFcn = createCallbackFcn(app, @p3_2Text_4ValueChanged, true);
            app.p3_2Text_4.Value = 40;
            app.IncludeCheckBox_4 = uicheckbox(app.IKssTab);
            app.IncludeCheckBox_4.ValueChangedFcn = createCallbackFcn(app, @IncludeCheckBox_4ValueChanged, true);
            app.IncludeCheckBox_4.Text = 'Include';
            app.IncludeCheckBox_4.Value = true;
            app.IKtosKv14Tab = uitab(app.TabGroup);
            app.IKtosKv14Tab.Title = 'IKto,s (Kv 1.4)';
            app.IKurTab = uitab(app.TabGroup);
            app.IKurTab.Title = 'IKur';
            app.GKurKnobLabel = uilabel(app.IKurTab);
            app.GKurKnobLabel.Text = 'GKur';
            app.GKurKnob = uiknob(app.IKurTab, 'continuous');
            app.GKurKnob.Limits = [0 0.5];
            app.GKurKnob.Value = 0.05766;
            app.p9Knob_7Label = uilabel(app.IKurTab);
            app.p9Knob_7Label.Text = 'p9';
            app.p9Knob_5 = uiknob(app.IKurTab, 'continuous');
            app.p9Knob_5.Limits = [5000 10000];
            app.p9Knob_5.Value = 5334;
            app.p10Knob_7Label = uilabel(app.IKurTab);
            app.p10Knob_7Label.Text = 'p10';
            app.p10Knob_5 = uiknob(app.IKurTab, 'continuous');
            app.p10Knob_5.Limits = [0 10000];
            app.p10Knob_5.Value = 4912;
            app.IncludeCheckBox_5 = uicheckbox(app.IKurTab);
            app.IncludeCheckBox_5.Text = 'Include';
            app.IK1Tab = uitab(app.TabGroup);
            app.IK1Tab.Title = 'IK1';
            app.p1Knob_3Label = uilabel(app.IK1Tab);
            app.p1Knob_3Label.Text = 'p1';
            app.p1Knob_6 = uiknob(app.IK1Tab, 'continuous');
            app.p1Knob_6.Limits = [0 120];
            app.p1Knob_6.ValueChangingFcn = createCallbackFcn(app, @p1Knob_6ValueChanging, true);
            app.p1Knob_6.Value = 59.215;
            app.p2Knob_3Label = uilabel(app.IK1Tab);
            app.p2Knob_3Label.Text = 'p2';
            app.p2Knob_6 = uiknob(app.IK1Tab, 'continuous');
            app.p2Knob_6.Limits = [-20 30];
            app.p2Knob_6.ValueChangingFcn = createCallbackFcn(app, @p2Knob_6ValueChanging, true);
            app.p2Knob_6.Value = 5.476;
            app.p3Knob_3Label = uilabel(app.IK1Tab);
            app.p3Knob_3Label.Text = 'p3';
            app.p3Knob_6 = uiknob(app.IK1Tab, 'continuous');
            app.p3Knob_6.Limits = [0 1000];
            app.p3Knob_6.ValueChangingFcn = createCallbackFcn(app, @p3Knob_6ValueChanging, true);
            app.p3Knob_6.Value = 594.31;
            app.p4Knob_5Label = uilabel(app.IK1Tab);
            app.p4Knob_5Label.Text = 'p4';
            app.p4Knob_6 = uiknob(app.IK1Tab, 'continuous');
            app.p4Knob_6.Limits = [-30 30];
            app.p4Knob_6.ValueChangingFcn = createCallbackFcn(app, @p4Knob_6ValueChanging, true);
            app.p4Knob_6.Value = 4.753;
            app.p5Knob_5Label = uilabel(app.IK1Tab);
            app.p5Knob_5Label.Text = 'p5';
            app.p5Knob_6 = uiknob(app.IK1Tab, 'continuous');
            app.p5Knob_6.Limits = [0 3];
            app.p5Knob_6.ValueChangingFcn = createCallbackFcn(app, @p5Knob_6ValueChanging, true);
            app.p5Knob_6.Value = 1.02;
            app.p6Knob_5Label_2 = uilabel(app.IK1Tab);
            app.p6Knob_5Label_2.Text = 'p6';
            app.p6Knob_6 = uiknob(app.IK1Tab, 'continuous');
            app.p6Knob_6.Limits = [0 1];
            app.p6Knob_6.ValueChangingFcn = createCallbackFcn(app, @p6Knob_6ValueChanging, true);
            app.p6Knob_6.Value = 0.2385;
            app.p7Knob_3Label = uilabel(app.IK1Tab);
            app.p7Knob_3Label.Text = 'p7';
            app.p7Knob_6 = uiknob(app.IK1Tab, 'continuous');
            app.p7Knob_6.Limits = [0 2];
            app.p7Knob_6.ValueChangingFcn = createCallbackFcn(app, @p7Knob_6ValueChanging, true);
            app.p7Knob_6.Value = 0.8;
            app.p8Knob_3Label = uilabel(app.IK1Tab);
            app.p8Knob_3Label.Text = 'p8';
            app.p8Knob_6 = uiknob(app.IK1Tab, 'continuous');
            app.p8Knob_6.Limits = [0 0.25];
            app.p8Knob_6.ValueChangingFcn = createCallbackFcn(app, @p8Knob_6ValueChanging, true);
            app.p8Knob_6.Value = 0.08032;
            app.p9Knob_4Label = uilabel(app.IK1Tab);
            app.p9Knob_4Label.Text = 'p9';
            app.p9Knob_6 = uiknob(app.IK1Tab, 'continuous');
            app.p9Knob_6.Limits = [0 0.25];
            app.p9Knob_6.ValueChangingFcn = createCallbackFcn(app, @p9Knob_6ValueChanging, true);
            app.p9Knob_6.Value = 0.06175;
            app.p10Knob_4Label = uilabel(app.IK1Tab);
            app.p10Knob_4Label.Text = 'p10';
            app.p10Knob_6 = uiknob(app.IK1Tab, 'continuous');
            app.p10Knob_6.Limits = [0 1];
            app.p10Knob_6.ValueChangingFcn = createCallbackFcn(app, @p10Knob_6ValueChanging, true);
            app.p10Knob_6.Value = 0.5143;
            app.IncludeCheckBox_6 = uicheckbox(app.IK1Tab);
            app.IncludeCheckBox_6.Text = 'Include';
            app.ResetButton_6 = uibutton(app.IK1Tab, 'push');
            app.ResetButton_6.ButtonPushedFcn = createCallbackFcn(app, @ResetButton_6Pushed, true);
            app.ResetButton_6.Text = 'Reset';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxesMiddleRight = uiaxes(app.RightPanel);
            title(app.UIAxesMiddleRight, 'Individual K^{+} Currents of Selected Voltage')
            xlabel(app.UIAxesMiddleRight, 'Time (ms)')
            ylabel(app.UIAxesMiddleRight, 'Current (pA/pF)')
            zlabel(app.UIAxesMiddleRight, 'Z')
            app.UIAxesTopRight = uiaxes(app.RightPanel);
            title(app.UIAxesTopRight, 'Sum of K^{+} Currents (Model Prediction)')
            xlabel(app.UIAxesTopRight, 'Time (ms)')
            ylabel(app.UIAxesTopRight, 'Current (pA/pF)')
            zlabel(app.UIAxesTopRight, 'Z')
            app.UIAxesBottomRight = uiaxes(app.RightPanel);
            title(app.UIAxesBottomRight, 'Sum of K^{+} Current of Selected Voltage')
            xlabel(app.UIAxesBottomRight, 'Time (ms)')
            ylabel(app.UIAxesBottomRight, 'Current (pA/pF)')
            zlabel(app.UIAxesBottomRight, 'Z')
            app.SelectVoltageDropDownLabel = uilabel(app.RightPanel);
            app.SelectVoltageDropDownLabel.Text = 'Select Voltage';
            app.SelectVoltageDropDown = uidropdown(app.RightPanel);
            app.SelectVoltageDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectVoltageDropDownValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Kv

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end