classdef KvApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Image2                         matlab.ui.control.Image
        CopyrightNSFPennStateLabel     matlab.ui.control.Label
        ModelOutputsKineticParametersLabel  matlab.ui.control.Label
        ComputerModelsLabel            matlab.ui.control.Label
        ExperimentalProtocolDataLabel  matlab.ui.control.Label
        RightPanel                     matlab.ui.container.Panel
        TabGroupCalibration            matlab.ui.container.TabGroup
        CurrentTab                     matlab.ui.container.Tab
        RMSEEditField                  matlab.ui.control.NumericEditField
        RMSEEditFieldLabel             matlab.ui.control.Label
        FileEditField                  matlab.ui.control.EditField
        FileEditFieldLabel             matlab.ui.control.Label
        VoltageDropDown                matlab.ui.control.DropDown
        VoltageDropDownLabel           matlab.ui.control.Label
        ImportModelParametersButton    matlab.ui.control.Button
        SaveModelParametersButton      matlab.ui.control.Button
        RunCalibrationButton           matlab.ui.control.Button
        UIAxesModelSum                 matlab.ui.control.UIAxes
        UIAxesModelIndv                matlab.ui.control.UIAxes
        DensityVoltageTab              matlab.ui.container.Tab
        DensityVoltage                 matlab.ui.container.Panel
        GenerateButton                 matlab.ui.control.Button
        Kv42TabCalib                   matlab.ui.container.Tab
        KvKtof                         matlab.ui.container.Panel
        Kv15Kv21K2PTabCalib            matlab.ui.container.Tab
        KvKss                          matlab.ui.container.Panel
        KvKslow2                       matlab.ui.container.Panel
        KvKslow1                       matlab.ui.container.Panel
        Kv14TabCalib                   matlab.ui.container.Tab
        KvKtos                         matlab.ui.container.Panel
        CenterPanel                    matlab.ui.container.Panel
        TabGroupComputerModel          matlab.ui.container.TabGroup
        Kv42Tab                        matlab.ui.container.Tab
        IktofImage                     matlab.ui.control.Image
        GKtofEditField                 matlab.ui.control.NumericEditField
        p_1Label_13                    matlab.ui.control.Label
        p12EditField                   matlab.ui.control.NumericEditField
        p_1Label_12                    matlab.ui.control.Label
        p11EditField                   matlab.ui.control.NumericEditField
        p_1Label_11                    matlab.ui.control.Label
        p10EditField                   matlab.ui.control.NumericEditField
        p_1Label_10                    matlab.ui.control.Label
        p9EditField                    matlab.ui.control.NumericEditField
        p_1Label_9                     matlab.ui.control.Label
        p8EditField                    matlab.ui.control.NumericEditField
        p_1Label_8                     matlab.ui.control.Label
        p7EditField                    matlab.ui.control.NumericEditField
        p_1Label_7                     matlab.ui.control.Label
        p6EditField                    matlab.ui.control.NumericEditField
        p_1Label_6                     matlab.ui.control.Label
        p5EditField                    matlab.ui.control.NumericEditField
        p_1Label_5                     matlab.ui.control.Label
        p4EditField                    matlab.ui.control.NumericEditField
        p_1Label_4                     matlab.ui.control.Label
        p3EditField                    matlab.ui.control.NumericEditField
        p_1Label_3                     matlab.ui.control.Label
        p2EditField                    matlab.ui.control.NumericEditField
        p_1Label_2                     matlab.ui.control.Label
        p1EditField                    matlab.ui.control.NumericEditField
        p_1Label                       matlab.ui.control.Label
        IncludeKtof                    matlab.ui.control.CheckBox
        LabelKtof                      matlab.ui.control.Label
        Kv15Kv21IKssTab                matlab.ui.container.Tab
        textBox1                       matlab.ui.control.Label
        GKssEditField                  matlab.ui.control.NumericEditField
        p_1Label_31                    matlab.ui.control.Label
        p3EditField4                   matlab.ui.control.NumericEditField
        p_1Label_30                    matlab.ui.control.Label
        p2EditField4                   matlab.ui.control.NumericEditField
        p_1Label_29                    matlab.ui.control.Label
        p1EditField4                   matlab.ui.control.NumericEditField
        p_1Label_28                    matlab.ui.control.Label
        GKslow2EditField               matlab.ui.control.NumericEditField
        p_1Label_27                    matlab.ui.control.Label
        p2EditField3                   matlab.ui.control.NumericEditField
        p_1Label_26                    matlab.ui.control.Label
        p1EditField3                   matlab.ui.control.NumericEditField
        p_1Label_25                    matlab.ui.control.Label
        GKslow1EditField               matlab.ui.control.NumericEditField
        p_1Label_24                    matlab.ui.control.Label
        p10EditField2                  matlab.ui.control.NumericEditField
        p_1Label_23                    matlab.ui.control.Label
        p9EditField2                   matlab.ui.control.NumericEditField
        p_1Label_22                    matlab.ui.control.Label
        p8EditField2                   matlab.ui.control.NumericEditField
        p_1Label_21                    matlab.ui.control.Label
        p7EditField2                   matlab.ui.control.NumericEditField
        p_1Label_20                    matlab.ui.control.Label
        p6EditField2                   matlab.ui.control.NumericEditField
        p_1Label_19                    matlab.ui.control.Label
        p5EditField2                   matlab.ui.control.NumericEditField
        p_1Label_18                    matlab.ui.control.Label
        p4EditField2                   matlab.ui.control.NumericEditField
        p_1Label_17                    matlab.ui.control.Label
        p3EditField2                   matlab.ui.control.NumericEditField
        p_1Label_16                    matlab.ui.control.Label
        p2EditField2                   matlab.ui.control.NumericEditField
        p_1Label_15                    matlab.ui.control.Label
        p1EditField2                   matlab.ui.control.NumericEditField
        p_1Label_14                    matlab.ui.control.Label
        IkssImage                      matlab.ui.control.Image
        Ikslow2Image                   matlab.ui.control.Image
        IncludeKss                     matlab.ui.control.CheckBox
        Ikslow1Image                   matlab.ui.control.Image
        IncludeKslow2                  matlab.ui.control.CheckBox
        IncludeKslow1                  matlab.ui.control.CheckBox
        LabelKss                       matlab.ui.control.Label
        LabelKslow2                    matlab.ui.control.Label
        LabelKslow1                    matlab.ui.control.Label
        Kv14Tab                        matlab.ui.container.Tab
        Image                          matlab.ui.control.Image
        LabelKtos                      matlab.ui.control.Label
        p2EditField5                   matlab.ui.control.NumericEditField
        p_1Label_34                    matlab.ui.control.Label
        p1EditField5                   matlab.ui.control.NumericEditField
        p_1Label_33                    matlab.ui.control.Label
        GKtosEditField                 matlab.ui.control.NumericEditField
        p_1Label_32                    matlab.ui.control.Label
        IncludeKtos                    matlab.ui.control.CheckBox
        LeftPanel                      matlab.ui.container.Panel
        KCurrentRecordingsPanel        matlab.ui.container.Panel
        FileEditField_2                matlab.ui.control.EditField
        FileEditField_2Label           matlab.ui.control.Label
        ImportDataButton               matlab.ui.control.Button
        UIAxesExpData                  matlab.ui.control.UIAxes
        ProtocolPanel                  matlab.ui.container.Panel
        UpdateButton                   matlab.ui.control.Button
        VoltagesteptomVEditField       matlab.ui.control.NumericEditField
        VoltagesteptomVEditFieldLabel  matlab.ui.control.Label
        StepsizemVEditField            matlab.ui.control.NumericEditField
        StepsizemVEditFieldLabel       matlab.ui.control.Label
        NernstpotentialmVEditField     matlab.ui.control.NumericEditField
        NernstpotentialmVEditFieldLabel  matlab.ui.control.Label
        DurationmsEditField            matlab.ui.control.NumericEditField
        DurationmsEditFieldLabel       matlab.ui.control.Label
        VoltagestepfrommVEditField     matlab.ui.control.NumericEditField
        VoltagestepfrommVEditFieldLabel  matlab.ui.control.Label
        HoldingpotentialmVEditField    matlab.ui.control.NumericEditField
        HoldingpotentialmVEditFieldLabel  matlab.ui.control.Label
        HoldingtimemsEditField         matlab.ui.control.NumericEditField
        HoldingtimemsEditFieldLabel    matlab.ui.control.Label
        UIAxesProtocol                 matlab.ui.control.UIAxes
    end

    properties (Access = private)
        kvktof_tiles
        kvktos_tiles
        kvkslow1_tiles
        kvkslow2_tiles
        kvkss_tiles
        density_tiles

        % time space
        t
        hold_t
        pulse_t
        tt

        % voltage space
        volts
        num_volts
        volt_now

        % experimental data
        exp_yksum_set
        exp_idx

        % currents
        yksum_set
        yktof_set
        yktos_set
        ykslow1_set
        ykslow2_set
        ykss_set

        % current flags
        yktof_idx
        yktos_idx
        ykslow1_idx
        ykslow2_idx
        ykss_idx
        
        % current parameters
        pdefault
        pktof
        pktos
        pkslow1
        pkslow2
        pkss

        % kinetics
        kvktof_set
        kvktos_set
        kvkslow1_set
        kvkslow2_set
        kvkss_set
        
        % external apps
        NanWarnApp
        VoltWarnApp
    end
    
    methods (Access = private)
        function [t, hold_t, pulse_t] = gen_time_space(app)
            % generate time information from given protocol
            hold_len = app.HoldingtimemsEditField.Value;
            time_len = hold_len + app.DurationmsEditField.Value;
            
            hold_t = 0:hold_len;
            pulse_t = (hold_len+1):time_len;
            pulse_t_shift = pulse_t - pulse_t(1);
            
            t = [hold_t, pulse_t];
            pulse_t = pulse_t_shift;
        end

        function [volts, num_volts] = gen_volt_space(app)
            % generate voltage information from given protocol
            minv = app.VoltagestepfrommVEditField.Value;
            stepv = app.StepsizemVEditField.Value;
            maxv = app.VoltagesteptomVEditField.Value;
            
            if stepv == 0
                volts = minv;
            else
                volts = minv:stepv:maxv;
            end
            num_volts = length(volts);
        end
        
        function [y,k] = gen_iktof(app,v)
            [y,k] = iktof(app.pktof, app.HoldingpotentialmVEditField.Value, ...
                v, app.tt, app.NernstpotentialmVEditField.Value);                
        end

        function [y,k] = gen_iktos(app,v)
            [y,k] = iktos(app.pktos, app.HoldingpotentialmVEditField.Value, ...
                v, app.tt, app.NernstpotentialmVEditField.Value);
        end
        
        function [y,k] = gen_ikslow1(app,v)
            [y,k] = ikslow1(app.pkslow1, app.HoldingpotentialmVEditField.Value, ...
                v, app.tt, app.NernstpotentialmVEditField.Value);
        end
        
        function [y,k] = gen_ikslow2(app,v)
            [y,k] = ikslow2(app.pkslow2, app.HoldingpotentialmVEditField.Value, ...
                v, app.tt, app.NernstpotentialmVEditField.Value);
        end
        
        function [y,k] = gen_ikss(app,v)
            [y,k] = ikss(app.pkss, app.HoldingpotentialmVEditField.Value, ...
                v, app.tt, app.NernstpotentialmVEditField.Value);                
        end

        function [yset,kset] = gen_wrapper(app, current_flag, current_generator)
            % wrapper function looping over voltages
            yset = zeros(length(app.t),app.num_volts);
            kset = [];
            if isequal(current_flag,1)
                for i=1:app.num_volts
                    [y,k] = current_generator(app,app.volts(i));
                    if any(isnan(y))
                        % warning message for NaN
                        app.NanWarnApp = warn_message_nan();
                        break
                    else
                        yset(:,i) = y;
                        kset(:,i) = k;
                    end
                end
            end
        end
    
        function update_sum_current(app)
            app.yksum_set = zeros(length(app.t), app.num_volts);
            for i=1:app.num_volts
                app.yksum_set(:,i) = app.yktof_set(:,i) + app.yktos_set(:,i) + ...
                    app.ykslow1_set(:,i) + app.ykslow2_set(:,i) + app.ykss_set(:,i);
            end
        end

        function plot_protocol(app)
            yhold = ones(length(app.hold_t), 1)*app.HoldingpotentialmVEditField.Value;
            ypulse = ones(length(app.pulse_t), 1)*app.volts(1);
            y = [yhold; ypulse];
            plot(app.UIAxesProtocol, app.t, y, 'LineWidth',1.5)
            
            hold(app.UIAxesProtocol, 'on')
            for i=2:app.num_volts
                ypulse = ones(length(app.pulse_t), 1)*app.volts(i);
                y = [yhold; ypulse];
                plot(app.UIAxesProtocol, app.t, y, 'LineWidth',1.5)
            end
            hold(app.UIAxesProtocol, 'off')
            % axis(app.UIAxesProtocol, 'tight')
        end
    
        function plot_currents(app)
            volt_idx = app.volt_now;

            % figure 1: individual K+ currents
            current_idx_set = [app.yktof_idx, app.yktos_idx, ...
                app.ykslow1_idx, app.ykslow2_idx, app.ykss_idx];
            active_current_idx = find(current_idx_set == 1);
            
            % first plot before 'hold on'
            plot_list = gobjects(length(active_current_idx),1);
            legend_list = strings(length(active_current_idx),1);            
            if isempty(active_current_idx)
                cla(app.UIAxesModelIndv)
                cla(app.UIAxesModelSum)
            else
                switch active_current_idx(1)
                    case 1
                        plot_list(1) = plot(app.UIAxesModelIndv, app.t, app.yktof_set(:,volt_idx));
                        legend_list(1) = "I_{Kto,f}";
                    case 2
                        plot_list(1) = plot(app.UIAxesModelIndv, app.t, app.yktos_set(:,volt_idx));
                        legend_list(1) = "I_{Kto,s}";
                    case 3
                        plot_list(1) = plot(app.UIAxesModelIndv, app.t, app.ykslow1_set(:,volt_idx));
                        legend_list(1) = "I_{Kslow1}";
                    case 4
                        plot_list(1) = plot(app.UIAxesModelIndv, app.t, app.ykslow2_set(:,volt_idx));
                        legend_list(1) = "I_{Kslow2}";
                    case 5
                        plot_list(1) = plot(app.UIAxesModelIndv, app.t, app.ykss_set(:,volt_idx));
                        legend_list(1) = "I_{Kss}";
                end
                
                hold(app.UIAxesModelIndv, 'on')
                for i = 2:length(active_current_idx)
                    switch active_current_idx(i)
                        case 1
                            plot_list(i) = plot(app.UIAxesModelIndv, app.t, app.yktof_set(:,volt_idx));
                            legend_list(i) = "I_{Kto,f}";
                        case 2
                            plot_list(i) = plot(app.UIAxesModelIndv, app.t, app.yktos_set(:,volt_idx));
                            legend_list(i) = "I_{Kto,s}";
                        case 3
                            plot_list(i) = plot(app.UIAxesModelIndv, app.t, app.ykslow1_set(:,volt_idx));
                            legend_list(i) = "I_{Kslow1}";
                        case 4
                            plot_list(i) = plot(app.UIAxesModelIndv, app.t, app.ykslow2_set(:,volt_idx));
                            legend_list(i) = "I_{Kslow2}";
                        case 5
                            plot_list(i) = plot(app.UIAxesModelIndv, app.t, app.ykss_set(:,volt_idx));
                            legend_list(i) = "I_{Kss}";
                    end
                end
                hold(app.UIAxesModelIndv, 'off')
                axis(app.UIAxesModelIndv, 'tight')
                legend(app.UIAxesModelIndv, legend_list)
            end
            
            % figure 2: iksum
            if app.exp_idx == true
                plot(app.UIAxesModelSum, app.t, app.exp_yksum_set(:,volt_idx), 'LineWidth',2, 'Color','blue')
                hold(app.UIAxesModelSum, 'on')
                plot(app.UIAxesModelSum, app.t, app.yksum_set(:,volt_idx), 'LineWidth',2, 'Color','red')
                hold(app.UIAxesModelSum, 'off')
                axis(app.UIAxesModelSum, 'tight')
                legend(app.UIAxesModelSum, ["Experimental Data","Model Prediction"])
            else
                plot(app.UIAxesModelSum, app.t, app.yksum_set(:,volt_idx), "LineWidth",2, "Color","red")
                axis(app.UIAxesModelSum, 'tight')
                legend(app.UIAxesModelSum, "Model Prediction")
            end
        end
        
        function plot_kvktof(app)
            app.kvktof_tiles = tiledlayout(app.KvKtof,4,2);
            if isequal(app.yktof_idx,1)
                xlabel(app.kvktof_tiles,"Voltage (mV)", "FontSize",12)
                ax1 = nexttile(app.kvktof_tiles);
                plot(ax1,app.volts,app.kvktof_set(5,:),"-o", "Color","black")
                set(ax1,'XLimSpec','tight')
                grid(ax1, 'on')
                ylabel(ax1,"\alpha_{a}", "FontSize",12)

                ax2 = nexttile(app.kvktof_tiles);
                plot(ax2,app.volts,app.kvktof_set(6,:),"-o", "Color","black")
                set(ax2,'XLimSpec','tight')
                grid(ax2, 'on')
                ylabel(ax2,"\beta_{a}", "FontSize",12)

                ax3 = nexttile(app.kvktof_tiles);
                plot(ax3,app.volts,app.kvktof_set(1,:),"-or")
                ylim(ax3,[0,1])
                set(ax3,'XLimSpec','tight')
                grid(ax3, 'on')
                ylabel(ax3,"a_{ss}", "FontSize",12)

                ax4 = nexttile(app.kvktof_tiles);
                plot(ax4,app.volts,app.kvktof_set(3,:),"-or")
                set(ax4,'XLimSpec','tight')
                grid(ax4, 'on')
                ylabel(ax4,"\tau_{a}", "FontSize",12)

                ax5 = nexttile(app.kvktof_tiles);
                plot(ax5,app.volts,app.kvktof_set(7,:),"-o", "Color","black")
                set(ax5,'XLimSpec','tight')
                grid(ax5, 'on')
                ylabel(ax5,"\alpha_{i}", "FontSize",12)

                ax6 = nexttile(app.kvktof_tiles);
                plot(ax6,app.volts,app.kvktof_set(8,:),"-o", "Color","black")
                set(ax6,'XLimSpec','tight')
                grid(ax6, 'on')
                ylabel(ax6,"\beta_{i}", "FontSize",12)

                ax7 = nexttile(app.kvktof_tiles);
                plot(ax7,app.volts,app.kvktof_set(2,:),"-ob")
                ylim(ax7,[0,1])
                set(ax7,'XLimSpec','tight')
                grid(ax7, 'on')
                ylabel(ax7,"i_{ss}", "FontSize",12)

                ax8 = nexttile(app.kvktof_tiles);
                plot(ax8,app.volts,app.kvktof_set(4,:),"-ob")
                set(ax8,'XLimSpec','tight')
                grid(ax8, 'on')
                ylabel(ax8,"\tau_{i}", "FontSize",12)                
            end
        end

        function plot_kvktos(app)
           app.kvktos_tiles = tiledlayout(app.KvKtos,2,2);
            if isequal(app.yktos_idx,1)
                xlabel(app.kvktos_tiles,"Voltage (mV)", "FontSize",12)
                ax1 = nexttile(app.kvktos_tiles);
                plot(ax1,app.volts,app.kvkslow1_set(1,:),"-or")
                set(ax1,'XLimSpec','tight')
                grid(ax1, 'on')
                ylabel(ax1,"a_{ss}", "FontSize",12)
    
                ax2 = nexttile(app.kvktos_tiles);
                plot(ax2,app.volts,app.kvkslow1_set(3,:),"-or")
                set(ax2,'XLimSpec','tight')
                grid(ax2, 'on')
                ylabel(ax2,"\tau_{a}^{(1)}")
    
                ax3 = nexttile(app.kvktos_tiles);
                plot(ax3,app.volts,app.kvkslow1_set(2,:),"-ob")
                set(ax3,'XLimSpec','tight')
                grid(ax3, 'on')
                ylabel(ax3,"i_{ss}")
    
                ax4 = nexttile(app.kvktos_tiles);
                plot(ax4,app.volts,app.kvktos_set(4,:),"-ob")
                set(ax4,'XLimSpec','tight')
                grid(ax4, 'on')
                ylabel(ax4,"\tau_{i}^{(1)}")
            end           
        end

        function plot_kvkslow1(app)
            app.kvkslow1_tiles = tiledlayout(app.KvKslow1,2,2);
            if isequal(app.ykslow1_idx,1)
                xlabel(app.kvkslow1_tiles,"Voltage (mV)", "FontSize",12)
                ax1 = nexttile(app.kvkslow1_tiles);
                plot(ax1,app.volts,app.kvkslow1_set(1,:),"-or")
                set(ax1,'XLimSpec','tight')
                grid(ax1, 'on')
                ylabel(ax1,"a_{ss}", "FontSize",12)

                ax2 = nexttile(app.kvkslow1_tiles);
                plot(ax2,app.volts,app.kvkslow1_set(3,:),"-or")
                set(ax2,'XLimSpec','tight')
                grid(ax2, 'on')
                ylabel(ax2,"\tau_{a}^{(1)}")

                ax3 = nexttile(app.kvkslow1_tiles);
                plot(ax3,app.volts,app.kvkslow1_set(2,:),"-ob")
                set(ax3,'XLimSpec','tight')
                grid(ax3, 'on')
                ylabel(ax3,"i_{ss}")

                ax4 = nexttile(app.kvkslow1_tiles);
                plot(ax4,app.volts,app.kvkslow1_set(4,:),"-ob")
                set(ax4,'XLimSpec','tight')
                grid(ax4, 'on')
                ylabel(ax4,"\tau_{i}^{(1)}")
            end            
        end

        function plot_kvkslow2(app)
            app.kvkslow2_tiles = tiledlayout(app.KvKslow2,1,2);
            if isequal(app.ykslow2_idx,1)
                xlabel(app.kvkslow2_tiles,"Voltage (mV)", "FontSize",12)
                ax1 = nexttile(app.kvkslow2_tiles);
                plot(ax1,app.volts,app.kvkslow2_set(4,:),"-ob")
                set(ax1,'XLimSpec','tight')
                grid(ax1, 'on')
                ylabel(ax1,"\tau_{i}^{(2)}")
            end            
        end

        function plot_kvkss(app)
            app.kvkss_tiles = tiledlayout(app.KvKss,1,2);
            if isequal(app.ykss_idx,1)
                xlabel(app.kvkss_tiles,"Voltage (mV)", "FontSize",12)
                ax1 = nexttile(app.kvkss_tiles);
                plot(ax1,app.volts,app.kvkss_set(2,:),"-or")
                set(ax1,'XLimSpec','tight')
                grid(ax1, 'on')
                ylabel(ax1,"\tau_{a}^{(3)}")
            end                        
        end
        
        function plot_density(app)
            % density-voltage plots
            num_currents = sum([app.yktof_idx,app.yktos_idx,app.ykslow1_idx, ...
                app.ykslow2_idx,app.ykss_idx]);
            if num_currents > 0
                app.density_tiles = tiledlayout(app.DensityVoltage,num_currents,2);
                xlabel(app.density_tiles,"Voltage (mV)","Fontsize",12)
                if isequal(app.yktof_idx,1)
                    ax = nexttile(app.density_tiles);
                    plot(ax,app.volts,max(app.yktof_set),'-o', 'Color','black')
                    set(ax,'XLimSpec','tight')
                    grid(ax, 'on')
                    ylabel(ax,"I_{Kto,f} Density", "FontSize",12)
                end
                if isequal(app.yktos_idx,1)
                    ax = nexttile(app.density_tiles);
                    plot(ax,app.volts,max(app.yktos_set),'-o', 'Color','black')
                    set(ax,'XLimSpec','tight')
                    grid(ax, 'on')
                    ylabel(ax,"I_{Kto,s} Density", "FontSize",12)
                end
                if isequal(app.ykslow1_idx,1)
                    ax = nexttile(app.density_tiles);
                    plot(ax,app.volts,max(app.ykslow1_set),'-o', 'Color','black')
                    set(ax,'XLimSpec','tight')
                    grid(ax, 'on')
                    ylabel(ax,"I_{Kslow1} Density", "FontSize",12)
                end
                if isequal(app.ykslow2_idx,1)
                    ax = nexttile(app.density_tiles);
                    plot(ax,app.volts,max(app.ykslow2_set),'-o', 'Color','black')
                    set(ax,'XLimSpec','tight')
                    grid(ax, 'on')
                    ylabel(ax,"I_{Kslow2} Density", "FontSize",12)
                end
                if isequal(app.ykss_idx,1)
                    ax = nexttile(app.density_tiles);
                    plot(ax,app.volts,max(app.ykss_set),'-o', 'Color','black')
                    set(ax,'XLimSpec','tight')
                    grid(ax, 'on')
                    ylabel(ax,"I_{Kss} Density", "FontSize",12)
                end
            end
        end

        function update_pktof(app, sol)
            app.pktof = sol;
            app.p1EditField.Value = sol(1);
            app.p2EditField.Value = sol(2);
            app.p3EditField.Value = sol(3);
            app.p4EditField.Value = sol(4);
            app.p5EditField.Value = sol(5);
            app.p6EditField.Value = sol(6);
            app.p7EditField.Value = sol(7);
            app.p8EditField.Value = sol(8);
            app.p9EditField.Value = sol(9);
            app.p10EditField.Value = sol(10);
            app.p11EditField.Value = sol(11);
            app.p12EditField.Value = sol(12);
            app.GKtofEditField.Value = sol(13);
        end
        
        function update_pktos(app, sol)
            app.pktos([8,10,11]) = sol;
            app.p1EditField5.Value = sol(1);
            app.p2EditField5.Value = sol(2);
            app.GKtosEditField.Value = sol(3);
        end

        function update_pkslow1(app, sol)
            app.pkslow1 = sol;
            app.pktos([1:7,9]) = sol([1:7,9]);
            app.pkslow2([1:7,9]) = sol([1:7,9]);
            app.pkss(1:3) = sol([1,3,4]);
            app.p1EditField2.Value = sol(1);
            app.p2EditField2.Value = sol(2);
            app.p3EditField2.Value = sol(3);
            app.p4EditField2.Value = sol(4);
            app.p5EditField2.Value = sol(5);
            app.p6EditField2.Value = sol(6);
            app.p7EditField2.Value = sol(7);
            app.p8EditField2.Value = sol(8);
            app.p9EditField2.Value = sol(9);
            app.p10EditField2.Value = sol(10);
            app.GKslow1EditField.Value = sol(11);
        end

        function update_pkslow2(app, sol)
            app.pkslow2([8,10,11]) = sol;
            app.p1EditField3.Value = sol(1);
            app.p2EditField3.Value = sol(2);
            app.GKslow2EditField.Value = sol(3);
        end

        function update_pkss(app, sol)
            app.pkss(4:7) = sol;
            app.p1EditField4.Value = sol(1);
            app.p2EditField4.Value = sol(2);
            app.p3EditField4.Value = sol(3);
            app.GKssEditField.Value = sol(4);
        end        
        
        function z = obj_rmse(app)
            z = sum(sqrt(mean((app.exp_yksum_set-app.yksum_set).^2)));
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.exp_idx = false;
            
            % generate time space
            [app.t, app.hold_t, app.pulse_t] = gen_time_space(app);
            app.tt = cell(1,3);
            app.tt{1} = app.t;
            app.tt{2} = app.hold_t;
            app.tt{3} = app.pulse_t;
            
            % generate clamping voltages based on protocol infomation
            [app.volts, app.num_volts] = gen_volt_space(app);
            
            % voltage drop-down menu
            volt_dd_items = strings(1, app.num_volts);
            for i=1:app.num_volts
                volt_dd_items(i) = strcat(num2str(app.volts(i)), " mV");
            end
            app.VoltageDropDown.Items = volt_dd_items;

            % plot default experimental protocol
            plot_protocol(app);

            % check currents to be included
            app.yktof_idx = app.IncludeKtof.Value;
            app.yktos_idx = app.IncludeKtos.Value;
            app.ykslow1_idx = app.IncludeKslow1.Value;
            app.ykslow2_idx = app.IncludeKslow2.Value;
            app.ykss_idx = app.IncludeKss.Value;
            
            % assign default iktof parameters
            app.pktof = NaN(13,1);
            app.pktof(1) = app.p1EditField.Value;
            app.pktof(2) = app.p2EditField.Value;
            app.pktof(3) = app.p3EditField.Value;
            app.pktof(4) = app.p4EditField.Value;
            app.pktof(5) = app.p5EditField.Value;
            app.pktof(6) = app.p6EditField.Value;
            app.pktof(7) = app.p7EditField.Value;
            app.pktof(8) = app.p8EditField.Value;
            app.pktof(9) = app.p9EditField.Value;
            app.pktof(10) = app.p10EditField.Value;
            app.pktof(11) = app.p11EditField.Value;
            app.pktof(12) = app.p12EditField.Value;
            app.pktof(13) = app.GKtofEditField.Value;
            
            % assign default kslow1 parameters
            app.pkslow1 = NaN(11,1);
            app.pkslow1(1) = app.p1EditField2.Value;
            app.pkslow1(2) = app.p2EditField2.Value;
            app.pkslow1(3) = app.p3EditField2.Value;
            app.pkslow1(4) = app.p4EditField2.Value;
            app.pkslow1(5) = app.p5EditField2.Value;
            app.pkslow1(6) = app.p6EditField2.Value;
            app.pkslow1(7) = app.p7EditField2.Value;
            app.pkslow1(8) = app.p8EditField2.Value;
            app.pkslow1(9) = app.p9EditField2.Value;
            app.pkslow1(10) = app.p10EditField2.Value;
            app.pkslow1(11) = app.GKslow1EditField.Value;
            
            % assign default kslow2 parameters
            app.pkslow2 = NaN(11,1);
            app.pkslow2([1:7,9]) = app.pkslow1([1:7,9]);
            app.pkslow2(8) = app.p1EditField3.Value;
            app.pkslow2(10) = app.p2EditField3.Value;
            app.pkslow2(11) = app.GKslow2EditField.Value;

            % assign default kss parameters
            app.pkss = NaN(7,1);
            app.pkss(1:3) = app.pkslow1([1,3,4]);
            app.pkss(4) = app.p1EditField4.Value;
            app.pkss(5) = app.p2EditField4.Value;
            app.pkss(6) = app.p3EditField4.Value;
            app.pkss(7) = app.GKssEditField.Value;
            
            % assign default ktos parameters
            app.pktos = NaN(11,1);
            app.pktos([1:7,9]) = app.pkslow1([1:7,9]);
            app.pktos(8) = app.p1EditField5.Value;
            app.pktos(10) = app.p2EditField5.Value;
            app.pktos(11) = app.GKtosEditField.Value;

            % save default parameter values
            app.pdefault = cell(5,1);
            app.pdefault{1} = app.pktof;
            app.pdefault{2} = app.pktos([8,10,11]);
            app.pdefault{3} = app.pkslow1;
            app.pdefault{4} = app.pkslow2([8,10,11]);
            app.pdefault{5} = app.pkss(4:7);

            % generate currents and their kinetics
            [app.yktof_set,app.kvktof_set] = gen_wrapper(app, app.yktof_idx, @gen_iktof);
            [app.yktos_set,app.kvktos_set] = gen_wrapper(app, app.yktos_idx, @gen_iktos);
            [app.ykslow1_set,app.kvkslow1_set] = gen_wrapper(app, app.ykslow1_idx, @gen_ikslow1);
            [app.ykslow2_set,app.kvkslow2_set] = gen_wrapper(app, app.ykslow2_idx, @gen_ikslow2);
            [app.ykss_set,app.kvkss_set] = gen_wrapper(app, app.ykss_idx, @gen_ikss);
            update_sum_current(app);

            % plot currents and kinetic variables
            app.volt_now = 1;
            plot_currents(app);
            plot_kvktof(app);
            plot_kvktos(app);
            plot_kvkslow1(app);
            plot_kvkslow2(app);
            plot_kvkss(app);
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            % update time space
            [app.t, app.hold_t, app.pulse_t] = gen_time_space(app);
            app.tt{1} = app.t;
            app.tt{2} = app.hold_t;
            app.tt{3} = app.pulse_t;
            
            % update voltages
            [app.volts, app.num_volts] = gen_volt_space(app);
            
            % voltage drop-down menu
            volt_dd_items = strings(1, app.num_volts);
            for i=1:app.num_volts
                volt_dd_items(i) = strcat(num2str(app.volts(i)), " mV");
            end
            app.VoltageDropDown.Items = volt_dd_items;

            % update protocol figure
            plot_protocol(app);

            % update currents
            [app.yktof_set,app.kvktof_set] = gen_wrapper(app, app.yktof_idx, @gen_iktof);
            [app.yktos_set,app.kvktos_set] = gen_wrapper(app, app.yktos_idx, @gen_iktos);
            [app.ykslow1_set,app.kvkslow1_set] = gen_wrapper(app, app.ykslow1_idx, @gen_ikslow1);
            [app.ykslow2_set,app.kvkslow2_set] = gen_wrapper(app, app.ykslow2_idx, @gen_ikslow2);
            [app.ykss_set,app.kvkss_set] = gen_wrapper(app, app.ykss_idx, @gen_ikss);
            update_sum_current(app);
            
            % reset experimental data
            if (app.exp_idx == true)
                app.exp_idx = false;
                app.FileEditField_2.Value = "";
                cla(app.UIAxesExpData)
                app.exp_idx = false;
            end

            % update currents figures
            app.volt_now = 1;
            app.VoltageDropDown.Value = volt_dd_items(1);
            plot_currents(app);
            plot_kvktof(app);
            plot_kvktos(app);
            plot_kvkslow1(app);
            plot_kvkslow2(app);
            plot_kvkss(app);
        end

        % Button pushed function: ImportDataButton
        function ImportDataButtonPushed(app, event)
            [file,path] = uigetfile(["*.xlsx";"*.csv"], "File Selector");
            
            % when close file selection window without selecting file
            if (isequal(file, 0) || isequal(path, 0))
                return
            end

            % load experimental data
            exp_data = table2array(readtable(fullfile(path, file)));
            exp_t = exp_data(:,1);

            % meta info
            app.exp_idx = true;
            exp_num_volts = size(exp_data,2)-1;
                        
            % check protocols match the experimental data
            if ~isequal(exp_num_volts, app.num_volts)
                app.VoltWarnApp = warn_message_exp_volt();
                app.exp_idx = false;
                app.FileEditField_2.Value = "";
                return
            end
            
            % estimate time points
            [~, ideal_hold_idx] = min(abs(exp_t-app.HoldingtimemsEditField.Value)); 
            [~, ideal_end_idx] = min(abs(exp_t-app.DurationmsEditField.Value));
            
            app.t = exp_data(1:ideal_end_idx,1);
            app.hold_t = app.t(1:ideal_hold_idx);            
            exp_pulse_t = app.t(ideal_hold_idx+1:end);
            app.pulse_t = exp_pulse_t - exp_pulse_t(1);
            app.tt{1} = app.t;
            app.tt{2} = app.hold_t;
            app.tt{3} = app.pulse_t;
            app.exp_yksum_set = exp_data(1:ideal_end_idx,2:end);
            
            % update currents
            [app.yktof_set,~] = gen_wrapper(app, app.yktof_idx, @gen_iktof);
            [app.yktos_set,~] = gen_wrapper(app, app.yktos_idx, @gen_iktos);
            [app.ykslow1_set,~] = gen_wrapper(app, app.ykslow1_idx, @gen_ikslow1);
            [app.ykslow2_set,~] = gen_wrapper(app, app.ykslow2_idx, @gen_ikslow2);
            [app.ykss_set,~] = gen_wrapper(app, app.ykss_idx, @gen_ikss);
            
            % update figure
            update_sum_current(app);            
            plot_currents(app);

            % visualize experimental data
            plot(app.UIAxesExpData, app.t, app.exp_yksum_set(:,1))
            hold(app.UIAxesExpData, 'on')
            for i = 2:app.num_volts
                plot(app.UIAxesExpData, app.t, app.exp_yksum_set(:,i))
            end
            hold(app.UIAxesExpData, 'off')
            axis(app.UIAxesExpData, 'tight')

            % display file name
            app.FileEditField_2.Value = file;            
        end

        % Button pushed function: RunCalibrationButton
        function RunCalibrationButtonPushed(app, event)
            if app.exp_idx == false
                return
            end
            
            %----- progress bar -----%
            % store original button text
            orig_button_text = app.RunCalibrationButton.Text;

            % return the original button state at end of execution
            cleanup = onCleanup(@()set(app.RunCalibrationButton, ...
                'Text',orig_button_text, 'Icon',''));

            % remove the button text
            app.RunCalibrationButton.Text = '';
            
            % create waitbar with same color as button
            wbar = permute(repmat(app.RunCalibrationButton.BackgroundColor,20,1,250),[1,3,2]);

            % black frame around waitbar
            wbar([1,end],:,:) = 0;
            wbar(:,[1,end],:) = 0;

            % load the empty waitbar to the button
            app.RunCalibrationButton.Icon = wbar;
            pause(0.01);
            
            % ----- model information -----%
            % current model structure %
            current_idx_set = [app.yktof_idx, app.yktos_idx, ...
                app.ykslow1_idx, app.ykslow2_idx, app.ykss_idx];
            current_names = {'iktof','iktos','ikslow1','ikslow2','ikss'};
            current_names = current_names(current_idx_set==1);
            
            % tunning index in individual current models
            num_currents = length(current_names);
            tune_idx1_ktof = [1,2,3,4,5,7,11,13];
            tune_idx1_ktos = [2,3];
            tune_idx1_kslow1 = [1,2,4,5,9,10,11];
            tune_idx1_kslow2 = [1,2,3];
            tune_idx1_kss = [1,2,3,4];
            
            idx_info1 = cell(1, num_currents);
            for i = 1:num_currents
                switch current_names{i}
                    case 'iktof'
                        idx_info1{i} = tune_idx1_ktof;
                    case 'iktos'
                        idx_info1{i} = tune_idx1_ktos;
                    case 'ikslow1'
                        idx_info1{i} = tune_idx1_kslow1;
                    case 'ikslow2'
                        idx_info1{i} = tune_idx1_kslow2;
                    case 'ikss'
                        idx_info1{i} = tune_idx1_kss;
                end
            end

            % tunning index in decision variable, p
            idx_info2 = cell(1, num_currents);
            cul_idx_len = 0;
            for i = 1: num_currents
                cul_idx_len_old = cul_idx_len;
                cul_idx_len = cul_idx_len + length(idx_info1{i});
                idx_info2{i} = (1+cul_idx_len_old):(cul_idx_len);
            end
            
            % create model structure
            model_info = [current_names; idx_info1; idx_info2];
            field_names = ["name","idx1","idx2"];
            model_info = cell2struct(model_info, field_names, 1);
            
            %----- parameter bounds -----%
            % default initial point and parameter bounds
            lb_ktof = NaN(1,length(app.pdefault{1}));
            lb_ktof([1:4, 13]) = [-70, eps, eps, 1, eps];
            lb_ktof([5,6,10,12]) = app.pdefault{1}([5,6,10,12])*0.15;
            lb_ktof([7,8]) = app.pdefault{1}([7,8])*0.1;
            lb_ktof([9,11]) = app.pdefault{1}([9,11])*0.7;
            lb_ktos = app.pdefault{2}*0.1;
            lb_kslow1 = [-70, -70, -70, 1, 1, eps, eps, eps, eps, 50+eps, eps];
            lb_kslow2 = [eps, 5000, eps];
            lb_kss = [eps, eps, eps, eps];
        
            ub_ktof = NaN(1,length(app.pdefault{1}));
            ub_ktof([1:4, 13]) = [70, 40, 40, 14, 1];
            ub_ktof(5:12) = app.pdefault{1}(5:12)*1.95;
            ub_ktos = app.pdefault{2}*1.9;
            ub_kslow1 = [50, 50, 50, 20, 20, 1, 30, 50, 20, 2000, 1];
            ub_kslow2 = [5000, 10000, 1];
            ub_kss = [1, 2000, 100, 1];
            
            p0 = NaN(1,cul_idx_len);
            lb = NaN(1,cul_idx_len);
            ub = NaN(1,cul_idx_len);
            for i = 1:num_currents
                switch current_names{i}
                    case 'iktof'
                        p0(idx_info2{i}) = app.pdefault{1}(tune_idx1_ktof);
                        lb(idx_info2{i}) = lb_ktof(tune_idx1_ktof);
                        ub(idx_info2{i}) = ub_ktof(tune_idx1_ktof);
                    case 'iktos'
                        p0(idx_info2{i}) = app.pdefault{2}(tune_idx1_ktos);
                        lb(idx_info2{i}) = lb_ktos(tune_idx1_ktos);
                        ub(idx_info2{i}) = ub_ktos(tune_idx1_ktos);
                    case 'ikslow1'
                        p0(idx_info2{i}) = app.pdefault{3}(tune_idx1_kslow1);
                        lb(idx_info2{i}) = lb_kslow1(tune_idx1_kslow1);
                        ub(idx_info2{i}) = ub_kslow1(tune_idx1_kslow1);
                    case 'ikslow2'
                        p0(idx_info2{i}) = app.pdefault{4}(tune_idx1_kslow2);
                        lb(idx_info2{i}) = lb_kslow2(tune_idx1_kslow2);
                        ub(idx_info2{i}) = ub_kslow2(tune_idx1_kslow2);
                    case 'ikss'
                        p0(idx_info2{i}) = app.pdefault{5}(tune_idx1_kss);
                        lb(idx_info2{i}) = lb_kss(tune_idx1_kss);
                        ub(idx_info2{i}) = ub_kss(tune_idx1_kss);
                end
            end
            
            %----- protocol info -----%
            protocol = cell(4,1);
            protocol{1} = app.HoldingpotentialmVEditField.Value;
            protocol{2} = app.NernstpotentialmVEditField.Value;
            min_volt = app.VoltagestepfrommVEditField.Value;
            volt_step = app.StepsizemVEditField.Value;
            max_volt = app.VoltagesteptomVEditField.Value;
            protocol{3} = min_volt:volt_step:max_volt;
            protocol{4} = app.tt; % updated when exp_data loaded
            
            %----- optimization loop -----%
            num_iters = 5;
            sol_list = NaN(num_iters,cul_idx_len);
            rmse_list = NaN(num_iters,1);

            % random initial points
            init_pts = lhsdesign(num_iters-1,cul_idx_len);
            for i = 1:size(init_pts,1)
                init_pts(i,:) = init_pts(i,:).*(ub-lb) + lb;
            end
            
            % initial run with the default parameters values
            [sol,rmse] = run_calib(app.exp_yksum_set, model_info, protocol, p0, app.pdefault, lb, ub);
            sol_list(1,:) = sol;
            rmse_list(1) = rmse;
            
            % update progress bar
            current_prog = min(round((size(wbar,2)-2)*(1/num_iters)), size(wbar,2)-2); 
            app.RunCalibrationButton.Icon(2:end-1, 2:current_prog+1, 1) = 0.25391; % (royalblue)
            app.RunCalibrationButton.Icon(2:end-1, 2:current_prog+1, 2) = 0.41016;
            app.RunCalibrationButton.Icon(2:end-1, 2:current_prog+1, 3) = 0.87891;
            pause(0.01);

            for i = 1:size(init_pts,1)
                [sol,rmse] = run_calib(app.exp_yksum_set, model_info, protocol,init_pts(i,:), app.pdefault, lb, ub);
                sol_list(i+1,:) = sol;
                rmse_list(i+1) = rmse;

                % update progress bar
                current_prog = min(round((size(wbar,2)-2)*((i+1)/num_iters)), size(wbar,2)-2); 
                app.RunCalibrationButton.Icon(2:end-1, 2:current_prog+1, 1) = 0.25391; % (royalblue)
                app.RunCalibrationButton.Icon(2:end-1, 2:current_prog+1, 2) = 0.41016;
                app.RunCalibrationButton.Icon(2:end-1, 2:current_prog+1, 3) = 0.87891;
                pause(0.01);
            end
            
            % update parameters
            [best_rmse,best_fit_idx] = min(rmse_list);
            best_sol = sol_list(best_fit_idx,:);
            for i = 1:num_currents
                switch current_names{i}
                    case 'iktof'
                        sol_ktof = app.pdefault{1};
                        sol_ktof(idx_info1{i}) = best_sol(idx_info2{i});
                        update_pktof(app,sol_ktof);
                        [app.yktof_set,app.kvktof_set] = gen_wrapper(app, app.yktof_idx, @gen_iktof);
                        plot_kvktof(app);
                    case 'iktos'
                        sol_ktos = app.pdefault{2};
                        sol_ktos(idx_info1{i}) = best_sol(idx_info2{i});
                        update_pktos(app, sol_ktos);
                        [app.yktos_set,app.kvktos_set] = gen_wrapper(app, app.yktos_idx, @gen_iktos);
                        plot_kvktos(app);
                    case 'ikslow1'
                        sol_kslow1 = app.pdefault{3};
                        sol_kslow1(idx_info1{i}) = best_sol(idx_info2{i});
                        update_pkslow1(app,sol_kslow1);
                        [app.ykslow1_set,app.kvkslow1_set] = gen_wrapper(app, app.ykslow1_idx, @gen_ikslow1);
                        plot_kvkslow1(app);
                    case 'ikslow2'
                        sol_kslow2 = app.pdefault{4};
                        sol_kslow2(idx_info1{i}) = best_sol(idx_info2{i});
                        update_pkslow2(app,sol_kslow2);
                        [app.ykslow2_set,app.kvkslow2_set] = gen_wrapper(app, app.ykslow2_idx, @gen_ikslow2);
                        plot_kvkslow2(app);
                    case 'ikss'
                        sol_kss = app.pdefault{5};
                        sol_kss(idx_info1{i}) = best_sol(idx_info2{i});
                        update_pkss(app,sol_kss);
                        [app.ykss_set,app.kvkss_set] = gen_wrapper(app, app.ykss_idx, @gen_ikss);
                        plot_kvkss(app);
                end
            end
            app.RMSEEditField.Value = best_rmse;
            update_sum_current(app);
            plot_currents(app);
        end

        % Button pushed function: ImportModelParametersButton
        function ImportModelParametersButtonPushed(app, event)
            [file,path] = uigetfile(["*.xlsx";"*.csv"], "File Selector");
            
            % when close file selection window without selecting file
            if (isequal(file, 0) || isequal(path, 0))
                return
            end
            
            % import parameters saved in Excel file format
            app.FileEditField.Value = file;
            param = readtable(fullfile(path, file));
            var_names = param.Properties.VariableNames;
            var_names = string(lower(var_names));
            
            app.RMSEEditField.Value = obj_rmse(app);

            if any(ismember("iktof", var_names))
                sol = param.iktof;
                sol = sol(~isnan(sol));
                update_pktof(app,sol);
                [app.yktof_set, app.kvktof_set] = gen_wrapper(app, app.yktof_idx, @gen_iktof);
                plot_kvktof(app);

                % calculate_density(app.kvkotf_set);
            end
            
            if any(ismember("iktos", var_names))
                sol = param.iktos;
                sol = sol(~isnan(sol));
                update_pktos(app,sol);
                [app.yktos_set, app.kvktos_set] = gen_wrapper(app, app.yktos_idx, @gen_iktos);
                plot_kvktos(app);
            end

            if any(ismember("ikslow1", var_names))
                sol = param.ikslow1;
                sol = sol(~isnan(sol));
                update_pkslow1(app,sol);
                [app.ykslow1_set, app.kvkslow1_set] = gen_wrapper(app, app.ykslow1_idx, @gen_ikslow1);
                plot_kvkslow1(app);
            end

            if any(ismember("ikslow2", var_names))
                sol = param.ikslow2;
                sol = sol(~isnan(sol));
                update_pkslow2(app,sol);
                [app.ykslow2_set,app.kvkslow2_set] = gen_wrapper(app, app.ykslow2_idx, @gen_ikslow2);
                plot_kvkslow2(app);
            end

            if any(ismember("ikss", var_names))
                sol = param.ikss;
                sol = sol(~isnan(sol));
                update_pkss(app,sol);
                [app.ykss_set,app.kvkss_set] = gen_wrapper(app, app.ykss_idx, @gen_ikss);
                plot_kvkss(app);
            end

            % update iksum and plot
            update_sum_current(app);
            plot_currents(app);
        end

        % Button pushed function: SaveModelParametersButton
        function SaveModelParametersButtonPushed(app, event)
            [file, path] = uiputfile("model_parameter.xlsx", "Save File Specifier");
            
            % when closing window without action
            if (isequal(file, 0) || isequal(path, 0))
                return
            end
            save_path = fullfile(path, file);

            % header
            current_idx_set = [app.yktof_idx, app.yktos_idx, ...
                app.ykslow1_idx, app.ykslow2_idx, app.ykss_idx];
            currents = ["iktof","iktos","ikslow1","ikslow2","ikss"];
            active_currents = currents(current_idx_set==1);
            writematrix(active_currents, save_path, "Sheet","Model Parameters", "Range","A1");
            
            % body
            max_param_len = 13;
            pmx = NaN(max_param_len,length(active_currents));

            pkto_save = app.pktof;
            pkto_save = [pkto_save; NaN(max_param_len-length(pkto_save),1)];
            
            pktos_save = app.pktos([8,10,11]);
            pktos_save = [pktos_save; NaN(max_param_len-length(pktos_save),1)];

            pkslow1_save = app.pkslow1;
            pkslow1_save = [pkslow1_save; NaN(max_param_len-length(pkslow1_save),1)];
            
            pkslow2_save = app.pkslow2([8,10,11]);
            pkslow2_save = [pkslow2_save; NaN(max_param_len-length(pkslow2_save),1)];

            pkss_save = app.pkss(4:7);
            pkss_save = [pkss_save; NaN(max_param_len-length(pkss_save),1)];

            for i = 1:sum(current_idx_set)
                switch active_currents(i)
                    case "iktof"
                        pmx(:, i) = pkto_save;
                    case "iktos"
                        pmx(:, i) = pktos_save;                        
                    case "ikslow1"
                        pmx(:, i) = pkslow1_save;
                    case "ikslow2"
                        pmx(:, i) = pkslow2_save;
                    case "ikss"
                        pmx(:, i) = pkss_save;
                end
            end
            writematrix(pmx, save_path, 'Sheet','Model Parameters', 'Range','A2');
        end

        % Value changed function: VoltageDropDown
        function VoltageDropDownValueChanged(app, event)
            value = app.VoltageDropDown.Value;
            volt = split(value, " ");
            volt = str2double(volt(1));
            app.volt_now = find(app.volts == volt);
            plot_currents(app);
        end

        % Value changed function: IncludeKtof
        function IncludeKtofValueChanged(app, event)
            value = app.IncludeKtof.Value;
            app.yktof_idx = value;
            [app.yktof_set, app.kvktof_set] = gen_wrapper(app, app.yktof_idx, @gen_iktof);
            update_sum_current(app);
            plot_currents(app);
            if value == 1
                plot_kvktof(app);
            else
                delete(app.KvKtof.Children)
            end
        end

        % Value changed function: IncludeKslow1
        function IncludeKslow1ValueChanged(app, event)
            value = app.IncludeKslow1.Value;
            app.ykslow1_idx = value;
            [app.ykslow1_set, app.kvktos_set] = gen_wrapper(app, app.ykslow1_idx, @gen_ikslow1);
            update_sum_current(app);
            plot_currents(app);
            if value == 1
                plot_kvkslow1(app);
            else
                delete(app.KvKslow1.Children)
            end
        end

        % Value changed function: IncludeKslow2
        function IncludeKslow2ValueChanged(app, event)
            value = app.IncludeKslow2.Value;
            app.ykslow2_idx = value;
            [app.ykslow2_set, app.kvkslow1_set] = gen_wrapper(app, app.ykslow2_idx, @gen_ikslow2);
            update_sum_current(app);
            plot_currents(app);
            if value == 1
                plot_kvkslow2(app);
            else
                delete(app.KvKslow2.Children)
            end
        end

        % Value changed function: IncludeKss
        function IncludeKssValueChanged(app, event)
            value = app.IncludeKss.Value;
            app.ykss_idx = value;
            [app.ykss_set, app.kvkss_set] = gen_wrapper(app, app.ykss_idx, @gen_ikss);
            update_sum_current(app);
            plot_currents(app);
            if value == 1
                plot_kvkss(app);
            else
                delete(app.KvKss.Children)
            end
        end

        % Value changed function: IncludeKtos
        function IncludeKtosValueChanged(app, event)
            value = app.IncludeKtos.Value;
            app.yktos_idx = value;
            [app.yktos_set, app.kvktos_set] = gen_wrapper(app, app.yktos_idx, @gen_iktos);
            update_sum_current(app);
            plot_currents(app);
            if value == 1
                plot_kvktos(app);
            else
                delete(app.KvKtos.Children)
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.NanWarnApp)
            delete(app.VoltWarnApp)
            delete(app)
        end

        % Value changed function: p1EditField
        function p1EditFieldValueChanged(app, event)
            value = app.p1EditField.Value;
            app.pktof(1) = value;
            [app.yktof_set, app.kvktof_set] = gen_wrapper(app, app.yktof_idx, @gen_iktof);
            update_sum_current(app);
            plot_currents(app);
            plot_kvktof(app);
        end

        % Value changed function: p2EditField
        function p2EditFieldValueChanged(app, event)
            value = app.p2EditField.Value;
            app.pktof(2) = value;
            [app.yktof_set, app.kvktof_set] = gen_wrapper(app, app.yktof_idx, @gen_iktof);
            update_sum_current(app);
            plot_currents(app);
            plot_kvktof(app);
        end

        % Value changed function: p3EditField
        function p3EditFieldValueChanged(app, event)
            value = app.p3EditField.Value;
            app.pktof(3) = value;
            [app.yktof_set, app.kvktof_set] = gen_wrapper(app, app.yktof_idx, @gen_iktof);
            update_sum_current(app);
            plot_currents(app);
            plot_kvktof(app);            
        end

        % Value changed function: p4EditField
        function p4EditFieldValueChanged(app, event)
            value = app.p4EditField.Value;
            app.pktof(4) = value;
            [app.yktof_set, app.kvktof_set] = gen_wrapper(app, app.yktof_idx, @gen_iktof);
            update_sum_current(app);
            plot_currents(app);
            plot_kvktof(app);
        end

        % Value changed function: p5EditField
        function p5EditFieldValueChanged(app, event)
            value = app.p5EditField.Value;
            app.pktof(5) = value;
            [app.yktof_set, app.kvktof_set] = gen_wrapper(app, app.yktof_idx, @gen_iktof);
            update_sum_current(app);
            plot_currents(app);
            plot_kvktof(app);
        end

        % Value changed function: p6EditField
        function p6EditFieldValueChanged(app, event)
            value = app.p6EditField.Value;
            app.pktof(6) = value;
            [app.yktof_set, app.kvktof_set] = gen_wrapper(app, app.yktof_idx, @gen_iktof);
            update_sum_current(app);
            plot_currents(app);
            plot_kvktof(app);
        end

        % Value changed function: p7EditField
        function p7EditFieldValueChanged(app, event)
            value = app.p7EditField.Value;
            app.pktof(7) = value;
            [app.yktof_set, app.kvktof_set] = gen_wrapper(app, app.yktof_idx, @gen_iktof);
            update_sum_current(app);
            plot_currents(app);
            plot_kvktof(app);
        end

        % Value changed function: p8EditField
        function p8EditFieldValueChanged(app, event)
            value = app.p8EditField.Value;
            app.pktof(8) = value;
            [app.yktof_set, app.kvktof_set] = gen_wrapper(app, app.yktof_idx, @gen_iktof);
            update_sum_current(app);
            plot_currents(app);
            plot_kvktof(app);
        end

        % Value changed function: p9EditField
        function p9EditFieldValueChanged(app, event)
            value = app.p9EditField.Value;
            app.pktof(9) = value;
            [app.yktof_set, app.kvktof_set] = gen_wrapper(app, app.yktof_idx, @gen_iktof);
            update_sum_current(app);
            plot_currents(app);
            plot_kvktof(app);
        end

        % Value changed function: p10EditField
        function p10EditFieldValueChanged(app, event)
            value = app.p10EditField.Value;
            app.pktof(10) = value;
            [app.yktof_set, app.kvktof_set] = gen_wrapper(app, app.yktof_idx, @gen_iktof);
            update_sum_current(app);
            plot_currents(app);
            plot_kvktof(app);
        end

        % Value changed function: p11EditField
        function p11EditFieldValueChanged(app, event)
            value = app.p11EditField.Value;
            app.pktof(11) = value;
            [app.yktof_set, app.kvktof_set] = gen_wrapper(app, app.yktof_idx, @gen_iktof);
            update_sum_current(app);
            plot_currents(app);
            plot_kvktof(app);
        end

        % Value changed function: p12EditField
        function p12EditFieldValueChanged(app, event)
            value = app.p12EditField.Value;
            app.pktof(12) = value;
            [app.yktof_set, app.kvktof_set] = gen_wrapper(app, app.yktof_idx, @gen_iktof);
            update_sum_current(app);
            plot_currents(app);
            plot_kvktof(app);
        end

        % Value changed function: GKtofEditField
        function GKtofEditFieldValueChanged(app, event)
            value = app.GKtofEditField.Value;
            app.pktof(13) = value;
            [app.yktof_set, app.kvktof_set] = gen_wrapper(app, app.yktof_idx, @gen_iktof);
            update_sum_current(app);
            plot_currents(app);
            plot_kvktof(app);
        end

        % Value changed function: p1EditField2
        function p1EditField2ValueChanged(app, event)
            value = app.p1EditField2.Value;
            app.pkslow1(1) = value;
            app.pkslow2(1) = value;
            app.pkss(1) = value;
            app.pktos(1) = value;
            
            [app.ykslow1_set, app.kvkslow1_set] = gen_wrapper(app, app.ykslow1_idx, @gen_ikslow1);
            [app.ykslow2_set, ~] = gen_wrapper(app, app.ykslow2_idx, @gen_ikslow2);
            [app.ykss_set, ~] = gen_wrapper(app, app.ykss_idx, @gen_ikss);
            [app.yktos_set, ~] = gen_wrapper(app, app.yktos_idx, @gen_iktos);

            update_sum_current(app);
            plot_currents(app);
            plot_kvkslow1(app);
            plot_kvktos(app);
        end

        % Value changed function: p2EditField2
        function p2EditField2ValueChanged(app, event)
            value = app.p2EditField2.Value;
            app.pkslow1(2) = value;
            app.pkslow2(2) = value;
            app.pktos(2) = value;
            
            [app.ykslow1_set, app.kvkslow1_set] = gen_wrapper(app, app.ykslow1_idx, @gen_ikslow1);
            [app.ykslow2_set, app.kvkslow2_set] = gen_wrapper(app, app.ykslow2_idx, @gen_ikslow2);
            [app.yktos_set, app.kvktos_set] = gen_wrapper(app, app.yktos_idx, @gen_iktos);
            
            update_sum_current(app);
            plot_currents(app);
            plot_kvkslow1(app);
            plot_kvkslow2(app);
            plot_kvktos(app);
        end

        % Value changed function: p3EditField2
        function p3EditField2ValueChanged(app, event)
            value = app.p3EditField2.Value;
            app.pkslow1(3) = value;
            app.pkslow2(3) = value;
            app.pkss(2) = value;
            app.pktos(3) = value;

            [app.ykslow1_set, app.kvkslow1_set] = gen_wrapper(app, app.ykslow1_idx, @gen_ikslow1);
            [app.ykslow2_set, ~] = gen_wrapper(app, app.ykslow2_idx, @gen_ikslow2);
            [app.ykss_set, app.kvkss_set] = gen_wrapper(app, app.ykss_idx, @gen_ikss);
            [app.yktos_set, ~] = gen_wrapper(app, app.yktos_idx, @gen_iktos);

            update_sum_current(app);
            plot_currents(app);
            plot_kvkslow1(app);
            plot_kvkss(app);
            plot_kvktos(app);
        end

        % Value changed function: p4EditField2
        function p4EditField2ValueChanged(app, event)
            value = app.p4EditField2.Value;
            app.pkslow1(4) = value;
            app.pkslow2(4) = value;
            app.pkss(3) = value;
            app.pktos(4) = value;

            [app.ykslow1_set, app.kvkslow1_set] = gen_wrapper(app, app.ykslow1_idx, @gen_ikslow1);
            [app.ykslow2_set, ~] = gen_wrapper(app, app.ykslow2_idx, @gen_ikslow2);
            [app.ykss_set, ~] = gen_wrapper(app, app.ykss_idx, @gen_ikss);
            [app.yktos_set, ~] = gen_wrapper(app, app.yktos_idx, @gen_iktos);

            update_sum_current(app);
            plot_currents(app);
            plot_kvkslow1(app);
            plot_kvktos(app);
        end

        % Value changed function: p5EditField2
        function p5EditField2ValueChanged(app, event)
            value = app.p5EditField2.Value;
            app.pkslow1(5) = value;
            app.pkslow2(5) = value;
            app.pktos(5) = value;
            
            [app.ykslow1_set, app.kvkslow1_set] = gen_wrapper(app, app.ykslow1_idx, @gen_ikslow1);
            [app.ykslow2_set, app.kvkslow2_set] = gen_wrapper(app, app.ykslow2_idx, @gen_ikslow2);
            [app.yktos_set, app.kvktos_set] = gen_wrapper(app, app.yktos_idx, @gen_iktos);
            
            update_sum_current(app);
            plot_currents(app);
            plot_kvkslow1(app);
            plot_kvkslow2(app);
            plot_kvktos(app);          
        end

        % Value changed function: p6EditField2
        function p6EditField2ValueChanged(app, event)
            value = app.p6EditField2.Value;
            app.pkslow1(6) = value;
            app.pkslow2(6) = value;
            app.pktos(6) = value;

            [app.ykslow1_set, app.kvkslow1_set] = gen_wrapper(app, app.ykslow1_idx, @gen_ikslow1);
            [app.ykslow2_set, ~] = gen_wrapper(app, app.ykslow2_idx, @gen_ikslow2);
            [app.yktos_set, ~] = gen_wrapper(app, app.yktos_idx, @gen_iktos);
            
            update_sum_current(app);
            plot_currents(app);
            plot_kvkslow1(app);
            plot_kvktos(app);
        end

        % Value changed function: p7EditField2
        function p7EditField2ValueChanged(app, event)
            value = app.p7EditField2.Value;
            app.pkslow1(7) = value;
            app.pkslow2(7) = value;
            app.pktos(7) = value;

            [app.ykslow1_set, app.kvkslow1_set] = gen_wrapper(app, app.ykslow1_idx, @gen_ikslow1);
            [app.ykslow2_set, ~] = gen_wrapper(app, app.ykslow2_idx, @gen_ikslow2);
            [app.yktos_set, ~] = gen_wrapper(app, app.yktos_idx, @gen_iktos);
            
            update_sum_current(app);
            plot_currents(app);
            plot_kvkslow1(app);
            plot_kvktos(app);           
        end

        % Value changed function: p8EditField2
        function p8EditField2ValueChanged(app, event)
            value = app.p8EditField2.Value;
            app.pkslow1(8) = value;
            [app.ykslow1_set, app.kvkslow1_set] = gen_wrapper(app, app.ykslow1_idx, @gen_ikslow1);
            update_sum_current(app);
            plot_currents(app);
            plot_kvkslow1(app);
        end

        % Value changed function: p9EditField2
        function p9EditField2ValueChanged(app, event)
            value = app.p9EditField2.Value;
            app.pkslow1(9) = value;
            app.pkslow2(9) = value;

            [app.ykslow1_set, app.kvkslow1_set] = gen_wrapper(app, app.ykslow1_idx, @gen_ikslow1);
            [app.ykslow2_set, ~] = gen_wrapper(app, app.ykslow2_idx, @gen_ikslow2);
            [app.yktos_set, ~] = gen_wrapper(app, app.yktos_idx, @gen_iktos);
            
            update_sum_current(app);
            plot_currents(app);
            plot_kvkslow1(app);
            plot_kvktos(app);            
        end

        % Value changed function: p10EditField2
        function p10EditField2ValueChanged(app, event)
            value = app.p10EditField2.Value;
            app.pkslow1(10) = value;
            [app.ykslow1_set, app.kvkslow1_set] = gen_wrapper(app, app.ykslow1_idx, @gen_ikslow1);
            update_sum_current(app);
            plot_currents(app);
            plot_kvkslow1(app);
        end

        % Value changed function: GKslow1EditField
        function GKslow1EditFieldValueChanged(app, event)
            value = app.GKslow1EditField.Value;
            app.pkslow1(11) = value;
            [app.ykslow1_set, ~] = gen_wrapper(app, app.ykslow1_idx, @gen_ikslow1);
            update_sum_current(app);
            plot_currents(app);
        end

        % Value changed function: p1EditField3
        function p1EditField3ValueChanged(app, event)
            value = app.p1EditField3.Value;
            app.pkslow2(8) = value;
            [app.ykslow2_set, app.kvkslow2_set] = gen_wrapper(app, app.ykslow2_idx, @gen_ikslow2);
            update_sum_current(app);
            plot_currents(app);
            plot_kvkslow2(app);
        end

        % Value changed function: p2EditField3
        function p2EditField3ValueChanged(app, event)
            value = app.p2EditField3.Value;
            app.pkslow2(10) = value;
            [app.ykslow2_set, app.kvkslow2_set] = gen_wrapper(app, app.ykslow2_idx, @gen_ikslow2);
            update_sum_current(app);
            plot_currents(app);
            plot_kvkslow2(app);
        end

        % Value changed function: GKslow2EditField
        function GKslow2EditFieldValueChanged(app, event)
            value = app.GKslow2EditField.Value;
            app.pkslow2(11) = value;
            [app.ykslow2_set, app.kvkslow2_set] = gen_wrapper(app, app.ykslow2_idx, @gen_ikslow2);
            update_sum_current(app);
            plot_currents(app);
        end

        % Value changed function: p1EditField4
        function p1EditField4ValueChanged(app, event)
            value = app.p1EditField4.Value;
            app.pkss(4) = value;
            [app.ykss_set, app.kvkss_set] = gen_wrapper(app, app.ykss_idx, @gen_ikss);
            update_sum_current(app);
            plot_currents(app);
            plot_kvkss(app);
        end

        % Value changed function: p2EditField4
        function p2EditField4ValueChanged(app, event)
            value = app.p2EditField4.Value;
            app.pkss(5) = value;
            [app.ykss_set, app.kvkss_set] = gen_wrapper(app, app.ykss_idx, @gen_ikss);
            update_sum_current(app);
            plot_currents(app);
            plot_kvkss(app);
        end

        % Value changed function: p3EditField4
        function p3EditField4ValueChanged(app, event)
            value = app.p3EditField4.Value;
            app.pkss(6) = value;
            [app.ykss_set, app.kvkss_set] = gen_wrapper(app, app.ykss_idx, @gen_ikss);
            update_sum_current(app);
            plot_currents(app);
            plot_kvkss(app);
        end

        % Value changed function: GKssEditField
        function GKssEditFieldValueChanged(app, event)
            value = app.GKssEditField.Value;
            app.pkss(7) = value;
            [app.ykss_set, app.kvkss_set] = gen_wrapper(app, app.ykss_idx, @gen_ikss);
            update_sum_current(app);
            plot_currents(app);
        end

        % Value changed function: p1EditField5
        function p1EditField5ValueChanged(app, event)
            value = app.p1EditField5.Value;
            app.pktos(8) = value;
            [app.yktos_set, app.kvktos_set] = gen_wrapper(app, app.yktos_idx, @gen_iktos);
            update_sum_current(app);
            plot_currents(app);
            plot_kvktos(app);
        end

        % Value changed function: p2EditField5
        function p2EditField5ValueChanged(app, event)
            value = app.p2EditField5.Value;
            app.pktos(10) = value;
            [app.yktos_set, app.kvktos_set] = gen_wrapper(app, app.yktos_idx, @gen_iktos);
            update_sum_current(app);
            plot_currents(app);
            plot_kvktos(app);
        end

        % Value changed function: GKtosEditField
        function GKtosEditFieldValueChanged(app, event)
            value = app.GKtosEditField.Value;
            app.pktos(11) = value;
            [app.yktos_set, app.kvktos_set] = gen_wrapper(app, app.yktos_idx, @gen_iktos);
            update_sum_current(app);
            plot_currents(app);
            plot_kvktos(app);
        end

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
            plot_density(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.LeftPanel = uipanel(app.UIFigure);
            app.ProtocolPanel = uipanel(app.LeftPanel);
            app.ProtocolPanel.Title = 'Voltage Clamp Protocols';
            app.UIAxesProtocol = uiaxes(app.ProtocolPanel);
            title(app.UIAxesProtocol, 'Protocol')
            xlabel(app.UIAxesProtocol, 'Time (ms)')
            ylabel(app.UIAxesProtocol, 'Clamping Voltage (mV)')
            app.HoldingtimemsEditFieldLabel = uilabel(app.ProtocolPanel);
            app.HoldingtimemsEditFieldLabel.Text = 'Holding time (ms)';
            app.HoldingtimemsEditField = uieditfield(app.ProtocolPanel, 'numeric');
            app.HoldingtimemsEditField.Value = 120;
            app.HoldingpotentialmVEditFieldLabel = uilabel(app.ProtocolPanel);
            app.HoldingpotentialmVEditFieldLabel.Text = 'Holding potential (mV)';
            app.HoldingpotentialmVEditField = uieditfield(app.ProtocolPanel, 'numeric');
            app.HoldingpotentialmVEditField.Value = -70;
            app.VoltagestepfrommVEditFieldLabel = uilabel(app.ProtocolPanel);
            app.VoltagestepfrommVEditFieldLabel.Text = 'Voltage step from (mV)';
            app.VoltagestepfrommVEditField = uieditfield(app.ProtocolPanel, 'numeric');
            app.VoltagestepfrommVEditField.Value = -30;
            app.DurationmsEditFieldLabel = uilabel(app.ProtocolPanel);
            app.DurationmsEditFieldLabel.Text = 'Duration (ms)';
            app.DurationmsEditField = uieditfield(app.ProtocolPanel, 'numeric');
            app.DurationmsEditField.ValueDisplayFormat = '%.0f';
            app.DurationmsEditField.Value = 4500;
            app.NernstpotentialmVEditFieldLabel = uilabel(app.ProtocolPanel);
            app.NernstpotentialmVEditFieldLabel.Text = 'Nernst potential (mV)';
            app.NernstpotentialmVEditField = uieditfield(app.ProtocolPanel, 'numeric');
            app.NernstpotentialmVEditField.Value = -91.1;
            app.StepsizemVEditFieldLabel = uilabel(app.ProtocolPanel);
            app.StepsizemVEditFieldLabel.Text = 'Step size (mV)';
            app.StepsizemVEditField = uieditfield(app.ProtocolPanel, 'numeric');
            app.StepsizemVEditField.Value = 10;
            app.VoltagesteptomVEditFieldLabel = uilabel(app.ProtocolPanel);
            app.VoltagesteptomVEditFieldLabel.Text = 'Voltage step to (mV)';
            app.VoltagesteptomVEditField = uieditfield(app.ProtocolPanel, 'numeric');
            app.VoltagesteptomVEditField.Value = 50;
            app.UpdateButton = uibutton(app.ProtocolPanel, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.KCurrentRecordingsPanel = uipanel(app.LeftPanel);
            app.KCurrentRecordingsPanel.Title = 'K+ Current Recordings';
            app.UIAxesExpData = uiaxes(app.KCurrentRecordingsPanel);
            title(app.UIAxesExpData, 'Normalized I_{Ksum} Recordings')
            xlabel(app.UIAxesExpData, 'Time (ms)')
            ylabel(app.UIAxesExpData, 'I_{K} (pA/pF)')
            app.ImportDataButton = uibutton(app.KCurrentRecordingsPanel, 'push');
            app.ImportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ImportDataButtonPushed, true);
            app.ImportDataButton.Text = 'Import Data';
            app.FileEditField_2Label = uilabel(app.KCurrentRecordingsPanel);
            app.FileEditField_2Label.Text = 'File ';
            app.FileEditField_2 = uieditfield(app.KCurrentRecordingsPanel, 'text');
            app.CenterPanel = uipanel(app.UIFigure);
            app.TabGroupComputerModel = uitabgroup(app.CenterPanel);
            app.Kv42Tab = uitab(app.TabGroupComputerModel);
            app.Kv42Tab.Title = 'Kv4.2';
            app.LabelKtof = uilabel(app.Kv42Tab);
            app.LabelKtof.Text = 'IKto,f (Kv4.2): Transient outward K+ current';
            app.IncludeKtof = uicheckbox(app.Kv42Tab);
            app.IncludeKtof.ValueChangedFcn = createCallbackFcn(app, @IncludeKtofValueChanged, true);
            app.IncludeKtof.Text = 'Include';
            app.IncludeKtof.Value = true;
            app.p_1Label = uilabel(app.Kv42Tab);
            app.p_1Label.Interpreter = 'latex';
            app.p_1Label.Text = '$p_{1}$';
            app.p1EditField = uieditfield(app.Kv42Tab, 'numeric');
            app.p1EditField.ValueDisplayFormat = '%11.5g';
            app.p1EditField.ValueChangedFcn = createCallbackFcn(app, @p1EditFieldValueChanged, true);
            app.p1EditField.Value = 33;
            app.p_1Label_2 = uilabel(app.Kv42Tab);
            app.p_1Label_2.Interpreter = 'latex';
            app.p_1Label_2.Text = '$p_{2}$';
            app.p2EditField = uieditfield(app.Kv42Tab, 'numeric');
            app.p2EditField.ValueDisplayFormat = '%11.5g';
            app.p2EditField.ValueChangedFcn = createCallbackFcn(app, @p2EditFieldValueChanged, true);
            app.p2EditField.Value = 15.5;
            app.p_1Label_3 = uilabel(app.Kv42Tab);
            app.p_1Label_3.Interpreter = 'latex';
            app.p_1Label_3.Text = '$p_{3}$';
            app.p3EditField = uieditfield(app.Kv42Tab, 'numeric');
            app.p3EditField.ValueDisplayFormat = '%11.5g';
            app.p3EditField.ValueChangedFcn = createCallbackFcn(app, @p3EditFieldValueChanged, true);
            app.p3EditField.Value = 20;
            app.p_1Label_4 = uilabel(app.Kv42Tab);
            app.p_1Label_4.Interpreter = 'latex';
            app.p_1Label_4.Text = '$p_{4}$';
            app.p4EditField = uieditfield(app.Kv42Tab, 'numeric');
            app.p4EditField.ValueDisplayFormat = '%11.5g';
            app.p4EditField.ValueChangedFcn = createCallbackFcn(app, @p4EditFieldValueChanged, true);
            app.p4EditField.Value = 7;
            app.p_1Label_5 = uilabel(app.Kv42Tab);
            app.p_1Label_5.Interpreter = 'latex';
            app.p_1Label_5.Text = '$p_{5}$';
            app.p5EditField = uieditfield(app.Kv42Tab, 'numeric');
            app.p5EditField.ValueDisplayFormat = '%11.5g';
            app.p5EditField.ValueChangedFcn = createCallbackFcn(app, @p5EditFieldValueChanged, true);
            app.p5EditField.Value = 0.03577;
            app.p_1Label_6 = uilabel(app.Kv42Tab);
            app.p_1Label_6.Interpreter = 'latex';
            app.p_1Label_6.Text = '$p_{6}$';
            app.p6EditField = uieditfield(app.Kv42Tab, 'numeric');
            app.p6EditField.ValueDisplayFormat = '%11.5g';
            app.p6EditField.ValueChangedFcn = createCallbackFcn(app, @p6EditFieldValueChanged, true);
            app.p6EditField.Value = 0.06237;
            app.p_1Label_7 = uilabel(app.Kv42Tab);
            app.p_1Label_7.Interpreter = 'latex';
            app.p_1Label_7.Text = '$p_{7}$';
            app.p7EditField = uieditfield(app.Kv42Tab, 'numeric');
            app.p7EditField.ValueDisplayFormat = '%11.5g';
            app.p7EditField.ValueChangedFcn = createCallbackFcn(app, @p7EditFieldValueChanged, true);
            app.p7EditField.Value = 0.18064;
            app.p_1Label_8 = uilabel(app.Kv42Tab);
            app.p_1Label_8.Interpreter = 'latex';
            app.p_1Label_8.Text = '$p_{8}$';
            app.p8EditField = uieditfield(app.Kv42Tab, 'numeric');
            app.p8EditField.ValueDisplayFormat = '%11.5g';
            app.p8EditField.ValueChangedFcn = createCallbackFcn(app, @p8EditFieldValueChanged, true);
            app.p8EditField.Value = 0.3956;
            app.p_1Label_9 = uilabel(app.Kv42Tab);
            app.p_1Label_9.Interpreter = 'latex';
            app.p_1Label_9.Text = '$p_{9}$';
            app.p9EditField = uieditfield(app.Kv42Tab, 'numeric');
            app.p9EditField.ValueDisplayFormat = '%11.5g';
            app.p9EditField.ValueChangedFcn = createCallbackFcn(app, @p9EditFieldValueChanged, true);
            app.p9EditField.Value = 0.000152;
            app.p_1Label_10 = uilabel(app.Kv42Tab);
            app.p_1Label_10.Interpreter = 'latex';
            app.p_1Label_10.Text = '$p_{10}$';
            app.p10EditField = uieditfield(app.Kv42Tab, 'numeric');
            app.p10EditField.ValueDisplayFormat = '%11.5g';
            app.p10EditField.ValueChangedFcn = createCallbackFcn(app, @p10EditFieldValueChanged, true);
            app.p10EditField.Value = 0.067083;
            app.p_1Label_11 = uilabel(app.Kv42Tab);
            app.p_1Label_11.Interpreter = 'latex';
            app.p_1Label_11.Text = '$p_{11}$';
            app.p11EditField = uieditfield(app.Kv42Tab, 'numeric');
            app.p11EditField.ValueDisplayFormat = '%11.5g';
            app.p11EditField.ValueChangedFcn = createCallbackFcn(app, @p11EditFieldValueChanged, true);
            app.p11EditField.Value = 0.00095;
            app.p_1Label_12 = uilabel(app.Kv42Tab);
            app.p_1Label_12.Interpreter = 'latex';
            app.p_1Label_12.Text = '$p_{12}$';
            app.p12EditField = uieditfield(app.Kv42Tab, 'numeric');
            app.p12EditField.ValueDisplayFormat = '%11.5g';
            app.p12EditField.ValueChangedFcn = createCallbackFcn(app, @p12EditFieldValueChanged, true);
            app.p12EditField.Value = 0.051335;
            app.p_1Label_13 = uilabel(app.Kv42Tab);
            app.p_1Label_13.Interpreter = 'latex';
            app.p_1Label_13.Text = '$G_{\mathrm{Kto,f}}$';
            app.GKtofEditField = uieditfield(app.Kv42Tab, 'numeric');
            app.GKtofEditField.ValueDisplayFormat = '%11.5g';
            app.GKtofEditField.ValueChangedFcn = createCallbackFcn(app, @GKtofEditFieldValueChanged, true);
            app.GKtofEditField.Value = 0.3846;
            app.IktofImage = uiimage(app.Kv42Tab);
            app.IktofImage.ImageSource = 'iktof.png';
            app.Kv15Kv21IKssTab = uitab(app.TabGroupComputerModel);
            app.Kv15Kv21IKssTab.Title = 'Kv1.5 / Kv2.1 / IKss';
            app.LabelKslow1 = uilabel(app.Kv15Kv21IKssTab);
            app.LabelKslow1.Text = 'IKslow1 (Kv1.5): Rapidly activating, slowly inactivating rectifier K+ current 1';
            app.LabelKslow2 = uilabel(app.Kv15Kv21IKssTab);
            app.LabelKslow2.Text = 'IKslow2 (Kv2.1): Rapidly activating, slowly inactivating rectifier K+ current 2';
            app.LabelKss = uilabel(app.Kv15Kv21IKssTab);
            app.LabelKss.Text = 'IKss: Non-inactivating steady-state K+ current';
            app.IncludeKslow1 = uicheckbox(app.Kv15Kv21IKssTab);
            app.IncludeKslow1.ValueChangedFcn = createCallbackFcn(app, @IncludeKslow1ValueChanged, true);
            app.IncludeKslow1.Text = 'Include';
            app.IncludeKslow1.Value = true;
            app.IncludeKslow2 = uicheckbox(app.Kv15Kv21IKssTab);
            app.IncludeKslow2.ValueChangedFcn = createCallbackFcn(app, @IncludeKslow2ValueChanged, true);
            app.IncludeKslow2.Text = 'Include';
            app.IncludeKslow2.Value = true;
            app.Ikslow1Image = uiimage(app.Kv15Kv21IKssTab);
            app.Ikslow1Image.ImageSource = 'ikslow1.png';
            app.IncludeKss = uicheckbox(app.Kv15Kv21IKssTab);
            app.IncludeKss.ValueChangedFcn = createCallbackFcn(app, @IncludeKssValueChanged, true);
            app.IncludeKss.Text = 'Include';
            app.IncludeKss.Value = true;
            app.Ikslow2Image = uiimage(app.Kv15Kv21IKssTab);
            app.Ikslow2Image.ImageSource = 'ikslow2.png';
            app.IkssImage = uiimage(app.Kv15Kv21IKssTab);
            app.IkssImage.ImageSource = 'ikss.png';
            app.p_1Label_14 = uilabel(app.Kv15Kv21IKssTab);
            app.p_1Label_14.Interpreter = 'latex';
            app.p_1Label_14.Text = '$p_{1}$';
            app.p1EditField2 = uieditfield(app.Kv15Kv21IKssTab, 'numeric');
            app.p1EditField2.ValueChangedFcn = createCallbackFcn(app, @p1EditField2ValueChanged, true);
            app.p1EditField2.Value = 22.5;
            app.p_1Label_15 = uilabel(app.Kv15Kv21IKssTab);
            app.p_1Label_15.Interpreter = 'latex';
            app.p_1Label_15.Text = '$p_{2}$';
            app.p2EditField2 = uieditfield(app.Kv15Kv21IKssTab, 'numeric');
            app.p2EditField2.ValueChangedFcn = createCallbackFcn(app, @p2EditField2ValueChanged, true);
            app.p2EditField2.Value = 45.2;
            app.p_1Label_16 = uilabel(app.Kv15Kv21IKssTab);
            app.p_1Label_16.Interpreter = 'latex';
            app.p_1Label_16.Text = '$p_{3}$';
            app.p3EditField2 = uieditfield(app.Kv15Kv21IKssTab, 'numeric');
            app.p3EditField2.ValueChangedFcn = createCallbackFcn(app, @p3EditField2ValueChanged, true);
            app.p3EditField2.Value = 40;
            app.p_1Label_17 = uilabel(app.Kv15Kv21IKssTab);
            app.p_1Label_17.Interpreter = 'latex';
            app.p_1Label_17.Text = '$p_{4}$';
            app.p4EditField2 = uieditfield(app.Kv15Kv21IKssTab, 'numeric');
            app.p4EditField2.ValueChangedFcn = createCallbackFcn(app, @p4EditField2ValueChanged, true);
            app.p4EditField2.Value = 7.7;
            app.p_1Label_18 = uilabel(app.Kv15Kv21IKssTab);
            app.p_1Label_18.Interpreter = 'latex';
            app.p_1Label_18.Text = '$p_{5}$';
            app.p5EditField2 = uieditfield(app.Kv15Kv21IKssTab, 'numeric');
            app.p5EditField2.ValueChangedFcn = createCallbackFcn(app, @p5EditField2ValueChanged, true);
            app.p5EditField2.Value = 5.7;
            app.p_1Label_19 = uilabel(app.Kv15Kv21IKssTab);
            app.p_1Label_19.Interpreter = 'latex';
            app.p_1Label_19.Text = '$p_{6}$';
            app.p6EditField2 = uieditfield(app.Kv15Kv21IKssTab, 'numeric');
            app.p6EditField2.ValueChangedFcn = createCallbackFcn(app, @p6EditField2ValueChanged, true);
            app.p6EditField2.Value = 0.0629;
            app.p_1Label_20 = uilabel(app.Kv15Kv21IKssTab);
            app.p_1Label_20.Interpreter = 'latex';
            app.p_1Label_20.Text = '$p_{7}$';
            app.p7EditField2 = uieditfield(app.Kv15Kv21IKssTab, 'numeric');
            app.p7EditField2.ValueChangedFcn = createCallbackFcn(app, @p7EditField2ValueChanged, true);
            app.p7EditField2.Value = 6.1;
            app.p_1Label_21 = uilabel(app.Kv15Kv21IKssTab);
            app.p_1Label_21.Interpreter = 'latex';
            app.p_1Label_21.Text = '$p_{8}$';
            app.p8EditField2 = uieditfield(app.Kv15Kv21IKssTab, 'numeric');
            app.p8EditField2.ValueChangedFcn = createCallbackFcn(app, @p8EditField2ValueChanged, true);
            app.p8EditField2.Value = 18;
            app.p_1Label_22 = uilabel(app.Kv15Kv21IKssTab);
            app.p_1Label_22.Interpreter = 'latex';
            app.p_1Label_22.Text = '$p_{9}$';
            app.p9EditField2 = uieditfield(app.Kv15Kv21IKssTab, 'numeric');
            app.p9EditField2.ValueChangedFcn = createCallbackFcn(app, @p9EditField2ValueChanged, true);
            app.p9EditField2.Value = 2.058;
            app.p_1Label_23 = uilabel(app.Kv15Kv21IKssTab);
            app.p_1Label_23.Interpreter = 'latex';
            app.p_1Label_23.Text = '$p_{10}$';
            app.p10EditField2 = uieditfield(app.Kv15Kv21IKssTab, 'numeric');
            app.p10EditField2.ValueChangedFcn = createCallbackFcn(app, @p10EditField2ValueChanged, true);
            app.p10EditField2.Value = 803;
            app.p_1Label_24 = uilabel(app.Kv15Kv21IKssTab);
            app.p_1Label_24.Interpreter = 'latex';
            app.p_1Label_24.Text = '$G_{\mathrm{Kslow1}}$';
            app.GKslow1EditField = uieditfield(app.Kv15Kv21IKssTab, 'numeric');
            app.GKslow1EditField.ValueChangedFcn = createCallbackFcn(app, @GKslow1EditFieldValueChanged, true);
            app.GKslow1EditField.Value = 0.16;
            app.p_1Label_25 = uilabel(app.Kv15Kv21IKssTab);
            app.p_1Label_25.Interpreter = 'latex';
            app.p_1Label_25.Text = '$p_{1}$';
            app.p1EditField3 = uieditfield(app.Kv15Kv21IKssTab, 'numeric');
            app.p1EditField3.ValueChangedFcn = createCallbackFcn(app, @p1EditField3ValueChanged, true);
            app.p1EditField3.Value = 4912;
            app.p_1Label_26 = uilabel(app.Kv15Kv21IKssTab);
            app.p_1Label_26.Interpreter = 'latex';
            app.p_1Label_26.Text = '$p_{2}$';
            app.p2EditField3 = uieditfield(app.Kv15Kv21IKssTab, 'numeric');
            app.p2EditField3.ValueChangedFcn = createCallbackFcn(app, @p2EditField3ValueChanged, true);
            app.p2EditField3.Value = 5334;
            app.p_1Label_27 = uilabel(app.Kv15Kv21IKssTab);
            app.p_1Label_27.Interpreter = 'latex';
            app.p_1Label_27.Text = '$G_{\mathrm{Kslow2}}$';
            app.GKslow2EditField = uieditfield(app.Kv15Kv21IKssTab, 'numeric');
            app.GKslow2EditField.ValueChangedFcn = createCallbackFcn(app, @GKslow2EditFieldValueChanged, true);
            app.GKslow2EditField.Value = 0.16;
            app.p_1Label_28 = uilabel(app.Kv15Kv21IKssTab);
            app.p_1Label_28.Interpreter = 'latex';
            app.p_1Label_28.Text = '$p_{1}$';
            app.p1EditField4 = uieditfield(app.Kv15Kv21IKssTab, 'numeric');
            app.p1EditField4.ValueChangedFcn = createCallbackFcn(app, @p1EditField4ValueChanged, true);
            app.p1EditField4.Value = 0.0862;
            app.p_1Label_29 = uilabel(app.Kv15Kv21IKssTab);
            app.p_1Label_29.Interpreter = 'latex';
            app.p_1Label_29.Text = '$p_{2}$';
            app.p2EditField4 = uieditfield(app.Kv15Kv21IKssTab, 'numeric');
            app.p2EditField4.ValueDisplayFormat = '%11.5g';
            app.p2EditField4.ValueChangedFcn = createCallbackFcn(app, @p2EditField4ValueChanged, true);
            app.p2EditField4.Value = 1235.5;
            app.p_1Label_30 = uilabel(app.Kv15Kv21IKssTab);
            app.p_1Label_30.Interpreter = 'latex';
            app.p_1Label_30.Text = '$p_{3}$';
            app.p3EditField4 = uieditfield(app.Kv15Kv21IKssTab, 'numeric');
            app.p3EditField4.ValueChangedFcn = createCallbackFcn(app, @p3EditField4ValueChanged, true);
            app.p3EditField4.Value = 13.17;
            app.p_1Label_31 = uilabel(app.Kv15Kv21IKssTab);
            app.p_1Label_31.Interpreter = 'latex';
            app.p_1Label_31.Text = '$G_{\mathrm{Kss}}$';
            app.GKssEditField = uieditfield(app.Kv15Kv21IKssTab, 'numeric');
            app.GKssEditField.ValueChangedFcn = createCallbackFcn(app, @GKssEditFieldValueChanged, true);
            app.GKssEditField.Value = 0.0611;
            app.textBox1 = uilabel(app.Kv15Kv21IKssTab);
            app.textBox1.Interpreter = 'latex';
            app.textBox1.Text = '* $p^{''}_{3} = p_3$ in IKslow1';
            app.Kv14Tab = uitab(app.TabGroupComputerModel);
            app.Kv14Tab.Title = 'Kv1.4';
            app.IncludeKtos = uicheckbox(app.Kv14Tab);
            app.IncludeKtos.ValueChangedFcn = createCallbackFcn(app, @IncludeKtosValueChanged, true);
            app.IncludeKtos.Text = 'Include';
            app.p_1Label_32 = uilabel(app.Kv14Tab);
            app.p_1Label_32.Interpreter = 'latex';
            app.p_1Label_32.Text = '$G_{\mathrm{Kto,s}}$';
            app.GKtosEditField = uieditfield(app.Kv14Tab, 'numeric');
            app.GKtosEditField.ValueChangedFcn = createCallbackFcn(app, @GKtosEditFieldValueChanged, true);
            app.GKtosEditField.Value = 0.0629;
            app.p_1Label_33 = uilabel(app.Kv14Tab);
            app.p_1Label_33.Interpreter = 'latex';
            app.p_1Label_33.Text = '$p_{1}$';
            app.p1EditField5 = uieditfield(app.Kv14Tab, 'numeric');
            app.p1EditField5.ValueChangedFcn = createCallbackFcn(app, @p1EditField5ValueChanged, true);
            app.p1EditField5.Value = 1050;
            app.p_1Label_34 = uilabel(app.Kv14Tab);
            app.p_1Label_34.Interpreter = 'latex';
            app.p_1Label_34.Text = '$p_{2}$';
            app.p2EditField5 = uieditfield(app.Kv14Tab, 'numeric');
            app.p2EditField5.ValueChangedFcn = createCallbackFcn(app, @p2EditField5ValueChanged, true);
            app.p2EditField5.Value = 270;
            app.LabelKtos = uilabel(app.Kv14Tab);
            app.LabelKtos.Text = 'IKto,s (Kv1.4): Transient outward K+ current';
            app.Image = uiimage(app.Kv14Tab);
            app.Image.ImageSource = 'iktos.png';
            app.RightPanel = uipanel(app.UIFigure);
            app.TabGroupCalibration = uitabgroup(app.RightPanel);
            app.CurrentTab = uitab(app.TabGroupCalibration);
            app.CurrentTab.Title = 'Current';
            app.UIAxesModelIndv = uiaxes(app.CurrentTab);
            title(app.UIAxesModelIndv, 'Individual K^{+} Currents')
            xlabel(app.UIAxesModelIndv, 'Time (ms)')
            ylabel(app.UIAxesModelIndv, 'K^{+} Currents (pA/pF)')
            zlabel(app.UIAxesModelIndv, 'Z')
            app.UIAxesModelSum = uiaxes(app.CurrentTab);
            title(app.UIAxesModelSum, 'I_{Ksum}')
            xlabel(app.UIAxesModelSum, 'Time (ms)')
            ylabel(app.UIAxesModelSum, 'I_{K} (pA/pF)')
            zlabel(app.UIAxesModelSum, 'Z')
            app.RunCalibrationButton = uibutton(app.CurrentTab, 'push');
            app.RunCalibrationButton.ButtonPushedFcn = createCallbackFcn(app, @RunCalibrationButtonPushed, true);
            app.RunCalibrationButton.Text = 'Run Calibration';
            app.SaveModelParametersButton = uibutton(app.CurrentTab, 'push');
            app.SaveModelParametersButton.ButtonPushedFcn = createCallbackFcn(app, @SaveModelParametersButtonPushed, true);
            app.SaveModelParametersButton.Text = 'Save Model Parameters';
            app.ImportModelParametersButton = uibutton(app.CurrentTab, 'push');
            app.ImportModelParametersButton.ButtonPushedFcn = createCallbackFcn(app, @ImportModelParametersButtonPushed, true);
            app.ImportModelParametersButton.Text = ' Import Model Parameters';
            app.VoltageDropDownLabel = uilabel(app.CurrentTab);
            app.VoltageDropDownLabel.Text = 'Voltage';
            app.VoltageDropDown = uidropdown(app.CurrentTab);
            app.VoltageDropDown.Items = {'-50', '-40', '-30', '-20', '-10', '0', '10', '20', '30', '40', '50'};
            app.VoltageDropDown.ValueChangedFcn = createCallbackFcn(app, @VoltageDropDownValueChanged, true);
            app.VoltageDropDown.Value = '-50';
            app.FileEditFieldLabel = uilabel(app.CurrentTab);
            app.FileEditFieldLabel.Text = 'File';
            app.FileEditField = uieditfield(app.CurrentTab, 'text');
            app.RMSEEditFieldLabel = uilabel(app.CurrentTab);
            app.RMSEEditFieldLabel.Text = 'RMSE';
            app.RMSEEditField = uieditfield(app.CurrentTab, 'numeric');
            app.DensityVoltageTab = uitab(app.TabGroupCalibration);
            app.DensityVoltageTab.Title = 'Density-Voltage';
            app.DensityVoltage = uipanel(app.DensityVoltageTab);
            app.DensityVoltage.Title = 'Density Voltage Relationships';
            app.GenerateButton = uibutton(app.DensityVoltage, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate';
            app.Kv42TabCalib = uitab(app.TabGroupCalibration);
            app.Kv42TabCalib.Title = 'Kv4.2';
            app.KvKtof = uipanel(app.Kv42TabCalib);
            app.KvKtof.Title = 'Kinetics Modeling Results of Kv4.2 (IKto,f)';
            app.Kv15Kv21K2PTabCalib = uitab(app.TabGroupCalibration);
            app.Kv15Kv21K2PTabCalib.Title = 'Kv1.5 / Kv2.1 / K2P';
            app.KvKslow1 = uipanel(app.Kv15Kv21K2PTabCalib);
            app.KvKslow1.Title = 'Kinetics Modeling Results of Kv1.5 (IKslow1) or Kv1.5/2.1 (IKslow)';
            app.KvKslow2 = uipanel(app.Kv15Kv21K2PTabCalib);
            app.KvKslow2.Title = 'Kinetics Modeling Results of Kv2.1 (IKslow2)';
            app.KvKss = uipanel(app.Kv15Kv21K2PTabCalib);
            app.KvKss.Title = 'Kinetics Modeling Results of K2P Family (IKss)';
            app.Kv14TabCalib = uitab(app.TabGroupCalibration);
            app.Kv14TabCalib.Title = 'Kv1.4';
            app.KvKtos = uipanel(app.Kv14TabCalib);
            app.KvKtos.Title = 'Kinetics Modeling Results of Kv1.4 (IKto,s)';
            app.ExperimentalProtocolDataLabel = uilabel(app.UIFigure);
            app.ExperimentalProtocolDataLabel.Text = 'Experimental Protocol & Data';
            app.ComputerModelsLabel = uilabel(app.UIFigure);
            app.ComputerModelsLabel.Text = 'Computer Models';
            app.ModelOutputsKineticParametersLabel = uilabel(app.UIFigure);
            app.ModelOutputsKineticParametersLabel.Text = 'Model Outputs & Kinetic Parameters';
            app.CopyrightNSFPennStateLabel = uilabel(app.UIFigure);
            app.CopyrightNSFPennStateLabel.Text = 'Copyright ©  Complex Systems Monitoring, Modeling and Control Laboratory';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'psu-logo.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = KvApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end