classdef SmartFactoryDigitalModelv4_220321 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        CopyrightNSFPennStateLabel      matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        ResultDisplayPanel              matlab.ui.container.Panel
        GridLayout7                     matlab.ui.container.GridLayout
        GridLayout9                     matlab.ui.container.GridLayout
        RealtimeResultDisplayCheckBox   matlab.ui.control.CheckBox
        ResultTypeDropDown              matlab.ui.control.DropDown
        ResultTypeDropDownLabel         matlab.ui.control.Label
        ShowButton                      matlab.ui.control.Button
        ResultDisplayTab                matlab.ui.container.TabGroup
        JobAgentTab                     matlab.ui.container.Tab
        GridLayout10                    matlab.ui.container.GridLayout
        GridLayout16                    matlab.ui.container.GridLayout
        BlockedJobsCheckBox             matlab.ui.control.CheckBox
        ChooseJobStatisticDropDown      matlab.ui.control.DropDown
        ChooseJobStatisticDropDownLabel  matlab.ui.control.Label
        JobNumberRecordAxes             matlab.ui.control.UIAxes
        JobStatisticAxes                matlab.ui.control.UIAxes
        MaterialHandlingAgentTab        matlab.ui.container.Tab
        GridLayout11                    matlab.ui.container.GridLayout
        GridLayout12                    matlab.ui.container.GridLayout
        ChooseMHAStatisticDropDown      matlab.ui.control.DropDown
        ChooseMHAStatisticDropDownLabel  matlab.ui.control.Label
        SelectMHADropDownLabel          matlab.ui.control.Label
        SelectMHADropDown               matlab.ui.control.DropDown
        MHATimeRecordAxes               matlab.ui.control.UIAxes
        MHAStatisticAxes                matlab.ui.control.UIAxes
        ProcessingAgentTab              matlab.ui.container.Tab
        GridLayout11_2                  matlab.ui.container.GridLayout
        GridLayout12_2                  matlab.ui.container.GridLayout
        MachineStatisticDropDown        matlab.ui.control.DropDown
        MachineStatisticDropDownLabel   matlab.ui.control.Label
        SelectMachineLabel              matlab.ui.control.Label
        SelectMachineDropDown           matlab.ui.control.DropDown
        MachineStatisticAxes            matlab.ui.control.UIAxes
        MachineTimeRecordAxes           matlab.ui.control.UIAxes
        JobProcessNetworkTab            matlab.ui.container.Tab
        GridLayout15                    matlab.ui.container.GridLayout
        GridLayout10_2                  matlab.ui.container.GridLayout
        GenerateButton                  matlab.ui.control.Button
        IncludeMHACheckBox              matlab.ui.control.CheckBox
        SelectJobDropDown               matlab.ui.control.DropDown
        SelectJobDropDownLabel          matlab.ui.control.Label
        RepSpinner                      matlab.ui.control.Spinner
        RepSpinnerLabel                 matlab.ui.control.Label
        JobProcessNetworkGraphAxes      matlab.ui.control.UIAxes
        Title                           matlab.ui.control.Label
        FactoryMapPanel                 matlab.ui.container.Panel
        GridLayout5                     matlab.ui.container.GridLayout
        GridLayout13                    matlab.ui.container.GridLayout
        StopButton                      matlab.ui.control.Button
        StartButton                     matlab.ui.control.Button
        TimeSpinner                     matlab.ui.control.Spinner
        DisplayMHAsCheckBox             matlab.ui.control.CheckBox
        GridLayout6                     matlab.ui.container.GridLayout
        TimeSlider                      matlab.ui.control.Slider
        TimeSliderLabel                 matlab.ui.control.Label
        FactoryMapGraph                 matlab.ui.control.UIAxes
        AgentSettingPanel               matlab.ui.container.Panel
        GridLayout8                     matlab.ui.container.GridLayout
        AgentSettingTab                 matlab.ui.container.TabGroup
        SystemTab_2                     matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        GridLayout14                    matlab.ui.container.GridLayout
        DataPathEditField               matlab.ui.control.EditField
        ImportdataCheckBox              matlab.ui.control.CheckBox
        NumberofReplicationsPanel       matlab.ui.container.Panel
        GridLayout4_4                   matlab.ui.container.GridLayout
        CurrentReplicationSpinner       matlab.ui.control.Spinner
        CurrentReplicationSpinnerLabel  matlab.ui.control.Label
        TotalReplicationsEditField      matlab.ui.control.NumericEditField
        TotalReplicationsEditFieldLabel  matlab.ui.control.Label
        SimulateButton                  matlab.ui.control.Button
        MaterialHandlingAgentTab_2      matlab.ui.container.Tab
        GridLayout3_3                   matlab.ui.container.GridLayout
        NumberofMaterialHandlingAgentsPanel  matlab.ui.container.Panel
        GridLayout4_3                   matlab.ui.container.GridLayout
        AGV_SEditField                  matlab.ui.control.NumericEditField
        AGV_SEditFieldLabel             matlab.ui.control.Label
        AGV_MEditField                  matlab.ui.control.NumericEditField
        AGV_MEditFieldLabel             matlab.ui.control.Label
        AGV_FEditField                  matlab.ui.control.NumericEditField
        AGV_FEditFieldLabel             matlab.ui.control.Label
        ProcessingAgentTab_2            matlab.ui.container.Tab
        GridLayout3_2                   matlab.ui.container.GridLayout
        NumberofProcessingAgentsPanel   matlab.ui.container.Panel
        GridLayout4_2                   matlab.ui.container.GridLayout
        MillingEditField                matlab.ui.control.NumericEditField
        MillingEditFieldLabel           matlab.ui.control.Label
        WeldingEditField                matlab.ui.control.NumericEditField
        WeldingEditFieldLabel           matlab.ui.control.Label
        DrillingEditField               matlab.ui.control.NumericEditField
        DrillingEditFieldLabel          matlab.ui.control.Label
        LatheEditField                  matlab.ui.control.NumericEditField
        LatheEditFieldLabel             matlab.ui.control.Label
        JobAgentTab_2                   matlab.ui.container.Tab
        GridLayout3                     matlab.ui.container.GridLayout
        InterarrivalTimePanel           matlab.ui.container.Panel
        GridLayout4                     matlab.ui.container.GridLayout
        IAT_Parameter2EditField         matlab.ui.control.NumericEditField
        IAT_Parameter2EditFieldLabel    matlab.ui.control.Label
        IAT_Parameter1EditField         matlab.ui.control.NumericEditField
        IAT_Parameter1EditFieldLabel    matlab.ui.control.Label
        IAT_DistributionDropDown        matlab.ui.control.DropDown
        IAT_DistributionDropDownLabel   matlab.ui.control.Label
        SimulationSettingPanel          matlab.ui.container.Panel
        GridLayout                      matlab.ui.container.GridLayout
        ActivateButton                  matlab.ui.control.Button
        SeedEditField                   matlab.ui.control.NumericEditField
        SeedEditFieldLabel              matlab.ui.control.Label
        UnitTimeDropDown                matlab.ui.control.DropDown
        UnitTimeDropDownLabel           matlab.ui.control.Label
        SimulationLengthEditField       matlab.ui.control.NumericEditField
        SimulationLengthEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)    
        % Predefined Database
        Database
        
        % Time properties
        IAT
        TimeUnit
        T_end
        currentTime
        callbackTime
        
        % Map properties
        occ
        
        % App Stop Condition
        StopCondition = 0
        
        % Result properties
        SummaryMachine
        SummaryMHA
        resJobCTAll
        resJobDTAll
        resMHAAll
        resMachineAll
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ActivateButton
        function ActivateButtonPushed(app, event)
            f = waitbar(0,'Please wait...');
            
            %Factory Environment
            Env_Factory();
            
            % Define Seed number
            global seed;
            seed = app.SeedEditField.Value;
            
            % Define Time variables
            %   Time Unit
            app.TimeUnit = str2double(app.UnitTimeDropDown.Value);
            %   Simulation time
            app.T_end = app.SimulationLengthEditField.Value;
            
            % Modifying app properties
            app.TimeSpinner.Limits(2) = app.T_end;
            app.TimeSlider.Limits(2) = app.T_end;
            
            waitbar(1,f,'Finish Simulation Setting');
            pause(0.5);
            close(f);

        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            f = waitbar(0,'Please wait...');
            pause(1);
            global seed;
            global seed2;
            global JobCount;
            
            if app.ImportdataCheckBox.Value == 0
            
                waitbar(0,f,'Simulation Begins');
                pause(0.5);
                
                seed = app.SeedEditField.Value;
    
                Rep = app.TotalReplicationsEditField.Value;
                app.CurrentReplicationSpinner.Limits(2) = Rep;
                app.RepSpinner.Limits(2) = Rep;
                
                for i = (1:Rep)
                    app.CurrentReplicationSpinner.Value = i;
                    % Current time
                    app.currentTime = 0;
                    
                    seed2 = 200229;
                    % Generating Agent List
                    Cloud = AgentList(app.T_end, app.TimeUnit);
                    
                    % Lode Agent Data
                    app.Database = load("SIM_Database.mat");
                    
                    % Setting Inter-arrival Time
                    app.IAT = {app.IAT_DistributionDropDown.Value, app.IAT_Parameter1EditField.Value, app.IAT_Parameter2EditField.Value};
                    
                    % Generating Inventory and Warehouse
                    Cloud.AddMachine(PR_Agent(app.Database.SourceNSink(1,:), Cloud));
                    Cloud.AddMachine(PR_Agent(app.Database.SourceNSink(2,:), Cloud));
                    
                    % Generating Machines
                    Input_Machine = [app.LatheEditField.Value, app.DrillingEditField.Value, app.WeldingEditField.Value, app.MillingEditField.Value, 0];
                    SIM_GeneratingMachine(Input_Machine, app.Database, Cloud);
                    
                    %   Change Machine Select Dropbox
                    app.SelectMachineDropDown.Items = cat(2, app.SelectMachineDropDown.Items, reshape(Cloud.MachineList(:,1), [1, length(Cloud.MachineList(:,1))]));
                    app.SelectMachineDropDown.Items = cat(2, app.SelectMachineDropDown.Items, ["Lathe", "Drilling", "Welding", "Milling"]);
                    
                    % Generating MHAs
                    Input_MHA = [app.AGV_FEditField.Value, app.AGV_MEditField.Value, app.AGV_SEditField.Value];
                    SIM_GeneratingMHA(Input_MHA, app.Database, Cloud);
                    
                    %   Change MHA Select Dropbox
                    app.SelectMHADropDown.Items = cat(2, "Whole MHAs", reshape(Cloud.MHAList(:,1), [1, length(Cloud.MHAList(:,1))]));
                    
                    waitbar(0.20,f,'Finish Agents Generating');
                    pause(0.5);
                    
                    
                    % Job Generation
                    JobCount = 1;
                    
                    % Schedule Job Enter
                    Cloud.SchedulePlan(app.currentTime + Env_Func_RNG(app.IAT, app.TimeUnit), "", "", "", "Job Enter")
                    app.currentTime = Cloud.EventPlan{1,1};
                    
                    waitbar(0.40,f,'Simulation Processing');
                    pause(0.5);
                    
                    while app.currentTime < app.T_end
                        if Cloud.EventPlan{1,5} == "Job Enter"
                            Cloud.ActJobEnter(Job_Func_SelectJob(app.Database.JobSequencesTable, app.Database.JobPortion), app.IAT, Cloud.EventPlan{1,1})
                            app.currentTime = Cloud.EventPlan{1,1};
                        elseif Cloud.EventPlan{1,5} == "Arrival"
                            Cloud.ActArrival(Cloud.EventPlan{1,1}, Cloud.EventPlan{1,3});
                            app.currentTime = Cloud.EventPlan{1,1};
                        elseif Cloud.EventPlan{1,5} == "Process End"
                            Cloud.ActProcessEnd(Cloud.EventPlan{1,1}, Cloud.EventPlan{1,3});
                            app.currentTime = Cloud.EventPlan{1,1};
                        elseif Cloud.EventPlan{1,5} == "Loading Job"
                            Cloud.ActLoadingJob(Cloud.EventPlan{1,1}, Cloud.EventPlan{1,3});
                            app.currentTime = Cloud.EventPlan{1,1};
                        elseif Cloud.EventPlan{1,5} == "Unloading Job"
                            Cloud.ActUnloadingJob(Cloud.EventPlan{1,1}, Cloud.EventPlan{1,3});
                            app.currentTime = Cloud.EventPlan{1,1};
                        elseif Cloud.EventPlan{1,5} == "MHA Restore"
                            Cloud.ActMHARestore(Cloud.EventPlan{1,1}, Cloud.EventPlan{1,3});
                            app.currentTime = Cloud.EventPlan{1,1};
                        elseif Cloud.EventPlan{1,5} == "MHA Maintenance"
                            Cloud.ActMHAMaintenance(Cloud.EventPlan{1,1}, Cloud.EventPlan{1,3});
                            app.currentTime = Cloud.EventPlan{1,1};
                        elseif Cloud.EventPlan{1,5} == "MHA Failure"
                            Cloud.ActMHAFailure(Cloud.EventPlan{1,1}, Cloud.EventPlan{1,3});
                            app.currentTime = Cloud.EventPlan{1,1};
                        elseif Cloud.EventPlan{1,5} == "Machine Restore"
                            Cloud.ActMachineRestore(Cloud.EventPlan{1,1}, Cloud.EventPlan{1,3});
                            app.currentTime = Cloud.EventPlan{1,1};
                        elseif Cloud.EventPlan{1,5} == "Machine Maintenance"
                            Cloud.ActMachineMaintenance(Cloud.EventPlan{1,1}, Cloud.EventPlan{1,3});
                            app.currentTime = Cloud.EventPlan{1,1};
                        elseif Cloud.EventPlan{1,5} == "Machine Failure"
                            Cloud.ActMachineFailure(Cloud.EventPlan{1,1}, Cloud.EventPlan{1,3});
                            app.currentTime = Cloud.EventPlan{1,1};
                        else
                            error("Error on activation at time " + Cloud.EventPlan{1,1} + " with " + Cloud.EventPlan{1,3});
                        end
                    end
                    
                    Cloud.EndSimulation(app.T_end);
                    CloudList = cat(1, CloudList, Cloud);
                    
                    waitbar(0.60,f,'Simulation Processing');
                    pause(0.5);
                end
                
                waitbar(0.80,f,'Finish Simulation Processing');
                pause(0.5);
                
                app.callbackTime = app.TimeSpinner.Value;
                
                waitbar(0.80,f,'Loading Simulated Result');
                pause(0.5);
                
                % Extracting and Storing Simulated Result
                [app.resMHAAll,app.resMachineAll,app.resJobCTAll, app.resJobDTAll, app.SummaryMHA, app.SummaryMachine] = ResultGeneration(CloudList, app.T_end, app.TotalReplicationsEditField.Value);
                
                waitbar(1,f,'Finish Loading Result');
                pause(0.5);
                close(f);
                
            else
                waitbar(0.25,f,'Loading Simulated Data');
                pause(0.5);
                
                % Import Data
                SIMData = load(app.DataPathEditField.Value);
                app.SummaryMachine = SIMData.SummaryMachine;
                app.SummaryMHA = SIMData.SummaryMHA;
                
                %   Change Machine Select Dropbox
                app.SelectMachineDropDown.Items = cat(2, "Whole Machines", reshape(app.SummaryMachine{1}(:,1), [1, length(app.SummaryMachine{1}(:,1))]));
                app.SelectMachineDropDown.Items = cat(2, app.SelectMachineDropDown.Items, ["Whole Lathe", "Whole Drilling", "Whole Welding", "Whole Milling"]);
                
                %   Change MHA Select Dropbox
                app.SelectMHADropDown.Items = cat(2, "Whole MHAs", reshape(app.SummaryMHA{1}(:,1), [1, length(app.SummaryMHA{1}(:,1))]));
                
                
                waitbar(0.50,f,'Loading Simulated Result');
                pause(0.5);
                
                % Change Presets
                app.SimulationLengthEditField.Value = SIMData.T_end;
                app.T_end = app.SimulationLengthEditField.Value;
                
                app.TimeUnit = SIMData.TimeUnit;
                
                app.AGV_FEditField.Value = SIMData.AGV(1);
                app.AGV_MEditField.Value = SIMData.AGV(2);
                app.AGV_SEditField.Value = SIMData.AGV(3);
                
                app.IAT = SIMData.IAT;
                app.IAT_Parameter1EditField.Value = app.IAT{2};
                app.IAT_Parameter2EditField.Value = app.IAT{3};
                
                app.TotalReplicationsEditField.Value = SIMData.Rep;
                app.CurrentReplicationSpinner.Limits(2) = app.TotalReplicationsEditField.Value;
                app.CurrentReplicationSpinner.Value = SIMData.Rep;
                app.RepSpinner.Limits(2) = SIMData.Rep;
                
                % Modifying app properties
                app.TimeSpinner.Limits(2) = app.T_end;
                app.TimeSlider.Limits(2) = app.T_end;
                
                waitbar(0.75,f,'Loading Simulated Result');
                pause(0.5);
                
            end
            
            % Extracting and Storing Simulated Result
            app.resJobCTAll = SIMData.resJobCTAll;
            app.resJobDTAll = SIMData.resJobDTAll;
            app.resMHAAll = SIMData.resMHAAll;
            app.resMachineAll = SIMData.resMachineAll;
            
            % Update Network Initial Property
            rep = app.RepSpinner.Value;
            DTStat = app.resJobDTAll{rep};
            
            %   Change Machine Select Dropbox
            app.SelectJobDropDown.Items = cat(2, ["All", "CPB", "CPK", "CPN", "CPP", "CPQ", "CPR"], reshape(DTStat(:,1), [1, length(DTStat(:,1))]));
            
            % Display Simulation Map
            %   Update Callback Time
            app.callbackTime = round(app.T_end * 3 / 10);
            app.TimeSpinner.Value = app.callbackTime;
            app.TimeSlider.Value = app.callbackTime;
            
            %   Choose the current replication and Factory map
            currentRep = app.CurrentReplicationSpinner.Value;
            app.occ = ones(30, 70, 3);
            
            FigureFactoryMap(app.FactoryMapGraph, currentRep, app.SummaryMHA, app.SummaryMachine, app.callbackTime, app.occ, app.DisplayMHAsCheckBox.Value);
            
            waitbar(1,f,'Finish Loading Result');
            pause(0.5);
            close(f);
            
            app.ShowButtonPushed(1);
            
            
        end

        % Value changed function: TimeSpinner
        function TimeSpinnerValueChanged(app, event)
            
            % Update Callback Time
            app.callbackTime = app.TimeSpinner.Value;
            app.TimeSlider.Value = app.callbackTime;
            
            % Choose the current replication and Factory map
            currentRep = app.CurrentReplicationSpinner.Value;
            
            FigureFactoryMap(app.FactoryMapGraph, currentRep, app.SummaryMHA, app.SummaryMachine, app.callbackTime, app.occ, app.DisplayMHAsCheckBox.Value);

        end

        % Value changed function: TimeSlider
        function TimeSliderValueChanged(app, event)
            
            % Update Callback Time
            app.callbackTime = app.TimeSlider.Value;
            app.TimeSpinner.Value = app.callbackTime;
        
            % Choose the current replication and Factory map
            currentRep = app.CurrentReplicationSpinner.Value;
            
            FigureFactoryMap(app.FactoryMapGraph, currentRep, app.SummaryMHA, app.SummaryMachine, app.callbackTime, app.occ, app.DisplayMHAsCheckBox.Value);
            
        end

        % Button pushed function: ShowButton
        function ShowButtonPushed(app, event)
            f = waitbar(0,'Please wait...');
            pause(0.1);
            
            
            waitbar(0.25,f,'Generating Workorder Result');
            pause(0.1);
            
            % The Workorder Result
            %   Call the System Result
            resJobTOT = app.resJobCTAll;
            
            if app.ResultTypeDropDown.Value == "Across-replication"
                resJob = mean(resJobTOT, 3);
            elseif app.ResultTypeDropDown.Value == "Single-replication"
                resJob = resJobTOT(:,:,app.CurrentReplicationSpinner.Value);
            end
            
            %   Display the Job Result
            resCurrentJob = resJob(:, 2);
            resGeneratedJob = resJob(:, 3);
            resFinishedJob = resJob(:, 4);
            resWaitingJob = resJob(:, 5);
            resBacklogJob = resJob(:, 6);
            resBlockedJob = resJob(:, 7);
            resTime = resJob(:, 1);
            
            if app.BlockedJobsCheckBox.Value == 1
                %       Display Time Record
                plot(app.JobNumberRecordAxes, resTime, resCurrentJob, "Color", '#EDB120', "LineWidth", 2);
                hold(app.JobNumberRecordAxes, "on");
                plot(app.JobNumberRecordAxes, resTime, resGeneratedJob, "Color", '#77AC30', "LineWidth", 2);
                plot(app.JobNumberRecordAxes, resTime, resFinishedJob, "Color", '#0072BD', "LineWidth", 2);
                plot(app.JobNumberRecordAxes, resTime, resWaitingJob, "Color", '#A2142F', "LineWidth", 2);
                plot(app.JobNumberRecordAxes, resTime, resBacklogJob, "Color", '#7E2F8E', "LineWidth", 2);
                plot(app.JobNumberRecordAxes, resTime, resBlockedJob, "Color", '#D95319', "LineWidth", 2);
                
                legend(app.JobNumberRecordAxes, {'Number of Current Jobs', 'Number of Generated Jobs', 'Number of Finished Jobs', 'Number in Waiting List', 'Number in Backlog List', 'Number of Blocked Jobs'}, 'Location', 'northwest')
                hold(app.JobNumberRecordAxes, "off");
                
                %       Display Statistic
                if app.ChooseJobStatisticDropDown.Value == "Number of Current Jobs"
                    plot(app.JobStatisticAxes, resTime, resCurrentJob, "Color", '#EDB120', "LineWidth", 2);
                elseif app.ChooseJobStatisticDropDown.Value == "Number of Generated Jobs"
                    plot(app.JobStatisticAxes, resTime, resGeneratedJob, "Color", '#77AC30', "LineWidth", 2);
                elseif app.ChooseJobStatisticDropDown.Value == "Number of Finished Jobs"
                    plot(app.JobStatisticAxes, resTime, resFinishedJob, "Color", '#0072BD', "LineWidth", 2);
                elseif app.ChooseJobStatisticDropDown.Value == "Number in Waiting List"
                    plot(app.JobStatisticAxes, resTime, resWaitingJob, "Color", '#A2142F', "LineWidth", 2);
                elseif app.ChooseJobStatisticDropDown.Value == "Number in Backlog List"
                    plot(app.JobStatisticAxes, resTime, resBacklogJob, "Color", '#7E2F8E', "LineWidth", 2);
                elseif app.ChooseJobStatisticDropDown.Value == "Number of Blocked Jobs"
                    plot(app.JobStatisticAxes, resTime, resBlockedJob, "Color", '#D95319', "LineWidth", 2);
                end
            else
                %       Display Time Record
                plot(app.JobNumberRecordAxes, resTime, resCurrentJob, "Color", '#EDB120', "LineWidth", 2);
                hold(app.JobNumberRecordAxes, "on");
                plot(app.JobNumberRecordAxes, resTime, resGeneratedJob, "Color", '#77AC30', "LineWidth", 2);
                plot(app.JobNumberRecordAxes, resTime, resFinishedJob, "Color", '#0072BD', "LineWidth", 2);
                plot(app.JobNumberRecordAxes, resTime, resWaitingJob, "Color", '#A2142F', "LineWidth", 2);
                plot(app.JobNumberRecordAxes, resTime, resBacklogJob, "Color", '#7E2F8E', "LineWidth", 2);
                
                legend(app.JobNumberRecordAxes, {'Number of Current Jobs', 'Number of Generated Jobs', 'Number of Finished Jobs', 'Number in Waiting List', 'Number in Backlog List'}, 'Location', 'northwest')
                hold(app.JobNumberRecordAxes, "off");
                
                %       Display Statistic
                if app.ChooseJobStatisticDropDown.Value == "Number of Current Jobs"
                    plot(app.JobStatisticAxes, resTime, resCurrentJob, "Color", '#EDB120', "LineWidth", 2);
                elseif app.ChooseJobStatisticDropDown.Value == "Number of Generated Jobs"
                    plot(app.JobStatisticAxes, resTime, resGeneratedJob, "Color", '#77AC30', "LineWidth", 2);
                elseif app.ChooseJobStatisticDropDown.Value == "Number of Finished Jobs"
                    plot(app.JobStatisticAxes, resTime, resFinishedJob, "Color", '#0072BD', "LineWidth", 2);
                elseif app.ChooseJobStatisticDropDown.Value == "Number in Waiting List"
                    plot(app.JobStatisticAxes, resTime, resWaitingJob, "Color", '#A2142F', "LineWidth", 2);
                elseif app.ChooseJobStatisticDropDown.Value == "Number in Backlog List"
                    plot(app.JobStatisticAxes, resTime, resBacklogJob, "Color", '#7E2F8E', "LineWidth", 2);
                elseif app.ChooseJobStatisticDropDown.Value == "Number of Blocked Jobs"
                    plot(app.JobStatisticAxes, resTime, resBlockedJob, "Color", '#D95319', "LineWidth", 2);
                end
            end
            
            waitbar(0.50,f,'Generating MHA Result');
            pause(0.1);
            
            % The MHA Result
            %   Call the MHA Result
            resMHATOT = app.resMHAAll;
            
            if app.SelectMHADropDown.Value == "Whole MHAs"
                resMHAWhole = mean(resMHATOT, 4);
            
            else
                MHADDValue = string(app.SelectMHADropDown.Value);
                idx = cellfun(@(x)ismember(x, MHADDValue), (app.SelectMHADropDown.Items), 'Uni', 0);
                idx = find(cell2mat(idx)) - 1;
                resMHAWhole = resMHATOT(:, :, :, idx);
            end
            
            if app.ResultTypeDropDown.Value == "Across-replication"
                resMHA = mean(resMHAWhole, 3);
            elseif app.ResultTypeDropDown.Value == "Single-replication"
                resMHA = resMHAWhole(:,:,app.CurrentReplicationSpinner.Value);
            end
            
            %   Display the MHA Result
            resMHATIdle = resMHA(:, 2) + resMHA(:, 7);
            resMHATWork = sum(resMHA(:, 3:6), 2);
            resMHATRecord = cat(2, resMHATIdle, cat(2, resMHATWork, cat(2, resMHA(:, 8), resMHA(:, 9))));
            
            %       Display Time Record
            plot(app.MHATimeRecordAxes, resMHA(:, 1), resMHATRecord(:, 1), "Color", '#EDB120', "LineWidth", 2);
            hold(app.MHATimeRecordAxes, "on");
            plot(app.MHATimeRecordAxes, resMHA(:, 1), resMHATRecord(:, 2), "Color", '#77AC30', "LineWidth", 2);
            plot(app.MHATimeRecordAxes, resMHA(:, 1), resMHATRecord(:, 3), "Color", '#0072BD', "LineWidth", 2);
            plot(app.MHATimeRecordAxes, resMHA(:, 1), resMHATRecord(:, 4), "Color", '#A2142F', "LineWidth", 2);
            
            legend(app.MHATimeRecordAxes, {'Idle', 'Work', 'Maintenance', 'Failure'}, 'Location', 'northwest')
            hold(app.MHATimeRecordAxes, "off");
            
            %       Display Statistic
            if app.ChooseMHAStatisticDropDown.Value == "Utilization"
                resMHAUtil = resMHATWork ./ resMHA(:, 1);
                plot(app.MHAStatisticAxes, resMHA(:, 1), resMHAUtil, "Color", '#7E2F8E', "LineWidth", 2);
            elseif app.ChooseMHAStatisticDropDown.Value == "W-Length"
                resMHAWLength = resMHA(:, 10);
                plot(app.MHAStatisticAxes, resMHA(:, 1), resMHAWLength, "Color", '#7E2F8E', "LineWidth", 2);
            end
            
            
            waitbar(0.75,f,'Generating Machine Result');
            pause(0.1);
            
            % The Machine Result
            %   Call the Machine Result
            resMachineTOT = app.resMachineAll;
            
            if app.SelectMachineDropDown.Value == "Whole Machines"
                resMachineWhole = mean(resMachineTOT, 4);
            elseif app.SelectMachineDropDown.Value == "Whole Lathe"
                resMachineWhole = mean(resMachineTOT(:, :, :, 1:app.LatheEditField.Value), 4);
            elseif app.SelectMachineDropDown.Value == "Whole Drilling"
                resMachineWhole = mean(resMachineTOT(:, :, :, (app.LatheEditField.Value + 1):(app.LatheEditField.Value + app.DrillingEditField.Value + 1)), 4);
            elseif app.SelectMachineDropDown.Value == "Whole Welding"
                resMachineWhole = mean(resMachineTOT(:, :, :, (app.LatheEditField.Value + app.DrillingEditField.Value + 1):(app.LatheEditField.Value + app.DrillingEditField.Value + app.WeldingEditField.Value + 1)), 4);
            elseif app.SelectMachineDropDown.Value == "Whole Milling"
                resMachineWhole = mean(resMachineTOT(:, :, :, (app.LatheEditField.Value + app.DrillingEditField.Value + app.WeldingEditField.Value + 1): end), 4);
            else
                MachineDDValue = string(app.SelectMachineDropDown.Value);
                idx = cellfun(@(x)ismember(x, MachineDDValue), (app.SelectMachineDropDown.Items), 'Uni', 0);
                idx = find(cell2mat(idx)) - 1;
                resMachineWhole = resMachineTOT(:, :, :, idx);
            end
            
            if app.ResultTypeDropDown.Value == "Across-replication"
                resMachine = mean(resMachineWhole, 3);
            elseif app.ResultTypeDropDown.Value == "Single-replication"
                resMachine = resMachineWhole(:,:,app.CurrentReplicationSpinner.Value);
            end
            
            %   Display the Machine Result
            resMachineTRecord = resMachine(:, 2:5);
            
            %       Display Time Record
            plot(app.MachineTimeRecordAxes, resMachine(:, 1), resMachineTRecord(:, 1), "Color", '#EDB120', "LineWidth", 2);
            hold(app.MachineTimeRecordAxes, "on");
            plot(app.MachineTimeRecordAxes, resMachine(:, 1), resMachineTRecord(:, 2), "Color", '#77AC30', "LineWidth", 2);
            plot(app.MachineTimeRecordAxes, resMachine(:, 1), resMachineTRecord(:, 3), "Color", '#0072BD', "LineWidth", 2);
            plot(app.MachineTimeRecordAxes, resMachine(:, 1), resMachineTRecord(:, 4), "Color", '#A2142F', "LineWidth", 2);
            
            legend(app.MachineTimeRecordAxes, {'Idle', 'Work', 'Maintenance', 'Failure'}, 'Location', 'northwest')
            hold(app.MachineTimeRecordAxes, "off");
            
            %       Display Statistic
            if app.MachineStatisticDropDown.Value == "Utilization"
                resMachineUtil = resMachineTRecord(:, 2) ./ resMachine(:, 1);
                plot(app.MachineStatisticAxes, resMachine(:, 1), resMachineUtil, "Color", '#7E2F8E', "LineWidth", 2);
            elseif app.MachineStatisticDropDown.Value == "Q_In-Length"
                resMachineQInLength = resMachine(:, 6);
                plot(app.MachineStatisticAxes, resMachine(:, 1), resMachineQInLength, "Color", '#7E2F8E', "LineWidth", 2);
            elseif app.MachineStatisticDropDown.Value == "Q_Out-Length"
                resMachineQOutLength = resMachine(:, 7);
                plot(app.MachineStatisticAxes, resMachine(:, 1), resMachineQOutLength, "Color", '#7E2F8E', "LineWidth", 2);
            end
            
            waitbar(1,f,'Finish Loading Result');
            pause(0.5);
            close(f);
            
            
        end

        % Value changed function: RepSpinner
        function RepSpinnerValueChanged(app, event)
            rep = app.RepSpinner.Value;
            DTStat = app.resJobDTAll{rep};
            
            % Change Machine Select Dropbox
            app.SelectJobDropDown.Items = cat(2, ["All", "CPB", "CPK", "CPN", "CPP", "CPQ", "CPR"], reshape(DTStat(:,1), [1, length(DTStat(:,1))]));
            
        end

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
            % Generate Adjacency Matrix
            [AMatrix, nodeName] = JobNetworkAMatrix(app.SummaryMachine, app.SummaryMHA, app.resJobDTAll, app.RepSpinner.Value, app.IncludeMHACheckBox.Value, app.SelectJobDropDown.Value);
            JobNetworkGraph(app.JobProcessNetworkGraphAxes, AMatrix, nodeName);
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            % Choose the Cloud of the current replication and Factory map
            currentRep = app.CurrentReplicationSpinner.Value;
            
            T_int = floor(app.callbackTime);
            
            % Getting the result
                % Job Result
                %    Call Job Result
                resJobTOT = app.resJobCTAll;
                
                if app.ResultTypeDropDown.Value == "Across-replication"
                    resJob = mean(resJobTOT, 3);
                elseif app.ResultTypeDropDown.Value == "Single-replication"
                    resJob = resJobTOT(:,:,app.CurrentReplicationSpinner.Value);
                end
            
                %    Extract Job Result
                resCurrentJob = resJob(:, 2);
                resGeneratedJob = resJob(:, 3);
                resFinishedJob = resJob(:, 4);
                resWaitingJob = resJob(:, 5);
                resBacklogJob = resJob(:, 6);
                resTime = resJob(:, 1);
            
                % MHA Result
                %    Call MHA Result
                resMHATOT = app.resMHAAll;
            
                if app.SelectMHADropDown.Value == "Whole MHAs"
                    resMHAWhole = mean(resMHATOT, 4);
                else
                    MHADDValue = string(app.SelectMHADropDown.Value);
                    idx = cellfun(@(x)ismember(x, MHADDValue), (app.SelectMHADropDown.Items), 'Uni', 0);
                    idx = find(cell2mat(idx)) - 1;
                    resMHAWhole = resMHATOT(:, :, :, idx);
                end
                
                if app.ResultTypeDropDown.Value == "Across-replication"
                    resMHA = mean(resMHAWhole, 3);
                elseif app.ResultTypeDropDown.Value == "Single-replication"
                    resMHA = resMHAWhole(:,:,app.CurrentReplicationSpinner.Value);
                end
            
                %   Extract MHA Result
                resMHATIdle = resMHA(:, 2) + resMHA(:, 7);
                resMHATWork = sum(resMHA(:, 3:6), 2);
                resMHATRecord = cat(2, resMHATIdle, cat(2, resMHATWork, cat(2, resMHA(:, 8), resMHA(:, 9))));
                
                % The Machine Result
                %   Call the Machine Result
                resMachineTOT = app.resMachineAll;
                
                if app.SelectMachineDropDown.Value == "Whole Machines"
                    resMachineWhole = mean(resMachineTOT, 4);
                elseif app.SelectMachineDropDown.Value == "Whole Lathe"
                    resMachineWhole = mean(resMachineTOT(:, :, :, 1:app.LatheEditField.Value), 4);
                elseif app.SelectMachineDropDown.Value == "Whole Drilling"
                    resMachineWhole = mean(resMachineTOT(:, :, :, (app.LatheEditField.Value + 1):(app.LatheEditField.Value + app.DrillingEditField.Value + 1)), 4);
                elseif app.SelectMachineDropDown.Value == "Whole Welding"
                    resMachineWhole = mean(resMachineTOT(:, :, :, (app.LatheEditField.Value + app.DrillingEditField.Value + 1):(app.LatheEditField.Value + app.DrillingEditField.Value + app.WeldingEditField.Value + 1)), 4);
                elseif app.SelectMachineDropDown.Value == "Whole Milling"
                    resMachineWhole = mean(resMachineTOT(:, :, :, (app.LatheEditField.Value + app.DrillingEditField.Value + app.WeldingEditField.Value + 1): end), 4);
                else
                    MachineDDValue = string(app.SelectMachineDropDown.Value);
                    idx = cellfun(@(x)ismember(x, MachineDDValue), (app.SelectMachineDropDown.Items), 'Uni', 0);
                    idx = find(cell2mat(idx)) - 1;
                    resMachineWhole = resMachineTOT(:, :, :, idx);
                end
                
                if app.ResultTypeDropDown.Value == "Across-replication"
                    resMachine = mean(resMachineWhole, 3);
                elseif app.ResultTypeDropDown.Value == "Single-replication"
                    resMachine = resMachineWhole(:,:,app.CurrentReplicationSpinner.Value);
                end
                
                %   Display the Machine Result
                resMachineTRecord = resMachine(:, 2:5);
                
            if app.RealtimeResultDisplayCheckBox.Value == 1
                % Display Job Overall Plot
                %   Display Time Record
                plot(app.JobNumberRecordAxes, resTime(1:T_int), resCurrentJob(1:T_int), "Color", '#EDB120', "LineWidth", 2);
                hold(app.JobNumberRecordAxes, "on");
                plot(app.JobNumberRecordAxes, resTime(1:T_int), resGeneratedJob(1:T_int), "Color", '#77AC30', "LineWidth", 2);
                plot(app.JobNumberRecordAxes, resTime(1:T_int), resFinishedJob(1:T_int), "Color", '#0072BD', "LineWidth", 2);
                plot(app.JobNumberRecordAxes, resTime(1:T_int), resWaitingJob(1:T_int), "Color", '#A2142F', "LineWidth", 2);
                plot(app.JobNumberRecordAxes, resTime(1:T_int), resBacklogJob(1:T_int), "Color", '#7E2F8E', "LineWidth", 2);
                hold(app.JobNumberRecordAxes, "off");
                
                J_cj = animatedline(app.JobNumberRecordAxes,"Color", '#EDB120', "LineWidth", 2);
                J_gj = animatedline(app.JobNumberRecordAxes,"Color", '#77AC30', "LineWidth", 2);
                J_fj = animatedline(app.JobNumberRecordAxes,"Color", '#0072BD', "LineWidth", 2);
                J_wj = animatedline(app.JobNumberRecordAxes,"Color", '#A2142F', "LineWidth", 2);
                J_bj = animatedline(app.JobNumberRecordAxes,"Color", '#7E2F8E', "LineWidth", 2);
                legend(app.JobNumberRecordAxes, {'Number of Current Jobs', 'Number of Generated Jobs', 'Number of Finished Jobs', 'Number in Waiting List', 'Number in Backlog List'}, 'Location', 'northwest')
                axis(app.JobNumberRecordAxes, [0, app.T_end, 0, max(max(resJob(:,2:end)))])
                
                %   Display Job Specific Plot
                if app.ChooseJobStatisticDropDown.Value == "Number of Current Jobs"
                    resJobSpec = resCurrentJob;
                elseif app.ChooseJobStatisticDropDown.Value == "Number of Generated Jobs"
                    resJobSpec = resGeneratedJob;
                elseif app.ChooseJobStatisticDropDown.Value == "Number of Finished Jobs"
                    resJobSpec = resFinishedJob;
                elseif app.ChooseJobStatisticDropDown.Value == "Number in Waiting List"
                    resJobSpec = resWaitingJob;
                elseif app.ChooseJobStatisticDropDown.Value == "Number in Backlog List"
                    resJobSpec = resBacklogJob;
                end
                
                plot(app.JobStatisticAxes, resTime(1:T_int), resJobSpec(1:T_int), "Color", '#7E2F8E', "LineWidth", 2);
                J_spec = animatedline(app.JobStatisticAxes, "Color", '#7E2F8E', "LineWidth", 2);
                axis(app.JobStatisticAxes, [0, app.T_end, 0, max(resJobSpec)])
                
                % Display MHA Overall Plot
                %       Display Time Record
                plot(app.MHATimeRecordAxes, resMHA(1:T_int, 1), resMHATRecord(1:T_int, 1), "Color", '#EDB120', "LineWidth", 2);
                hold(app.MHATimeRecordAxes, "on");
                plot(app.MHATimeRecordAxes, resMHA(1:T_int, 1), resMHATRecord(1:T_int, 2), "Color", '#77AC30', "LineWidth", 2);
                plot(app.MHATimeRecordAxes, resMHA(1:T_int, 1), resMHATRecord(1:T_int, 3), "Color", '#0072BD', "LineWidth", 2);
                plot(app.MHATimeRecordAxes, resMHA(1:T_int, 1), resMHATRecord(1:T_int, 4), "Color", '#A2142F', "LineWidth", 2);
                hold(app.MHATimeRecordAxes, "off");
                
                MH_I = animatedline(app.MHATimeRecordAxes, "Color", '#EDB120', "LineWidth", 2);
                MH_W = animatedline(app.MHATimeRecordAxes, "Color", '#77AC30', "LineWidth", 2);
                MH_M = animatedline(app.MHATimeRecordAxes, "Color", '#0072BD', "LineWidth", 2);
                MH_F = animatedline(app.MHATimeRecordAxes, "Color", '#A2142F', "LineWidth", 2);
                legend(app.MHATimeRecordAxes, {'Idle', 'Work', 'Maintenance', 'Failure'}, 'Location', 'northwest')
                axis(app.MHATimeRecordAxes, [0, app.T_end, 0, max(max(resMHATRecord(:,:)))])
                
                %       Display MHA Specific Statistic
                if app.ChooseMHAStatisticDropDown.Value == "Utilization"
                    resMHASpec = resMHATWork ./ resMHA(:, 1);
                elseif app.ChooseMHAStatisticDropDown.Value == "W-Length"
                    resMHASpec = resMHA(:, 10);
                end
                
                plot(app.MHAStatisticAxes, resMHA(1:T_int, 1), resMHASpec(1:T_int), "Color", '#7E2F8E', "LineWidth", 2);
                MH_spec = animatedline(app.MHAStatisticAxes, "Color", '#7E2F8E', "LineWidth", 2);
                axis(app.MHAStatisticAxes, [0, app.T_end, 0, max(resMHASpec)])
                
                % Display Machine Overall Plot
                %       Display Time Record
                plot(app.MachineTimeRecordAxes, resMachine(1:T_int, 1), resMachineTRecord(1:T_int, 1), "Color", '#EDB120', "LineWidth", 2);
                hold(app.MachineTimeRecordAxes, "on");
                plot(app.MachineTimeRecordAxes, resMachine(1:T_int, 1), resMachineTRecord(1:T_int, 2), "Color", '#77AC30', "LineWidth", 2);
                plot(app.MachineTimeRecordAxes, resMachine(1:T_int, 1), resMachineTRecord(1:T_int, 3), "Color", '#0072BD', "LineWidth", 2);
                plot(app.MachineTimeRecordAxes, resMachine(1:T_int, 1), resMachineTRecord(1:T_int, 4), "Color", '#A2142F', "LineWidth", 2);
                hold(app.MachineTimeRecordAxes, "off");
                
                MA_I = animatedline(app.MachineTimeRecordAxes, "Color", '#EDB120', "LineWidth", 2);
                MA_W = animatedline(app.MachineTimeRecordAxes, "Color", '#77AC30', "LineWidth", 2);
                MA_M = animatedline(app.MachineTimeRecordAxes, "Color", '#0072BD', "LineWidth", 2);
                MA_F = animatedline(app.MachineTimeRecordAxes, "Color", '#A2142F', "LineWidth", 2);
                legend(app.MachineTimeRecordAxes, {'Idle', 'Work', 'Maintenance', 'Failure'}, 'Location', 'northwest')
                axis(app.MachineTimeRecordAxes, [0, app.T_end, 0, max(max(resMachineTRecord(:,:)))])
            
                %       Display Machine Specific Statistic
                if app.MachineStatisticDropDown.Value == "Utilization"
                    resMachineSpec = resMachineTRecord(:, 2) ./ resMachine(:, 1);
                elseif app.MachineStatisticDropDown.Value == "Q_In-Length"
                    resMachineSpec = resMachine(:, 6);
                elseif app.MachineStatisticDropDown.Value == "Q_Out-Length"
                    resMachineSpec = resMachine(:, 7);
                end

                plot(app.MachineStatisticAxes, resMachine(1:T_int, 1), resMachineSpec(1:T_int), "Color", '#7E2F8E', "LineWidth", 2);
                MA_spec = animatedline(app.MachineStatisticAxes, "Color", '#7E2F8E', "LineWidth", 2);
                axis(app.MachineStatisticAxes, [0, app.T_end, 0, max(resMachineSpec)])
                
                % Update Callback Time
                while T_int < app.T_end - 1
                    app.callbackTime = T_int;
                    app.TimeSlider.Value = app.callbackTime;
                    app.TimeSpinner.Value = app.callbackTime;
                    
                    addpoints(J_cj,resTime(T_int), resCurrentJob(T_int))
                    addpoints(J_gj,resTime(T_int), resGeneratedJob(T_int))
                    addpoints(J_fj,resTime(T_int), resFinishedJob(T_int))
                    addpoints(J_wj,resTime(T_int), resWaitingJob(T_int))
                    addpoints(J_bj,resTime(T_int), resBacklogJob(T_int))
                    
                    addpoints(J_spec, resTime(T_int), resJobSpec(T_int))
                    
                    addpoints(MH_I, resMHA(T_int, 1), resMHATRecord(T_int, 1))
                    addpoints(MH_W, resMHA(T_int, 1), resMHATRecord(T_int, 2))
                    addpoints(MH_M, resMHA(T_int, 1), resMHATRecord(T_int, 3))
                    addpoints(MH_F, resMHA(T_int, 1), resMHATRecord(T_int, 4))
                    
                    addpoints(MH_spec, resMHA(T_int, 1), resMHASpec(T_int))
                    
                    addpoints(MA_I, resMachine(T_int, 1), resMachineTRecord(T_int, 1))
                    addpoints(MA_W, resMachine(T_int, 1), resMachineTRecord(T_int, 2))
                    addpoints(MA_M, resMachine(T_int, 1), resMachineTRecord(T_int, 3))
                    addpoints(MA_F, resMachine(T_int, 1), resMachineTRecord(T_int, 4))
                    
                    addpoints(MA_spec, resMachine(T_int, 1), resMachineSpec(T_int))
                    drawnow
                    
                    FigureFactoryMap(app.FactoryMapGraph, currentRep, app.SummaryMHA, app.SummaryMachine, app.callbackTime, app.occ, app.DisplayMHAsCheckBox.Value);
                    pause(0.05);
                    T_int = T_int + 1;
                    if app.StopCondition == 1
                        app.StopCondition = 0;
                        return
                    end
                    if T_int >= app.T_end - 1
                        % The Last Callback Time
                        app.callbackTime = app.T_end - 1e-5;
                        app.TimeSlider.Value = app.callbackTime;
                        app.TimeSpinner.Value = app.callbackTime;
                        
                        FigureFactoryMap(app.FactoryMapGraph, currentRep, app.SummaryMHA, app.SummaryMachine, app.callbackTime, app.occ, app.DisplayMHAsCheckBox.Value);
                        drawnow;
                    end
                end
            
            % Without Realtime Check Box
            else
                % Update Callback Time
                while T_int < app.T_end - 1
                    app.callbackTime = T_int;
                    app.TimeSlider.Value = app.callbackTime;
                    app.TimeSpinner.Value = app.callbackTime;
                    
                    FigureFactoryMap(app.FactoryMapGraph, currentRep, app.SummaryMHA, app.SummaryMachine, app.callbackTime, app.occ, app.DisplayMHAsCheckBox.Value);
                    pause(0.1);
                    T_int = T_int + 1;
                    if app.StopCondition == 1
                        app.StopCondition = 0;
                        return
                    end
                    if T_int >= app.T_end - 1
                        % The Last Callback Time
                        app.callbackTime = app.T_end - 1e-5;
                        app.TimeSlider.Value = app.callbackTime;
                        app.TimeSpinner.Value = app.callbackTime;
                        
                        FigureFactoryMap(app.FactoryMapGraph, currentRep, app.SummaryMHA, app.SummaryMachine, app.callbackTime, app.occ, app.DisplayMHAsCheckBox.Value);
                    end
                end
            end

        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            
            app.StopCondition = 1;
            
        end

        % Value changed function: CurrentReplicationSpinner
        function CurrentReplicationSpinnerValueChanged(app, event)
            
            % Choose the current replication and Factory map
            currentRep = app.CurrentReplicationSpinner.Value;
            
            FigureFactoryMap(app.FactoryMapGraph, currentRep, app.SummaryMHA, app.SummaryMachine, app.callbackTime, app.occ, app.DisplayMHAsCheckBox.Value);
            
            app.ShowButtonPushed(1);
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SimulationSettingPanel = uipanel(app.UIFigure);
            app.SimulationSettingPanel.Title = 'Simulation Setting';
            app.GridLayout = uigridlayout(app.SimulationSettingPanel);
            app.SimulationLengthEditFieldLabel = uilabel(app.GridLayout);
            app.SimulationLengthEditFieldLabel.Text = 'Simulation Length';
            app.SimulationLengthEditField = uieditfield(app.GridLayout, 'numeric');
            app.SimulationLengthEditField.Limits = [0 Inf];
            app.SimulationLengthEditField.ValueDisplayFormat = '%.0f';
            app.SimulationLengthEditField.Value = 2000;
            app.UnitTimeDropDownLabel = uilabel(app.GridLayout);
            app.UnitTimeDropDownLabel.Text = 'Unit Time';
            app.UnitTimeDropDown = uidropdown(app.GridLayout);
            app.UnitTimeDropDown.Items = {'0.1', '0.01', '0.001'};
            app.UnitTimeDropDown.Value = '0.1';
            app.SeedEditFieldLabel = uilabel(app.GridLayout);
            app.SeedEditFieldLabel.Text = 'Seed';
            app.SeedEditField = uieditfield(app.GridLayout, 'numeric');
            app.SeedEditField.LowerLimitInclusive = 'off';
            app.SeedEditField.Limits = [0 Inf];
            app.SeedEditField.ValueDisplayFormat = '%.0f';
            app.SeedEditField.Value = 1234;
            app.ActivateButton = uibutton(app.GridLayout, 'push');
            app.ActivateButton.ButtonPushedFcn = createCallbackFcn(app, @ActivateButtonPushed, true);
            app.ActivateButton.Text = 'Activate';
            app.AgentSettingPanel = uipanel(app.UIFigure);
            app.AgentSettingPanel.Title = 'Agent Setting';
            app.GridLayout8 = uigridlayout(app.AgentSettingPanel);
            app.AgentSettingTab = uitabgroup(app.GridLayout8);
            app.SystemTab_2 = uitab(app.AgentSettingTab);
            app.SystemTab_2.Title = 'System';
            app.GridLayout2 = uigridlayout(app.SystemTab_2);
            app.SimulateButton = uibutton(app.GridLayout2, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.NumberofReplicationsPanel = uipanel(app.GridLayout2);
            app.NumberofReplicationsPanel.Title = 'Number of Replications';
            app.GridLayout4_4 = uigridlayout(app.NumberofReplicationsPanel);
            app.TotalReplicationsEditFieldLabel = uilabel(app.GridLayout4_4);
            app.TotalReplicationsEditFieldLabel.Text = 'Total Replications';
            app.TotalReplicationsEditField = uieditfield(app.GridLayout4_4, 'numeric');
            app.TotalReplicationsEditField.Limits = [0 Inf];
            app.TotalReplicationsEditField.Value = 10;
            app.CurrentReplicationSpinnerLabel = uilabel(app.GridLayout4_4);
            app.CurrentReplicationSpinnerLabel.Text = 'Current Replication';
            app.CurrentReplicationSpinner = uispinner(app.GridLayout4_4);
            app.CurrentReplicationSpinner.Limits = [1 Inf];
            app.CurrentReplicationSpinner.ValueChangedFcn = createCallbackFcn(app, @CurrentReplicationSpinnerValueChanged, true);
            app.CurrentReplicationSpinner.Value = 1;
            app.GridLayout14 = uigridlayout(app.GridLayout2);
            app.ImportdataCheckBox = uicheckbox(app.GridLayout14);
            app.ImportdataCheckBox.Text = 'Import data';
            app.ImportdataCheckBox.Value = true;
            app.DataPathEditField = uieditfield(app.GridLayout14, 'text');
            app.DataPathEditField.Value = 'Res_Exp00_00_20k_16_1234.mat';
            app.MaterialHandlingAgentTab_2 = uitab(app.AgentSettingTab);
            app.MaterialHandlingAgentTab_2.Title = 'Material Handling Agent';
            app.GridLayout3_3 = uigridlayout(app.MaterialHandlingAgentTab_2);
            app.NumberofMaterialHandlingAgentsPanel = uipanel(app.GridLayout3_3);
            app.NumberofMaterialHandlingAgentsPanel.Title = 'Number of Material Handling Agents';
            app.GridLayout4_3 = uigridlayout(app.NumberofMaterialHandlingAgentsPanel);
            app.AGV_FEditFieldLabel = uilabel(app.GridLayout4_3);
            app.AGV_FEditFieldLabel.Text = 'AGV_F';
            app.AGV_FEditField = uieditfield(app.GridLayout4_3, 'numeric');
            app.AGV_FEditField.Limits = [0 20];
            app.AGV_FEditField.Value = 2;
            app.AGV_MEditFieldLabel = uilabel(app.GridLayout4_3);
            app.AGV_MEditFieldLabel.Text = 'AGV_M';
            app.AGV_MEditField = uieditfield(app.GridLayout4_3, 'numeric');
            app.AGV_MEditField.Limits = [0 20];
            app.AGV_MEditField.Value = 2;
            app.AGV_SEditFieldLabel = uilabel(app.GridLayout4_3);
            app.AGV_SEditFieldLabel.Text = 'AGV_S';
            app.AGV_SEditField = uieditfield(app.GridLayout4_3, 'numeric');
            app.AGV_SEditField.Limits = [0 20];
            app.AGV_SEditField.Value = 2;
            app.ProcessingAgentTab_2 = uitab(app.AgentSettingTab);
            app.ProcessingAgentTab_2.Title = 'Processing Agent';
            app.GridLayout3_2 = uigridlayout(app.ProcessingAgentTab_2);
            app.NumberofProcessingAgentsPanel = uipanel(app.GridLayout3_2);
            app.NumberofProcessingAgentsPanel.Title = 'Number of Processing Agents';
            app.GridLayout4_2 = uigridlayout(app.NumberofProcessingAgentsPanel);
            app.LatheEditFieldLabel = uilabel(app.GridLayout4_2);
            app.LatheEditFieldLabel.Text = 'Lathe';
            app.LatheEditField = uieditfield(app.GridLayout4_2, 'numeric');
            app.LatheEditField.Limits = [0 20];
            app.LatheEditField.Value = 10;
            app.DrillingEditFieldLabel = uilabel(app.GridLayout4_2);
            app.DrillingEditFieldLabel.Text = 'Drilling';
            app.DrillingEditField = uieditfield(app.GridLayout4_2, 'numeric');
            app.DrillingEditField.Limits = [0 20];
            app.DrillingEditField.Value = 4;
            app.WeldingEditFieldLabel = uilabel(app.GridLayout4_2);
            app.WeldingEditFieldLabel.Text = 'Welding';
            app.WeldingEditField = uieditfield(app.GridLayout4_2, 'numeric');
            app.WeldingEditField.Limits = [0 20];
            app.WeldingEditField.Value = 3;
            app.MillingEditFieldLabel = uilabel(app.GridLayout4_2);
            app.MillingEditFieldLabel.Text = 'Milling';
            app.MillingEditField = uieditfield(app.GridLayout4_2, 'numeric');
            app.MillingEditField.Limits = [0 20];
            app.MillingEditField.Value = 3;
            app.JobAgentTab_2 = uitab(app.AgentSettingTab);
            app.JobAgentTab_2.Title = 'Job Agent';
            app.GridLayout3 = uigridlayout(app.JobAgentTab_2);
            app.InterarrivalTimePanel = uipanel(app.GridLayout3);
            app.InterarrivalTimePanel.Title = 'Inter-arrival Time';
            app.GridLayout4 = uigridlayout(app.InterarrivalTimePanel);
            app.IAT_DistributionDropDownLabel = uilabel(app.GridLayout4);
            app.IAT_DistributionDropDownLabel.Text = 'IAT_Distribution';
            app.IAT_DistributionDropDown = uidropdown(app.GridLayout4);
            app.IAT_DistributionDropDown.Items = {'Exponential', 'Gamma', 'Lognormal', 'Uniform', 'Weibull', 'Constant'};
            app.IAT_DistributionDropDown.Value = 'Exponential';
            app.IAT_Parameter1EditFieldLabel = uilabel(app.GridLayout4);
            app.IAT_Parameter1EditFieldLabel.Text = 'IAT_Parameter1';
            app.IAT_Parameter1EditField = uieditfield(app.GridLayout4, 'numeric');
            app.IAT_Parameter1EditField.Limits = [0 Inf];
            app.IAT_Parameter1EditField.Value = 50;
            app.IAT_Parameter2EditFieldLabel = uilabel(app.GridLayout4);
            app.IAT_Parameter2EditFieldLabel.Text = 'IAT_Parameter2';
            app.IAT_Parameter2EditField = uieditfield(app.GridLayout4, 'numeric');
            app.IAT_Parameter2EditField.Limits = [0 Inf];
            app.IAT_Parameter2EditField.Value = 1;
            app.FactoryMapPanel = uipanel(app.UIFigure);
            app.FactoryMapPanel.Title = 'Factory Map';
            app.GridLayout5 = uigridlayout(app.FactoryMapPanel);
            app.FactoryMapGraph = uiaxes(app.GridLayout5);
            xlabel(app.FactoryMapGraph, 'X (meters)')
            ylabel(app.FactoryMapGraph, 'Y (meters)')
            zlabel(app.FactoryMapGraph, 'Z')
            app.FactoryMapGraph.XLim = [0 70];
            app.FactoryMapGraph.YLim = [0 30];
            app.FactoryMapGraph.XTick = [0 5 10 15 20 25 30 35 40 45 50 55 60 65 70];
            app.FactoryMapGraph.XTickLabel = {''; ''; '0'; '5'; '10'; '15'; '20'; '25'; '30'; '35'; '40'; '45'; '50'; ''; ''};
            app.FactoryMapGraph.YTick = [0 5 10 15 20 25 30];
            app.FactoryMapGraph.YTickLabel = {'30'; '25'; '20'; '15'; '10'; '5'; '0'};
            app.GridLayout6 = uigridlayout(app.GridLayout5);
            app.TimeSliderLabel = uilabel(app.GridLayout6);
            app.TimeSliderLabel.Text = 'Time';
            app.TimeSlider = uislider(app.GridLayout6);
            app.TimeSlider.Limits = [0 10000];
            app.TimeSlider.ValueChangedFcn = createCallbackFcn(app, @TimeSliderValueChanged, true);
            app.TimeSlider.Value = 600;
            app.GridLayout13 = uigridlayout(app.GridLayout5);
            app.DisplayMHAsCheckBox = uicheckbox(app.GridLayout13);
            app.DisplayMHAsCheckBox.Text = 'Display MHAs';
            app.DisplayMHAsCheckBox.Value = true;
            app.TimeSpinner = uispinner(app.GridLayout13);
            app.TimeSpinner.Limits = [0 Inf];
            app.TimeSpinner.ValueDisplayFormat = '%.2f';
            app.TimeSpinner.ValueChangedFcn = createCallbackFcn(app, @TimeSpinnerValueChanged, true);
            app.TimeSpinner.Value = 600;
            app.StartButton = uibutton(app.GridLayout13, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.GridLayout13, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.Title = uilabel(app.UIFigure);
            app.Title.Text = 'Digital Twin Factory Model';
            app.ResultDisplayPanel = uipanel(app.UIFigure);
            app.ResultDisplayPanel.Title = 'Result Display';
            app.GridLayout7 = uigridlayout(app.ResultDisplayPanel);
            app.ResultDisplayTab = uitabgroup(app.GridLayout7);
            app.JobAgentTab = uitab(app.ResultDisplayTab);
            app.JobAgentTab.Title = 'Job Agent';
            app.GridLayout10 = uigridlayout(app.JobAgentTab);
            app.JobStatisticAxes = uiaxes(app.GridLayout10);
            title(app.JobStatisticAxes, 'Number of Jobs')
            xlabel(app.JobStatisticAxes, 'Time')
            ylabel(app.JobStatisticAxes, 'Count')
            zlabel(app.JobStatisticAxes, 'Z')
            app.JobNumberRecordAxes = uiaxes(app.GridLayout10);
            title(app.JobNumberRecordAxes, 'Number of Jobs')
            xlabel(app.JobNumberRecordAxes, 'Time')
            ylabel(app.JobNumberRecordAxes, 'Count')
            zlabel(app.JobNumberRecordAxes, 'Z')
            app.GridLayout16 = uigridlayout(app.GridLayout10);
            app.ChooseJobStatisticDropDownLabel = uilabel(app.GridLayout16);
            app.ChooseJobStatisticDropDownLabel.Text = 'Choose Job Statistic';
            app.ChooseJobStatisticDropDown = uidropdown(app.GridLayout16);
            app.ChooseJobStatisticDropDown.Items = {'Number of Current Jobs', 'Number of Generated Jobs', 'Number of Finished Jobs', 'Number in Waiting List', 'Number in Backlog List', 'Number of Blocked Jobs'};
            app.ChooseJobStatisticDropDown.Value = 'Number of Current Jobs';
            app.BlockedJobsCheckBox = uicheckbox(app.GridLayout16);
            app.BlockedJobsCheckBox.Text = {'Blocked Jobs'; ''};
            app.MaterialHandlingAgentTab = uitab(app.ResultDisplayTab);
            app.MaterialHandlingAgentTab.Title = 'Material Handling Agent';
            app.GridLayout11 = uigridlayout(app.MaterialHandlingAgentTab);
            app.MHAStatisticAxes = uiaxes(app.GridLayout11);
            title(app.MHAStatisticAxes, 'Statistic of MHA')
            xlabel(app.MHAStatisticAxes, 'Time')
            ylabel(app.MHAStatisticAxes, 'Statistic')
            zlabel(app.MHAStatisticAxes, 'Z')
            app.MHATimeRecordAxes = uiaxes(app.GridLayout11);
            title(app.MHATimeRecordAxes, 'State Time Record of MHA')
            xlabel(app.MHATimeRecordAxes, 'Time')
            ylabel(app.MHATimeRecordAxes, 'Time Record')
            zlabel(app.MHATimeRecordAxes, 'Z')
            app.MHATimeRecordAxes.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.MHATimeRecordAxes.YTickLabel = {'0'; '20'; '40'; '60'; '80'; '100'};
            app.GridLayout12 = uigridlayout(app.GridLayout11);
            app.SelectMHADropDown = uidropdown(app.GridLayout12);
            app.SelectMHADropDown.Items = {'Whole MHAs'};
            app.SelectMHADropDown.Value = 'Whole MHAs';
            app.SelectMHADropDownLabel = uilabel(app.GridLayout12);
            app.SelectMHADropDownLabel.Text = 'Select MHA';
            app.ChooseMHAStatisticDropDownLabel = uilabel(app.GridLayout12);
            app.ChooseMHAStatisticDropDownLabel.Text = 'Choose MHA Statistic';
            app.ChooseMHAStatisticDropDown = uidropdown(app.GridLayout12);
            app.ChooseMHAStatisticDropDown.Items = {'Utilization', 'W-Length'};
            app.ChooseMHAStatisticDropDown.Value = 'Utilization';
            app.ProcessingAgentTab = uitab(app.ResultDisplayTab);
            app.ProcessingAgentTab.Title = 'Processing Agent';
            app.GridLayout11_2 = uigridlayout(app.ProcessingAgentTab);
            app.MachineTimeRecordAxes = uiaxes(app.GridLayout11_2);
            title(app.MachineTimeRecordAxes, 'State Time Record of Whole Machines')
            xlabel(app.MachineTimeRecordAxes, 'Time')
            ylabel(app.MachineTimeRecordAxes, 'Time Record')
            zlabel(app.MachineTimeRecordAxes, 'Z')
            app.MachineTimeRecordAxes.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.MachineTimeRecordAxes.YTickLabel = {'0'; '20'; '40'; '60'; '80'; '100'};
            app.MachineStatisticAxes = uiaxes(app.GridLayout11_2);
            title(app.MachineStatisticAxes, 'Statistic of Whole Machines')
            xlabel(app.MachineStatisticAxes, 'Time')
            ylabel(app.MachineStatisticAxes, 'Statistic')
            zlabel(app.MachineStatisticAxes, 'Z')
            app.GridLayout12_2 = uigridlayout(app.GridLayout11_2);
            app.SelectMachineDropDown = uidropdown(app.GridLayout12_2);
            app.SelectMachineDropDown.Items = {'Whole Whole Machines'};
            app.SelectMachineDropDown.Value = 'Whole Whole Machines';
            app.SelectMachineLabel = uilabel(app.GridLayout12_2);
            app.SelectMachineLabel.Text = 'Select Machine';
            app.MachineStatisticDropDownLabel = uilabel(app.GridLayout12_2);
            app.MachineStatisticDropDownLabel.Text = 'Machine Statistic';
            app.MachineStatisticDropDown = uidropdown(app.GridLayout12_2);
            app.MachineStatisticDropDown.Items = {'Utilization', 'Q_In-Length', 'Q_Out-Length'};
            app.MachineStatisticDropDown.Value = 'Utilization';
            app.JobProcessNetworkTab = uitab(app.ResultDisplayTab);
            app.JobProcessNetworkTab.Title = 'Job Process Network';
            app.GridLayout15 = uigridlayout(app.JobProcessNetworkTab);
            app.JobProcessNetworkGraphAxes = uiaxes(app.GridLayout15);
            title(app.JobProcessNetworkGraphAxes, 'Job Process Network Graph')
            zlabel(app.JobProcessNetworkGraphAxes, 'Z')
            app.JobProcessNetworkGraphAxes.XTick = [];
            app.JobProcessNetworkGraphAxes.YTick = [];
            app.GridLayout10_2 = uigridlayout(app.GridLayout15);
            app.RepSpinnerLabel = uilabel(app.GridLayout10_2);
            app.RepSpinnerLabel.Text = 'Rep';
            app.RepSpinner = uispinner(app.GridLayout10_2);
            app.RepSpinner.Limits = [1 Inf];
            app.RepSpinner.ValueChangedFcn = createCallbackFcn(app, @RepSpinnerValueChanged, true);
            app.RepSpinner.Value = 1;
            app.SelectJobDropDownLabel = uilabel(app.GridLayout10_2);
            app.SelectJobDropDownLabel.Text = 'Select Job';
            app.SelectJobDropDown = uidropdown(app.GridLayout10_2);
            app.SelectJobDropDown.Items = {'All'};
            app.SelectJobDropDown.Value = 'All';
            app.IncludeMHACheckBox = uicheckbox(app.GridLayout10_2);
            app.IncludeMHACheckBox.Text = 'Include MHA';
            app.GenerateButton = uibutton(app.GridLayout10_2, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate';
            app.GridLayout9 = uigridlayout(app.GridLayout7);
            app.ShowButton = uibutton(app.GridLayout9, 'push');
            app.ShowButton.ButtonPushedFcn = createCallbackFcn(app, @ShowButtonPushed, true);
            app.ShowButton.Text = 'Show';
            app.ResultTypeDropDownLabel = uilabel(app.GridLayout9);
            app.ResultTypeDropDownLabel.Text = 'Result Type';
            app.ResultTypeDropDown = uidropdown(app.GridLayout9);
            app.ResultTypeDropDown.Items = {'Across-replication', 'Single-replication'};
            app.ResultTypeDropDown.Value = 'Across-replication';
            app.RealtimeResultDisplayCheckBox = uicheckbox(app.GridLayout9);
            app.RealtimeResultDisplayCheckBox.Text = 'Real-time Result Display';
            app.RealtimeResultDisplayCheckBox.Value = true;
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'PSU_Logo.png';
            app.CopyrightNSFPennStateLabel = uilabel(app.UIFigure);
            app.CopyrightNSFPennStateLabel.Text = 'Copyright ©  Complex Systems Monitoring, Modeling and Control Laboratory';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SmartFactoryDigitalModelv4_220321

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end