classdef Kv < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        ProtocolspecificationsTab       matlab.ui.container.Tab
        HoldingtimemsEditFieldLabel     matlab.ui.control.Label
        HoldingtimemsEditField          matlab.ui.control.NumericEditField
        HoldingpotentialmVEditFieldLabel  matlab.ui.control.Label
        HoldingpotentialmVEditField     matlab.ui.control.NumericEditField
        ClampingvoltageminmVEditFieldLabel  matlab.ui.control.Label
        ClampingvoltageminmVEditField   matlab.ui.control.NumericEditField
        ClampingtimemsEditFieldLabel    matlab.ui.control.Label
        ClampingtimemsEditField         matlab.ui.control.NumericEditField
        ProtocolVisualizationUpdateButton  matlab.ui.control.Button
        NernstpotentialEkmVLabel        matlab.ui.control.Label
        NernstpotentialEkmVEditField    matlab.ui.control.NumericEditField
        DatauploadButton                matlab.ui.control.Button
        ClampingvoltagestepmVEditFieldLabel  matlab.ui.control.Label
        ClampingvoltagestepmVEditField  matlab.ui.control.NumericEditField
        ClampingvoltagemaxmVEditFieldLabel  matlab.ui.control.Label
        ClampingvoltagemaxmVEditField   matlab.ui.control.NumericEditField
        ModelCalibrationButton          matlab.ui.control.Button
        UIAxesTopLeft                   matlab.ui.control.UIAxes
        UIAxesBottomLeft                matlab.ui.control.UIAxes
        IKtofTab                        matlab.ui.container.Tab
        p1KnobLabel                     matlab.ui.control.Label
        p1Knob                          matlab.ui.control.Knob
        p2KnobLabel                     matlab.ui.control.Label
        p2Knob                          matlab.ui.control.Knob
        p3KnobLabel                     matlab.ui.control.Label
        p3Knob                          matlab.ui.control.Knob
        p4KnobLabel                     matlab.ui.control.Label
        p4Knob                          matlab.ui.control.Knob
        p5KnobLabel                     matlab.ui.control.Label
        p5Knob                          matlab.ui.control.Knob
        p7KnobLabel                     matlab.ui.control.Label
        p7Knob                          matlab.ui.control.Knob
        p8KnobLabel                     matlab.ui.control.Label
        p8Knob                          matlab.ui.control.Knob
        p10KnobLabel                    matlab.ui.control.Label
        p10Knob                         matlab.ui.control.Knob
        p11KnobLabel                    matlab.ui.control.Label
        p11Knob                         matlab.ui.control.Knob
        GKtofKnobLabel                  matlab.ui.control.Label
        GKtofKnob                       matlab.ui.control.Knob
        TextArea                        matlab.ui.control.TextArea
        HighlightShowEquationsButton    matlab.ui.control.Button
        ResetButton                     matlab.ui.control.Button
        p6KnobLabel                     matlab.ui.control.Label
        p6Knob                          matlab.ui.control.Knob
        p9KnobLabel                     matlab.ui.control.Label
        p9Knob                          matlab.ui.control.Knob
        p12KnobLabel                    matlab.ui.control.Label
        p12Knob                         matlab.ui.control.Knob
        IKtosTab                        matlab.ui.container.Tab
        TextArea_7                      matlab.ui.control.TextArea
        HighlightShowEquationsButton_7  matlab.ui.control.Button
        p1Knob_6Label_2                 matlab.ui.control.Label
        p1Knob_7                        matlab.ui.control.Knob
        p2Knob_6Label_2                 matlab.ui.control.Label
        p2Knob_7                        matlab.ui.control.Knob
        p3Knob_6Label_2                 matlab.ui.control.Label
        p3Knob_7                        matlab.ui.control.Knob
        p4Knob_6Label_2                 matlab.ui.control.Label
        p4Knob_7                        matlab.ui.control.Knob
        p5Knob_6Label_2                 matlab.ui.control.Label
        p5Knob_7                        matlab.ui.control.Knob
        p6Knob_5Label                   matlab.ui.control.Label
        p6Knob_7                        matlab.ui.control.Knob
        p7Knob_5Label                   matlab.ui.control.Label
        p7Knob_7                        matlab.ui.control.Knob
        p8Knob_5Label                   matlab.ui.control.Label
        p8Knob_7                        matlab.ui.control.Knob
        p9Knob_5Label                   matlab.ui.control.Label
        p9Knob_7                        matlab.ui.control.Knob
        GKtosKnobLabel                  matlab.ui.control.Label
        GKtosKnob                       matlab.ui.control.Knob
        ResetButton_7                   matlab.ui.control.Button
        IK1Tab                          matlab.ui.container.Tab
        Image3                          matlab.ui.control.Image
        p1Knob_6Label                   matlab.ui.control.Label
        p1Knob_2                        matlab.ui.control.Knob
        p2Knob_6Label                   matlab.ui.control.Label
        p2Knob_2                        matlab.ui.control.Knob
        p3Knob_6Label                   matlab.ui.control.Label
        p3Knob_2                        matlab.ui.control.Knob
        KoLabel                         matlab.ui.control.Label
        KoKnob                          matlab.ui.control.Knob
        p1EditField_2                   matlab.ui.control.NumericEditField
        p2EditField_2                   matlab.ui.control.NumericEditField
        p3EditField_2                   matlab.ui.control.NumericEditField
        KoEditField                     matlab.ui.control.NumericEditField
        Koc1EditField                   matlab.ui.control.NumericEditField
        ResetButton_2                   matlab.ui.control.Button
        HightlightButton_2              matlab.ui.control.Button
        IKsTab                          matlab.ui.container.Tab
        Image2                          matlab.ui.control.Image
        p1Knob_7Label                   matlab.ui.control.Label
        p1Knob_3                        matlab.ui.control.Knob
        p2Knob_7Label                   matlab.ui.control.Label
        p2Knob_3                        matlab.ui.control.Knob
        p3Knob_7Label                   matlab.ui.control.Label
        p3Knob_3                        matlab.ui.control.Knob
        p4Knob_6Label                   matlab.ui.control.Label
        p4Knob_3                        matlab.ui.control.Knob
        p5Knob_6Label                   matlab.ui.control.Label
        p5Knob_3                        matlab.ui.control.Knob
        GKsKnobLabel                    matlab.ui.control.Label
        GKsKnob                         matlab.ui.control.Knob
        GKsEditField                    matlab.ui.control.NumericEditField
        p1EditField_3                   matlab.ui.control.NumericEditField
        p2EditField_3                   matlab.ui.control.NumericEditField
        p3EditField_3                   matlab.ui.control.NumericEditField
        p4EditField_3                   matlab.ui.control.NumericEditField
        p5EditField_3                   matlab.ui.control.NumericEditField
        p1c1EditField_3                 matlab.ui.control.NumericEditField
        p1c2EditField_3                 matlab.ui.control.NumericEditField
        ResetButton_3                   matlab.ui.control.Button
        HightlightButton_3              matlab.ui.control.Button
        IKurTab                         matlab.ui.container.Tab
        TextArea_4                      matlab.ui.control.TextArea
        HighlightShowEquationsButton_4  matlab.ui.control.Button
        p1Knob_2Label                   matlab.ui.control.Label
        p1Knob_4                        matlab.ui.control.Knob
        p2Knob_2Label                   matlab.ui.control.Label
        p2Knob_4                        matlab.ui.control.Knob
        p3Knob_2Label                   matlab.ui.control.Label
        p3Knob_4                        matlab.ui.control.Knob
        p4Knob_2Label                   matlab.ui.control.Label
        p4Knob_4                        matlab.ui.control.Knob
        p5Knob_2Label                   matlab.ui.control.Label
        p5Knob_4                        matlab.ui.control.Knob
        p6Knob_2Label                   matlab.ui.control.Label
        p6Knob_4                        matlab.ui.control.Knob
        p7Knob_2Label                   matlab.ui.control.Label
        p7Knob_4                        matlab.ui.control.Knob
        p8Knob_2Label                   matlab.ui.control.Label
        p8Knob_4                        matlab.ui.control.Knob
        p9Knob_2Label                   matlab.ui.control.Label
        p9Knob_4                        matlab.ui.control.Knob
        GKurKnobLabel                   matlab.ui.control.Label
        GKurKnob                        matlab.ui.control.Knob
        ResetButton_4                   matlab.ui.control.Button
        IKssTab                         matlab.ui.container.Tab
        Image                           matlab.ui.control.Image
        GKssEditField                   matlab.ui.control.NumericEditField
        GKssKnobLabel                   matlab.ui.control.Label
        GKssKnob                        matlab.ui.control.Knob
        p1Knob_5Label                   matlab.ui.control.Label
        p1Knob_5                        matlab.ui.control.Knob
        p2Knob_5Label                   matlab.ui.control.Label
        p2Knob_5                        matlab.ui.control.Knob
        p3Knob_5Label                   matlab.ui.control.Label
        p3Knob_5                        matlab.ui.control.Knob
        p4Knob_5Label                   matlab.ui.control.Label
        p4Knob_5                        matlab.ui.control.Knob
        p5Knob_5Label                   matlab.ui.control.Label
        p5Knob_5                        matlab.ui.control.Knob
        p1EditField_5                   matlab.ui.control.NumericEditField
        p2EditField_5                   matlab.ui.control.NumericEditField
        p3EditField_5                   matlab.ui.control.NumericEditField
        p4EditField_5                   matlab.ui.control.NumericEditField
        p5EditField_5                   matlab.ui.control.NumericEditField
        ResetButton_5                   matlab.ui.control.Button
        HightlightButton_5              matlab.ui.control.Button
        RightPanel                      matlab.ui.container.Panel
        SelectVoltageDropDownLabel      matlab.ui.control.Label
        SelectVoltageDropDown           matlab.ui.control.DropDown
        UIAxesMiddleRight               matlab.ui.control.UIAxes
        UIAxesTopRight                  matlab.ui.control.UIAxes
        UIAxesBottomRight               matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    properties (Access = private)
        % time space
        tH % holding time
        tP % voltage-clamp time
        t % entire time
        
        % voltages
        volts
        num_volts
        volt_idx
        
        % currents
        yksum_set
        yktof_set
        yktos_set
        yk1_set
        yks_set
        ykur_set
        ykss_set

        % apps for dialog windows
        KtofDialogApp
        KtosDialogApp
        KurDialogApp
        
        % warning window
        WarnApp
        
        % default parameter values
        Ktof_default
        Ktos_default
        K1_default
        Ks_default
        Kur_default
        Kss_default
        
        % experimental data
        exp_t
        exp_current_set
        exp_idx
        exp_num_volts
    end
    
    properties (Access = public)
        % For IKtof dialog box
        p1Ktof = 30;
        p2Ktof = 13.5; 
        p3Ktof = 33.5; 
        p4Ktof = 7; 
        p5Ktof = 0.18064; 
        p6Ktof = 0.03577; 
        p7Ktof = 0.3956; 
        p8Ktof = 0.06237; 
        p9Ktof = 0.000152; 
        p10Ktof = 0.067083; 
        p11Ktof = 0.00095; 
        p12Ktof = 0.051335; 
        GKtof = 0.4067; 

        % For IKtos dialog box
        p1Ktos = 22.5;
        p2Ktos = 7.7;
        p3Ktos = 45.2;
        p4Ktos = 5.7;
        p5Ktos = 0.493;
        p6Ktos = 0.0629;
        p7Ktos = 2.058;
        p8Ktos = 270;
        p9Ktos = 1050;
        GKtos = 0.0629;        

        % For IKur dialog box
        p1Kur = 22.5;
        p2Kur = 7.7;
        p3Kur = 45.2;
        p4Kur = 5.7;
        p5Kur = 0.493;
        p6Kur = 0.0629;
        p7Kur = 2.058;
        p8Kur = 1200;
        p9Kur = 170;
        GKur = 0.16;
    end
    
    methods (Access = private)
        function [tH, tP, t] = gen_time_space(app)
            tH = 0:app.HoldingtimemsEditField.Value;
            tP = 0:app.ClampingtimemsEditField.Value;
            tP_shift = tP + app.HoldingtimemsEditField.Value + 1;
            t = [tH, tP_shift];
        end
        
        function [volts, num_volts] = gen_volts(app)
            minv = app.ClampingvoltageminmVEditField.Value;
            stepv = app.ClampingvoltagestepmVEditField.Value;
            maxv = app.ClampingvoltagemaxmVEditField.Value;
            
            if stepv == 0
                volts = minv;
            else
                volts = minv:stepv:maxv;
            end
            num_volts = length(volts);
        end
        
        function [current_trc] = gen_IKtof(app, v)
            param = zeros(14, 1);
            param(1) = app.p1Knob.Value;
            param(2) = app.p2Knob.Value;
            param(3) = app.p3Knob.Value;
            param(4) = app.p4Knob.Value;
            param(5) = app.p5Knob.Value;
            param(6) = app.p6Knob.Value;
            param(7) = app.p7Knob.Value;
            param(8) = app.p8Knob.Value;
            param(9) = app.p9Knob.Value;
            param(10) = app.p10Knob.Value;
            param(11) = app.p11Knob.Value;
            param(12) = app.p12Knob.Value;
            param(13) = app.GKtofKnob.Value;
            param(14) = app.NernstpotentialEkmVEditField.Value;
            
            tt = cell(1, 3);
            tt{1} = app.t;
            tt{2} = app.tH;
            tt{3} = app.tP;
            current_trc = IKtof(param, app.HoldingpotentialmVEditField.Value, v, tt);
        end

        function [current_trc] = gen_IKtos(app, v)
            param = zeros(11, 1);
            param(1) = app.p1Knob_7.Value;
            param(2) = app.p2Knob_7.Value;
            param(3) = app.p3Knob_7.Value;
            param(4) = app.p4Knob_7.Value;
            param(5) = app.p5Knob_7.Value;
            param(6) = app.p6Knob_7.Value;
            param(7) = app.p7Knob_7.Value;
            param(8) = app.p8Knob_7.Value;
            param(9) = app.p9Knob_7.Value;
            param(10) = app.GKtosKnob.Value;
            param(11) = app.NernstpotentialEkmVEditField.Value;
            
            tt = cell(1, 3);
            tt{1} = app.t;
            tt{2} = app.tH;
            tt{3} = app.tP;
            current_trc = IKtos(param, app.HoldingpotentialmVEditField.Value, v, tt);
        end
        
        function [current_trc] = gen_IK1(app, v)
            param = zeros(5, 1);
            param(1) = app.p1Knob_2.Value;
            param(2) = app.p2Knob_2.Value;
            param(3) = app.p3Knob_2.Value;
            param(4) = app.KoKnob.Value;
            param(5) = app.NernstpotentialEkmVEditField.Value;
            
            tt = cell(1, 3);
            tt{1} = app.t;
            tt{2} = app.tH;
            tt{3} = app.tP;
            current_trc = IK1(param, app.HoldingpotentialmVEditField.Value, v, tt);
        end
        
        function [current_trc] = gen_IKs(app, v)
            param = zeros(7, 1);
            param(1) = app.p1Knob_3.Value;
            param(2) = app.p2Knob_3.Value;
            param(3) = app.p3Knob_3.Value;
            param(4) = app.p4Knob_3.Value;
            param(5) = app.p5Knob_3.Value;
            param(6) = app.GKsKnob.Value;
            param(7) = app.NernstpotentialEkmVEditField.Value;
            
            tt = cell(1, 3);
            tt{1} = app.t;
            tt{2} = app.tH;
            tt{3} = app.tP;
            current_trc = IKs(param, app.HoldingpotentialmVEditField.Value, v, tt);            
        end

        function [current_trc] = gen_IKur(app, v)
            param = zeros(11, 1);
            param(1) = app.p1Knob_4.Value;
            param(2) = app.p2Knob_4.Value;
            param(3) = app.p3Knob_4.Value;
            param(4) = app.p4Knob_4.Value;
            param(5) = app.p5Knob_4.Value;
            param(6) = app.p6Knob_4.Value;
            param(7) = app.p7Knob_4.Value;
            param(8) = app.p8Knob_4.Value;
            param(9) = app.p9Knob_4.Value;
            param(10) = app.GKurKnob.Value;
            param(11) = app.NernstpotentialEkmVEditField.Value;
            
            tt = cell(1, 3);
            tt{1} = app.t;
            tt{2} = app.tH;
            tt{3} = app.tP;
            current_trc = IKur(param, app.HoldingpotentialmVEditField.Value, v, tt);
        end
        
        function [current_trc] = gen_IKss(app, v)
            param = zeros(7, 1);
            param(1) = app.p1Knob_5.Value;
            param(2) = app.p2Knob_5.Value;
            param(3) = app.p3Knob_5.Value;
            param(4) = app.p4Knob_5.Value;
            param(5) = app.p5Knob_5.Value;            
            param(6) = app.GKssKnob.Value;
            param(7) = app.NernstpotentialEkmVEditField.Value;
            
            tt = cell(1, 3);
            tt{1} = app.t;
            tt{2} = app.tH;
            tt{3} = app.tP;
            current_trc = IKss(param, app.HoldingpotentialmVEditField.Value, v, tt);
        end
        
        function [current_trc_set] = gen_currents_over_volts(app, current_generator)
            % wrapper function looping over voltages
            current_trc_set = zeros(length(app.t), app.num_volts);
            for i=1:app.num_volts
                current_trc_set(:,i) = current_generator(app, app.volts(i));
            end
        end
        
        function plot_protocol(app)
            yH = ones(1, length(app.tH))*app.HoldingpotentialmVEditField.Value;
            yP = ones(1, length(app.tP))*app.volts(1);
            y = [yH, yP];
            plot(app.UIAxesTopLeft, app.t, y, 'LineWidth',1.5)
            
            hold(app.UIAxesTopLeft, "on")
            for i=2:app.num_volts
                yP = ones(1, length(app.tP))*app.volts(i);
                y = [yH, yP];
                plot(app.UIAxesTopLeft, app.t, y, 'LineWidth',1.5)
            end
            hold(app.UIAxesTopLeft, "off")
        end
        
        function plot_sum_currents_allvolts(app)
            % top-right axes; IKsum
            plot(app.UIAxesTopRight, app.t, app.yksum_set(:,1))
            hold(app.UIAxesTopRight, "on")
            for i=2:app.num_volts
                plot(app.UIAxesTopRight, app.t, app.yksum_set(:,i))
            end
            hold(app.UIAxesTopRight, "off")
            axis(app.UIAxesTopRight, "tight")
        end
        
        function plot_currents_onevolt(app, volt_idx, highlight_idx)
            % MiddleRight; individual K+ currents for one voltage
            l(1) = plot(app.UIAxesMiddleRight, app.t, app.yktof_set(:,volt_idx));
            
            hold(app.UIAxesMiddleRight, "on")
            l(6) = plot(app.UIAxesMiddleRight, app.t, app.yktos_set(:,volt_idx));
            l(2) = plot(app.UIAxesMiddleRight, app.t, app.yk1_set(:,volt_idx));
            l(3) = plot(app.UIAxesMiddleRight, app.t, app.yks_set(:,volt_idx));
            l(4) = plot(app.UIAxesMiddleRight, app.t, app.ykur_set(:,volt_idx));
            l(5) = plot(app.UIAxesMiddleRight, app.t, app.ykss_set(:,volt_idx));
            hold(app.UIAxesMiddleRight, "off")
            
            switch highlight_idx
                case false
                    legend(app.UIAxesMiddleRight, "I_{Ktof}","I_{Ktos}","I_{KI}","I_{Ks}","I_{Kur}","I_{Kss}", "Location","northeast")
                case 1
                    legend(app.UIAxesMiddleRight, l(highlight_idx), "I_{Ktof}", "Location","northeast")
                case 2
                    legend(app.UIAxesMiddleRight, l(highlight_idx), "I_{KI}", "Location","northeast")
                case 3
                    legend(app.UIAxesMiddleRight, l(highlight_idx), "I_{Ks}", "Location","northeast")
                case 4
                    legend(app.UIAxesMiddleRight, l(highlight_idx), "I_{Kur}", "Location","northeast")
                case 5
                    legend(app.UIAxesMiddleRight, l(highlight_idx), "I_{Kss}", "Location","northeast")
                case 6
                    legend(app.UIAxesMiddleRight, l(highlight_idx), "I_{Kto}", "Location","northeast")
            end            
            set(l(highlight_idx), "LineWidth",2)       
            axis(app.UIAxesMiddleRight, "tight")
            
            % IKsum for one voltage
            if isequal(app.exp_idx, true) && isequal(app.num_volts, app.exp_num_volts)
                plot(app.UIAxesBottomRight, app.t, app.yksum_set(:,volt_idx), "LineWidth",2, "Color","red")
                hold(app.UIAxesBottomRight, "on")
                plot(app.UIAxesBottomRight, app.exp_t, app.exp_current_set(:,volt_idx), "LineWidth",2, "Color",[0,0,0,0.5])
                hold(app.UIAxesBottomRight, "off")
                axis(app.UIAxesBottomRight, "tight")
                legend(app.UIAxesBottomRight, "Model Prediction","Experimental Data", "Location","northeast")
            elseif app.num_volts ~= app.exp_num_volts
                app.WarnApp = warn_message();
                plot(app.UIAxesBottomRight, app.t, app.yksum_set(:,volt_idx), "LineWidth",2, "Color","red")
                axis(app.UIAxesBottomRight, "tight")
                legend(app.UIAxesBottomRight, "Model Prediction", "Location","northeast")
            else
                plot(app.UIAxesBottomRight, app.t, app.yksum_set(:,volt_idx), "LineWidth",2, "Color","red")
                axis(app.UIAxesBottomRight, "tight")
                legend(app.UIAxesBottomRight, "Model Prediction", "Location","northeast")                
            end
        end
        
        function reset_ktof(app)
            app.p1Knob.Value = app.Ktof_default(1);
            app.p1Ktof = app.Ktof_default(1);
            app.p2Knob.Value = app.Ktof_default(2);
            app.p2Ktof = app.Ktof_default(2);
            app.p3Knob.Value = app.Ktof_default(3);
            app.p3Ktof = app.Ktof_default(3);
            app.p4Knob.Value = app.Ktof_default(4);
            app.p4Ktof = app.Ktof_default(4);
            app.p5Knob.Value = app.Ktof_default(5);
            app.p5Ktof = app.Ktof_default(5);
            app.p6Knob.Value = app.Ktof_default(6);
            app.p6Ktof = app.Ktof_default(6);
            app.p7Knob.Value = app.Ktof_default(7);
            app.p7Ktof = app.Ktof_default(7);
            app.p8Knob.Value = app.Ktof_default(8);
            app.p8Ktof = app.Ktof_default(8);
            app.p9Knob.Value = app.Ktof_default(9);
            app.p9Ktof = app.Ktof_default(9);
            app.p10Knob.Value = app.Ktof_default(10);
            app.p10Ktof = app.Ktof_default(10);
            app.p11Knob.Value = app.Ktof_default(11);
            app.p11Ktof = app.Ktof_default(11);
            app.p12Knob.Value = app.Ktof_default(12);
            app.p12Ktof = app.Ktof_default(12);
            app.GKtofKnob.Value = app.Ktof_default(13);
            app.GKtof = app.Ktof_default(13);    
        end
        
        function reset_ktos(app)
            app.p1Knob_7.Value = app.Ktos_default(1);
            app.p1Ktos = app.Ktos_default(1);
            app.p2Knob_7.Value = app.Ktos_default(2);
            app.p2Ktos = app.Ktos_default(2);
            app.p3Knob_7.Value = app.Ktos_default(3);
            app.p3Ktos = app.Ktos_default(3);
            app.p4Knob_7.Value = app.Ktos_default(4);
            app.p4Ktos = app.Ktos_default(4);
            app.p5Knob_7.Value = app.Ktos_default(5);
            app.p5Ktos = app.Ktos_default(5);
            app.p6Knob_7.Value = app.Ktos_default(6);
            app.p6Ktos = app.Ktos_default(6);
            app.p7Knob_7.Value = app.Ktos_default(7);
            app.p7Ktos = app.Ktos_default(7);
            app.p8Knob_7.Value = app.Ktos_default(8);
            app.p8Ktos = app.Ktos_default(8);
            app.p9Knob_7.Value = app.Ktos_default(9);
            app.p9Ktos = app.Ktos_default(9);
            app.GKtosKnob.Value = app.Ktos_default(10);
            app.GKtos = app.Ktos_default(10);
        end
        
        function reset_k1(app)
            app.p1Knob_2.Value = app.K1_default(1);
            app.p1EditField_2.Value = app.K1_default(1);
            
            app.p2Knob_2.Value = app.K1_default(2);
            app.p2EditField_2.Value = app.K1_default(2);
            
            app.p3Knob_2.Value = app.K1_default(3);
            app.p3EditField_2.Value = app.K1_default(3);
            
            app.KoKnob.Value = app.K1_default(4);
            app.KoEditField.Value = app.K1_default(4);
            app.Koc1EditField.Value = app.K1_default(4);
        end
        
        function reset_ks(app)
            app.p1Knob_3.Value = app.Ks_default(1);
            app.p1EditField_3.Value = app.Ks_default(1);
            app.p1c1EditField_3.Value = app.Ks_default(1);
            app.p1c2EditField_3.Value = app.Ks_default(1);
            
            app.p2Knob_3.Value = app.Ks_default(2);
            app.p2EditField_3.Value = app.Ks_default(2);
            
            app.p3Knob_3.Value = app.Ks_default(3);
            app.p3EditField_3.Value = app.Ks_default(3);
            
            app.p4Knob_3.Value = app.Ks_default(4);
            app.p4EditField_3.Value = app.Ks_default(4);
            
            app.p5Knob_3.Value = app.Ks_default(5);
            app.p5EditField_3.Value = app.Ks_default(5);
            
            app.GKsKnob.Value = app.Ks_default(6);
            app.GKsEditField.Value = app.Ks_default(6);
        end
        
        function reset_kur(app)
            app.p1Knob_4.Value = app.Kur_default(1);
            app.p1Kur = app.Kur_default(1);
            app.p2Knob_4.Value = app.Kur_default(2);
            app.p2Kur = app.Kur_default(2);
            app.p3Knob_4.Value = app.Kur_default(3);
            app.p3Kur = app.Kur_default(3);
            app.p4Knob_4.Value = app.Kur_default(4);
            app.p4Kur = app.Kur_default(4);
            app.p5Knob_4.Value = app.Kur_default(5);
            app.p5Kur = app.Kur_default(5);
            app.p6Knob_4.Value = app.Kur_default(6);
            app.p6Kur = app.Kur_default(6);
            app.p7Knob_4.Value = app.Kur_default(7);
            app.p7Kur = app.Kur_default(7);
            app.p8Knob_4.Value = app.Kur_default(8);
            app.p8Kur = app.Kur_default(8);
            app.p9Knob_4.Value = app.Kur_default(9);
            app.p9Kur = app.Kur_default(9);
            app.GKurKnob.Value = app.Kur_default(10);
            app.GKur = app.Kur_default(10);
        end
        
        function reset_kss(app)
            app.p1Knob_5.Value = app.Kss_default(1);
            app.p1EditField_5.Value = app.Kss_default(1);
            
            app.p2Knob_5.Value = app.Kss_default(2);
            app.p2EditField_5.Value = app.Kss_default(2);
            
            app.p3Knob_5.Value = app.Kss_default(3);
            app.p3EditField_5.Value = app.Kss_default(3);
            
            app.p4Knob_5.Value = app.Kss_default(4);
            app.p4EditField_5.Value = app.Kss_default(4);
            
            app.p5Knob_5.Value = app.Kss_default(5);
            app.p5EditField_5.Value = app.Kss_default(5);
            
            app.GKssKnob.Value = app.Kss_default(6);
            app.GKsEditField.Value = app.Kss_default(6);        
        end
        
        function update_sum_current(app, old_current_set, new_current_set)
            for i=1:app.num_volts
                app.yksum_set(:,i) = app.yksum_set(:,i) - old_current_set(:,i) + new_current_set(:,i);
            end
        end
    end
    
    methods (Access = public)      
        function update_ktof_dialog(app, param)
            % update knobs
            app.p1Knob.Value = param(1);
            app.p2Knob.Value = param(2);
            app.p3Knob.Value = param(3);
            app.p4Knob.Value = param(4);
            app.p5Knob.Value = param(5);
            app.p6Knob.Value = param(6);
            app.p7Knob.Value = param(7);
            app.p8Knob.Value = param(8);
            app.p9Knob.Value = param(9);
            app.p10Knob.Value = param(10);
            app.p11Knob.Value = param(11);
            app.p12Knob.Value = param(12);
            app.GKtofKnob.Value = param(13);
            
            % update dialog box
            app.p1Ktof = param(1);
            app.p2Ktof = param(2);
            app.p3Ktof = param(3);
            app.p4Ktof = param(4);
            app.p5Ktof = param(5);
            app.p6Ktof = param(6);
            app.p7Ktof = param(7);
            app.p8Ktof = param(8);
            app.p9Ktof = param(9);
            app.p10Ktof = param(10);
            app.p11Ktof = param(11);
            app.p12Ktof = param(12);
            app.GKtof = param(13);
            
            % generate new IKtof and update plots
            new_yktof_set = gen_currents_over_volts(app, @gen_IKtof);
            update_sum_current(app, app.yktof_set, new_yktof_set);
            app.yktof_set = new_yktof_set;
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 1);
        end
        
        function update_ktos_dialog(app, param)
            % update knobs
            app.p1Knob_7.Value = param(1);
            app.p2Knob_7.Value = param(2);
            app.p3Knob_7.Value = param(3);
            app.p4Knob_7.Value = param(4);
            app.p5Knob_7.Value = param(5);
            app.p6Knob_7.Value = param(6);
            app.p7Knob_7.Value = param(7);
            app.p8Knob_7.Value = param(8);
            app.p9Knob_7.Value = param(9);
            app.GKtosKnob.Value = param(11);
            
            % update dialog box
            app.p1Ktos = param(1);
            app.p2Ktos = param(2);
            app.p3Ktos = param(3);
            app.p4Ktos = param(4);
            app.p5Ktos = param(5);
            app.p6Ktos = param(6);
            app.p7Ktos = param(7);
            app.p8Ktos = param(8);
            app.p9Ktos = param(9);
            app.GKtos = param(11);
            
            % generate new IKtos and update plots
            new_yktos_set = gen_currents_over_volts(app, @gen_IKtos);
            update_sum_current(app, app.yktos_set, new_yktos_set);
            app.yktos_set = new_yktos_set;
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 6);
        end
        
        function update_kur_dialog(app, param)
            % update parameters
            app.p1Knob_4.Value = param(1);
            app.p2Knob_4.Value = param(2);
            app.p3Knob_4.Value = param(3);
            app.p4Knob_4.Value = param(4);
            app.p5Knob_4.Value = param(5);
            app.p6Knob_4.Value = param(6);
            app.p7Knob_4.Value = param(7);
            app.p8Knob_4.Value = param(8);
            app.p9Knob_4.Value = param(9);
            app.GKurKnob.Value = param(10);
            
            % update dialog box
            app.p1Kur = param(1);
            app.p2Kur = param(2);
            app.p3Kur = param(3);
            app.p4Kur = param(4);
            app.p5Kur = param(5);
            app.p6Kur = param(6);
            app.p7Kur = param(7);
            app.p8Kur = param(8);
            app.p9Kur = param(9);
            app.GKur = param(10);            
            
            % generate new IKur and update plots
            new_ykur_set = gen_currents_over_volts(app, @gen_IKur);
            update_sum_current(app, app.ykur_set, new_ykur_set);
            app.ykur_set = new_ykur_set;
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 4);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % assign default parameter values Ktof
            app.Ktof_default(1) = app.p1Ktof;
            app.Ktof_default(2) = app.p2Ktof;
            app.Ktof_default(3) = app.p3Ktof;
            app.Ktof_default(4) = app.p4Ktof;
            app.Ktof_default(5) = app.p5Ktof;
            app.Ktof_default(6) = app.p6Ktof;
            app.Ktof_default(7) = app.p7Ktof;
            app.Ktof_default(8) = app.p8Ktof;
            app.Ktof_default(9) = app.p9Ktof;
            app.Ktof_default(10) = app.p10Ktof;
            app.Ktof_default(11) = app.p11Ktof;
            app.Ktof_default(12) = app.p12Ktof;
            app.Ktof_default(13) = app.GKtof;
            
            % assign default parameter values Ktos
            app.Ktos_default(1) = app.p1Ktos;
            app.Ktos_default(2) = app.p2Ktos;
            app.Ktos_default(3) = app.p3Ktos;
            app.Ktos_default(4) = app.p4Ktos;
            app.Ktos_default(5) = app.p5Ktos;
            app.Ktos_default(6) = app.p6Ktos;
            app.Ktos_default(7) = app.p7Ktos;
            app.Ktos_default(8) = app.p8Ktos;
            app.Ktos_default(9) = app.p9Ktos;
            app.Ktos_default(10) = app.GKtos;
            
            % assign default parameter values K1
            app.K1_default(1) = 0.0896;
            app.K1_default(2) = 210;
            app.K1_default(3) = 0.2938;
            app.K1_default(4) = 5400;
            
            % assign default parameter values Ks
            app.Ks_default(1) = 26.5;
            app.Ks_default(2) = 0.128;
            app.Ks_default(3) = 0.038;
            app.Ks_default(4) = 0.00000481333;
            app.Ks_default(5) = 0.0000953333;
            app.Ks_default(6) = 0.00575;
            
            % assign default parameter values Kur
            app.Kur_default(1) = app.p1Kur;
            app.Kur_default(2) = app.p2Kur;
            app.Kur_default(3) = app.p3Kur;
            app.Kur_default(4) = app.p4Kur;
            app.Kur_default(5) = app.p5Kur;
            app.Kur_default(6) = app.p6Kur;
            app.Kur_default(7) = app.p7Kur;
            app.Kur_default(8) = app.p8Kur;
            app.Kur_default(9) = app.p9Kur;
            app.Kur_default(10) = app.GKur;
            
            % assign default parameter values Kss
            app.Kss_default(1) = 22.5;
            app.Kss_default(2) = 7.7;
            app.Kss_default(3) = 39.3;
            app.Kss_default(4) = 0.0862;
            app.Kss_default(5) = 13.17;
            app.Kss_default(6) = 0.05;

            % generate time space
            [app.tH, app.tP, app.t] = gen_time_space(app);  
            
            % generate clamping voltages based on protocol infomation
            [app.volts, app.num_volts] = gen_volts(app);
            
            % voltage drop-down menu
            volt_dd_items = strings(1, app.num_volts);
            for i=1:app.num_volts
                volt_dd_items(i) = strcat(num2str(app.volts(i)), " mV");
            end
            app.SelectVoltageDropDown.Items = volt_dd_items;
            
            % plot default voltage clamp protocol
            plot_protocol(app);
            
            % generate & plot IKsum
            app.yktof_set = gen_currents_over_volts(app, @gen_IKtof);
            app.yktos_set = gen_currents_over_volts(app, @gen_IKtos);
            app.yk1_set = gen_currents_over_volts(app, @gen_IK1);
            app.yks_set = gen_currents_over_volts(app, @gen_IKs);
            app.ykur_set = gen_currents_over_volts(app, @gen_IKur);
            app.ykss_set = gen_currents_over_volts(app, @gen_IKss);

            app.yksum_set = app.yktof_set + app.yktos_set + app.yk1_set + app.yks_set + app.ykur_set + app.ykss_set;
            plot_sum_currents_allvolts(app);
            
            % plot individual K+ currents
            app.volt_idx = 1;
            plot_currents_onevolt(app, app.volt_idx, false);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {874, 874};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {495, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.KtofDialogApp)
            delete(app.KtosDialogApp)
            delete(app.KurDialogApp)
            delete(app.WarnApp)
            delete(app)
        end

        % Button pushed function: ProtocolVisualizationUpdateButton
        function ProtocolVisualizationUpdateButtonPushed(app, event)
            % update time space
            [app.tH, app.tP, app.t] = gen_time_space(app);
            
            % update voltages
            [app.volts, app.num_volts] = gen_volts(app);
            
            % voltage drop-down menu
            volt_dd_items = strings(1, app.num_volts);
            for i=1:app.num_volts
                volt_dd_items(i) = strcat(num2str(app.volts(i)), " mV");
            end
            app.SelectVoltageDropDown.Items = volt_dd_items;
            
            % update protocl visualization
            plot_protocol(app);

            % update and plot currents with new protocol
            app.yktof_set = gen_currents_over_volts(app, @gen_IKtof);
            app.yktos_set = gen_currents_over_volts(app, @gen_IKtos);
            app.yk1_set = gen_currents_over_volts(app, @gen_IK1);
            app.yks_set = gen_currents_over_volts(app, @gen_IKs);
            app.ykur_set = gen_currents_over_volts(app, @gen_IKur);
            app.ykss_set = gen_currents_over_volts(app, @gen_IKss);
            app.yksum_set = app.yktof_set + app.yktos_set + app.yk1_set + app.yks_set + app.ykur_set + app.ykss_set;
            
            app.volt_idx = 1;
            app.SelectVoltageDropDown.Value = volt_dd_items(1);
            plot_currents_onevolt(app, app.volt_idx, false);
            plot_sum_currents_allvolts(app);
        end

        % Button pushed function: DatauploadButton
        function DatauploadButtonPushed(app, event)
            [file,path] = uigetfile(["*.csv";"*.xlsx"], "File Selector");
            exp_data = table2array(readtable(fullfile(path, file)));
                        
            app.exp_t = exp_data(:,1);
            app.exp_current_set = exp_data(:,2:end);

            % meta info
            app.exp_idx = true;
            [~, app.exp_num_volts] = size(app.exp_current_set);
            
            plot(app.UIAxesBottomLeft, app.exp_t, app.exp_current_set(:,1))
            hold(app.UIAxesBottomLeft, "on")
            for i=2:app.exp_num_volts
                plot(app.UIAxesBottomLeft, app.exp_t, app.exp_current_set(:,i))
            end
            hold(app.UIAxesBottomLeft, "off")
            axis(app.UIAxesBottomLeft, "tight")
            
            plot_currents_onevolt(app, app.volt_idx, false);
        end

        % Value changed function: SelectVoltageDropDown
        function SelectVoltageDropDownValueChanged(app, event)
            value = app.SelectVoltageDropDown.Value;
            volt = split(value, " ");
            volt = str2double(volt(1));
            app.volt_idx = find(app.volts == volt);
            
            plot_currents_onevolt(app, app.volt_idx, false);
        end

        % Button pushed function: HighlightShowEquationsButton
        function HighlightShowEquationsButtonPushed(app, event)
            % IKtof
            plot_currents_onevolt(app, app.volt_idx, 1);
            
            % open IKtof dialog
            app.KtofDialogApp = IKtof_dialog(app);
        end

        % Button pushed function: HighlightShowEquationsButton_7
        function ShowEquationsButton_7Pushed(app, event)
            % IKtos
            plot_currents_onevolt(app, app.volt_idx, 6);
            app.KtosDialogApp = IKtos_dialog(app);
        end

        % Button pushed function: HightlightButton_2
        function HightlightButton_2Pushed(app, event)
            plot_currents_onevolt(app, app.volt_idx, 2);
        end

        % Button pushed function: HightlightButton_3
        function HightlightButton_3Pushed(app, event)
            plot_currents_onevolt(app, app.volt_idx, 3);
        end

        % Button pushed function: HighlightShowEquationsButton_4
        function HighlightShowEquationsButton_4Pushed(app, event)
            % IKur
            plot_currents_onevolt(app, app.volt_idx, 4);
            app.KurDialogApp = IKur_dialog(app);
        end

        % Button pushed function: HightlightButton_5
        function HightlightButton_5Pushed(app, event)
            plot_currents_onevolt(app, app.volt_idx, 5);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            reset_ktof(app);
            
            new_yktof_set = gen_currents_over_volts(app, @gen_IKtof);
            update_sum_current(app, app.yktof_set, new_yktof_set);
            app.yktof_set = new_yktof_set;
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 1);            
        end

        % Button pushed function: ResetButton_2
        function ResetButton_2Pushed(app, event)
            reset_k1(app);
            
            new_yk1_set = gen_currents_over_volts(app, @gen_IK1);
            update_sum_current(app, app.yk1_set, new_yk1_set);
            app.yk1_set = new_yk1_set;
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 2);
        end

        % Button pushed function: ResetButton_3
        function ResetButton_3Pushed(app, event)
            reset_ks(app);
            
            new_yks_set = gen_currents_over_volts(app, @gen_IKs);
            update_sum_current(app, app.yks_set, new_yks_set);            
            app.yks_set = new_yks_set;
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 3);
        end

        % Button pushed function: ResetButton_4
        function ResetButton_4Pushed(app, event)
            reset_kur(app);
            
            new_ykur_set = gen_currents_over_volts(app, @gen_IKur);
            update_sum_current(app, app.ykur_set, new_ykur_set);
            app.ykur_set = new_ykur_set;
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 4);
        end

        % Button pushed function: ResetButton_5
        function ResetButton_5Pushed(app, event)
            reset_kss(app);
            
            new_ykss_set = gen_currents_over_volts(app, @gen_IKss);
            update_sum_current(app, app.ykss_set, new_ykss_set);
            app.ykss_set = new_ykss_set;
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 5);
        end

        % Button pushed function: ResetButton_7
        function ResetButton_7Pushed(app, event)
            reset_ktos(app);
            
            new_yktos_set = gen_currents_over_volts(app, @gen_IKtos);
            update_sum_current(app, app.yktos_set, new_yktos_set);
            app.yktos_set = new_yktos_set;
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 6);
        end

        % Value changing function: GKtofKnob
        function GKtofKnobValueChanging(app, event)
            changingValue = event.Value;
            app.GKtofKnob.Value = changingValue;
            app.GKtof = changingValue;          
            
            new_yktof_set = gen_currents_over_volts(app, @gen_IKtof);
            update_sum_current(app, app.yktof_set, new_yktof_set);
            app.yktof_set = new_yktof_set;
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 1);
        end

        % Value changing function: p1Knob
        function p1KnobValueChanging(app, event)
            changingValue = event.Value;
            app.p1Knob.Value = changingValue;
            app.p1Ktof = changingValue;          
            
            new_yktof_set = gen_currents_over_volts(app, @gen_IKtof);
            update_sum_current(app, app.yktof_set, new_yktof_set);
            app.yktof_set = new_yktof_set;
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 1);
        end

        % Value changing function: p2Knob
        function p2KnobValueChanging(app, event)
            changingValue = event.Value;
            app.p2Knob.Value = changingValue;
            app.p2Ktof = changingValue;
            
            new_yktof_set = gen_currents_over_volts(app, @gen_IKtof);
            update_sum_current(app, app.yktof_set, new_yktof_set);
            app.yktof_set = new_yktof_set;
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 1);
        end

        % Value changing function: p3Knob
        function p3KnobValueChanging(app, event)
            changingValue = event.Value;
            app.p3Knob.Value = changingValue;
            app.p3Ktof = changingValue;
            
            new_yktof_set = gen_currents_over_volts(app, @gen_IKtof);
            update_sum_current(app, app.yktof_set, new_yktof_set);
            app.yktof_set = new_yktof_set;
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 1);
        end

        % Value changing function: p4Knob
        function p4KnobValueChanging(app, event)
            changingValue = event.Value;
            app.p4Knob.Value = changingValue;
            app.p4Ktof = changingValue;
            
            new_yktof_set = gen_currents_over_volts(app, @gen_IKtof);
            update_sum_current(app, app.yktof_set, new_yktof_set);
            app.yktof_set = new_yktof_set;
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 1);
        end

        % Value changing function: p5Knob
        function p5KnobValueChanging(app, event)
            changingValue = event.Value;
            app.p5Knob.Value = changingValue;
            app.p5Ktof = changingValue;
            
            new_yktof_set = gen_currents_over_volts(app, @gen_IKtof);
            update_sum_current(app, app.yktof_set, new_yktof_set);
            app.yktof_set = new_yktof_set;
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 1);
        end

        % Value changing function: p6Knob
        function p6KnobValueChanging(app, event)
            changingValue = event.Value;
            app.p6Knob.Value = changingValue;
            app.p6Ktof = changingValue;
            
            new_yktof_set = gen_currents_over_volts(app, @gen_IKtof);
            update_sum_current(app, app.yktof_set, new_yktof_set);
            app.yktof_set = new_yktof_set;
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 1);
        end

        % Value changing function: p7Knob
        function p7KnobValueChanging(app, event)
            changingValue = event.Value;
            app.p7Knob.Value = changingValue;
            app.p7Ktof = changingValue;
            
            new_yktof_set = gen_currents_over_volts(app, @gen_IKtof);
            update_sum_current(app, app.yktof_set, new_yktof_set);
            app.yktof_set = new_yktof_set;
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 1);
        end

        % Value changing function: p8Knob
        function p8KnobValueChanging(app, event)
            changingValue = event.Value;
            app.p8Knob.Value = changingValue;
            app.p8Ktof = changingValue;
            
            new_yktof_set = gen_currents_over_volts(app, @gen_IKtof);
            update_sum_current(app, app.yktof_set, new_yktof_set);
            app.yktof_set = new_yktof_set;
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 1);
        end

        % Value changing function: p9Knob
        function p9KnobValueChanging(app, event)
            changingValue = event.Value;
            app.p9Knob.Value = changingValue;
            app.p9Ktof = changingValue;
            
            new_yktof_set = gen_currents_over_volts(app, @gen_IKtof);
            update_sum_current(app, app.yktof_set, new_yktof_set);
            app.yktof_set = new_yktof_set;
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 1);
        end

        % Value changing function: p10Knob
        function p10KnobValueChanging(app, event)
            changingValue = event.Value;
            app.p10Knob.Value = changingValue;
            app.p10Ktof = changingValue;
            
            new_yktof_set = gen_currents_over_volts(app, @gen_IKtof);
            update_sum_current(app, app.yktof_set, new_yktof_set);
            app.yktof_set = new_yktof_set;
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 1);
        end

        % Value changing function: p11Knob
        function p11KnobValueChanging(app, event)
            changingValue = event.Value;
            app.p11Knob.Value = changingValue;
            app.p11Ktof = changingValue;
            
            new_yktof_set = gen_currents_over_volts(app, @gen_IKtof);
            update_sum_current(app, app.yktof_set, new_yktof_set);
            app.yktof_set = new_yktof_set;
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 1);
        end

        % Value changing function: p12Knob
        function p12KnobValueChanging(app, event)
            changingValue = event.Value;
            app.p12Knob.Value = changingValue;
            app.p12Ktof = changingValue;
            
            new_yktof_set = gen_currents_over_volts(app, @gen_IKtof);
            update_sum_current(app, app.yktof_set, new_yktof_set);
            app.yktof_set = new_yktof_set;
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 1);
        end

        % Callback function: KoEditField, KoKnob, Koc1EditField
        function KoKnobValueChanging(app, event)
            changingValue = event.Value;
            app.KoKnob.Value = changingValue;
            app.KoEditField.Value = changingValue;
            app.Koc1EditField.Value = changingValue;
            
            new_yk1_set = gen_currents_over_volts(app, @gen_IK1);
            update_sum_current(app, app.yk1_set, new_yk1_set);
            app.yk1_set = new_yk1_set;
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 2);
        end

        % Callback function: p1EditField_2, p1Knob_2
        function p1Knob_2ValueChanging(app, event)
            changingValue = event.Value;
            app.p1Knob_2.Value = changingValue;
            app.p1EditField_2.Value = changingValue;
            
            new_yk1_set = gen_currents_over_volts(app, @gen_IK1);
            update_sum_current(app, app.yk1_set, new_yk1_set);
            app.yk1_set = new_yk1_set;
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 2);
        end

        % Callback function: p2EditField_2, p2Knob_2
        function p2Knob_2ValueChanging(app, event)
            changingValue = event.Value;
            app.p2Knob_2.Value = changingValue;
            app.p2EditField_2.Value = changingValue;
            
            new_yk1_set = gen_currents_over_volts(app, @gen_IK1);
            update_sum_current(app, app.yk1_set, new_yk1_set);
            app.yk1_set = new_yk1_set;
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 2);
        end

        % Callback function: p3EditField_2, p3Knob_2
        function p3Knob_2ValueChanging(app, event)
            changingValue = event.Value;
            app.p3Knob_2.Value = changingValue;
            app.p3EditField_2.Value = changingValue;
            
            new_yk1_set = gen_currents_over_volts(app, @gen_IK1);
            update_sum_current(app, app.yk1_set, new_yk1_set);
            app.yk1_set = new_yk1_set;
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 2);
        end

        % Callback function: GKsEditField, GKsKnob
        function GKsKnobValueChanging(app, event)
            changingValue = event.Value;
            app.GKsKnob.Value = changingValue;
            app.GKsEditField.Value = changingValue;
            
            new_yks_set = gen_currents_over_volts(app, @gen_IKs);
            update_sum_current(app, app.yks_set, new_yks_set);
            app.yks_set = new_yks_set;            
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 3);
        end

        % Callback function: p1EditField_3, p1Knob_3, 
        % p1c1EditField_3, p1c2EditField_3
        function p1Knob_3ValueChanging(app, event)
            changingValue = event.Value;
            app.p1Knob_3.Value = changingValue;
            app.p1EditField_3.Value = changingValue;
            app.p1c1EditField_3.Value = changingValue;
            app.p1c2EditField_3.Value = changingValue;
            
            new_yks_set = gen_currents_over_volts(app, @gen_IKs);
            update_sum_current(app, app.yks_set, new_yks_set);
            app.yks_set = new_yks_set;            
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 3);
        end

        % Callback function: p2EditField_3, p2Knob_3
        function p2Knob_3ValueChanging(app, event)
            changingValue = event.Value;
            app.p2Knob_3.Value = changingValue;
            app.p2EditField_3.Value = changingValue;
            
            new_yks_set = gen_currents_over_volts(app, @gen_IKs);
            update_sum_current(app, app.yks_set, new_yks_set);
            app.yks_set = new_yks_set;            
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 3);
        end

        % Callback function: p3EditField_3, p3Knob_3
        function p3Knob_3ValueChanging(app, event)
            changingValue = event.Value;
            app.p3Knob_3.Value = changingValue;
            app.p3EditField_3.Value = changingValue;
            
            new_yks_set = gen_currents_over_volts(app, @gen_IKs);
            update_sum_current(app, app.yks_set, new_yks_set);
            app.yks_set = new_yks_set;            
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 3);
        end

        % Callback function: p4EditField_3, p4Knob_3
        function p4Knob_3ValueChanging(app, event)
            changingValue = event.Value;
            app.p4Knob_3.Value = changingValue;
            app.p4EditField_3.Value = changingValue;
            
            new_yks_set = gen_currents_over_volts(app, @gen_IKs);
            update_sum_current(app, app.yks_set, new_yks_set);
            app.yks_set = new_yks_set;            
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 3);
        end

        % Callback function: p5EditField_3, p5Knob_3
        function p5Knob_3ValueChanging(app, event)
            changingValue = event.Value;
            app.p5Knob_3.Value = changingValue;
            app.p5EditField_3.Value = changingValue;
            
            new_yks_set = gen_currents_over_volts(app, @gen_IKs);
            update_sum_current(app, app.yks_set, new_yks_set);
            app.yks_set = new_yks_set;            
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 3);
        end

        % Value changing function: GKurKnob
        function GKurKnobValueChanging(app, event)
            changingValue = event.Value;
            app.GKurKnob.Value = changingValue;
            app.GKur = changingValue;
            
            new_ykur_set = gen_currents_over_volts(app, @gen_IKur);
            update_sum_current(app, app.ykur_set, new_ykur_set);
            app.ykur_set = new_ykur_set;
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 4);
        end

        % Value changing function: p1Knob_4
        function p1Knob_4ValueChanging(app, event)
            changingValue = event.Value;
            app.p1Knob_4.Value = changingValue;
            app.p1Kur = changingValue;
            
            new_ykur_set = gen_currents_over_volts(app, @gen_IKur);
            update_sum_current(app, app.ykur_set, new_ykur_set);
            app.ykur_set = new_ykur_set;
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 4);
        end

        % Value changing function: p2Knob_4
        function p2Knob_4ValueChanging(app, event)
            changingValue = event.Value;
            app.p2Knob_4.Value = changingValue;
            app.p2Kur = changingValue;
            
            new_ykur_set = gen_currents_over_volts(app, @gen_IKur);
            update_sum_current(app, app.ykur_set, new_ykur_set);
            app.ykur_set = new_ykur_set;
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 4);
        end

        % Value changing function: p3Knob_4
        function p3Knob_4ValueChanging(app, event)
            changingValue = event.Value;
            app.p3Knob_4.Value = changingValue;
            app.p3Kur = changingValue;
            
            new_ykur_set = gen_currents_over_volts(app, @gen_IKur);
            update_sum_current(app, app.ykur_set, new_ykur_set);
            app.ykur_set = new_ykur_set;
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 4);
        end

        % Value changing function: p4Knob_4
        function p4Knob_4ValueChanging(app, event)
            changingValue = event.Value;
            app.p4Knob_4.Value = changingValue;
            app.p4Kur = changingValue;
            
            new_ykur_set = gen_currents_over_volts(app, @gen_IKur);
            update_sum_current(app, app.ykur_set, new_ykur_set);
            app.ykur_set = new_ykur_set;
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 4);
        end

        % Value changing function: p5Knob_4
        function p5Knob_4ValueChanging(app, event)
            changingValue = event.Value;
            app.p5Knob_4.Value = changingValue;
            app.p5Kur = changingValue;
            
            new_ykur_set = gen_currents_over_volts(app, @gen_IKur);
            update_sum_current(app, app.ykur_set, new_ykur_set);
            app.ykur_set = new_ykur_set;
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 4);
        end

        % Value changing function: p6Knob_4
        function p6Knob_4ValueChanging(app, event)
            changingValue = event.Value;
            app.p6Knob_4.Value = changingValue;
            app.p6Kur = changingValue;
            
            new_ykur_set = gen_currents_over_volts(app, @gen_IKur);
            update_sum_current(app, app.ykur_set, new_ykur_set);
            app.ykur_set = new_ykur_set;
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 4);
        end

        % Value changing function: p7Knob_4
        function p7Knob_4ValueChanging(app, event)
            changingValue = event.Value;
            app.p7Knob_4.Value = changingValue;
            app.p7Kur = changingValue;
            
            new_ykur_set = gen_currents_over_volts(app, @gen_IKur);
            update_sum_current(app, app.ykur_set, new_ykur_set);
            app.ykur_set = new_ykur_set;
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 4);
        end

        % Value changing function: p8Knob_4
        function p8Knob_4ValueChanging(app, event)
            changingValue = event.Value;
            app.p8Knob_4.Value = changingValue;
            app.p8Kur = changingValue;
            
            new_ykur_set = gen_currents_over_volts(app, @gen_IKur);
            update_sum_current(app, app.ykur_set, new_ykur_set);
            app.ykur_set = new_ykur_set;
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 4);
        end

        % Value changing function: p9Knob_4
        function p9Knob_4ValueChanging(app, event)
            changingValue = event.Value;
            app.p9Knob_4.Value = changingValue;
            app.p9Kur = changingValue;
            
            new_ykur_set = gen_currents_over_volts(app, @gen_IKur);
            update_sum_current(app, app.ykur_set, new_ykur_set);
            app.ykur_set = new_ykur_set;
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 4);
        end

        % Callback function: GKssEditField, GKssKnob
        function GKssKnobValueChanging(app, event)
            changingValue = event.Value;
            app.GKssKnob.Value = changingValue;
            app.GKssEditField.Value = changingValue;
            
            new_ykss_set = gen_currents_over_volts(app, @gen_IKss);
            update_sum_current(app, app.ykss_set, new_ykss_set);
            app.ykss_set = new_ykss_set;
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 5);
        end

        % Callback function: p1EditField_5, p1Knob_5
        function p1Knob_5ValueChanging(app, event)
            changingValue = event.Value;
            app.p1Knob_5.Value = changingValue;
            app.p1EditField_5.Value = changingValue;
            app.p1c1EditField_3.Value = changingValue;
            app.p1c2EditField_3.Value = changingValue;
            
            new_ykss_set = gen_currents_over_volts(app, @gen_IKss);
            update_sum_current(app, app.ykss_set, new_ykss_set);
            app.ykss_set = new_ykss_set;
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 5);        
        end

        % Callback function: p2EditField_5, p2Knob_5
        function p2Knob_5ValueChanging(app, event)
            changingValue = event.Value;
            app.p2Knob_5.Value = changingValue;
            app.p2EditField_5.Value = changingValue;
            
            new_ykss_set = gen_currents_over_volts(app, @gen_IKss);
            update_sum_current(app, app.ykss_set, new_ykss_set);
            app.ykss_set = new_ykss_set;
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 5);          
        end

        % Callback function: p3EditField_5, p3Knob_5
        function p3Knob_5ValueChanging(app, event)
            changingValue = event.Value;
            app.p3Knob_5.Value = changingValue;
            app.p3EditField_5.Value = changingValue;
            
            new_ykss_set = gen_currents_over_volts(app, @gen_IKss);
            update_sum_current(app, app.ykss_set, new_ykss_set);
            app.ykss_set = new_ykss_set;
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 5);
        end

        % Callback function: p4EditField_5, p4Knob_5
        function p4Knob_5ValueChanging(app, event)
            changingValue = event.Value;
            app.p4Knob_5.Value = changingValue;
            app.p4EditField_5.Value = changingValue;
            
            new_ykss_set = gen_currents_over_volts(app, @gen_IKss);
            update_sum_current(app, app.ykss_set, new_ykss_set);
            app.ykss_set = new_ykss_set;
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 5);           
        end

        % Callback function: p5EditField_5, p5Knob_5
        function p5Knob_5ValueChanging(app, event)
            changingValue = event.Value;
            app.p5Knob_5.Value = changingValue;
            app.p5EditField_5.Value = changingValue;

            new_ykss_set = gen_currents_over_volts(app, @gen_IKss);
            update_sum_current(app, app.ykss_set, new_ykss_set);
            app.ykss_set = new_ykss_set;
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 5);          
        end

        % Value changing function: GKtosKnob
        function GKtosKnobValueChanging(app, event)
            changingValue = event.Value;
            app.GKtosKnob.Value = changingValue;
            app.GKtos = changingValue;
            
            new_yktos_set = gen_currents_over_volts(app, @gen_IKtos);
            update_sum_current(app, app.yktos_set, new_yktos_set);
            app.yktos_set = new_yktos_set;
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 6);
        end

        % Value changing function: p1Knob_7
        function p1Knob_7ValueChanging(app, event)
            changingValue = event.Value;
            app.p1Knob_7.Value = changingValue;
            app.p1Ktos = changingValue;
            
            new_yktos_set = gen_currents_over_volts(app, @gen_IKtos);
            update_sum_current(app, app.yktos_set, new_yktos_set);
            app.yktos_set = new_yktos_set;
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 6);           
        end

        % Value changing function: p2Knob_7
        function p2Knob_7ValueChanging(app, event)
            changingValue = event.Value;
            app.p2Knob_7.Value = changingValue;
            app.p2Ktos = changingValue;
            
            new_yktos_set = gen_currents_over_volts(app, @gen_IKtos);
            update_sum_current(app, app.yktos_set, new_yktos_set);
            app.yktos_set = new_yktos_set;
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 6);
        end

        % Value changing function: p3Knob_7
        function p3Knob_7ValueChanging(app, event)
            changingValue = event.Value;
            app.p3Knob_7.Value = changingValue;
            app.p3Ktos = changingValue;
            
            new_yktos_set = gen_currents_over_volts(app, @gen_IKtos);
            update_sum_current(app, app.yktos_set, new_yktos_set);
            app.yktos_set = new_yktos_set;
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 6);
        end

        % Value changing function: p4Knob_7
        function p4Knob_7ValueChanging(app, event)
            changingValue = event.Value;
            app.p4Knob_7.Value = changingValue;
            app.p4Ktos = changingValue;
            
            new_yktos_set = gen_currents_over_volts(app, @gen_IKtos);
            update_sum_current(app, app.yktos_set, new_yktos_set);
            app.yktos_set = new_yktos_set;
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 6);          
        end

        % Value changing function: p5Knob_7
        function p5Knob_7ValueChanging(app, event)
            changingValue = event.Value;
            app.p5Knob_7.Value = changingValue;
            app.p5Ktos = changingValue;
            
            new_yktos_set = gen_currents_over_volts(app, @gen_IKtos);
            update_sum_current(app, app.yktos_set, new_yktos_set);
            app.yktos_set = new_yktos_set;
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 6);         
        end

        % Value changing function: p6Knob_7
        function p6Knob_7ValueChanging(app, event)
            changingValue = event.Value;
            app.p6Knob_7.Value = changingValue;
            app.p6Ktos = changingValue;
            
            new_yktos_set = gen_currents_over_volts(app, @gen_IKtos);
            update_sum_current(app, app.yktos_set, new_yktos_set);
            app.yktos_set = new_yktos_set;
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 6);          
        end

        % Value changing function: p7Knob_7
        function p7Knob_7ValueChanging(app, event)
            changingValue = event.Value;
            app.p7Knob_7.Value = changingValue;
            app.p7Ktos = changingValue;
            
            new_yktos_set = gen_currents_over_volts(app, @gen_IKtos);
            update_sum_current(app, app.yktos_set, new_yktos_set);
            app.yktos_set = new_yktos_set;
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 6);            
        end

        % Value changing function: p8Knob_7
        function p8Knob_7ValueChanging(app, event)
            changingValue = event.Value;
            app.p8Knob_7.Value = changingValue;
            app.p8Ktos = changingValue;
            
            new_yktos_set = gen_currents_over_volts(app, @gen_IKtos);
            update_sum_current(app, app.yktos_set, new_yktos_set);
            app.yktos_set = new_yktos_set;
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 6);          
        end

        % Value changing function: p9Knob_7
        function p9Knob_7ValueChanging(app, event)
            changingValue = event.Value;
            app.p9Knob_7.Value = changingValue;
            app.p9Ktos = changingValue;
            
            new_yktos_set = gen_currents_over_volts(app, @gen_IKtos);
            update_sum_current(app, app.yktos_set, new_yktos_set);
            app.yktos_set = new_yktos_set;
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 6);            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.ProtocolspecificationsTab = uitab(app.TabGroup);
            app.ProtocolspecificationsTab.Title = 'Protocol specifications';
            app.HoldingtimemsEditFieldLabel = uilabel(app.ProtocolspecificationsTab);
            app.HoldingtimemsEditFieldLabel.Text = 'Holding time (ms)';
            app.HoldingtimemsEditField = uieditfield(app.ProtocolspecificationsTab, 'numeric');
            app.HoldingtimemsEditField.Value = 100;
            app.HoldingpotentialmVEditFieldLabel = uilabel(app.ProtocolspecificationsTab);
            app.HoldingpotentialmVEditFieldLabel.Text = 'Holding potential (mV)';
            app.HoldingpotentialmVEditField = uieditfield(app.ProtocolspecificationsTab, 'numeric');
            app.HoldingpotentialmVEditField.Value = -70;
            app.ClampingvoltageminmVEditFieldLabel = uilabel(app.ProtocolspecificationsTab);
            app.ClampingvoltageminmVEditFieldLabel.Text = 'Clamping voltage min (mV)';
            app.ClampingvoltageminmVEditField = uieditfield(app.ProtocolspecificationsTab, 'numeric');
            app.ClampingvoltageminmVEditField.Value = -50;
            app.ClampingtimemsEditFieldLabel = uilabel(app.ProtocolspecificationsTab);
            app.ClampingtimemsEditFieldLabel.Text = 'Clamping time (ms)';
            app.ClampingtimemsEditField = uieditfield(app.ProtocolspecificationsTab, 'numeric');
            app.ClampingtimemsEditField.ValueDisplayFormat = '%.0f';
            app.ClampingtimemsEditField.Value = 4500;
            app.ProtocolVisualizationUpdateButton = uibutton(app.ProtocolspecificationsTab, 'push');
            app.ProtocolVisualizationUpdateButton.ButtonPushedFcn = createCallbackFcn(app, @ProtocolVisualizationUpdateButtonPushed, true);
            app.ProtocolVisualizationUpdateButton.Text = 'Protocol Visualization & Update';
            app.NernstpotentialEkmVLabel = uilabel(app.ProtocolspecificationsTab);
            app.NernstpotentialEkmVLabel.Text = 'Nernst potential Ek (mV)';
            app.NernstpotentialEkmVEditField = uieditfield(app.ProtocolspecificationsTab, 'numeric');
            app.NernstpotentialEkmVEditField.Value = -91.1;
            app.DatauploadButton = uibutton(app.ProtocolspecificationsTab, 'push');
            app.DatauploadButton.ButtonPushedFcn = createCallbackFcn(app, @DatauploadButtonPushed, true);
            app.DatauploadButton.Text = 'Data upload';
            app.ClampingvoltagestepmVEditFieldLabel = uilabel(app.ProtocolspecificationsTab);
            app.ClampingvoltagestepmVEditFieldLabel.Text = 'Clamping voltage step (mV)';
            app.ClampingvoltagestepmVEditField = uieditfield(app.ProtocolspecificationsTab, 'numeric');
            app.ClampingvoltagestepmVEditField.Value = 10;
            app.ClampingvoltagemaxmVEditFieldLabel = uilabel(app.ProtocolspecificationsTab);
            app.ClampingvoltagemaxmVEditFieldLabel.Text = 'Clamping voltage max (mV)';
            app.ClampingvoltagemaxmVEditField = uieditfield(app.ProtocolspecificationsTab, 'numeric');
            app.ClampingvoltagemaxmVEditField.Value = 50;
            app.ModelCalibrationButton = uibutton(app.ProtocolspecificationsTab, 'push');
            app.ModelCalibrationButton.Text = 'Model Calibration';
            app.UIAxesTopLeft = uiaxes(app.ProtocolspecificationsTab);
            title(app.UIAxesTopLeft, 'Protocol')
            xlabel(app.UIAxesTopLeft, 'Time (ms)')
            ylabel(app.UIAxesTopLeft, 'Voltage (mV)')
            zlabel(app.UIAxesTopLeft, 'Z')
            app.UIAxesBottomLeft = uiaxes(app.ProtocolspecificationsTab);
            title(app.UIAxesBottomLeft, 'Experimental Data')
            xlabel(app.UIAxesBottomLeft, 'Time (ms)')
            ylabel(app.UIAxesBottomLeft, 'Current (pA/pF)')
            zlabel(app.UIAxesBottomLeft, 'Z')
            app.IKtofTab = uitab(app.TabGroup);
            app.IKtofTab.Title = 'IKtof';
            app.p1KnobLabel = uilabel(app.IKtofTab);
            app.p1KnobLabel.Text = 'p1';
            app.p1Knob = uiknob(app.IKtofTab, 'continuous');
            app.p1Knob.Limits = [-50 60];
            app.p1Knob.ValueChangingFcn = createCallbackFcn(app, @p1KnobValueChanging, true);
            app.p1Knob.Value = 30;
            app.p2KnobLabel = uilabel(app.IKtofTab);
            app.p2KnobLabel.Text = 'p2';
            app.p2Knob = uiknob(app.IKtofTab, 'continuous');
            app.p2Knob.Limits = [-60 60];
            app.p2Knob.ValueChangingFcn = createCallbackFcn(app, @p2KnobValueChanging, true);
            app.p2Knob.Value = 13.5;
            app.p3KnobLabel = uilabel(app.IKtofTab);
            app.p3KnobLabel.Text = 'p3';
            app.p3Knob = uiknob(app.IKtofTab, 'continuous');
            app.p3Knob.Limits = [-80 60];
            app.p3Knob.ValueChangingFcn = createCallbackFcn(app, @p3KnobValueChanging, true);
            app.p3Knob.Value = 33.5;
            app.p4KnobLabel = uilabel(app.IKtofTab);
            app.p4KnobLabel.Text = 'p4';
            app.p4Knob = uiknob(app.IKtofTab, 'continuous');
            app.p4Knob.Limits = [1 15];
            app.p4Knob.ValueChangingFcn = createCallbackFcn(app, @p4KnobValueChanging, true);
            app.p4Knob.Value = 7;
            app.p5KnobLabel = uilabel(app.IKtofTab);
            app.p5KnobLabel.Text = 'p5';
            app.p5Knob = uiknob(app.IKtofTab, 'continuous');
            app.p5Knob.Limits = [0 1.5];
            app.p5Knob.ValueChangingFcn = createCallbackFcn(app, @p5KnobValueChanging, true);
            app.p5Knob.Value = 0.18064;
            app.p7KnobLabel = uilabel(app.IKtofTab);
            app.p7KnobLabel.Text = 'p7';
            app.p7Knob = uiknob(app.IKtofTab, 'continuous');
            app.p7Knob.Limits = [0 1.5];
            app.p7Knob.ValueChangingFcn = createCallbackFcn(app, @p7KnobValueChanging, true);
            app.p7Knob.Value = 0.3956;
            app.p8KnobLabel = uilabel(app.IKtofTab);
            app.p8KnobLabel.Text = 'p8';
            app.p8Knob = uiknob(app.IKtofTab, 'continuous');
            app.p8Knob.Limits = [0 1];
            app.p8Knob.ValueChangingFcn = createCallbackFcn(app, @p8KnobValueChanging, true);
            app.p8Knob.Value = 0.06237;
            app.p10KnobLabel = uilabel(app.IKtofTab);
            app.p10KnobLabel.Text = 'p10';
            app.p10Knob = uiknob(app.IKtofTab, 'continuous');
            app.p10Knob.Limits = [0 0.15];
            app.p10Knob.ValueChangingFcn = createCallbackFcn(app, @p10KnobValueChanging, true);
            app.p10Knob.Value = 0.067083;
            app.p11KnobLabel = uilabel(app.IKtofTab);
            app.p11KnobLabel.Text = 'p11';
            app.p11Knob = uiknob(app.IKtofTab, 'continuous');
            app.p11Knob.Limits = [0 0.003];
            app.p11Knob.ValueChangingFcn = createCallbackFcn(app, @p11KnobValueChanging, true);
            app.p11Knob.Value = 0.00095;
            app.GKtofKnobLabel = uilabel(app.IKtofTab);
            app.GKtofKnobLabel.Text = 'GKtof';
            app.GKtofKnob = uiknob(app.IKtofTab, 'continuous');
            app.GKtofKnob.Limits = [0 1];
            app.GKtofKnob.ValueChangingFcn = createCallbackFcn(app, @GKtofKnobValueChanging, true);
            app.GKtofKnob.Value = 0.4067;
            app.TextArea = uitextarea(app.IKtofTab);
            app.TextArea.Value = {'IKtof: Transient outward K+ current '};
            app.HighlightShowEquationsButton = uibutton(app.IKtofTab, 'push');
            app.HighlightShowEquationsButton.ButtonPushedFcn = createCallbackFcn(app, @HighlightShowEquationsButtonPushed, true);
            app.HighlightShowEquationsButton.Text = 'Highlight & Show Equations';
            app.ResetButton = uibutton(app.IKtofTab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.p6KnobLabel = uilabel(app.IKtofTab);
            app.p6KnobLabel.Text = 'p6';
            app.p6Knob = uiknob(app.IKtofTab, 'continuous');
            app.p6Knob.Limits = [0 0.5];
            app.p6Knob.ValueChangingFcn = createCallbackFcn(app, @p6KnobValueChanging, true);
            app.p6Knob.Value = 0.03577;
            app.p9KnobLabel = uilabel(app.IKtofTab);
            app.p9KnobLabel.Text = 'p9';
            app.p9Knob = uiknob(app.IKtofTab, 'continuous');
            app.p9Knob.Limits = [0 0.001];
            app.p9Knob.ValueChangingFcn = createCallbackFcn(app, @p9KnobValueChanging, true);
            app.p9Knob.Value = 0.000152;
            app.p12KnobLabel = uilabel(app.IKtofTab);
            app.p12KnobLabel.Text = 'p12';
            app.p12Knob = uiknob(app.IKtofTab, 'continuous');
            app.p12Knob.Limits = [0 0.5];
            app.p12Knob.ValueChangingFcn = createCallbackFcn(app, @p12KnobValueChanging, true);
            app.p12Knob.Value = 0.051335;
            app.IKtosTab = uitab(app.TabGroup);
            app.IKtosTab.Title = 'IKtos';
            app.TextArea_7 = uitextarea(app.IKtosTab);
            app.TextArea_7.Value = {'IKtos: Transient outward K+ current '};
            app.HighlightShowEquationsButton_7 = uibutton(app.IKtosTab, 'push');
            app.HighlightShowEquationsButton_7.ButtonPushedFcn = createCallbackFcn(app, @ShowEquationsButton_7Pushed, true);
            app.HighlightShowEquationsButton_7.Text = 'Highlight & Show Equations';
            app.p1Knob_6Label_2 = uilabel(app.IKtosTab);
            app.p1Knob_6Label_2.Text = 'p1';
            app.p1Knob_7 = uiknob(app.IKtosTab, 'continuous');
            app.p1Knob_7.Limits = [-60 60];
            app.p1Knob_7.ValueChangingFcn = createCallbackFcn(app, @p1Knob_7ValueChanging, true);
            app.p1Knob_7.Value = 22.5;
            app.p2Knob_6Label_2 = uilabel(app.IKtosTab);
            app.p2Knob_6Label_2.Text = 'p2';
            app.p2Knob_7 = uiknob(app.IKtosTab, 'continuous');
            app.p2Knob_7.Limits = [1 20];
            app.p2Knob_7.ValueChangingFcn = createCallbackFcn(app, @p2Knob_7ValueChanging, true);
            app.p2Knob_7.Value = 7.7;
            app.p3Knob_6Label_2 = uilabel(app.IKtosTab);
            app.p3Knob_6Label_2.Text = 'p3';
            app.p3Knob_7 = uiknob(app.IKtosTab, 'continuous');
            app.p3Knob_7.Limits = [-80 100];
            app.p3Knob_7.ValueChangingFcn = createCallbackFcn(app, @p3Knob_7ValueChanging, true);
            app.p3Knob_7.Value = 45.2;
            app.p4Knob_6Label_2 = uilabel(app.IKtosTab);
            app.p4Knob_6Label_2.Text = 'p4';
            app.p4Knob_7 = uiknob(app.IKtosTab, 'continuous');
            app.p4Knob_7.Limits = [1 20];
            app.p4Knob_7.ValueChangingFcn = createCallbackFcn(app, @p4Knob_7ValueChanging, true);
            app.p4Knob_7.Value = 7.7;
            app.p5Knob_6Label_2 = uilabel(app.IKtosTab);
            app.p5Knob_6Label_2.Text = 'p5';
            app.p5Knob_7 = uiknob(app.IKtosTab, 'continuous');
            app.p5Knob_7.Limits = [-5 5];
            app.p5Knob_7.ValueChangingFcn = createCallbackFcn(app, @p5Knob_7ValueChanging, true);
            app.p5Knob_7.Value = 0.493;
            app.p6Knob_5Label = uilabel(app.IKtosTab);
            app.p6Knob_5Label.Text = 'p6';
            app.p6Knob_7 = uiknob(app.IKtosTab, 'continuous');
            app.p6Knob_7.Limits = [-1 1];
            app.p6Knob_7.ValueChangingFcn = createCallbackFcn(app, @p6Knob_7ValueChanging, true);
            app.p6Knob_7.Value = 0.0629;
            app.p7Knob_5Label = uilabel(app.IKtosTab);
            app.p7Knob_5Label.Text = 'p7';
            app.p7Knob_7 = uiknob(app.IKtosTab, 'continuous');
            app.p7Knob_7.Limits = [0 10];
            app.p7Knob_7.ValueChangingFcn = createCallbackFcn(app, @p7Knob_7ValueChanging, true);
            app.p7Knob_7.Value = 2.058;
            app.p8Knob_5Label = uilabel(app.IKtosTab);
            app.p8Knob_5Label.Text = 'p8';
            app.p8Knob_7 = uiknob(app.IKtosTab, 'continuous');
            app.p8Knob_7.Limits = [0 1500];
            app.p8Knob_7.ValueChangingFcn = createCallbackFcn(app, @p8Knob_7ValueChanging, true);
            app.p8Knob_7.Value = 270;
            app.p9Knob_5Label = uilabel(app.IKtosTab);
            app.p9Knob_5Label.Text = 'p9';
            app.p9Knob_7 = uiknob(app.IKtosTab, 'continuous');
            app.p9Knob_7.Limits = [0 5000];
            app.p9Knob_7.ValueChangingFcn = createCallbackFcn(app, @p9Knob_7ValueChanging, true);
            app.p9Knob_7.Value = 1050;
            app.GKtosKnobLabel = uilabel(app.IKtosTab);
            app.GKtosKnobLabel.Text = 'GKtos';
            app.GKtosKnob = uiknob(app.IKtosTab, 'continuous');
            app.GKtosKnob.Limits = [0 1];
            app.GKtosKnob.ValueChangingFcn = createCallbackFcn(app, @GKtosKnobValueChanging, true);
            app.GKtosKnob.Value = 0.0629;
            app.ResetButton_7 = uibutton(app.IKtosTab, 'push');
            app.ResetButton_7.ButtonPushedFcn = createCallbackFcn(app, @ResetButton_7Pushed, true);
            app.ResetButton_7.Text = 'Reset';
            app.IK1Tab = uitab(app.TabGroup);
            app.IK1Tab.Title = 'IK1';
            app.Image3 = uiimage(app.IK1Tab);
            app.Image3.ImageSource = 'iki.PNG';
            app.p1Knob_6Label = uilabel(app.IK1Tab);
            app.p1Knob_6Label.Text = 'p1';
            app.p1Knob_2 = uiknob(app.IK1Tab, 'continuous');
            app.p1Knob_2.Limits = [0 0.5];
            app.p1Knob_2.ValueChangingFcn = createCallbackFcn(app, @p1Knob_2ValueChanging, true);
            app.p1Knob_2.Value = 0.0896;
            app.p2Knob_6Label = uilabel(app.IK1Tab);
            app.p2Knob_6Label.Text = 'p2';
            app.p2Knob_2 = uiknob(app.IK1Tab, 'continuous');
            app.p2Knob_2.Limits = [-200 600];
            app.p2Knob_2.ValueChangingFcn = createCallbackFcn(app, @p2Knob_2ValueChanging, true);
            app.p2Knob_2.Value = 210;
            app.p3Knob_6Label = uilabel(app.IK1Tab);
            app.p3Knob_6Label.Text = 'p3';
            app.p3Knob_2 = uiknob(app.IK1Tab, 'continuous');
            app.p3Knob_2.Limits = [0 1];
            app.p3Knob_2.ValueChangingFcn = createCallbackFcn(app, @p3Knob_2ValueChanging, true);
            app.p3Knob_2.Value = 0.2938;
            app.KoLabel = uilabel(app.IK1Tab);
            app.KoLabel.Text = '[K+]o';
            app.KoKnob = uiknob(app.IK1Tab, 'continuous');
            app.KoKnob.Limits = [0 10000];
            app.KoKnob.ValueChangingFcn = createCallbackFcn(app, @KoKnobValueChanging, true);
            app.KoKnob.Value = 5400;
            app.p1EditField_2 = uieditfield(app.IK1Tab, 'numeric');
            app.p1EditField_2.ValueDisplayFormat = '%11.3g';
            app.p1EditField_2.ValueChangedFcn = createCallbackFcn(app, @p1Knob_2ValueChanging, true);
            app.p1EditField_2.Value = 0.0896;
            app.p2EditField_2 = uieditfield(app.IK1Tab, 'numeric');
            app.p2EditField_2.ValueChangedFcn = createCallbackFcn(app, @p2Knob_2ValueChanging, true);
            app.p2EditField_2.Value = 210;
            app.p3EditField_2 = uieditfield(app.IK1Tab, 'numeric');
            app.p3EditField_2.ValueChangedFcn = createCallbackFcn(app, @p3Knob_2ValueChanging, true);
            app.p3EditField_2.Value = 0.2938;
            app.KoEditField = uieditfield(app.IK1Tab, 'numeric');
            app.KoEditField.ValueChangedFcn = createCallbackFcn(app, @KoKnobValueChanging, true);
            app.KoEditField.Value = 5400;
            app.Koc1EditField = uieditfield(app.IK1Tab, 'numeric');
            app.Koc1EditField.ValueChangedFcn = createCallbackFcn(app, @KoKnobValueChanging, true);
            app.Koc1EditField.Value = 5400;
            app.ResetButton_2 = uibutton(app.IK1Tab, 'push');
            app.ResetButton_2.ButtonPushedFcn = createCallbackFcn(app, @ResetButton_2Pushed, true);
            app.ResetButton_2.Text = 'Reset';
            app.HightlightButton_2 = uibutton(app.IK1Tab, 'push');
            app.HightlightButton_2.ButtonPushedFcn = createCallbackFcn(app, @HightlightButton_2Pushed, true);
            app.HightlightButton_2.Text = 'Hightlight';
            app.IKsTab = uitab(app.TabGroup);
            app.IKsTab.Title = 'IKs';
            app.Image2 = uiimage(app.IKsTab);
            app.Image2.ImageSource = 'iks.PNG';
            app.p1Knob_7Label = uilabel(app.IKsTab);
            app.p1Knob_7Label.Text = 'p1';
            app.p1Knob_3 = uiknob(app.IKsTab, 'continuous');
            app.p1Knob_3.Limits = [-50 100];
            app.p1Knob_3.ValueChangingFcn = createCallbackFcn(app, @p1Knob_3ValueChanging, true);
            app.p1Knob_3.Value = 26.5;
            app.p2Knob_7Label = uilabel(app.IKsTab);
            app.p2Knob_7Label.Text = 'p2';
            app.p2Knob_3 = uiknob(app.IKsTab, 'continuous');
            app.p2Knob_3.Limits = [0 0.5];
            app.p2Knob_3.ValueChangingFcn = createCallbackFcn(app, @p2Knob_3ValueChanging, true);
            app.p2Knob_3.Value = 0.128;
            app.p3Knob_7Label = uilabel(app.IKsTab);
            app.p3Knob_7Label.Text = 'p3';
            app.p3Knob_3 = uiknob(app.IKsTab, 'continuous');
            app.p3Knob_3.Limits = [0 0.5];
            app.p3Knob_3.ValueChangingFcn = createCallbackFcn(app, @p3Knob_3ValueChanging, true);
            app.p3Knob_3.Value = 0.038;
            app.p4Knob_6Label = uilabel(app.IKsTab);
            app.p4Knob_6Label.Text = 'p4';
            app.p4Knob_3 = uiknob(app.IKsTab, 'continuous');
            app.p4Knob_3.Limits = [0 1e-05];
            app.p4Knob_3.ValueChangingFcn = createCallbackFcn(app, @p4Knob_3ValueChanging, true);
            app.p4Knob_3.Value = 4.81333e-06;
            app.p5Knob_6Label = uilabel(app.IKsTab);
            app.p5Knob_6Label.Text = 'p5';
            app.p5Knob_3 = uiknob(app.IKsTab, 'continuous');
            app.p5Knob_3.Limits = [0 0.0005];
            app.p5Knob_3.ValueChangingFcn = createCallbackFcn(app, @p5Knob_3ValueChanging, true);
            app.p5Knob_3.Value = 9.53333e-05;
            app.GKsKnobLabel = uilabel(app.IKsTab);
            app.GKsKnobLabel.Text = 'GKs';
            app.GKsKnob = uiknob(app.IKsTab, 'continuous');
            app.GKsKnob.Limits = [0 0.015];
            app.GKsKnob.ValueChangingFcn = createCallbackFcn(app, @GKsKnobValueChanging, true);
            app.GKsKnob.Value = 0.00575;
            app.GKsEditField = uieditfield(app.IKsTab, 'numeric');
            app.GKsEditField.ValueChangedFcn = createCallbackFcn(app, @GKsKnobValueChanging, true);
            app.GKsEditField.Value = 0.00575;
            app.p1EditField_3 = uieditfield(app.IKsTab, 'numeric');
            app.p1EditField_3.ValueChangedFcn = createCallbackFcn(app, @p1Knob_3ValueChanging, true);
            app.p1EditField_3.Value = 26.5;
            app.p2EditField_3 = uieditfield(app.IKsTab, 'numeric');
            app.p2EditField_3.ValueChangedFcn = createCallbackFcn(app, @p2Knob_3ValueChanging, true);
            app.p2EditField_3.Value = 0.128;
            app.p3EditField_3 = uieditfield(app.IKsTab, 'numeric');
            app.p3EditField_3.ValueChangedFcn = createCallbackFcn(app, @p3Knob_3ValueChanging, true);
            app.p3EditField_3.Value = 0.038;
            app.p4EditField_3 = uieditfield(app.IKsTab, 'numeric');
            app.p4EditField_3.ValueDisplayFormat = '%.11f';
            app.p4EditField_3.ValueChangedFcn = createCallbackFcn(app, @p4Knob_3ValueChanging, true);
            app.p4EditField_3.Value = 4.81333e-06;
            app.p5EditField_3 = uieditfield(app.IKsTab, 'numeric');
            app.p5EditField_3.ValueDisplayFormat = '%.10f';
            app.p5EditField_3.ValueChangedFcn = createCallbackFcn(app, @p5Knob_3ValueChanging, true);
            app.p5EditField_3.Value = 9.53333e-05;
            app.p1c1EditField_3 = uieditfield(app.IKsTab, 'numeric');
            app.p1c1EditField_3.ValueChangedFcn = createCallbackFcn(app, @p1Knob_3ValueChanging, true);
            app.p1c1EditField_3.Value = 26.5;
            app.p1c2EditField_3 = uieditfield(app.IKsTab, 'numeric');
            app.p1c2EditField_3.ValueChangedFcn = createCallbackFcn(app, @p1Knob_3ValueChanging, true);
            app.p1c2EditField_3.Value = 26.5;
            app.ResetButton_3 = uibutton(app.IKsTab, 'push');
            app.ResetButton_3.ButtonPushedFcn = createCallbackFcn(app, @ResetButton_3Pushed, true);
            app.ResetButton_3.Text = 'Reset';
            app.HightlightButton_3 = uibutton(app.IKsTab, 'push');
            app.HightlightButton_3.ButtonPushedFcn = createCallbackFcn(app, @HightlightButton_3Pushed, true);
            app.HightlightButton_3.Text = 'Hightlight';
            app.IKurTab = uitab(app.TabGroup);
            app.IKurTab.Title = 'IKur';
            app.TextArea_4 = uitextarea(app.IKurTab);
            app.TextArea_4.Value = {'IKur: Ultrarapidly activating delayed rectifier K+ current'};
            app.HighlightShowEquationsButton_4 = uibutton(app.IKurTab, 'push');
            app.HighlightShowEquationsButton_4.ButtonPushedFcn = createCallbackFcn(app, @HighlightShowEquationsButton_4Pushed, true);
            app.HighlightShowEquationsButton_4.Text = 'Highlight & Show Equations';
            app.p1Knob_2Label = uilabel(app.IKurTab);
            app.p1Knob_2Label.Text = 'p1';
            app.p1Knob_4 = uiknob(app.IKurTab, 'continuous');
            app.p1Knob_4.Limits = [-60 60];
            app.p1Knob_4.ValueChangingFcn = createCallbackFcn(app, @p1Knob_4ValueChanging, true);
            app.p1Knob_4.Value = 22.5;
            app.p2Knob_2Label = uilabel(app.IKurTab);
            app.p2Knob_2Label.Text = 'p2';
            app.p2Knob_4 = uiknob(app.IKurTab, 'continuous');
            app.p2Knob_4.Limits = [1 20];
            app.p2Knob_4.ValueChangingFcn = createCallbackFcn(app, @p2Knob_4ValueChanging, true);
            app.p2Knob_4.Value = 7.7;
            app.p3Knob_2Label = uilabel(app.IKurTab);
            app.p3Knob_2Label.Text = 'p3';
            app.p3Knob_4 = uiknob(app.IKurTab, 'continuous');
            app.p3Knob_4.Limits = [-80 100];
            app.p3Knob_4.ValueChangingFcn = createCallbackFcn(app, @p3Knob_4ValueChanging, true);
            app.p3Knob_4.Value = 45.2;
            app.p4Knob_2Label = uilabel(app.IKurTab);
            app.p4Knob_2Label.Text = 'p4';
            app.p4Knob_4 = uiknob(app.IKurTab, 'continuous');
            app.p4Knob_4.Limits = [1 20];
            app.p4Knob_4.ValueChangingFcn = createCallbackFcn(app, @p4Knob_4ValueChanging, true);
            app.p4Knob_4.Value = 5.7;
            app.p5Knob_2Label = uilabel(app.IKurTab);
            app.p5Knob_2Label.Text = 'p5';
            app.p5Knob_4 = uiknob(app.IKurTab, 'continuous');
            app.p5Knob_4.Limits = [-5 5];
            app.p5Knob_4.ValueChangingFcn = createCallbackFcn(app, @p5Knob_4ValueChanging, true);
            app.p5Knob_4.Value = 0.493;
            app.p6Knob_2Label = uilabel(app.IKurTab);
            app.p6Knob_2Label.Text = 'p6';
            app.p6Knob_4 = uiknob(app.IKurTab, 'continuous');
            app.p6Knob_4.Limits = [-1 1];
            app.p6Knob_4.ValueChangingFcn = createCallbackFcn(app, @p6Knob_4ValueChanging, true);
            app.p6Knob_4.Value = 0.0629;
            app.p7Knob_2Label = uilabel(app.IKurTab);
            app.p7Knob_2Label.Text = 'p7';
            app.p7Knob_4 = uiknob(app.IKurTab, 'continuous');
            app.p7Knob_4.Limits = [0 10];
            app.p7Knob_4.ValueChangingFcn = createCallbackFcn(app, @p7Knob_4ValueChanging, true);
            app.p7Knob_4.Value = 2.058;
            app.p8Knob_2Label = uilabel(app.IKurTab);
            app.p8Knob_2Label.Text = 'p8';
            app.p8Knob_4 = uiknob(app.IKurTab, 'continuous');
            app.p8Knob_4.Limits = [0 8000];
            app.p8Knob_4.ValueChangingFcn = createCallbackFcn(app, @p8Knob_4ValueChanging, true);
            app.p8Knob_4.Value = 1200;
            app.p9Knob_2Label = uilabel(app.IKurTab);
            app.p9Knob_2Label.Text = 'p9';
            app.p9Knob_4 = uiknob(app.IKurTab, 'continuous');
            app.p9Knob_4.Limits = [0 500];
            app.p9Knob_4.ValueChangingFcn = createCallbackFcn(app, @p9Knob_4ValueChanging, true);
            app.p9Knob_4.Value = 170;
            app.GKurKnobLabel = uilabel(app.IKurTab);
            app.GKurKnobLabel.Text = 'GKur';
            app.GKurKnob = uiknob(app.IKurTab, 'continuous');
            app.GKurKnob.Limits = [0 1];
            app.GKurKnob.ValueChangingFcn = createCallbackFcn(app, @GKurKnobValueChanging, true);
            app.GKurKnob.Value = 0.16;
            app.ResetButton_4 = uibutton(app.IKurTab, 'push');
            app.ResetButton_4.ButtonPushedFcn = createCallbackFcn(app, @ResetButton_4Pushed, true);
            app.ResetButton_4.Text = 'Reset';
            app.IKssTab = uitab(app.TabGroup);
            app.IKssTab.Title = 'IKss';
            app.Image = uiimage(app.IKssTab);
            app.Image.ImageSource = 'ikss.PNG';
            app.GKssEditField = uieditfield(app.IKssTab, 'numeric');
            app.GKssEditField.ValueChangedFcn = createCallbackFcn(app, @GKssKnobValueChanging, true);
            app.GKssEditField.Value = 0.05;
            app.GKssKnobLabel = uilabel(app.IKssTab);
            app.GKssKnobLabel.Text = 'GKss';
            app.GKssKnob = uiknob(app.IKssTab, 'continuous');
            app.GKssKnob.Limits = [0 0.5];
            app.GKssKnob.ValueChangingFcn = createCallbackFcn(app, @GKssKnobValueChanging, true);
            app.GKssKnob.Value = 0.05;
            app.p1Knob_5Label = uilabel(app.IKssTab);
            app.p1Knob_5Label.Text = 'p1';
            app.p1Knob_5 = uiknob(app.IKssTab, 'continuous');
            app.p1Knob_5.Limits = [-60 60];
            app.p1Knob_5.ValueChangingFcn = createCallbackFcn(app, @p1Knob_5ValueChanging, true);
            app.p1Knob_5.Value = 22.5;
            app.p2Knob_5Label = uilabel(app.IKssTab);
            app.p2Knob_5Label.Text = 'p2';
            app.p2Knob_5 = uiknob(app.IKssTab, 'continuous');
            app.p2Knob_5.Limits = [0 20];
            app.p2Knob_5.ValueChangingFcn = createCallbackFcn(app, @p2Knob_5ValueChanging, true);
            app.p2Knob_5.Value = 7.7;
            app.p3Knob_5Label = uilabel(app.IKssTab);
            app.p3Knob_5Label.Text = 'p3';
            app.p3Knob_5 = uiknob(app.IKssTab, 'continuous');
            app.p3Knob_5.Limits = [0 90];
            app.p3Knob_5.ValueChangingFcn = createCallbackFcn(app, @p3Knob_5ValueChanging, true);
            app.p3Knob_5.Value = 39.3;
            app.p4Knob_5Label = uilabel(app.IKssTab);
            app.p4Knob_5Label.Text = 'p4';
            app.p4Knob_5 = uiknob(app.IKssTab, 'continuous');
            app.p4Knob_5.Limits = [0 0.5];
            app.p4Knob_5.ValueChangingFcn = createCallbackFcn(app, @p4Knob_5ValueChanging, true);
            app.p4Knob_5.Value = 0.0862;
            app.p5Knob_5Label = uilabel(app.IKssTab);
            app.p5Knob_5Label.Text = 'p5';
            app.p5Knob_5 = uiknob(app.IKssTab, 'continuous');
            app.p5Knob_5.Limits = [-30 60];
            app.p5Knob_5.ValueChangingFcn = createCallbackFcn(app, @p5Knob_5ValueChanging, true);
            app.p5Knob_5.Value = 13.17;
            app.p1EditField_5 = uieditfield(app.IKssTab, 'numeric');
            app.p1EditField_5.ValueChangedFcn = createCallbackFcn(app, @p1Knob_5ValueChanging, true);
            app.p1EditField_5.Value = 22.5;
            app.p2EditField_5 = uieditfield(app.IKssTab, 'numeric');
            app.p2EditField_5.ValueChangedFcn = createCallbackFcn(app, @p2Knob_5ValueChanging, true);
            app.p2EditField_5.Value = 7.7;
            app.p3EditField_5 = uieditfield(app.IKssTab, 'numeric');
            app.p3EditField_5.ValueChangedFcn = createCallbackFcn(app, @p3Knob_5ValueChanging, true);
            app.p3EditField_5.Value = 39.3;
            app.p4EditField_5 = uieditfield(app.IKssTab, 'numeric');
            app.p4EditField_5.ValueChangedFcn = createCallbackFcn(app, @p4Knob_5ValueChanging, true);
            app.p4EditField_5.Value = 0.0862;
            app.p5EditField_5 = uieditfield(app.IKssTab, 'numeric');
            app.p5EditField_5.ValueChangedFcn = createCallbackFcn(app, @p5Knob_5ValueChanging, true);
            app.p5EditField_5.Value = 13.17;
            app.ResetButton_5 = uibutton(app.IKssTab, 'push');
            app.ResetButton_5.ButtonPushedFcn = createCallbackFcn(app, @ResetButton_5Pushed, true);
            app.ResetButton_5.Text = 'Reset';
            app.HightlightButton_5 = uibutton(app.IKssTab, 'push');
            app.HightlightButton_5.ButtonPushedFcn = createCallbackFcn(app, @HightlightButton_5Pushed, true);
            app.HightlightButton_5.Text = 'Hightlight';
            app.RightPanel = uipanel(app.GridLayout);
            app.SelectVoltageDropDownLabel = uilabel(app.RightPanel);
            app.SelectVoltageDropDownLabel.Text = 'Select Voltage';
            app.SelectVoltageDropDown = uidropdown(app.RightPanel);
            app.SelectVoltageDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectVoltageDropDownValueChanged, true);
            app.UIAxesMiddleRight = uiaxes(app.RightPanel);
            title(app.UIAxesMiddleRight, 'Individual K^{+} Currents of Selected Voltage')
            xlabel(app.UIAxesMiddleRight, 'Time (ms)')
            ylabel(app.UIAxesMiddleRight, 'Current (pA/pF)')
            zlabel(app.UIAxesMiddleRight, 'Z')
            app.UIAxesTopRight = uiaxes(app.RightPanel);
            title(app.UIAxesTopRight, 'Sum of K^{+} Currents')
            xlabel(app.UIAxesTopRight, 'Time (ms)')
            ylabel(app.UIAxesTopRight, 'Current (pA/pF)')
            zlabel(app.UIAxesTopRight, 'Z')
            app.UIAxesBottomRight = uiaxes(app.RightPanel);
            title(app.UIAxesBottomRight, 'Sum of K^{+} Current of Selected Voltage')
            xlabel(app.UIAxesBottomRight, 'Time (ms)')
            ylabel(app.UIAxesBottomRight, 'Current (pA/pF)')
            zlabel(app.UIAxesBottomRight, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Kv

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end