classdef IKslow1_dialog < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        p13EditField    matlab.ui.control.NumericEditField
        p12EditField    matlab.ui.control.NumericEditField
        p11_2EditField  matlab.ui.control.NumericEditField
        p11_1EditField  matlab.ui.control.NumericEditField
        p10EditField    matlab.ui.control.NumericEditField
        p9EditField     matlab.ui.control.NumericEditField
        p8EditField     matlab.ui.control.NumericEditField
        p7_2EditField   matlab.ui.control.NumericEditField
        p7_1EditField   matlab.ui.control.NumericEditField
        p6EditField     matlab.ui.control.NumericEditField
        p5_2EditField   matlab.ui.control.NumericEditField
        p5_1EditField   matlab.ui.control.NumericEditField
        p4EditField     matlab.ui.control.NumericEditField
        p3_2EditField   matlab.ui.control.NumericEditField
        p3_1EditField   matlab.ui.control.NumericEditField
        p2_2EditField   matlab.ui.control.NumericEditField
        p2_1EditField   matlab.ui.control.NumericEditField
        p1EditField     matlab.ui.control.NumericEditField
        Image           matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.p1EditField.Value = mainapp.p1Knob_2.Value;
            app.p2_1EditField.Value = mainapp.p2Knob_2.Value;
            app.p2_2EditField.Value = mainapp.p2Knob_2.Value;
            app.p3_1EditField.Value = mainapp.p3Knob_2.Value;
            app.p3_2EditField.Value = mainapp.p3Knob_2.Value;
            app.p4EditField.Value = mainapp.p4Knob_2.Value;
            app.p5_1EditField.Value = mainapp.p5Knob_2.Value;
            app.p5_2EditField.Value = mainapp.p5Knob_2.Value;
            app.p6EditField.Value = mainapp.p6Knob_2.Value;
            app.p7_1EditField.Value = mainapp.p7Knob_2.Value;
            app.p7_2EditField.Value = mainapp.p7Knob_2.Value;
            app.p8EditField.Value = mainapp.p8Knob_2.Value;
            app.p9EditField.Value = mainapp.p9Knob_2.Value;
            app.p10EditField.Value = mainapp.p10Knob_2.Value;
            app.p11_1EditField.Value = mainapp.fKslow1Knob.Value;
            app.p11_2EditField.Value = mainapp.fKslow1Knob.Value;
            app.p12EditField.Value = mainapp.GKslow1Knob.Value;
            app.p13EditField.Value = mainapp.GKslow1pKnob.Value;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'ikslow1.png';
            app.p1EditField = uieditfield(app.UIFigure, 'numeric');
            app.p1EditField.Limits = [-70 50];
            app.p1EditField.Value = 22.5;
            app.p2_1EditField = uieditfield(app.UIFigure, 'numeric');
            app.p2_1EditField.Limits = [-70 50];
            app.p2_1EditField.Value = 45.2;
            app.p2_2EditField = uieditfield(app.UIFigure, 'numeric');
            app.p2_2EditField.Limits = [-70 50];
            app.p2_2EditField.Value = 45.2;
            app.p3_1EditField = uieditfield(app.UIFigure, 'numeric');
            app.p3_1EditField.Limits = [-70 50];
            app.p3_1EditField.Value = 40;
            app.p3_2EditField = uieditfield(app.UIFigure, 'numeric');
            app.p3_2EditField.Limits = [-70 50];
            app.p3_2EditField.Value = 40;
            app.p4EditField = uieditfield(app.UIFigure, 'numeric');
            app.p4EditField.Limits = [2.22044604925031e-16 10];
            app.p4EditField.Value = 7.7;
            app.p5_1EditField = uieditfield(app.UIFigure, 'numeric');
            app.p5_1EditField.Limits = [2.2204e-16 50];
            app.p5_1EditField.Value = 5.7;
            app.p5_2EditField = uieditfield(app.UIFigure, 'numeric');
            app.p5_2EditField.Limits = [2.2204e-16 50];
            app.p5_2EditField.Value = 5.7;
            app.p6EditField = uieditfield(app.UIFigure, 'numeric');
            app.p6EditField.Limits = [2.2204e-16 50];
            app.p6EditField.Value = 6.1;
            app.p7_1EditField = uieditfield(app.UIFigure, 'numeric');
            app.p7_1EditField.Limits = [2.2204e-16 1];
            app.p7_1EditField.Value = 0.0629;
            app.p7_2EditField = uieditfield(app.UIFigure, 'numeric');
            app.p7_2EditField.Limits = [2.2204e-16 1];
            app.p7_2EditField.Value = 0.0629;
            app.p8EditField = uieditfield(app.UIFigure, 'numeric');
            app.p8EditField.Limits = [2.2204e-16 100];
            app.p8EditField.Value = 2.058;
            app.p9EditField = uieditfield(app.UIFigure, 'numeric');
            app.p9EditField.Limits = [2.2204e-16 1000];
            app.p9EditField.Value = 803;
            app.p10EditField = uieditfield(app.UIFigure, 'numeric');
            app.p10EditField.Limits = [2.2204e-16 50];
            app.p10EditField.Value = 18;
            app.p11_1EditField = uieditfield(app.UIFigure, 'numeric');
            app.p11_1EditField.Limits = [2.2204e-16 1];
            app.p11_1EditField.Value = 0.9214774521;
            app.p11_2EditField = uieditfield(app.UIFigure, 'numeric');
            app.p11_2EditField.Limits = [2.2204e-16 1];
            app.p11_2EditField.Value = 0.9214774521;
            app.p12EditField = uieditfield(app.UIFigure, 'numeric');
            app.p12EditField.Limits = [2.2204e-16 0.5];
            app.p12EditField.Value = 0.05766;
            app.p13EditField = uieditfield(app.UIFigure, 'numeric');
            app.p13EditField.Limits = [2.2204e-16 0.5];
            app.p13EditField.Value = 0.07496;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = IKslow1_dialog(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end