classdef ikto_dialog < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        Image1             matlab.ui.control.Image
        GKtoEditField      matlab.ui.control.NumericEditField
        EnterButton        matlab.ui.control.Button
        Image2             matlab.ui.control.Image
        p1EditField_1      matlab.ui.control.NumericEditField
        p1EditField_2      matlab.ui.control.NumericEditField
        GKtopEditField     matlab.ui.control.NumericEditField
        fKto_1             matlab.ui.control.NumericEditField
        fKto_2             matlab.ui.control.NumericEditField
        p2EditField        matlab.ui.control.NumericEditField
        p2p3EditField_1    matlab.ui.control.NumericEditField
        p2p3EditField_2    matlab.ui.control.NumericEditField
        p2p3EditField_3    matlab.ui.control.NumericEditField
        p1p4EditField_1    matlab.ui.control.NumericEditField
        p1p4EditField_2    matlab.ui.control.NumericEditField
        p2p5EditField      matlab.ui.control.NumericEditField
        p6EditField_1      matlab.ui.control.NumericEditField
        p6EditField_2      matlab.ui.control.NumericEditField
        p6EditField_3      matlab.ui.control.NumericEditField
        p6EditField_4      matlab.ui.control.NumericEditField
        p6EditField_5      matlab.ui.control.NumericEditField
        p6EditField_6      matlab.ui.control.NumericEditField
        p6EditField_7      matlab.ui.control.NumericEditField
        p6EditField_8      matlab.ui.control.NumericEditField
        p7EditField_1      matlab.ui.control.NumericEditField
        p7EditField_2      matlab.ui.control.NumericEditField
        p8EditField_1      matlab.ui.control.NumericEditField
        p8EditField_2      matlab.ui.control.NumericEditField
        p9EditField_1      matlab.ui.control.NumericEditField
        p9EditField_2      matlab.ui.control.NumericEditField
        p10EditField_2     matlab.ui.control.NumericEditField
        p10EditField_1     matlab.ui.control.NumericEditField
        p11EditField_1     matlab.ui.control.NumericEditField
        p11EditField_2     matlab.ui.control.NumericEditField
        p12EditField_1     matlab.ui.control.NumericEditField
        p12EditField_2     matlab.ui.control.NumericEditField
        p13EditField_1     matlab.ui.control.NumericEditField
        p13EditField_2     matlab.ui.control.NumericEditField
        p14EditField_1     matlab.ui.control.NumericEditField
        p14EditField_2     matlab.ui.control.NumericEditField
        p2p3p5EditField_1  matlab.ui.control.NumericEditField
        p2p3p5EditField_2  matlab.ui.control.NumericEditField
        p2p3p5EditField_3  matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        KvApp % main app
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.KvApp = mainapp;
            
            app.p1EditField_1.Value = mainapp.p1Knob_1.Value;
            app.p2EditField.Value = mainapp.p2Knob_1.Value;
            app.p2p3EditField_1.Value = mainapp.p2Knob_1.Value + mainapp.p2Knob_1.Value;
            app.p1p4EditField_1.Value = mainapp.p1Knob_1.Value - mainapp.p4Knob_1.Value;
            app.p2p5EditField.Value = mainapp.p2Knob_1.Value - mainapp.p5Knob_1.Value;
            app.p6EditField_1.Value = mainapp.p6Knob_1.Value;
            app.p7EditField_1.Value = mainapp.p7Knob_1.Value;
            app.p8EditField_1.Value = mainapp.p8Knob_1.Value;
            app.p9EditField_1.Value = mainapp.p9Knob_1.Value;
            app.p10EditField_1.Value = mainapp.p10Knob_1.Value;
            app.p11EditField_1.Value = mainapp.p11Knob_1.Value;
            app.p12EditField_1.Value = mainapp.p12Knob_1.Value;
            app.p13EditField_1.Value = mainapp.p13Knob_1.Value;
            app.p14EditField_1.Value = mainapp.p14Knob_1.Value;
            app.p2p3p5EditField_1.Value = mainapp.p2Knob_1.Value + mainapp.p3Knob_1.Value - mainapp.p5Knob_1.Value;
            
            app.fKto_1.Value = mainapp.fKtoKnob.Value;
            app.GKtoEditField.Value = mainapp.GKtoKnob.Value;
            app.GKtopEditField.Value = mainapp.GKtopKnob.Value;
            
            % syncronized numeric boxed
            app.p1EditField_2.Value = mainapp.p1Knob_1.Value;
            app.p2p3EditField_2.Value = mainapp.p2Knob_1.Value + mainapp.p2Knob_1.Value;
            app.p2p3EditField_3.Value = mainapp.p2Knob_1.Value + mainapp.p2Knob_1.Value;
            app.p1p4EditField_2.Value = mainapp.p1Knob_1.Value - mainapp.p4Knob_1.Value;
            app.p6EditField_2.Value = mainapp.p6Knob_1.Value;
            app.p6EditField_3.Value = mainapp.p6Knob_1.Value;
            app.p6EditField_4.Value = mainapp.p6Knob_1.Value;
            app.p6EditField_5.Value = mainapp.p6Knob_1.Value;
            app.p6EditField_6.Value = mainapp.p6Knob_1.Value;
            app.p6EditField_7.Value = mainapp.p6Knob_1.Value;
            app.p6EditField_8.Value = mainapp.p6Knob_1.Value;
            app.p7EditField_2.Value = mainapp.p7Knob_1.Value;
            app.p8EditField_2.Value = mainapp.p8Knob_1.Value;
            app.p9EditField_2.Value = mainapp.p9Knob_1.Value;
            app.p10EditField_2.Value = mainapp.p10Knob_1.Value;
            app.p11EditField_2.Value = mainapp.p11Knob_1.Value;
            app.p12EditField_2.Value = mainapp.p12Knob_1.Value;
            app.p13EditField_2.Value = mainapp.p13Knob_1.Value;
            app.p14EditField_2.Value = mainapp.p14Knob_1.Value;
            app.p2p3p5EditField_2.Value = mainapp.p2Knob_1.Value + mainapp.p3Knob_1.Value - mainapp.p5Knob_1.Value;
            app.p2p3EditField_3.Value = mainapp.p2Knob_1.Value + mainapp.p3Knob_1.Value - mainapp.p5Knob_1.Value;
            app.fKto_2.Value = mainapp.fKtoKnob.Value;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % app.KvApp.HighlightShowEquationsButton.Enable = "on";
            delete(app)
        end

        % Button pushed function: EnterButton
        function EnterButtonPushed(app, event)
            param = zeros(17, 1);
            param(1) = app.p1EditField_1.Value;
            param(2) = app.p2EditField.Value;
            param(3) = app.p2p3EditField_1.Value - app.p2EditField.Value; % need test that p2p3 >= p2?
            param(4) = app.p1EditField_1.Value - app.p1p4EditField_1.Value; % also need test?
            param(5) = app.p2EditField.Value - app.p2p5EditField.Value;
            param(6) = app.p6EditField_1.Value;
            param(7) = app.p7EditField_1.Value;
            param(8) = app.p8EditField_1.Value;
            param(9) = app.p9EditField_1.Value;
            param(10) = app.p10EditField_1.Value;
            param(11) = app.p11EditField_1.Value;
            param(12) = app.p12EditField_1.Value;
            param(13) = app.p13EditField_1.Value;
            param(14) = app.p14EditField_1.Value;
            param(15) = app.fKto_1.Value;
            param(16) = app.GKtoEditField.Value;
            param(17) = app.GKtopEditField.Value;
            
            update_kto_dialog(app.KvApp, param);            
            delete(app)
        end

        % Value changed function: p1EditField_1
        function p1EditField_1ValueChanged(app, event)
            value = app.p1EditField_1.Value;
            app.p1EditField_2.Value = value;
        end

        % Value changed function: p1EditField_2
        function p1EditField_2ValueChanged(app, event)
            value = app.p1EditField_2.Value;
            app.p1EditField_1.Value = value;
        end

        % Value changed function: fKto_1
        function fKto_1ValueChanged(app, event)
            value = app.fKto_1.Value;
            app.fKto_2.Value = value;
        end

        % Value changed function: fKto_2
        function fKto_2ValueChanged(app, event)
            value = app.fKto_2.Value;
            app.fKto_1.Value = value;
        end

        % Value changed function: p2p3EditField_1
        function p2p3EditField_1ValueChanged(app, event)
            value = app.p2p3EditField_1.Value;
            app.p2p3EditField_2.Value = value;
            app.p2p3EditField_3.Value = value;
        end

        % Value changed function: p2p3EditField_2
        function p2p3EditField_2ValueChanged(app, event)
            value = app.p2p3EditField_2.Value;
            app.p2p3EditField_1.Value = value;
            app.p2p3EditField_3.Value = value;
        end

        % Value changed function: p2p3EditField_3
        function p2p3EditField_3ValueChanged(app, event)
            value = app.p2p3EditField_3.Value;
            app.p2p3EditField_1.Value = value;
            app.p2p3EditField_2.Value = value;
        end

        % Value changed function: p1p4EditField_1
        function p1p4EditField_1ValueChanged(app, event)
            value = app.p1p4EditField_1.Value;
            app.p1p4EditField_2.Value = value;
        end

        % Value changed function: p1p4EditField_2
        function p1p4EditField_2ValueChanged(app, event)
            value = app.p1p4EditField_2.Value;
            app.p1p4EditField_1.Value = value;
        end

        % Value changed function: p6EditField_1
        function p6EditField_1ValueChanged(app, event)
            value = app.p6EditField_1.Value;
            app.p6EditField_2.Value = value;
            app.p6EditField_3.Value = value;
            app.p6EditField_4.Value = value;
            app.p6EditField_5.Value = value;
            app.p6EditField_6.Value = value;
            app.p6EditField_7.Value = value;
            app.p6EditField_8.Value = value;
        end

        % Value changed function: p6EditField_2
        function p6EditField_2ValueChanged(app, event)
            value = app.p6EditField_2.Value;
            app.p6EditField_1.Value = value;
            app.p6EditField_3.Value = value;
            app.p6EditField_4.Value = value;
            app.p6EditField_5.Value = value;
            app.p6EditField_6.Value = value;
            app.p6EditField_7.Value = value;
            app.p6EditField_8.Value = value;            
        end

        % Value changed function: p6EditField_3
        function p6EditField_3ValueChanged(app, event)
            value = app.p6EditField_3.Value;
            app.p6EditField_1.Value = value;
            app.p6EditField_2.Value = value;
            app.p6EditField_4.Value = value;
            app.p6EditField_5.Value = value;
            app.p6EditField_6.Value = value;
            app.p6EditField_7.Value = value;
            app.p6EditField_8.Value = value;            
        end

        % Value changed function: p6EditField_4
        function p6EditField_4ValueChanged(app, event)
            value = app.p6EditField_4.Value;
            app.p6EditField_1.Value = value;
            app.p6EditField_2.Value = value;
            app.p6EditField_3.Value = value;
            app.p6EditField_5.Value = value;
            app.p6EditField_6.Value = value;
            app.p6EditField_7.Value = value;
            app.p6EditField_8.Value = value;
        end

        % Value changed function: p6EditField_5
        function p6EditField_5ValueChanged(app, event)
            value = app.p6EditField_5.Value;
            app.p6EditField_1.Value = value;
            app.p6EditField_2.Value = value;
            app.p6EditField_3.Value = value;
            app.p6EditField_4.Value = value;
            app.p6EditField_6.Value = value;
            app.p6EditField_7.Value = value;
            app.p6EditField_8.Value = value;
        end

        % Value changed function: p6EditField_6
        function p6EditField_6ValueChanged(app, event)
            value = app.p6EditField_6.Value;
            app.p6EditField_1.Value = value;
            app.p6EditField_2.Value = value;
            app.p6EditField_3.Value = value;
            app.p6EditField_4.Value = value;
            app.p6EditField_5.Value = value;
            app.p6EditField_7.Value = value;
            app.p6EditField_8.Value = value;
        end

        % Value changed function: p6EditField_7
        function p6EditField_7ValueChanged(app, event)
            value = app.p6EditField_7.Value;
            app.p6EditField_1.Value = value;
            app.p6EditField_2.Value = value;
            app.p6EditField_3.Value = value;
            app.p6EditField_4.Value = value;
            app.p6EditField_5.Value = value;
            app.p6EditField_6.Value = value;
            app.p6EditField_8.Value = value;
        end

        % Value changed function: p6EditField_8
        function p6EditField_8ValueChanged(app, event)
            value = app.p6EditField_8.Value;
            app.p6EditField_1.Value = value;
            app.p6EditField_2.Value = value;
            app.p6EditField_3.Value = value;
            app.p6EditField_4.Value = value;
            app.p6EditField_5.Value = value;
            app.p6EditField_6.Value = value;
            app.p6EditField_7.Value = value;
        end

        % Value changed function: p7EditField_1
        function p7EditField_1ValueChanged(app, event)
            value = app.p7EditField_1.Value;
            app.p7EditField_2.Value = value;
        end

        % Value changed function: p7EditField_2
        function p7EditField_2ValueChanged(app, event)
            value = app.p7EditField_2.Value;
            app.p7EditField_1.Value = value;
        end

        % Value changed function: p8EditField_1
        function p8EditField_1ValueChanged(app, event)
            value = app.p8EditField_1.Value;
            app.p8EditField_2.Value = value;
        end

        % Value changed function: p8EditField_2
        function p8EditField_2ValueChanged(app, event)
            value = app.p8EditField_2.Value;
            app.p8EditField_1.Value = value;
        end

        % Value changed function: p9EditField_1
        function p9EditField_1ValueChanged(app, event)
            value = app.p9EditField_1.Value;
            app.p9EditField_2.Value = value;
        end

        % Value changed function: p9EditField_2
        function p9EditField_2ValueChanged(app, event)
            value = app.p9EditField_2.Value;
            app.p9EditField_1.Value = value;
        end

        % Value changed function: p10EditField_1
        function p10EditField_1ValueChanged(app, event)
            value = app.p10EditField_1.Value;
            app.p10EditField_2.Value = value;
        end

        % Value changed function: p10EditField_2
        function p10EditField_2ValueChanged(app, event)
            value = app.p10EditField_2.Value;
            app.p10EditField_1.Value = value;
        end

        % Value changed function: p11EditField_1
        function p11EditField_1ValueChanged(app, event)
            value = app.p11EditField_1.Value;
            app.p11EditField_2.Value = value;
        end

        % Value changed function: p11EditField_2
        function p11EditField_2ValueChanged(app, event)
            value = app.p11EditField_2.Value;
            app.p11EditField_1.Value = value;
        end

        % Value changed function: p12EditField_1
        function p12EditField_1ValueChanged(app, event)
            value = app.p12EditField_1.Value;
            app.p12EditField_2.Value = value;
        end

        % Value changed function: p12EditField_2
        function p12EditField_2ValueChanged(app, event)
            value = app.p12EditField_2.Value;
            app.p12EditField_1.Value = value;
        end

        % Value changed function: p13EditField_1
        function p13EditField_1ValueChanged(app, event)
            value = app.p13EditField_1.Value;
            app.p13EditField_2.Value = value;
        end

        % Value changed function: p13EditField_2
        function p13EditField_2ValueChanged(app, event)
            value = app.p13EditField_2.Value;
            app.p13EditField_1.Value = value;
        end

        % Value changed function: p14EditField_1
        function p14EditField_1ValueChanged(app, event)
            value = app.p14EditField_1.Value;
            app.p14EditField_2.Value = value;
        end

        % Value changed function: p14EditField_2
        function p14EditField_2ValueChanged(app, event)
            value = app.p14EditField_2.Value;
            app.p14EditField_1.Value = value;
        end

        % Value changed function: p2p3p5EditField_1
        function p2p3p5EditField_1ValueChanged(app, event)
            value = app.p2p3p5EditField_1.Value;
            app.p2p3p5EditField_2.Value = value;
            app.p2p3p5EditField_3.Value = value;
        end

        % Value changed function: p2p3p5EditField_2
        function p2p3p5EditField_2ValueChanged(app, event)
            value = app.p2p3p5EditField_2.Value;
            app.p2p3p5EditField_1.Value = value;
            app.p2p3p5EditField_3.Value = value;
        end

        % Value changed function: p2p3p5EditField_3
        function p2p3p5EditField_3ValueChanged(app, event)
            value = app.p2p3p5EditField_3.Value;
            app.p2p3p5EditField_1.Value = value;
            app.p2p3p5EditField_2.Value = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Image1 = uiimage(app.UIFigure);
            app.Image1.ImageSource = 'ikto_half1.png';
            app.GKtoEditField = uieditfield(app.UIFigure, 'numeric');
            app.GKtoEditField.Value = 0.14067;
            app.EnterButton = uibutton(app.UIFigure, 'push');
            app.EnterButton.ButtonPushedFcn = createCallbackFcn(app, @EnterButtonPushed, true);
            app.EnterButton.Text = 'Enter';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'ikto_half2.png';
            app.p1EditField_1 = uieditfield(app.UIFigure, 'numeric');
            app.p1EditField_1.ValueChangedFcn = createCallbackFcn(app, @p1EditField_1ValueChanged, true);
            app.p1EditField_1.Value = 33;
            app.p1EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.p1EditField_2.ValueChangedFcn = createCallbackFcn(app, @p1EditField_2ValueChanged, true);
            app.p1EditField_2.Value = 33;
            app.GKtopEditField = uieditfield(app.UIFigure, 'numeric');
            app.GKtopEditField.Value = 0.387;
            app.fKto_1 = uieditfield(app.UIFigure, 'numeric');
            app.fKto_1.ValueChangedFcn = createCallbackFcn(app, @fKto_1ValueChanged, true);
            app.fKto_1.Value = 0.2087704319;
            app.fKto_2 = uieditfield(app.UIFigure, 'numeric');
            app.fKto_2.ValueChangedFcn = createCallbackFcn(app, @fKto_2ValueChanged, true);
            app.fKto_2.Value = 0.2087704319;
            app.p2EditField = uieditfield(app.UIFigure, 'numeric');
            app.p2EditField.Value = 15.5;
            app.p2p3EditField_1 = uieditfield(app.UIFigure, 'numeric');
            app.p2p3EditField_1.ValueChangedFcn = createCallbackFcn(app, @p2p3EditField_1ValueChanged, true);
            app.p2p3EditField_1.Value = 35.5;
            app.p2p3EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.p2p3EditField_2.ValueChangedFcn = createCallbackFcn(app, @p2p3EditField_2ValueChanged, true);
            app.p2p3EditField_2.Value = 35.5;
            app.p2p3EditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.p2p3EditField_3.ValueChangedFcn = createCallbackFcn(app, @p2p3EditField_3ValueChanged, true);
            app.p2p3EditField_3.Value = 35.5;
            app.p1p4EditField_1 = uieditfield(app.UIFigure, 'numeric');
            app.p1p4EditField_1.ValueChangedFcn = createCallbackFcn(app, @p1p4EditField_1ValueChanged, true);
            app.p1p4EditField_1.Value = 17;
            app.p1p4EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.p1p4EditField_2.ValueChangedFcn = createCallbackFcn(app, @p1p4EditField_2ValueChanged, true);
            app.p1p4EditField_2.Value = 17;
            app.p2p5EditField = uieditfield(app.UIFigure, 'numeric');
            app.p2p5EditField.Value = 7.5;
            app.p6EditField_1 = uieditfield(app.UIFigure, 'numeric');
            app.p6EditField_1.ValueChangedFcn = createCallbackFcn(app, @p6EditField_1ValueChanged, true);
            app.p6EditField_1.Value = 7;
            app.p6EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.p6EditField_2.ValueChangedFcn = createCallbackFcn(app, @p6EditField_2ValueChanged, true);
            app.p6EditField_2.Value = 7;
            app.p6EditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.p6EditField_3.ValueChangedFcn = createCallbackFcn(app, @p6EditField_3ValueChanged, true);
            app.p6EditField_3.Value = 7;
            app.p6EditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.p6EditField_4.ValueChangedFcn = createCallbackFcn(app, @p6EditField_4ValueChanged, true);
            app.p6EditField_4.Value = 7;
            app.p6EditField_5 = uieditfield(app.UIFigure, 'numeric');
            app.p6EditField_5.ValueChangedFcn = createCallbackFcn(app, @p6EditField_5ValueChanged, true);
            app.p6EditField_5.Value = 7;
            app.p6EditField_6 = uieditfield(app.UIFigure, 'numeric');
            app.p6EditField_6.ValueChangedFcn = createCallbackFcn(app, @p6EditField_6ValueChanged, true);
            app.p6EditField_6.Value = 7;
            app.p6EditField_7 = uieditfield(app.UIFigure, 'numeric');
            app.p6EditField_7.ValueChangedFcn = createCallbackFcn(app, @p6EditField_7ValueChanged, true);
            app.p6EditField_7.Value = 7;
            app.p6EditField_8 = uieditfield(app.UIFigure, 'numeric');
            app.p6EditField_8.ValueChangedFcn = createCallbackFcn(app, @p6EditField_8ValueChanged, true);
            app.p6EditField_8.Value = 7;
            app.p7EditField_1 = uieditfield(app.UIFigure, 'numeric');
            app.p7EditField_1.ValueChangedFcn = createCallbackFcn(app, @p7EditField_1ValueChanged, true);
            app.p7EditField_1.Value = 0.03577;
            app.p7EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.p7EditField_2.ValueChangedFcn = createCallbackFcn(app, @p7EditField_2ValueChanged, true);
            app.p7EditField_2.Value = 0.03577;
            app.p8EditField_1 = uieditfield(app.UIFigure, 'numeric');
            app.p8EditField_1.ValueChangedFcn = createCallbackFcn(app, @p8EditField_1ValueChanged, true);
            app.p8EditField_1.Value = 0.06237;
            app.p8EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.p8EditField_2.ValueChangedFcn = createCallbackFcn(app, @p8EditField_2ValueChanged, true);
            app.p8EditField_2.Value = 0.06237;
            app.p9EditField_1 = uieditfield(app.UIFigure, 'numeric');
            app.p9EditField_1.ValueDisplayFormat = '%11.6g';
            app.p9EditField_1.ValueChangedFcn = createCallbackFcn(app, @p9EditField_1ValueChanged, true);
            app.p9EditField_1.Value = 0.18064;
            app.p9EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.p9EditField_2.ValueDisplayFormat = '%11.6g';
            app.p9EditField_2.ValueChangedFcn = createCallbackFcn(app, @p9EditField_2ValueChanged, true);
            app.p9EditField_2.Value = 0.18064;
            app.p10EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.p10EditField_2.ValueDisplayFormat = '%11.6g';
            app.p10EditField_2.ValueChangedFcn = createCallbackFcn(app, @p10EditField_2ValueChanged, true);
            app.p10EditField_2.Value = 0.3956;
            app.p10EditField_1 = uieditfield(app.UIFigure, 'numeric');
            app.p10EditField_1.ValueDisplayFormat = '%11.5g';
            app.p10EditField_1.ValueChangedFcn = createCallbackFcn(app, @p10EditField_1ValueChanged, true);
            app.p10EditField_1.Value = 0.3956;
            app.p11EditField_1 = uieditfield(app.UIFigure, 'numeric');
            app.p11EditField_1.ValueDisplayFormat = '%11.5g';
            app.p11EditField_1.ValueChangedFcn = createCallbackFcn(app, @p11EditField_1ValueChanged, true);
            app.p11EditField_1.Value = 0.000152;
            app.p11EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.p11EditField_2.ValueDisplayFormat = '%11.8g';
            app.p11EditField_2.ValueChangedFcn = createCallbackFcn(app, @p11EditField_2ValueChanged, true);
            app.p11EditField_2.Value = 0.000152;
            app.p12EditField_1 = uieditfield(app.UIFigure, 'numeric');
            app.p12EditField_1.ValueDisplayFormat = '%11.7g';
            app.p12EditField_1.ValueChangedFcn = createCallbackFcn(app, @p12EditField_1ValueChanged, true);
            app.p12EditField_1.Value = 0.067083;
            app.p12EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.p12EditField_2.ValueDisplayFormat = '%11.7g';
            app.p12EditField_2.ValueChangedFcn = createCallbackFcn(app, @p12EditField_2ValueChanged, true);
            app.p12EditField_2.Value = 0.067083;
            app.p13EditField_1 = uieditfield(app.UIFigure, 'numeric');
            app.p13EditField_1.ValueDisplayFormat = '%11.8g';
            app.p13EditField_1.ValueChangedFcn = createCallbackFcn(app, @p13EditField_1ValueChanged, true);
            app.p13EditField_1.Value = 0.00095;
            app.p13EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.p13EditField_2.ValueDisplayFormat = '%11.8g';
            app.p13EditField_2.ValueChangedFcn = createCallbackFcn(app, @p13EditField_2ValueChanged, true);
            app.p13EditField_2.Value = 0.00095;
            app.p14EditField_1 = uieditfield(app.UIFigure, 'numeric');
            app.p14EditField_1.ValueDisplayFormat = '%11.8g';
            app.p14EditField_1.ValueChangedFcn = createCallbackFcn(app, @p14EditField_1ValueChanged, true);
            app.p14EditField_1.Value = 0.051335;
            app.p14EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.p14EditField_2.ValueDisplayFormat = '%11.8g';
            app.p14EditField_2.ValueChangedFcn = createCallbackFcn(app, @p14EditField_2ValueChanged, true);
            app.p14EditField_2.Value = 0.051335;
            app.p2p3p5EditField_1 = uieditfield(app.UIFigure, 'numeric');
            app.p2p3p5EditField_1.ValueDisplayFormat = '%11.3g';
            app.p2p3p5EditField_1.ValueChangedFcn = createCallbackFcn(app, @p2p3p5EditField_1ValueChanged, true);
            app.p2p3p5EditField_1.Value = 27.5;
            app.p2p3p5EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.p2p3p5EditField_2.ValueDisplayFormat = '%11.3g';
            app.p2p3p5EditField_2.ValueChangedFcn = createCallbackFcn(app, @p2p3p5EditField_2ValueChanged, true);
            app.p2p3p5EditField_2.Value = 27.5;
            app.p2p3p5EditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.p2p3p5EditField_3.ValueDisplayFormat = '%11.3g';
            app.p2p3p5EditField_3.ValueChangedFcn = createCallbackFcn(app, @p2p3p5EditField_3ValueChanged, true);
            app.p2p3p5EditField_3.Value = 27.5;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ikto_dialog(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end