classdef IKtof_dialog < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        Image           matlab.ui.control.Image
        p1EditField     matlab.ui.control.NumericEditField
        p2EditField     matlab.ui.control.NumericEditField
        p3EditField     matlab.ui.control.NumericEditField
        p4EditField     matlab.ui.control.NumericEditField
        p5EditField     matlab.ui.control.NumericEditField
        p6EditField     matlab.ui.control.NumericEditField
        p7EditField     matlab.ui.control.NumericEditField
        p8EditField     matlab.ui.control.NumericEditField
        p9EditField     matlab.ui.control.NumericEditField
        p10EditField    matlab.ui.control.NumericEditField
        p11EditField    matlab.ui.control.NumericEditField
        p12EditField    matlab.ui.control.NumericEditField
        GKtofEditField  matlab.ui.control.NumericEditField
        EnterButton     matlab.ui.control.Button
        p1c1EditField   matlab.ui.control.NumericEditField
        p3c1EditField   matlab.ui.control.NumericEditField
        p3c2EditField   matlab.ui.control.NumericEditField
        p4c1EditField   matlab.ui.control.NumericEditField
        p4c2EditField   matlab.ui.control.NumericEditField
        p4c3EditField   matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        KvApp % main app
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.KvApp = mainapp;
            
            app.p1EditField.Value = mainapp.p1Ktof;
            app.p2EditField.Value = mainapp.p2Ktof;
            app.p3EditField.Value = mainapp.p3Ktof;
            app.p4EditField.Value = mainapp.p4Ktof;
            app.p5EditField.Value = mainapp.p5Ktof;
            app.p6EditField.Value = mainapp.p6Ktof;
            app.p7EditField.Value = mainapp.p7Ktof;
            app.p8EditField.Value = mainapp.p8Ktof;
            app.p9EditField.Value = mainapp.p9Ktof;
            app.p10EditField.Value = mainapp.p10Ktof;
            app.p11EditField.Value = mainapp.p11Ktof;
            app.p12EditField.Value = mainapp.p12Ktof;
            app.GKtofEditField.Value = mainapp.GKtof;
            
            app.p1c1EditField.Value = mainapp.p1Ktof;
            app.p3c1EditField.Value = mainapp.p3Ktof;
            app.p3c2EditField.Value = mainapp.p3Ktof;
            app.p4c1EditField.Value = mainapp.p4Ktof;
            app.p4c2EditField.Value = mainapp.p4Ktof;
            app.p4c3EditField.Value = mainapp.p4Ktof;
        end

        % Button pushed function: EnterButton
        function EnterButtonPushed(app, event)
            param = zeros(13, 1);
            param(1) = app.p1EditField.Value;
            param(2) = app.p2EditField.Value;
            param(3) = app.p3EditField.Value;
            param(4) = app.p4EditField.Value;
            param(5) = app.p5EditField.Value;
            param(6) = app.p6EditField.Value;
            param(7) = app.p7EditField.Value;
            param(8) = app.p8EditField.Value;
            param(9) = app.p9EditField.Value;
            param(10) = app.p10EditField.Value;
            param(11) = app.p11EditField.Value;
            param(12) = app.p12EditField.Value;
            param(13) = app.GKtofEditField.Value;
            
            update_ktof_dialog(app.KvApp, param);            
            delete(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % app.KvApp.HighlightShowEquationsButton.Enable = "on";
            delete(app)
        end

        % Value changed function: p1EditField
        function p1EditFieldValueChanged(app, event)
            value = app.p1EditField.Value;
            app.p1c1EditField.Value = value;
        end

        % Value changed function: p1c1EditField
        function p1c1EditFieldValueChanged(app, event)
            value = app.p1c1EditField.Value;
            app.p1EditField = value;
        end

        % Value changed function: p3EditField
        function p3EditFieldValueChanged(app, event)
            value = app.p3EditField.Value;
            app.p3c1EditField.Value = value;
            app.p3c2EditField.Value = value;
        end

        % Value changed function: p3c1EditField
        function p3c1EditFieldValueChanged(app, event)
            value = app.p3c1EditField.Value;
            app.p3EditField.Value = value;
            app.p3c2EditField.Value = value;
        end

        % Value changed function: p3c2EditField
        function p3c2EditFieldValueChanged(app, event)
            value = app.p3c2EditField.Value;
            app.p3EditField.Value = value;
            app.p3c1EditField.Value = value;
        end

        % Value changed function: p4EditField
        function p4EditFieldValueChanged(app, event)
            value = app.p4EditField.Value;
            app.p4c1EditField.Value = value;
            app.p4c2EditField.Value = value;
            app.p4c3EditField.Value = value;
        end

        % Value changed function: p4c1EditField
        function p4c1EditFieldValueChanged(app, event)
            value = app.p4c1EditField.Value;
            app.p4EditField.Value = value;
            app.p4c2EditField.Value = value;
            app.p4c3EditField.Value = value;
        end

        % Value changed function: p4c2EditField
        function p4c2EditFieldValueChanged(app, event)
            value = app.p4c2EditField.Value;
            app.p4EditField.Value = value;
            app.p4c1EditField.Value = value;
            app.p4c3EditField.Value = value;
        end

        % Value changed function: p4c3EditField
        function p4c3EditFieldValueChanged(app, event)
            value = app.p4c3EditField.Value;
            app.p4EditField.Value = value;
            app.p4c1EditField.Value = value;
            app.p4c2EditField.Value = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'iktof.png';
            app.p1EditField = uieditfield(app.UIFigure, 'numeric');
            app.p1EditField.ValueChangedFcn = createCallbackFcn(app, @p1EditFieldValueChanged, true);
            app.p1EditField.Value = 30;
            app.p2EditField = uieditfield(app.UIFigure, 'numeric');
            app.p2EditField.Value = 13.5;
            app.p3EditField = uieditfield(app.UIFigure, 'numeric');
            app.p3EditField.ValueChangedFcn = createCallbackFcn(app, @p3EditFieldValueChanged, true);
            app.p3EditField.Value = 33.5;
            app.p4EditField = uieditfield(app.UIFigure, 'numeric');
            app.p4EditField.ValueChangedFcn = createCallbackFcn(app, @p4EditFieldValueChanged, true);
            app.p4EditField.Value = 7;
            app.p5EditField = uieditfield(app.UIFigure, 'numeric');
            app.p5EditField.ValueDisplayFormat = '%11.7g';
            app.p5EditField.Value = 0.18064;
            app.p6EditField = uieditfield(app.UIFigure, 'numeric');
            app.p6EditField.ValueDisplayFormat = '%11.5g';
            app.p6EditField.Value = 0.03577;
            app.p7EditField = uieditfield(app.UIFigure, 'numeric');
            app.p7EditField.ValueDisplayFormat = '%11.7g';
            app.p7EditField.Value = 0.3956;
            app.p8EditField = uieditfield(app.UIFigure, 'numeric');
            app.p8EditField.ValueDisplayFormat = '%11.5g';
            app.p8EditField.Value = 0.06237;
            app.p9EditField = uieditfield(app.UIFigure, 'numeric');
            app.p9EditField.ValueDisplayFormat = '%11.7g';
            app.p9EditField.Value = 0.000152;
            app.p10EditField = uieditfield(app.UIFigure, 'numeric');
            app.p10EditField.ValueDisplayFormat = '%11.7g';
            app.p10EditField.Value = 0.067083;
            app.p11EditField = uieditfield(app.UIFigure, 'numeric');
            app.p11EditField.ValueDisplayFormat = '%11.7g';
            app.p11EditField.Value = 0.00095;
            app.p12EditField = uieditfield(app.UIFigure, 'numeric');
            app.p12EditField.ValueDisplayFormat = '%11.7g';
            app.p12EditField.Value = 0.051335;
            app.GKtofEditField = uieditfield(app.UIFigure, 'numeric');
            app.GKtofEditField.Value = 0.4067;
            app.EnterButton = uibutton(app.UIFigure, 'push');
            app.EnterButton.ButtonPushedFcn = createCallbackFcn(app, @EnterButtonPushed, true);
            app.EnterButton.Text = 'Enter';
            app.p1c1EditField = uieditfield(app.UIFigure, 'numeric');
            app.p1c1EditField.ValueChangedFcn = createCallbackFcn(app, @p1c1EditFieldValueChanged, true);
            app.p1c1EditField.Value = 30;
            app.p3c1EditField = uieditfield(app.UIFigure, 'numeric');
            app.p3c1EditField.ValueChangedFcn = createCallbackFcn(app, @p3c1EditFieldValueChanged, true);
            app.p3c1EditField.Value = 33.5;
            app.p3c2EditField = uieditfield(app.UIFigure, 'numeric');
            app.p3c2EditField.ValueChangedFcn = createCallbackFcn(app, @p3c2EditFieldValueChanged, true);
            app.p3c2EditField.Value = 33.5;
            app.p4c1EditField = uieditfield(app.UIFigure, 'numeric');
            app.p4c1EditField.ValueChangedFcn = createCallbackFcn(app, @p4c1EditFieldValueChanged, true);
            app.p4c1EditField.Value = 7;
            app.p4c2EditField = uieditfield(app.UIFigure, 'numeric');
            app.p4c2EditField.ValueChangedFcn = createCallbackFcn(app, @p4c2EditFieldValueChanged, true);
            app.p4c2EditField.Value = 7;
            app.p4c3EditField = uieditfield(app.UIFigure, 'numeric');
            app.p4c3EditField.ValueChangedFcn = createCallbackFcn(app, @p4c3EditFieldValueChanged, true);
            app.p4c3EditField.Value = 7;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = IKtof_dialog(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end