classdef IKur_dialog < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        p1EditFieldLabel    matlab.ui.control.Label
        p1EditField         matlab.ui.control.NumericEditField
        p2EditFieldLabel    matlab.ui.control.Label
        p2EditField         matlab.ui.control.NumericEditField
        p3EditFieldLabel    matlab.ui.control.Label
        p3EditField         matlab.ui.control.NumericEditField
        p4EditFieldLabel    matlab.ui.control.Label
        p4EditField         matlab.ui.control.NumericEditField
        p5EditFieldLabel    matlab.ui.control.Label
        p5EditField         matlab.ui.control.NumericEditField
        p6EditFieldLabel    matlab.ui.control.Label
        p6EditField         matlab.ui.control.NumericEditField
        p7EditFieldLabel    matlab.ui.control.Label
        p7EditField         matlab.ui.control.NumericEditField
        p8EditFieldLabel    matlab.ui.control.Label
        p8EditField         matlab.ui.control.NumericEditField
        p9EditFieldLabel    matlab.ui.control.Label
        p9EditField         matlab.ui.control.NumericEditField
        p10EditFieldLabel   matlab.ui.control.Label
        p10EditField        matlab.ui.control.NumericEditField
        p11EditFieldLabel   matlab.ui.control.Label
        p11EditField        matlab.ui.control.NumericEditField
        GKurEditFieldLabel  matlab.ui.control.Label
        GKurEditField       matlab.ui.control.NumericEditField
        EnterButton         matlab.ui.control.Button
    end

    
    properties (Access = private)
        KvApp % main app
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.KvApp = mainapp;
            
            app.p1EditField.Value = app.KvApp.p1Kur;
            app.p2EditField.Value = app.KvApp.p2Kur;
            app.p3EditField.Value = app.KvApp.p3Kur;
            app.p4EditField.Value = app.KvApp.p4Kur;
            app.p5EditField.Value = app.KvApp.p5Kur;
            app.p6EditField.Value = app.KvApp.p6Kur;
            app.p7EditField.Value = app.KvApp.p7Kur;
            app.p8EditField.Value = app.KvApp.p8Kur;
            app.p9EditField.Value = app.KvApp.p9Kur;
            app.p10EditField.Value = app.KvApp.p10Kur;
            app.p11EditField.Value = app.KvApp.p11Kur;
            app.GKurEditField.Value = app.KvApp.GKur;            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Button pushed function: EnterButton
        function EnterButtonPushed(app, event)
            param = zeros(12, 1);
            param(1) = app.p1EditField.Value;
            param(2) = app.p2EditField.Value;
            param(3) = app.p3EditField.Value;
            param(4) = app.p4EditField.Value;
            param(5) = app.p5EditField.Value;
            param(6) = app.p6EditField.Value;
            param(7) = app.p7EditField.Value;
            param(8) = app.p8EditField.Value;
            param(9) = app.p9EditField.Value;
            param(10) = app.p10EditField.Value;
            param(11) = app.p11EditField.Value;
            param(12) = app.GKurEditField.Value;
            
            update_kur(app.KvApp, param);
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.p1EditFieldLabel = uilabel(app.UIFigure);
            app.p1EditFieldLabel.Text = 'p1';
            app.p1EditField = uieditfield(app.UIFigure, 'numeric');
            app.p1EditField.Value = 22.5;
            app.p2EditFieldLabel = uilabel(app.UIFigure);
            app.p2EditFieldLabel.Text = 'p2';
            app.p2EditField = uieditfield(app.UIFigure, 'numeric');
            app.p2EditField.Value = 7.7;
            app.p3EditFieldLabel = uilabel(app.UIFigure);
            app.p3EditFieldLabel.Text = 'p3';
            app.p3EditField = uieditfield(app.UIFigure, 'numeric');
            app.p3EditField.Value = 0.493;
            app.p4EditFieldLabel = uilabel(app.UIFigure);
            app.p4EditFieldLabel.Text = 'p4';
            app.p4EditField = uieditfield(app.UIFigure, 'numeric');
            app.p4EditField.Value = 0.0629;
            app.p5EditFieldLabel = uilabel(app.UIFigure);
            app.p5EditFieldLabel.Text = 'p5';
            app.p5EditField = uieditfield(app.UIFigure, 'numeric');
            app.p5EditField.Value = 2.058;
            app.p6EditFieldLabel = uilabel(app.UIFigure);
            app.p6EditFieldLabel.Text = 'p6';
            app.p6EditField = uieditfield(app.UIFigure, 'numeric');
            app.p6EditField.Value = 45.2;
            app.p7EditFieldLabel = uilabel(app.UIFigure);
            app.p7EditFieldLabel.Text = 'p7';
            app.p7EditField = uieditfield(app.UIFigure, 'numeric');
            app.p7EditField.Value = 5.7;
            app.p8EditFieldLabel = uilabel(app.UIFigure);
            app.p8EditFieldLabel.Text = 'p8';
            app.p8EditField = uieditfield(app.UIFigure, 'numeric');
            app.p8EditField.Value = 1200;
            app.p9EditFieldLabel = uilabel(app.UIFigure);
            app.p9EditFieldLabel.Text = 'p9';
            app.p9EditField = uieditfield(app.UIFigure, 'numeric');
            app.p9EditField.Value = 170;
            app.p10EditFieldLabel = uilabel(app.UIFigure);
            app.p10EditFieldLabel.Text = 'p10';
            app.p10EditField = uieditfield(app.UIFigure, 'numeric');
            app.p10EditField.Value = 45.2;
            app.p11EditFieldLabel = uilabel(app.UIFigure);
            app.p11EditFieldLabel.Text = 'p11';
            app.p11EditField = uieditfield(app.UIFigure, 'numeric');
            app.p11EditField.Value = 5.7;
            app.GKurEditFieldLabel = uilabel(app.UIFigure);
            app.GKurEditFieldLabel.Text = 'GKur';
            app.GKurEditField = uieditfield(app.UIFigure, 'numeric');
            app.GKurEditField.Value = 0.16;
            app.EnterButton = uibutton(app.UIFigure, 'push');
            app.EnterButton.ButtonPushedFcn = createCallbackFcn(app, @EnterButtonPushed, true);
            app.EnterButton.Text = 'Enter';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = IKur_dialog(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end