classdef Kv < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        ProtocolTab                     matlab.ui.container.Tab
        HoldingtimemsEditFieldLabel     matlab.ui.control.Label
        HoldingtimemsEditField          matlab.ui.control.NumericEditField
        HoldingpotentialmVEditFieldLabel  matlab.ui.control.Label
        HoldingpotentialmVEditField     matlab.ui.control.NumericEditField
        ClampingvoltageminmVEditFieldLabel  matlab.ui.control.Label
        ClampingvoltageminmVEditField   matlab.ui.control.NumericEditField
        ClampingtimemsEditFieldLabel    matlab.ui.control.Label
        ClampingtimemsEditField         matlab.ui.control.NumericEditField
        ProtocolVisualizationUpdateButton  matlab.ui.control.Button
        NernstpotentialEkmVLabel        matlab.ui.control.Label
        NernstpotentialEkmVEditField    matlab.ui.control.NumericEditField
        DatauploadButton                matlab.ui.control.Button
        ClampingvoltagestepmVEditFieldLabel  matlab.ui.control.Label
        ClampingvoltagestepmVEditField  matlab.ui.control.NumericEditField
        ClampingvoltagemaxmVEditFieldLabel  matlab.ui.control.Label
        ClampingvoltagemaxmVEditField   matlab.ui.control.NumericEditField
        ModelCalibrationButton          matlab.ui.control.Button
        UIAxesTopLeft                   matlab.ui.control.UIAxes
        UIAxesBottomLeft                matlab.ui.control.UIAxes
        IKtoKv42Tab                     matlab.ui.container.Tab
        TextArea_1                      matlab.ui.control.TextArea
        HighlightShowEquationsButton_1  matlab.ui.control.Button
        ResetButton_1                   matlab.ui.control.Button
        fKtoKnobLabel                   matlab.ui.control.Label
        fKtoKnob                        matlab.ui.control.Knob
        GKtoKnobLabel                   matlab.ui.control.Label
        GKtoKnob                        matlab.ui.control.Knob
        GKtopKnobLabel                  matlab.ui.control.Label
        GKtopKnob                       matlab.ui.control.Knob
        p1Knob_6Label                   matlab.ui.control.Label
        p1Knob_1                        matlab.ui.control.Knob
        p2Knob_6Label                   matlab.ui.control.Label
        p2Knob_1                        matlab.ui.control.Knob
        p3Knob_6Label                   matlab.ui.control.Label
        p3Knob_1                        matlab.ui.control.Knob
        p4Knob_6Label                   matlab.ui.control.Label
        p4Knob_1                        matlab.ui.control.Knob
        p5Knob_6Label                   matlab.ui.control.Label
        p5Knob_1                        matlab.ui.control.Knob
        p6Knob_5Label                   matlab.ui.control.Label
        p6Knob_1                        matlab.ui.control.Knob
        p7Knob_5Label                   matlab.ui.control.Label
        p7Knob_1                        matlab.ui.control.Knob
        p8Knob_5Label                   matlab.ui.control.Label
        p8Knob_1                        matlab.ui.control.Knob
        p9Knob_5Label                   matlab.ui.control.Label
        p9Knob_1                        matlab.ui.control.Knob
        p10KnobLabel                    matlab.ui.control.Label
        p10Knob_1                       matlab.ui.control.Knob
        p11KnobLabel                    matlab.ui.control.Label
        p11Knob_1                       matlab.ui.control.Knob
        p12KnobLabel                    matlab.ui.control.Label
        p12Knob_1                       matlab.ui.control.Knob
        p13KnobLabel                    matlab.ui.control.Label
        p13Knob_1                       matlab.ui.control.Knob
        p14KnobLabel                    matlab.ui.control.Label
        p14Knob_1                       matlab.ui.control.Knob
        IKslow1Kv15Tab                  matlab.ui.container.Tab
        TextArea_2                      matlab.ui.control.TextArea
        HighlightShowEquationsButton_2  matlab.ui.control.Button
        ResetButton_2                   matlab.ui.control.Button
        fKslow1KnobLabel                matlab.ui.control.Label
        fKslow1Knob                     matlab.ui.control.Knob
        GKslow1Label                    matlab.ui.control.Label
        GKslow1Knob                     matlab.ui.control.Knob
        GKslo1pLabel                    matlab.ui.control.Label
        GKslow1pKnob                    matlab.ui.control.Knob
        p1Knob_2Label                   matlab.ui.control.Label
        p1Knob_2                        matlab.ui.control.Knob
        p2Knob_2Label                   matlab.ui.control.Label
        p2Knob_2                        matlab.ui.control.Knob
        p3Knob_2Label                   matlab.ui.control.Label
        p3Knob_2                        matlab.ui.control.Knob
        p4Knob_2Label                   matlab.ui.control.Label
        p4Knob_2                        matlab.ui.control.Knob
        p5Knob_2Label                   matlab.ui.control.Label
        p5Knob_2                        matlab.ui.control.Knob
        p6Knob_2Label                   matlab.ui.control.Label
        p6Knob_2                        matlab.ui.control.Knob
        p7Knob_2Label                   matlab.ui.control.Label
        p7Knob_2                        matlab.ui.control.Knob
        p8Knob_2Label                   matlab.ui.control.Label
        p8Knob_2                        matlab.ui.control.Knob
        p9Knob_2Label                   matlab.ui.control.Label
        p9Knob_2                        matlab.ui.control.Knob
        p10Knob_2Label                  matlab.ui.control.Label
        p10Knob_2                       matlab.ui.control.Knob
        IKslow2Kv21Tab                  matlab.ui.container.Tab
        TextArea_3                      matlab.ui.control.TextArea
        HighlightShowEquationsButton_3  matlab.ui.control.Button
        ResetButton_3                   matlab.ui.control.Button
        GKslow2Label                    matlab.ui.control.Label
        GKslow2Knob                     matlab.ui.control.Knob
        p1Knob_3Label                   matlab.ui.control.Label
        p1Knob_3                        matlab.ui.control.Knob
        p2Knob_3Label                   matlab.ui.control.Label
        p2Knob_3                        matlab.ui.control.Knob
        p3Knob_3Label                   matlab.ui.control.Label
        p3Knob_3                        matlab.ui.control.Knob
        p4Knob_3Label                   matlab.ui.control.Label
        p4Knob_3                        matlab.ui.control.Knob
        p5Knob_3Label                   matlab.ui.control.Label
        p5Knob_3                        matlab.ui.control.Knob
        p6Knob_3Label                   matlab.ui.control.Label
        p6Knob_3                        matlab.ui.control.Knob
        p7Knob_3Label                   matlab.ui.control.Label
        p7Knob_3                        matlab.ui.control.Knob
        p8Knob_3Label                   matlab.ui.control.Label
        p8Knob_3                        matlab.ui.control.Knob
        p9Knob_3Label                   matlab.ui.control.Label
        p9Knob_3                        matlab.ui.control.Knob
        p10Knob_3Label                  matlab.ui.control.Label
        p10Knob_3                       matlab.ui.control.Knob
        IKssTab                         matlab.ui.container.Tab
        ResetButton_4                   matlab.ui.control.Button
        HightlightButton_4              matlab.ui.control.Button
        TextArea_4                      matlab.ui.control.TextArea
        GKssLabel                       matlab.ui.control.Label
        GKss                            matlab.ui.control.Knob
        p1Knob_4Label                   matlab.ui.control.Label
        p1Knob_4                        matlab.ui.control.Knob
        p2Knob_4Label                   matlab.ui.control.Label
        p2Knob_4                        matlab.ui.control.Knob
        p3Knob_4Label                   matlab.ui.control.Label
        p3Knob_4                        matlab.ui.control.Knob
        p4Knob_4Label                   matlab.ui.control.Label
        p4Knob_4                        matlab.ui.control.Knob
        p5Knob_4Label                   matlab.ui.control.Label
        p5Knob_4                        matlab.ui.control.Knob
        p6Knob_4Label                   matlab.ui.control.Label
        p6Knob_4                        matlab.ui.control.Knob
        RightPanel                      matlab.ui.container.Panel
        SelectVoltageDropDownLabel      matlab.ui.control.Label
        SelectVoltageDropDown           matlab.ui.control.DropDown
        UIAxesMiddleRight               matlab.ui.control.UIAxes
        UIAxesTopRight                  matlab.ui.control.UIAxes
        UIAxesBottomRight               matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    properties (Access = private)
        % time space
        t % entire time
        hold_t % holding time
        pulse_t % voltage-clamping time
        
        % voltages
        volts
        num_volts
        volt_idx
        
        % currents
        yksum_set
        ykto_set
        ykslow1_set
        ykslow2_set
        ykss_set
        
        % apps for dialog windows
        KtoDialogApp
        Kslow1DialogApp
        Kslow2DialogApp
        
        % warning window
        WarnApp
        
        % default parameter values
        Kto_default
        Kslow1_default
        Kslow2_default
        Kss_default
        
        % experimental data
        exp_t
        exp_yksum_set
        exp_idx
        exp_num_volts
    end
    
    properties (Access = public)
        % For IKto dialog box
        p1Kto = 33; 
        p2Kto = 15.5; 
        p3Kto = 20; 
        p4Kto = 16; 
        p5Kto = 8; 
        p6Kto = 7; 
        p7Kto = 0.03577; 
        p8Kto = 0.06237; 
        p9Kto = 0.18064; 
        p10Kto = 0.3956; 
        p11Kto = 0.000152; 
        p12Kto = 0.067083; 
        p13Kto = 0.00095; 
        p14Kto = 0.051335; 
        fKto = 0.2087704319; 
        GKto = 0.14067; 
        GKtop = 0.387;
        
        % For IKslow1 dialog box
        p1Kslow1 = 22.5
        p2Kslow1 = 45.2
        p3Kslow1 = 40.0
        p4Kslow1 = 7.7
        p5Kslow1 = 5.7
        p6Kslow1 = 6.1
        p7Kslow1 = 0.0629
        p8Kslow1 = 2.058
        p9Kslow1 = 803.0
        p10Kslow1 = 18.0
        fKslow1 = 0.9214774521
        GKslow1 = 0.05766
        GKslow1p = 0.07496
        
        % For IKslow2 dialog box
        p9Kslow2 = 5334;
        p10Kslow2 = 4912;
    end
    
    methods (Access = private)
        function [t, hold_t, pulse_t] = gen_time_space(app)
            hold_len = app.HoldingtimemsEditField.Value;
            time_len = hold_len + app.ClampingtimemsEditField.Value;
            
            hold_t = 0:hold_len;
            pulse_t = (hold_len+1):time_len;
            pulse_t_shift = pulse_t - pulse_t(1);
            
            t = [hold_t, pulse_t];
            pulse_t = pulse_t_shift;
        end
        
        function [volts, num_volts] = gen_volts(app)
            minv = app.ClampingvoltageminmVEditField.Value;
            stepv = app.ClampingvoltagestepmVEditField.Value;
            maxv = app.ClampingvoltagemaxmVEditField.Value;
            
            if stepv == 0
                volts = minv;
            else
                volts = minv:stepv:maxv;
            end
            num_volts = length(volts);
        end
        
        function [current_trc] = gen_IKto(app, v)
            param = zeros(17, 1);
            param(1) = app.p1Knob_1.Value;
            param(2) = app.p2Knob_1.Value;
            param(3) = app.p3Knob_1.Value;
            param(4) = app.p4Knob_1.Value;
            param(5) = app.p5Knob_1.Value;
            param(6) = app.p6Knob_1.Value;
            param(7) = app.p7Knob_1.Value;
            param(8) = app.p8Knob_1.Value;
            param(9) = app.p9Knob_1.Value;
            param(10) = app.p10Knob_1.Value;
            param(11) = app.p11Knob_1.Value;
            param(12) = app.p12Knob_1.Value;
            param(13) = app.p13Knob_1.Value;
            param(14) = app.p14Knob_1.Value;
            param(15) = app.fKtoKnob.Value;
            param(16) = app.GKtoKnob.Value;
            param(17) = app.GKtopKnob.Value;
            
            tt = cell(1, 3);
            tt{1} = app.t;
            tt{2} = app.hold_t;
            tt{3} = app.pulse_t;
            current_trc = IKto(param, app.HoldingpotentialmVEditField.Value, ...
                v, tt, app.NernstpotentialEkmVEditField.Value);
        end
        
        function [current_trc] = gen_IKslow1(app, v)
            param = zeros(13,1);
            param(1) = app.p1Knob_2.Value;
            param(2) = app.p2Knob_2.Value;
            param(3) = app.p3Knob_2.Value;
            param(4) = app.p4Knob_2.Value;
            param(5) = app.p5Knob_2.Value;
            param(6) = app.p6Knob_2.Value;
            param(7) = app.p7Knob_2.Value;
            param(8) = app.p8Knob_2.Value;
            param(9) = app.p9Knob_2.Value;
            param(10) = app.p10Knob_2.Value;
            param(11) = app.fKslow1Knob.Value;
            param(12) = app.GKslow1Knob.Value;
            param(13) = app.GKslow1pKnob.Value;
            
            tt = cell(1, 3);
            tt{1} = app.t;
            tt{2} = app.hold_t;
            tt{3} = app.pulse_t;
            current_trc = IKslow1(param, app.HoldingpotentialmVEditField.Value, ...
                v, tt, app.NernstpotentialEkmVEditField.Value);
        end
        
        function [current_trc] = gen_IKslow2(app, v)
            param = zeros(11,1);
            param(1) = app.p1Knob_3.Value;
            param(2) = app.p2Knob_3.Value;
            param(3) = app.p3Knob_3.Value;
            param(4) = app.p4Knob_3.Value;
            param(5) = app.p5Knob_3.Value;
            param(6) = app.p6Knob_3.Value;
            param(7) = app.p7Knob_3.Value;
            param(8) = app.p8Knob_3.Value;
            param(9) = app.p9Knob_3.Value;
            param(10) = app.p10Knob_3.Value;
            param(11) = app.GKslow2Knob.Value;
            
            tt = cell(1, 3);
            tt{1} = app.t;
            tt{2} = app.hold_t;
            tt{3} = app.pulse_t;            
            current_trc = IKslow2(param, app.HoldingpotentialmVEditField.Value, ...
                v, tt, app.NernstpotentialEkmVEditField.Value);
        end
        
        function [current_trc] = gen_IKss(app, v)
            param = zeros(7,1);
            param(1) = app.p1Knob_4.Value;
            param(2) = app.p2Knob_4.Value;
            param(3) = app.p3Knob_4.Value;
            param(4) = app.p4Knob_4.Value;
            param(5) = app.p5Knob_4.Value;
            param(6) = app.p6Knob_4.Value;
            param(7) = app.GKss.Value;
            
            tt = cell(1, 3);
            tt{1} = app.t;
            tt{2} = app.hold_t;
            tt{3} = app.pulse_t;            
            current_trc = IKss(param, app.HoldingpotentialmVEditField.Value, ...
                v, tt, app.NernstpotentialEkmVEditField.Value);
        end
        
        function [current_trc_set] = gen_currents_over_volts(app, current_generator)
            % wrapper function looping over voltages
            current_trc_set = zeros(length(app.t), app.num_volts);
            for i=1:app.num_volts
                current_trc_set(:,i) = current_generator(app, app.volts(i));
            end
        end
        
        function plot_protocol(app)
            yH = ones(1, length(app.hold_t))*app.HoldingpotentialmVEditField.Value;
            yP = ones(1, length(app.pulse_t))*app.volts(1);
            y = [yH, yP];
            plot(app.UIAxesTopLeft, app.t, y, 'LineWidth',1.5)
            
            hold(app.UIAxesTopLeft, "on")
            for i=2:app.num_volts
                yP = ones(1, length(app.pulse_t))*app.volts(i);
                y = [yH, yP];
                plot(app.UIAxesTopLeft, app.t, y, 'LineWidth',1.5)
            end
            hold(app.UIAxesTopLeft, "off")
        end
        
        function plot_sum_currents_allvolts(app)
            % top-right axes; IKsum
            plot(app.UIAxesTopRight, app.t, app.yksum_set(:,1))
            hold(app.UIAxesTopRight, "on")
            for i=2:app.num_volts
                plot(app.UIAxesTopRight, app.t, app.yksum_set(:,i))
            end
            hold(app.UIAxesTopRight, "off")
            axis(app.UIAxesTopRight, "tight")
        end
        
        function plot_currents_onevolt(app, volt_idx, highlight_idx)
            % MiddleRight; individual K+ currents for one voltage
            l(1) = plot(app.UIAxesMiddleRight, app.t, app.ykto_set(:,volt_idx));
            
            hold(app.UIAxesMiddleRight, "on")
            l(2) = plot(app.UIAxesMiddleRight, app.t, app.ykslow1_set(:,volt_idx));
            l(3) = plot(app.UIAxesMiddleRight, app.t, app.ykslow2_set(:,volt_idx));
            l(4) = plot(app.UIAxesMiddleRight, app.t, app.ykss_set(:,volt_idx));
            hold(app.UIAxesMiddleRight, "off")
            
            switch highlight_idx
                case false
                    legend(app.UIAxesMiddleRight, "I_{Kto}","I_{Kslow1}","I_{Kslow2}","I_{Kss}", "Location","northeast")
                case 1
                    legend(app.UIAxesMiddleRight, l(highlight_idx), "I_{Kto}", "Location","northeast")
                case 2
                    legend(app.UIAxesMiddleRight, l(highlight_idx), "I_{Kslow1}", "Location","northeast")
                case 3
                    legend(app.UIAxesMiddleRight, l(highlight_idx), "I_{Kslow2}", "Location","northeast")
                case 4
                    legend(app.UIAxesMiddleRight, l(highlight_idx), "I_{Kss}", "Location","northeast")
            end            
            set(l(highlight_idx), "LineWidth",2)       
            axis(app.UIAxesMiddleRight, "tight")
            
            % IKsum for one voltage
            if isequal(app.exp_idx, true) && isequal(app.num_volts, app.exp_num_volts)
                plot(app.UIAxesBottomRight, app.exp_t, app.exp_yksum_set(:,volt_idx), "LineWidth",2)
                hold(app.UIAxesBottomRight, "on")
                plot(app.UIAxesBottomRight, app.t, app.yksum_set(:,volt_idx), "LineWidth",2, "Color","red")
                hold(app.UIAxesBottomRight, "off")
                axis(app.UIAxesBottomRight, "tight")
                legend(app.UIAxesBottomRight, "Experimental Data", "Model Prediction", "Location","northeast")
            elseif app.num_volts ~= app.exp_num_volts
                app.WarnApp = warn_message();
                plot(app.UIAxesBottomRight, app.t, app.yksum_set(:,volt_idx), "LineWidth",2, "Color","red")
                axis(app.UIAxesBottomRight, "tight")
                legend(app.UIAxesBottomRight, "Model Prediction", "Location","northeast")
            else
                plot(app.UIAxesBottomRight, app.t, app.yksum_set(:,volt_idx), "LineWidth",2, "Color","red")
                axis(app.UIAxesBottomRight, "tight")
                legend(app.UIAxesBottomRight, "Model Prediction", "Location","northeast")                
            end
        end
        
        function reset_kto(app)
            app.p1Knob_1.Value = app.Kto_default(1);
            app.p1Kto = app.Kto_default(1);
            app.p2Knob_1.Value = app.Kto_default(2);
            app.p2Kto = app.Kto_default(2);
            app.p3Knob_1.Value = app.Kto_default(3);
            app.p3Kto = app.Kto_default(3);
            app.p4Knob_1.Value = app.Kto_default(4);
            app.p4Kto = app.Kto_default(4);
            app.p5Knob_1.Value = app.Kto_default(5);
            app.p5Kto = app.Kto_default(5);
            app.p6Knob_1.Value = app.Kto_default(6);
            app.p6Kto = app.Kto_default(6);
            app.p7Knob_1.Value = app.Kto_default(7);
            app.p7Kto = app.Kto_default(7);
            app.p8Knob_1.Value = app.Kto_default(8);
            app.p8Kto = app.Kto_default(8);
            app.p9Knob_1.Value = app.Kto_default(9);
            app.p9Kto = app.Kto_default(9);
            app.p10Knob_1.Value = app.Kto_default(10);
            app.p10Kto = app.Kto_default(10);
            app.p11Knob_1.Value = app.Kto_default(11);
            app.p11Kto = app.Kto_default(11);
            app.p12Knob_1.Value = app.Kto_default(12);
            app.p12Kto = app.Kto_default(12);
            app.p13Knob_1.Value = app.Kto_default(13);
            app.p13Kto = app.Kto_default(13);
            app.p14Knob_1.Value = app.Kto_default(14);
            app.p14Kto = app.Kto_default(14);
            app.fKtoKnob.Value = app.Kto_default(15);
            app.fKto = app.Kto_default(15);
            app.GKtoKnob.Value = app.Kto_default(16);
            app.GKto = app.Kto_default(16);
            app.GKtopKnob.Value = app.Kto_default(17);
            app.GKtop = app.Kto_default(17);
        end
        
        function reset_kslow1(app)
            % shared
            app.p1Knob_2.Value = app.Kslow1_default(1);
            app.p1Kslow1 = app.Kslow1_default(1);
            app.p1Knob_3.Value = app.Kslow1_default(1);
            app.p1Knob_4.Value = app.Kslow1_default(1);
            
            app.p2Knob_2.Value = app.Kslow1_default(2);
            app.p2Kslow1 = app.Kslow1_default(2);
            app.p2Knob_3.Value = app.Kslow1_default(2);
            
            app.p3Knob_2.Value = app.Kslow1_default(3);
            app.p3Kslow1 = app.Kslow1_default(3);
            app.p3Knob_3.Value = app.Kslow1_default(3);
            app.p2Knob_4.Value = app.Kslow1_default(3);
            
            app.p4Knob_2.Value = app.Kslow1_default(4);
            app.p4Kslow1 = app.Kslow1_default(4);
            app.p4Knob_3.Value = app.Kslow1_default(4);
            app.p3Knob_4.Value = app.Kslow1_default(4);
            
            app.p5Knob_2.Value = app.Kslow1_default(5);
            app.p5Kslow1 = app.Kslow1_default(5);
            app.p5Knob_3.Value = app.Kslow1_default(5);
            
            app.p6Knob_2.Value = app.Kslow1_default(6);
            app.p6Kslow1 = app.Kslow1_default(6);
            app.p6Knob_3.Value = app.Kslow1_default(6);
            
            app.p7Knob_2.Value = app.Kslow1_default(7);
            app.p7Kslow1 = app.Kslow1_default(7);
            app.p7Knob_3.Value = app.Kslow1_default(7);
            
            app.p8Knob_2.Value = app.Kslow1_default(8);
            app.p8Kslow1 = app.Kslow1_default(8);
            app.p8Knob_3.Value = app.Kslow1_default(8);
            
            app.GKslow1Knob.Value = app.Kslow1_default(12);
            app.GKslow1 = app.Kslow1_default(12);
            app.GKslow2Knob.Value = app.Kslow1_default(12); 
            
            % unique
            app.p9Knob_2.Value = app.Kslow1_default(9);
            app.p9Kslow1 = app.Kslow1_default(9);
            app.p10Knob_2.Value = app.Kslow1_default(10);
            app.p10Kslow1 = app.Kslow1_default(10);
            app.fKslow1Knob.Value = app.Kslow1_default(11);
            app.fKslow1 = app.Kslow1_default(11);
            app.GKslow1pKnob.Value = app.Kslow1_default(13);
            app.GKslow1p = app.Kslow1_default(13);
        end
        
        function reset_kslow2(app)
            % shared
            app.p1Knob_2.Value = app.Kslow1_default(1);
            app.p1Kslow1 = app.Kslow1_default(1);
            app.p1Knob_3.Value = app.Kslow1_default(1);
            app.p1Knob_4.Value = app.Kslow1_default(1);
            
            app.p2Knob_2.Value = app.Kslow1_default(2);
            app.p2Kslow1 = app.Kslow1_default(2);
            app.p2Knob_3.Value = app.Kslow1_default(2);
            
            app.p3Knob_2.Value = app.Kslow1_default(3);
            app.p3Kslow1 = app.Kslow1_default(3);
            app.p3Knob_3.Value = app.Kslow1_default(3);
            app.p2Knob_4.Value = app.Kslow1_default(3);
            
            app.p4Knob_2.Value = app.Kslow1_default(4);
            app.p4Kslow1 = app.Kslow1_default(4);
            app.p4Knob_3.Value = app.Kslow1_default(4);
            app.p3Knob_4.Value = app.Kslow1_default(4);
            
            app.p5Knob_2.Value = app.Kslow1_default(5);
            app.p5Kslow1 = app.Kslow1_default(5);
            app.p5Knob_3.Value = app.Kslow1_default(5);
            
            app.p6Knob_2.Value = app.Kslow1_default(6);
            app.p6Kslow1 = app.Kslow1_default(6);
            app.p6Knob_3.Value = app.Kslow1_default(6);
            
            app.p7Knob_2.Value = app.Kslow1_default(7);
            app.p7Kslow1 = app.Kslow1_default(7);
            app.p7Knob_3.Value = app.Kslow1_default(7);
            
            app.p8Knob_2.Value = app.Kslow1_default(8);
            app.p8Kslow1 = app.Kslow1_default(8);
            app.p8Knob_3.Value = app.Kslow1_default(8);
            
            app.GKslow1Knob.Value = app.Kslow1_default(12);
            app.GKslow1 = app.Kslow1_default(12);
            app.GKslow2Knob.Value = app.Kslow1_default(12);
            
            % unique
            app.p9Knob_3.Value = app.Kslow2_default(1);
            app.p9Kslow2 = app.Kslow2_default(1);
            app.p10Knob_3.Value = app.Kslow2_default(2);
            app.p10Kslow2 = app.Kslow2_default(2);
        end
        
        function reset_Kss(app)
            % shared
            app.p1Knob_2.Value = app.Kslow1_default(1);
            app.p1Kslow1 = app.Kslow1_default(1);
            app.p1Knob_3.Value = app.Kslow1_default(1);
            app.p1Knob_4.Value = app.Kslow1_default(1);
            
            app.p3Knob_2.Value = app.Kslow1_default(3);
            app.p3Kslow1 = app.Kslow1_default(3);
            app.p3Knob_3.Value = app.Kslow1_default(3);
            app.p2Knob_4.Value = app.Kslow1_default(3);
            
            app.p4Knob_2.Value = app.Kslow1_default(4);
            app.p4Kslow1 = app.Kslow1_default(4);
            app.p4Knob_3.Value = app.Kslow1_default(4);
            app.p3Knob_4.Value = app.Kslow1_default(4);
            
            % unique
            app.p4Knob_4.Value = app.Kss_default(1);
            app.p5Knob_4.Value = app.Kss_default(2);
            app.p6Knob_4.Value = app.Kss_default(3);
            app.GKss.Value = app.Kss_default(4);
        end
        
        function update_sum_current(app)
            for i=1:app.num_volts
                app.yksum_set(:,i) = app.ykto_set(:,i) + app.ykslow1_set(:,i) + app.ykslow2_set(:,i);
            end
        end
    end
    
    methods (Access = public)
        % public functions for allowing dialog apps to access
        function update_kto_dialog(app, param)
            % update knobs
            app.p1Knob_1.Value = param(1);
            app.p2Knob_1.Value = param(2);
            app.p3Knob_1.Value = param(3);
            app.p4Knob_1.Value = param(4);
            app.p5Knob_1.Value = param(5);
            app.p6Knob_1.Value = param(6);
            app.p7Knob_1.Value = param(7);
            app.p8Knob_1.Value = param(8);
            app.p9Knob_1.Value = param(9);
            app.p10Knob_1.Value = param(10);
            app.p11Knob_1.Value = param(11);
            app.p12Knob_1.Value = param(12);
            app.p13Knob_1.Value = param(13);
            app.p14Knob_1.Value = param(14);
            app.fKtoKnob.Value = param(15);
            app.GKtoKnob.Value = param(16);
            app.GKtopKnob.Value = param(17);
            
            % update dialog box
            app.p1Kto = param(1);
            app.p2Kto = param(2);
            app.p3Kto = param(3);
            app.p4Kto = param(4);
            app.p5Kto = param(5);
            app.p6Kto = param(6);
            app.p7Kto = param(7);
            app.p8Kto = param(8);
            app.p9Kto = param(9);
            app.p10Kto = param(10);
            app.p11Kto = param(11);
            app.p12Kto = param(12);
            app.p13Kto = param(13);
            app.p14Kto = param(14);
            app.fKto = param(15);
            app.GKto = param(16);
            app.GKtop = param(17);
            
            % generate new IKto and update plots
            app.ykto_set = gen_currents_over_volts(app, @gen_IKto);
            update_sum_current(app);
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 1);
        end
        
        function update_kslow1_dialog(app, param)
            % update knobs
            app.p1Knob_2.Value = param(1);
            app.p2Knob_2.Value = param(2);
            app.p3Knob_2.Value = param(3);
            app.p4Knob_2.Value = param(4);
            app.p5Knob_2.Value = param(5);
            app.p6Knob_2.Value = param(6);
            app.p7Knob_2.Value = param(7);
            app.p8Knob_2.Value = param(8);
            app.p9Knob_2.Value = param(9);
            app.p10Knob_2.Value = param(10);
            app.fKslow1Knob.Value = param(11);
            app.GKslow1Knob.Value = param(12);
            app.GKslow1pKnob.Value = param(13);
            
            % update dialog box
            app.p1Kslow1 = param(1);
            app.p2Kslow1 = param(2);
            app.p3Kslow1 = param(3);
            app.p4Kslow1 = param(4);
            app.p5Kslow1 = param(5);
            app.p6Kslow1 = param(6);
            app.p7Kslow1 = param(7);
            app.p8Kslow1 = param(8);
            app.p9Kslow1 = param(9);
            app.p10Kslow1 = param(10);
            app.fKslow1 = param(11);
            app.GKslow1 = param(12);
            app.GKslow1p = param(13);
            
            % generate new IKslow1 and update plots
            app.ykslow1_set = gen_currents_over_volts(app, @gen_IKslow1);
            update_sum_current(app);
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 2);            
        end
        
        function update_kslow2_dialog(app, param)
            % update knobs
            app.p1Knob_3.Value = param(1);
            app.p2Knob_3.Value = param(2);
            app.p3Knob_3.Value = param(3);
            app.p4Knob_3.Value = param(4);
            app.p5Knob_3.Value = param(5);
            app.p6Knob_3.Value = param(6);
            app.p7Knob_3.Value = param(7);
            app.p8Knob_3.Value = param(8);
            app.p9Knob_3.Value = param(9);
            app.p10Knob_3.Value = param(10);
            app.GKslow2Knob.Value = param(11);
            
            % update dialog box: shared
            app.p1Kslow1 = param(1);
            app.p2Kslow1 = param(2);
            app.p3Kslow1 = param(3);
            app.p4Kslow1 = param(4);
            app.p5Kslow1 = param(5);
            app.p6Kslow1 = param(6);
            app.p7Kslow1 = param(7);
            app.p8Kslow1 = param(8);
            
            % update dialog box: unique
            app.p9Kslow2 = param(9);
            app.p10Kslow2 = param(10);
            
            % generate new IKslow2 and update plots
            app.ykslow2_set = gen_currents_over_volts(app, @gen_IKslow2);
            update_sum_current(app);
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 3);  
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % assign default parameter values IKto
            app.Kto_default(1) = app.p1Kto;
            app.Kto_default(2) = app.p2Kto;
            app.Kto_default(3) = app.p3Kto;
            app.Kto_default(4) = app.p4Kto;
            app.Kto_default(5) = app.p5Kto;
            app.Kto_default(6) = app.p6Kto;
            app.Kto_default(7) = app.p7Kto;
            app.Kto_default(8) = app.p8Kto;
            app.Kto_default(9) = app.p9Kto;
            app.Kto_default(10) = app.p10Kto;
            app.Kto_default(11) = app.p11Kto;
            app.Kto_default(12) = app.p12Kto;
            app.Kto_default(13) = app.p13Kto;
            app.Kto_default(14) = app.p14Kto;
            app.Kto_default(15) = app.fKto;
            app.Kto_default(16) = app.GKto;
            app.Kto_default(17) = app.GKtop;
            
            % assign default parameter values IKslow1
            app.Kslow1_default(1) = app.p1Kslow1;
            app.Kslow1_default(2) = app.p2Kslow1;
            app.Kslow1_default(3) = app.p3Kslow1;
            app.Kslow1_default(4) = app.p4Kslow1;
            app.Kslow1_default(5) = app.p5Kslow1;
            app.Kslow1_default(6) = app.p6Kslow1;
            app.Kslow1_default(7) = app.p7Kslow1;
            app.Kslow1_default(8) = app.p8Kslow1;
            app.Kslow1_default(9) = app.p9Kslow1;
            app.Kslow1_default(10) = app.p10Kslow1;
            app.Kslow1_default(11) = app.fKslow1;
            app.Kslow1_default(12) = app.GKslow1;
            app.Kslow1_default(13) = app.GKslow1p;      
            
            % assign default parameter values IKslow2
            app.Kslow2_default(1) = app.p9Kslow2;
            app.Kslow2_default(2) = app.p10Kslow2;
            
            % assign default parameter values IKss
            app.Kss_default(1) = 0.0862;
            app.Kss_default(2) = 1235.5;
            app.Kss_default(3) = 13.17;
            app.Kss_default(4) = 0.0428;
            
            % generate time space
            [app.t, app.hold_t, app.pulse_t] = gen_time_space(app);
            
            % generate clamping voltages based on protocol infomation
            [app.volts, app.num_volts] = gen_volts(app);
            
            % voltage drop-down menu
            volt_dd_items = strings(1, app.num_volts);
            for i=1:app.num_volts
                volt_dd_items(i) = strcat(num2str(app.volts(i)), " mV");
            end
            app.SelectVoltageDropDown.Items = volt_dd_items;
            
            % plot default voltage clamp protocol
            plot_protocol(app);
            
            % generate individual K+ currents
            app.ykto_set = gen_currents_over_volts(app, @gen_IKto);
            app.ykslow1_set = gen_currents_over_volts(app, @gen_IKslow1);
            app.ykslow2_set = gen_currents_over_volts(app, @gen_IKslow2);
            app.ykss_set = gen_currents_over_volts(app, @gen_IKss);
            
            % calculate amd plot Iksum
            app.yksum_set = app.ykto_set + app.ykslow1_set + app.ykslow2_set + app.ykss_set;
            plot_sum_currents_allvolts(app);
            
            % plot individual K+ currents
            app.volt_idx = 1;
            plot_currents_onevolt(app, app.volt_idx, false);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {874, 874};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {495, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.KtoDialogApp)
            delete(app.Kslow1DialogApp)
            delete(app.Kslow2DialogApp)
            delete(app.WarnApp)
            delete(app)
        end

        % Button pushed function: ProtocolVisualizationUpdateButton
        function ProtocolVisualizationUpdateButtonPushed(app, event)
            % update time space
            [app.t, app.hold_t, app.pulse_t] = gen_time_space(app);
            
            % update voltages
            [app.volts, app.num_volts] = gen_volts(app);
            
            % voltage drop-down menu
            volt_dd_items = strings(1, app.num_volts);
            for i=1:app.num_volts
                volt_dd_items(i) = strcat(num2str(app.volts(i)), " mV");
            end
            app.SelectVoltageDropDown.Items = volt_dd_items;
            
            % update protocl visualization
            plot_protocol(app);

            % update and plot currents with new protocol
            app.ykto_set = gen_currents_over_volts(app, @gen_IKto);
            app.ykslow1_set = gen_currents_over_volts(app, @gen_IKslow1);
            app.ykslow2_set = gen_currents_over_volts(app, @gen_IKslow2);
            app.ykss_set = gen_currents_over_volts(app, @gen_IKss);
            app.yksum_set = app.ykto_set + app.ykslow1_set + app.ykslow2_set + app.ykss_set;
            
            app.volt_idx = 1;
            app.SelectVoltageDropDown.Value = volt_dd_items(1);
            plot_currents_onevolt(app, app.volt_idx, false);
            plot_sum_currents_allvolts(app);
        end

        % Button pushed function: DatauploadButton
        function DatauploadButtonPushed(app, event)
            [file,path] = uigetfile(["*.csv";"*.xlsx"], "File Selector");
            exp_data = table2array(readtable(fullfile(path, file)));
                        
            app.exp_t = exp_data(:,1);
            app.exp_yksum_set = exp_data(:,2:end);

            % meta info
            app.exp_idx = true;
            [~, app.exp_num_volts] = size(app.exp_yksum_set);
            
            plot(app.UIAxesBottomLeft, app.exp_t, app.exp_yksum_set(:,1))
            hold(app.UIAxesBottomLeft, "on")
            for i=2:app.exp_num_volts
                plot(app.UIAxesBottomLeft, app.exp_t, app.exp_yksum_set(:,i))
            end
            hold(app.UIAxesBottomLeft, "off")
            axis(app.UIAxesBottomLeft, "tight")
            
            plot_currents_onevolt(app, app.volt_idx, false);
        end

        % Button pushed function: ModelCalibrationButton
        function ModelCalibrationButtonPushed(app, event)
            % estimate holding time
            [~, ideal_hold_idx] = min(abs(app.exp_t - app.HoldingtimemsEditField.Value));
            
            % generate time space
            time_space = cell(1,3);
            time_space{1} = app.exp_t;
            time_space{2} = app.exp_t(1:ideal_hold_idx);
            local_pulse_t = app.exp_t(ideal_hold_idx+1:end);
            pulse_t_adj = local_pulse_t - local_pulse_t(1);
            time_space{3} = pulse_t_adj;
            
            % optimization constraints 
            A = [];
            b = [];
            Aeq = [];
            beq = [];
            lb = [];
            ub = [];
            nonlcon = [];
            
            p0 = [33,15.5,20,8,7,0.3956,0.00095,0.051335,0.14067,0.387,22.5,45.2,40,2.058,803,0.05766,0.07496,5334,13.17,0.0428];
            lb = p0 - 10*p0;
            ub = p0 + 10*p0;
            lb([5, 6, 7, 8, 9, 10, 14, 15, 16, 17, 18, 19, 20]) = eps;
            
            low = p0 - 2*p0;
            high = p0 + 2*p0;
            low([5, 6, 7, 8, 9, 10, 14, 15, 16, 17, 18, 19, 20]) = eps;
            
            options = optimoptions('fmincon', 'MaxFunctionEvaluations',1e+3);
            % obj_fn(p0, app.HoldingpotentialmVEditField.Value, app.volts, time_space, app.exp_yksum_set, app.NernstpotentialEkmVEditField.Value, true)
            opt_fun = @(p) obj_fn(p, app.HoldingpotentialmVEditField.Value, app.volts, ...
                time_space, app.exp_yksum_set, app.NernstpotentialEkmVEditField.Value, true);
            
            num_iters = 3;
            rmse_list = zeros(num_iters, 1);
            sol_list = cell(num_iters, 1);
            
            % first run with p0
            [sol, fval] = fmincon(opt_fun, p0, A, b, Aeq, beq, lb, ub, nonlcon, options);
            sol_list{1} = sol;
            rmse_list(1) = fval;
            
            for i = 2:num_iters
                % random intialization
                running_p0 = zeros(length(p0), 1);
                for j = 1:length(p0)
                    unif_dist = makedist('Uniform', 'lower',low(j), 'upper',high(j));
                    running_p0(j) = random(unif_dist, 1);
                end
                
                % optimization
                [sol, fval] = fmincon(opt_fun, running_p0, A, b, Aeq, beq, lb, ub, nonlcon, options);
                sol_list{i} = sol;
                rmse_list(i) = fval;
            end
            
            % select best solution
            [~, best_fit_idx] = min(rmse_list);
            sol = sol_list{best_fit_idx};
            
            % visualize calibration result
            [~, ~, ~, ~, yksum_hat] = reduced_model(sol, app.HoldingpotentialmVEditField.Value, app.volts(1), ...
                time_space, app.NernstpotentialEkmVEditField.Value);
            
            local_t = time_space{1};
            plot(local_t, yksum_hat)
            hold on
            for i=2:app.num_volts
                [~, ~, ~, ~, yksum_hat] = reduced_model(sol, app.HoldingpotentialmVEditField.Value, app.volts(i), ...
                    time_space, app.NernstpotentialEkmVEditField.Value);
                plot(local_t, yksum_hat)
            end
            hold off
            axis tight
            xlabel('Time (ms)')
            ylabel('Current (pA/pF)')
        end

        % Value changed function: SelectVoltageDropDown
        function SelectVoltageDropDownValueChanged(app, event)
            value = app.SelectVoltageDropDown.Value;
            volt = split(value, " ");
            volt = str2double(volt(1));
            app.volt_idx = find(app.volts == volt);
            
            plot_currents_onevolt(app, app.volt_idx, false);
        end

        % Button pushed function: HighlightShowEquationsButton_1
        function HighlightShowEquationsButton_1_1Pushed(app, event)
            % hightlight IKto (index 1)
            plot_currents_onevolt(app, app.volt_idx, 1);
            
            % open IKto dialog
            app.KtoDialogApp = IKto_dialog(app);
        end

        % Button pushed function: ResetButton_1
        function ResetButton_1_1Pushed(app, event)
            % reset parameters of IKto to default
            reset_kto(app);
            
            app.ykto_set = gen_currents_over_volts(app, @gen_IKto);
            update_sum_current(app);
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 1);            
        end

        % Value changing function: p1Knob_1
        function p1Knob_1ValueChanging(app, event)
            changingValue = event.Value;
            app.p1Knob_1.Value = changingValue;
            app.p1Kto = changingValue;
            
            app.ykto_set = gen_currents_over_volts(app, @gen_IKto);
            update_sum_current(app);
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 1);
        end

        % Value changing function: p2Knob_1
        function p2Knob_1ValueChanging(app, event)
            changingValue = event.Value;
            app.p2Knob_1.Value = changingValue;
            app.p2Kto = changingValue;
            
            app.ykto_set = gen_currents_over_volts(app, @gen_IKto);
            update_sum_current(app);
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 1);
        end

        % Value changing function: p3Knob_1
        function p3Knob_1ValueChanging(app, event)
            changingValue = event.Value;
            app.p3Knob_1.Value = changingValue;
            app.p3Kto = changingValue;
            
            app.ykto_set = gen_currents_over_volts(app, @gen_IKto);
            update_sum_current(app);
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 1);
        end

        % Value changing function: p4Knob_1
        function p4Knob_1ValueChanging(app, event)
            changingValue = event.Value;
            app.p4Knob_1.Value = changingValue;
            app.p4Kto = changingValue;
            
            app.ykto_set = gen_currents_over_volts(app, @gen_IKto);
            update_sum_current(app);
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 1);
        end

        % Value changing function: p5Knob_1
        function p5Knob_1ValueChanging(app, event)
            changingValue = event.Value;
            app.p5Knob_1.Value = changingValue;
            app.p5Kto = changingValue;
            
            app.ykto_set = gen_currents_over_volts(app, @gen_IKto);
            update_sum_current(app);
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 1);
        end

        % Value changing function: p6Knob_1
        function p6Knob_1ValueChanging(app, event)
            changingValue = event.Value;
            app.p6Knob_1.Value = changingValue;
            app.p6Kto = changingValue;
            
            app.ykto_set = gen_currents_over_volts(app, @gen_IKto);
            update_sum_current(app);
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 1);
        end

        % Value changing function: p7Knob_1
        function p7Knob_1ValueChanging(app, event)
            changingValue = event.Value;
            app.p7Knob_1.Value = changingValue;
            app.p7Kto = changingValue;
            
            app.ykto_set = gen_currents_over_volts(app, @gen_IKto);
            update_sum_current(app);
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 1);
        end

        % Value changing function: p8Knob_1
        function p8Knob_1ValueChanging(app, event)
            changingValue = event.Value;
            app.p8Knob_1.Value = changingValue;
            app.p8Kto = changingValue;
            
            app.ykto_set = gen_currents_over_volts(app, @gen_IKto);
            update_sum_current(app);
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 1);
        end

        % Value changing function: p9Knob_1
        function p9Knob_1ValueChanging(app, event)
            changingValue = event.Value;
            app.p9Knob_1.Value = changingValue;
            app.p9Kto = changingValue;
            
            app.ykto_set = gen_currents_over_volts(app, @gen_IKto);
            update_sum_current(app);
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 1);
        end

        % Value changing function: p10Knob_1
        function p10Knob_1ValueChanging(app, event)
            changingValue = event.Value;
            app.p10Knob_1.Value = changingValue;
            app.p10Kto = changingValue;
            
            app.ykto_set = gen_currents_over_volts(app, @gen_IKto);
            update_sum_current(app);
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 1);
        end

        % Value changing function: p11Knob_1
        function p11Knob_1ValueChanging(app, event)
            changingValue = event.Value;
            app.p11Knob_1.Value = changingValue;
            app.p11Kto = changingValue;
            
            app.ykto_set = gen_currents_over_volts(app, @gen_IKto);
            update_sum_current(app);
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 1);
        end

        % Value changing function: p12Knob_1
        function p12Knob_1ValueChanging(app, event)
            changingValue = event.Value;
            app.p12Knob_1.Value = changingValue;
            app.p12Kto = changingValue;
            
            app.ykto_set = gen_currents_over_volts(app, @gen_IKto);
            update_sum_current(app);
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 1);
        end

        % Value changing function: p13Knob_1
        function p13Knob_1ValueChanging(app, event)
            changingValue = event.Value;
            app.p13Knob_1.Value = changingValue;
            app.p13Kto = changingValue;
            
            app.ykto_set = gen_currents_over_volts(app, @gen_IKto);
            update_sum_current(app);
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 1);
        end

        % Value changing function: p14Knob_1
        function p14Knob_1ValueChanging(app, event)
            changingValue = event.Value;
            app.p14Knob_1.Value = changingValue;
            app.p14Kto = changingValue;
            
            app.ykto_set = gen_currents_over_volts(app, @gen_IKto);
            update_sum_current(app);
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 1);
        end

        % Value changing function: fKtoKnob
        function fKtoKnobValueChanging(app, event)
            changingValue = event.Value;
            app.fKtoKnob.Value = changingValue;
            app.fKto = changingValue;
            
            app.ykto_set = gen_currents_over_volts(app, @gen_IKto);
            update_sum_current(app);
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 1);
        end

        % Value changing function: GKtoKnob
        function GKtoKnobValueChanging2(app, event)
            changingValue = event.Value;
            app.GKtoKnob.Value = changingValue;
            app.GKto = changingValue;
            
            app.ykto_set = gen_currents_over_volts(app, @gen_IKto);
            update_sum_current(app);
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 1);
        end

        % Value changing function: GKtopKnob
        function GKtopKnobValueChanging(app, event)
            changingValue = event.Value;
            app.GKtopKnob.Value = changingValue;
            app.GKtop = changingValue;
            
            app.ykto_set = gen_currents_over_volts(app, @gen_IKto);
            update_sum_current(app);
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 1);
        end

        % Button pushed function: HighlightShowEquationsButton_2
        function HighlightShowEquationsButton_2Pushed(app, event)
            % highlight IKslow1 (index2)
            plot_currents_onevolt(app, app.volt_idx, 2);
            
            % open IKslow1 dialog
            app.Kslow1DialogApp = IKslow1_dialog(app);
        end

        % Button pushed function: ResetButton_2
        function ResetButton_2Pushed(app, event)
            % reset parameters of IKslow1 to default
            reset_kslow1(app);
            
            app.ykslow1_set = gen_currents_over_volts(app, @gen_IKslow1);
            app.ykslow2_set = gen_currents_over_volts(app, @gen_IKslow2);
            app.ykss_set = gen_currents_over_volts(app, @gen_IKss);

            update_sum_current(app);
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 2); 
        end

        % Value changing function: p1Knob_2
        function p1Knob_2ValueChanging(app, event)
            changingValue = event.Value;
            
            % update IKslow1 knob and public variable
            app.p1Knob_2.Value = changingValue;
            app.p1Kslow1 = changingValue;
            app.ykslow1_set = gen_currents_over_volts(app, @gen_IKslow1);
            
            % update IKslow2 knob and public variable
            app.p1Knob_3.Value = changingValue;
            app.ykslow2_set = gen_currents_over_volts(app, @gen_IKslow2);
            
            % update IKss knob
            app.p1Knob_4.Value = changingValue;
            app.ykss_set = gen_currents_over_volts(app, @gen_IKss);
            
            % update IKsum
            update_sum_current(app);
            
            % update figures
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 2);             
        end

        % Value changing function: p2Knob_2
        function p2Knob_2ValueChanging(app, event)
            changingValue = event.Value;

            % update IKslow1 knob and public variable
            app.p2Knob_2.Value = changingValue;
            app.p2Kslow1 = changingValue;
            app.ykslow1_set = gen_currents_over_volts(app, @gen_IKslow1);
            
            % update IKslow2 knob and public variable
            app.p2Knob_3.Value = changingValue;
            app.ykslow2_set = gen_currents_over_volts(app, @gen_IKslow2);
           
            % update IKsum
            update_sum_current(app);            
            
            % update figures
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 2);
        end

        % Value changing function: p3Knob_2
        function p3Knob_2ValueChanging(app, event)
            changingValue = event.Value;
            
            % update IKslow1 knob and public variable
            app.p3Knob_2.Value = changingValue;
            app.p3Kslow1 = changingValue;
            app.ykslow1_set = gen_currents_over_volts(app, @gen_IKslow1);
            
            % update IKslow2 knob and public variable
            app.p3Knob_3.Value = changingValue;
            app.ykslow2_set = gen_currents_over_volts(app, @gen_IKslow2);
            
            % update IKss knob
            app.p2Knob_4.Value = changingValue;
            app.ykss_set = gen_currents_over_volts(app, @gen_IKss);
            
            % update IKsum
            update_sum_current(app);
            
            % update figures
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 2);                    
        end

        % Value changing function: p4Knob_2
        function p4Knob_2ValueChanging(app, event)
            changingValue = event.Value;
            
            % update IKslow1 knob and public variable
            app.p4Knob_2.Value = changingValue;
            app.p4Kslow1 = changingValue;
            app.ykslow1_set = gen_currents_over_volts(app, @gen_IKslow1);
            
            % update IKslow2 knob and public variable
            app.p4Knob_3.Value = changingValue;
            app.ykslow2_set = gen_currents_over_volts(app, @gen_IKslow2);
            
            % update IKss knob
            app.p3Knob_4.Value = changingValue;
            app.ykss_set = gen_currents_over_volts(app, @gen_IKss);
            
            % update IKsum
            update_sum_current(app);
            
            % update figures
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 2);                    
        end

        % Value changing function: p5Knob_2
        function p5Knob_2ValueChanging(app, event)
            changingValue = event.Value;
            
            % update IKslow1 knob and public variable
            app.p5Knob_2.Value = changingValue;
            app.p5Kslow1 = changingValue;
            app.ykslow1_set = gen_currents_over_volts(app, @gen_IKslow1);
            
            % update IKslow2 knob and public variable
            app.p5Knob_3.Value = changingValue;
            app.ykslow2_set = gen_currents_over_volts(app, @gen_IKslow2);
           
            % update IKsum
            update_sum_current(app);
            
            % update figures
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 2);            
        end

        % Value changing function: p6Knob_2
        function p6Knob_2ValueChanging(app, event)
            changingValue = event.Value;
            
            % update IKslow1 knob and public variable
            app.p6Knob_2.Value = changingValue;
            app.p6Kslow1 = changingValue;
            app.ykslow1_set = gen_currents_over_volts(app, @gen_IKslow1);
            
            % update IKslow2 knob and public variable
            app.p6Knob_3.Value = changingValue;
            app.ykslow2_set = gen_currents_over_volts(app, @gen_IKslow2);
           
            % update IKsum
            update_sum_current(app);
            
            % update figures
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 2);            
        end

        % Value changing function: p7Knob_2
        function p7Knob_2ValueChanging(app, event)
            changingValue = event.Value;
            
            % update IKslow1 knob and public variable
            app.p7Knob_2.Value = changingValue;
            app.p7Kslow1 = changingValue;
            app.ykslow1_set = gen_currents_over_volts(app, @gen_IKslow1);
            
            % update IKslow2 knob and public variable
            app.p7Knob_3.Value = changingValue;
            app.ykslow2_set = gen_currents_over_volts(app, @gen_IKslow2);
           
            % update IKsum
            update_sum_current(app);
            
            % update figures
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 2);            
        end

        % Value changing function: p8Knob_2
        function p8Knob_2ValueChanging(app, event)
            changingValue = event.Value;
            
            % update IKslow1 knob and public variable
            app.p8Knob_2.Value = changingValue;
            app.p8Kslow1 = changingValue;
            app.ykslow1_set = gen_currents_over_volts(app, @gen_IKslow1);
            
            % update IKslow2 knob and public variable
            app.p8Knob_3.Value = changingValue;
            app.ykslow2_set = gen_currents_over_volts(app, @gen_IKslow2);
           
            % update IKsum
            update_sum_current(app);
            
            % update figures
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 2);            
        end

        % Value changing function: p9Knob_2
        function p9Knob_2ValueChanging(app, event)
            changingValue = event.Value;
            app.p9Knob_2.Value = changingValue;
            app.p9Kslow1 = changingValue;
            
            app.ykslow1_set = gen_currents_over_volts(app, @gen_IKslow1);
            
            % update IKsum
            update_sum_current(app);
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 2);
        end

        % Value changing function: p10Knob_2
        function p10Knob_2ValueChanging(app, event)
            changingValue = event.Value;
            app.p10Knob_2.Value = changingValue;
            app.p10Kslow1 = changingValue;
            
            app.ykslow1_set = gen_currents_over_volts(app, @gen_IKslow1);
            
            % update IKsum
            update_sum_current(app);
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 2);
        end

        % Value changing function: fKslow1Knob
        function fKslow1KnobValueChanging(app, event)
            changingValue = event.Value;
            app.fKslow1Knob.Value = changingValue;
            app.fKslow1 = changingValue;
            
            app.ykslow1_set = gen_currents_over_volts(app, @gen_IKslow1);
            
            % update IKsum
            update_sum_current(app);
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 2);
        end

        % Value changing function: GKslow1Knob
        function GKslow1KnobValueChanging(app, event)
            changingValue = event.Value;
            app.GKslow1Knob.Value = changingValue;
            app.GKslow1 = changingValue;
            
            app.ykslow1_set = gen_currents_over_volts(app, @gen_IKslow1);
            
            % update IKsum
            update_sum_current(app);
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 2);
        end

        % Value changing function: GKslow1pKnob
        function GKslow1pKnobValueChanging(app, event)
            changingValue = event.Value;
            app.GKslow1pKnob.Value = changingValue;
            app.GKslow1p = changingValue;
            
            app.ykslow1_set = gen_currents_over_volts(app, @gen_IKslow1);
            
            % update IKsum
            update_sum_current(app);
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 2);
        end

        % Button pushed function: HighlightShowEquationsButton_3
        function HighlightShowEquationsButton_3Pushed(app, event)
            % highlight IKslow2 (index3)
            plot_currents_onevolt(app, app.volt_idx, 3);
            
            % open IKslow2 dialog
            app.Kslow2DialogApp = IKslow2_dialog(app);
        end

        % Button pushed function: ResetButton_3
        function ResetButton_3Pushed(app, event)
            % reset parameters of IKslow2 to default
            reset_kslow2(app);
            
            app.ykslow1_set = gen_currents_over_volts(app, @gen_IKslow1);
            app.ykslow2_set = gen_currents_over_volts(app, @gen_IKslow2);
            app.ykss_set = gen_currents_over_volts(app, @gen_IKss);

            update_sum_current(app);
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 3);             
        end

        % Value changing function: p1Knob_3
        function p1Knob_3ValueChanging(app, event)
            changingValue = event.Value;
            
            % update IKslow1 knob and public variable
            app.p1Knob_2.Value = changingValue;
            app.p1Kslow1 = changingValue;
            app.ykslow1_set = gen_currents_over_volts(app, @gen_IKslow1);
            
            % update IKslow2 knob
            app.p1Knob_3.Value = changingValue;
            app.ykslow2_set = gen_currents_over_volts(app, @gen_IKslow2);
            
            % update IKss knob
            app.p1Knob_4.Value = changingValue;
            app.ykss_set = gen_currents_over_volts(app, @gen_IKss);
            
            % update IKsum
            update_sum_current(app);
            
            % update figures
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 3);        
        end

        % Value changing function: p2Knob_3
        function p2Knob_3ValueChanging(app, event)
            changingValue = event.Value;
            
            % update IKslow1 knob and public variable
            app.p2Knob_2.Value = changingValue;
            app.p2Kslow1 = changingValue;
            app.ykslow1_set = gen_currents_over_volts(app, @gen_IKslow1);
            
            % update IKslow2 knob
            app.p2Knob_3.Value = changingValue;
            app.ykslow2_set = gen_currents_over_volts(app, @gen_IKslow2);
            
            % update IKsum
            update_sum_current(app);
            
            % update figures
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 3);
        end

        % Value changing function: p3Knob_3
        function p3Knob_3ValueChanging(app, event)
            changingValue = event.Value;
            
            % update IKslow1 knob and public variable
            app.p3Knob_2.Value = changingValue;
            app.p3Kslow1 = changingValue;
            app.ykslow1_set = gen_currents_over_volts(app, @gen_IKslow1);
            
            % update IKslow2 knob
            app.p3Knob_3.Value = changingValue;
            app.ykslow2_set = gen_currents_over_volts(app, @gen_IKslow2);
            
            % update IKss knob
            app.p2Knob_4.Value = changingValue;
            app.ykss_set = gen_currents_over_volts(app, @gen_IKss);
            
            % update IKsum
            update_sum_current(app);
            
            % update figures
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 3);
        end

        % Value changing function: p4Knob_3
        function p4Knob_3ValueChanging(app, event)
            changingValue = event.Value;
            
            % update IKslow1 knob and public variable
            app.p4Knob_2.Value = changingValue;
            app.p4Kslow1 = changingValue;
            app.ykslow1_set = gen_currents_over_volts(app, @gen_IKslow1);
            
            % update IKslow2 knob
            app.p4Knob_3.Value = changingValue;
            app.ykslow2_set = gen_currents_over_volts(app, @gen_IKslow2);
            
            % update IKss knob
            app.p3Knob_4.Value = changingValue;
            app.ykss_set = gen_currents_over_volts(app, @gen_IKss);
            
            % update IKsum
            update_sum_current(app);
            
            % update figures
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 3);
        end

        % Value changing function: p5Knob_3
        function p5Knob_3ValueChanging(app, event)
            changingValue = event.Value;
            
            % update IKslow1 knob and public variable
            app.p5Knob_2.Value = changingValue;
            app.p5Kslow1 = changingValue;
            app.ykslow1_set = gen_currents_over_volts(app, @gen_IKslow1);
            
            % update IKslow2 knob
            app.p5Knob_3.Value = changingValue;
            app.ykslow2_set = gen_currents_over_volts(app, @gen_IKslow2);
            
            % update IKsum
            update_sum_current(app);
            
            % update figures
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 3);
        end

        % Value changing function: p6Knob_3
        function p6Knob_3ValueChanging(app, event)
            changingValue = event.Value;
            
            % update IKslow1 knob and public variable
            app.p6Knob_2.Value = changingValue;
            app.p6Kslow1 = changingValue;
            app.ykslow1_set = gen_currents_over_volts(app, @gen_IKslow1);
            
            % update IKslow2 knob
            app.p6Knob_3.Value = changingValue;
            app.ykslow2_set = gen_currents_over_volts(app, @gen_IKslow2);
            
            % update IKsum
            update_sum_current(app);
            
            % update figures
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 3);
        end

        % Value changing function: p7Knob_3
        function p7Knob_3ValueChanging(app, event)
            changingValue = event.Value;
            
            % update IKslow1 knob and public variable
            app.p7Knob_2.Value = changingValue;
            app.p7Kslow1 = changingValue;
            app.ykslow1_set = gen_currents_over_volts(app, @gen_IKslow1);
            
            % update IKslow2 knob
            app.p7Knob_3.Value = changingValue;
            app.ykslow2_set = gen_currents_over_volts(app, @gen_IKslow2);
            
            % update IKsum
            update_sum_current(app);
            
            % update figures
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 3);
        end

        % Value changing function: p8Knob_3
        function p8Knob_3ValueChanging(app, event)
            changingValue = event.Value;
            
            % update IKslow1 knob and public variable
            app.p8Knob_2.Value = changingValue;
            app.p8Kslow1 = changingValue;
            app.ykslow1_set = gen_currents_over_volts(app, @gen_IKslow1);
            
            % update IKslow2 knob
            app.p8Knob_3.Value = changingValue;
            app.ykslow2_set = gen_currents_over_volts(app, @gen_IKslow2);
            
            % update IKsum
            update_sum_current(app);
            
            % update figures
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 3);
        end

        % Value changing function: p9Knob_3
        function p9Knob_3ValueChanging(app, event)
            changingValue = event.Value;
            
            % update IKslow2 knob and public variable
            app.p9Knob_3.Value = changingValue;
            app.p9Kslow2 = changingValue;
            app.ykslow2_set = gen_currents_over_volts(app, @gen_IKslow2);
            
            % update IKsum
            update_sum_current(app);
            
            % update figures
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 3);
        end

        % Value changing function: p10Knob_3
        function p10Knob_3ValueChanging(app, event)
            changingValue = event.Value;
            
            % update IKslow2 knob and public variable
            app.p10Knob_3.Value = changingValue;
            app.p10Kslow2 = changingValue;
            app.ykslow2_set = gen_currents_over_volts(app, @gen_IKslow2);
            
            % update IKsum
            update_sum_current(app);
            
            % update figures
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 3);
        end

        % Value changing function: GKslow2Knob
        function GKslow2KnobValueChanging(app, event)
            changingValue = event.Value;
            
            % update IKslow1 knob and public variable
            app.GKslow1Knob.Value = changingValue;
            app.GKslow1 = changingValue;
            app.ykslow1_set = gen_currents_over_volts(app, @gen_IKslow1);
            
            % update IKslow2 knob
            app.GKslow2Knob.Value = changingValue;
            app.ykslow2_set = gen_currents_over_volts(app, @gen_IKslow2);
            
            % update IKsum
            update_sum_current(app);
            
            % update figures
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 3);
        end

        % Button pushed function: HightlightButton_4
        function HightlightButton_4Pushed(app, event)
            % highlight IKslow2 (index4)
            plot_currents_onevolt(app, app.volt_idx, 4);
        end

        % Button pushed function: ResetButton_4
        function ResetButton_4Pushed(app, event)
            % reset parameters of IKslow2 to default
            reset_Kss(app);
            
            app.ykslow1_set = gen_currents_over_volts(app, @gen_IKslow1);
            app.ykslow2_set = gen_currents_over_volts(app, @gen_IKslow2);
            app.ykss_set = gen_currents_over_volts(app, @gen_IKss);

            update_sum_current(app);
            
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 4);             
        end

        % Value changing function: p1Knob_4
        function p1Knob_4ValueChanging(app, event)
            changingValue = event.Value;
            
            % update IKslow1 knob and public variable
            app.p1Knob_2.Value = changingValue;
            app.p1Kslow1 = changingValue;
            app.ykslow1_set = gen_currents_over_volts(app, @gen_IKslow1);
            
            % update IKslow2 knob
            app.p1Knob_3.Value = changingValue;
            app.ykslow2_set = gen_currents_over_volts(app, @gen_IKslow2);
            
            % update IKss knob
            app.p1Knob_4.Value = changingValue;
            app.ykss_set = gen_currents_over_volts(app, @gen_IKss);
            
            % update IKsum
            update_sum_current(app);
            
            % update figures
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 4);
        end

        % Value changing function: p2Knob_4
        function p2Knob_4ValueChanging(app, event)
            changingValue = event.Value;
            
            % update IKslow1 knob and public variable
            app.p3Knob_2.Value = changingValue;
            app.p3Kslow1 = changingValue;
            app.ykslow1_set = gen_currents_over_volts(app, @gen_IKslow1);
            
            % update IKslow2 knob
            app.p3Knob_3.Value = changingValue;
            app.ykslow2_set = gen_currents_over_volts(app, @gen_IKslow2);
            
            % update IKss knob
            app.p2Knob_4.Value = changingValue;
            app.ykss_set = gen_currents_over_volts(app, @gen_IKss);
            
            % update IKsum
            update_sum_current(app);
            
            % update figures
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 4);
        end

        % Value changing function: p3Knob_4
        function p3Knob_4ValueChanging(app, event)
            changingValue = event.Value;
            
            % update IKslow1 knob and public variable
            app.p4Knob_2.Value = changingValue;
            app.p4Kslow1 = changingValue;
            app.ykslow1_set = gen_currents_over_volts(app, @gen_IKslow1);
            
            % update IKslow2 knob
            app.p4Knob_3.Value = changingValue;
            app.ykslow2_set = gen_currents_over_volts(app, @gen_IKslow2);
            
            % update IKss knob
            app.p3Knob_4.Value = changingValue;
            app.ykss_set = gen_currents_over_volts(app, @gen_IKss);
            
            % update IKsum
            update_sum_current(app);
            
            % update figures
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 4);
        end

        % Value changing function: p4Knob_4
        function p4Knob_4ValueChanging(app, event)
            changingValue = event.Value;
            
            % update IKss knob and Ikss
            app.p4Knob_4.Value = changingValue;
            app.ykss_set = gen_currents_over_volts(app, @gen_IKss);
            
            % update IKsum
            update_sum_current(app);
            
            % update figures
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 4);
        end

        % Value changing function: p5Knob_4
        function p5Knob_4ValueChanging(app, event)
            changingValue = event.Value;
            
            % update IKss knob and Ikss
            app.p5Knob_4.Value = changingValue;
            app.ykss_set = gen_currents_over_volts(app, @gen_IKss);
            
            % update IKsum
            update_sum_current(app);
            
            % update figures
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 4);
        end

        % Value changing function: p6Knob_4
        function p6Knob_4ValueChanging(app, event)
            changingValue = event.Value;
            
            % update IKss knob and Ikss
            app.p6Knob_4.Value = changingValue;
            app.ykss_set = gen_currents_over_volts(app, @gen_IKss);
            
            % update IKsum
            update_sum_current(app);
            
            % update figures
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 4);
        end

        % Value changing function: GKss
        function GKssValueChanging(app, event)
            changingValue = event.Value;
            
            % update IKss knob and Ikss
            app.GKss.Value = changingValue;
            app.ykss_set = gen_currents_over_volts(app, @gen_IKss);
            
            % update IKsum
            update_sum_current(app);
            
            % update figures
            plot_sum_currents_allvolts(app);
            plot_currents_onevolt(app, app.volt_idx, 4);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.ProtocolTab = uitab(app.TabGroup);
            app.ProtocolTab.Title = 'Protocol';
            app.HoldingtimemsEditFieldLabel = uilabel(app.ProtocolTab);
            app.HoldingtimemsEditFieldLabel.Text = 'Holding time (ms)';
            app.HoldingtimemsEditField = uieditfield(app.ProtocolTab, 'numeric');
            app.HoldingtimemsEditField.Value = 100;
            app.HoldingpotentialmVEditFieldLabel = uilabel(app.ProtocolTab);
            app.HoldingpotentialmVEditFieldLabel.Text = 'Holding potential (mV)';
            app.HoldingpotentialmVEditField = uieditfield(app.ProtocolTab, 'numeric');
            app.HoldingpotentialmVEditField.Value = -70;
            app.ClampingvoltageminmVEditFieldLabel = uilabel(app.ProtocolTab);
            app.ClampingvoltageminmVEditFieldLabel.Text = 'Clamping voltage min (mV)';
            app.ClampingvoltageminmVEditField = uieditfield(app.ProtocolTab, 'numeric');
            app.ClampingvoltageminmVEditField.Value = -50;
            app.ClampingtimemsEditFieldLabel = uilabel(app.ProtocolTab);
            app.ClampingtimemsEditFieldLabel.Text = 'Clamping time (ms)';
            app.ClampingtimemsEditField = uieditfield(app.ProtocolTab, 'numeric');
            app.ClampingtimemsEditField.ValueDisplayFormat = '%.0f';
            app.ClampingtimemsEditField.Value = 4500;
            app.ProtocolVisualizationUpdateButton = uibutton(app.ProtocolTab, 'push');
            app.ProtocolVisualizationUpdateButton.ButtonPushedFcn = createCallbackFcn(app, @ProtocolVisualizationUpdateButtonPushed, true);
            app.ProtocolVisualizationUpdateButton.Text = 'Protocol Visualization & Update';
            app.NernstpotentialEkmVLabel = uilabel(app.ProtocolTab);
            app.NernstpotentialEkmVLabel.Text = 'Nernst potential Ek (mV)';
            app.NernstpotentialEkmVEditField = uieditfield(app.ProtocolTab, 'numeric');
            app.NernstpotentialEkmVEditField.Value = -91.1;
            app.DatauploadButton = uibutton(app.ProtocolTab, 'push');
            app.DatauploadButton.ButtonPushedFcn = createCallbackFcn(app, @DatauploadButtonPushed, true);
            app.DatauploadButton.Text = 'Data upload';
            app.ClampingvoltagestepmVEditFieldLabel = uilabel(app.ProtocolTab);
            app.ClampingvoltagestepmVEditFieldLabel.Text = 'Clamping voltage step (mV)';
            app.ClampingvoltagestepmVEditField = uieditfield(app.ProtocolTab, 'numeric');
            app.ClampingvoltagestepmVEditField.Value = 10;
            app.ClampingvoltagemaxmVEditFieldLabel = uilabel(app.ProtocolTab);
            app.ClampingvoltagemaxmVEditFieldLabel.Text = 'Clamping voltage max (mV)';
            app.ClampingvoltagemaxmVEditField = uieditfield(app.ProtocolTab, 'numeric');
            app.ClampingvoltagemaxmVEditField.Value = 50;
            app.ModelCalibrationButton = uibutton(app.ProtocolTab, 'push');
            app.ModelCalibrationButton.ButtonPushedFcn = createCallbackFcn(app, @ModelCalibrationButtonPushed, true);
            app.ModelCalibrationButton.Text = 'Model Calibration';
            app.UIAxesTopLeft = uiaxes(app.ProtocolTab);
            title(app.UIAxesTopLeft, 'Protocol')
            xlabel(app.UIAxesTopLeft, 'Time (ms)')
            ylabel(app.UIAxesTopLeft, 'Voltage (mV)')
            zlabel(app.UIAxesTopLeft, 'Z')
            app.UIAxesBottomLeft = uiaxes(app.ProtocolTab);
            title(app.UIAxesBottomLeft, 'Experimental Data')
            xlabel(app.UIAxesBottomLeft, 'Time (ms)')
            ylabel(app.UIAxesBottomLeft, 'Current (pA/pF)')
            zlabel(app.UIAxesBottomLeft, 'Z')
            app.IKtoKv42Tab = uitab(app.TabGroup);
            app.IKtoKv42Tab.Title = 'IKto (Kv 4.2)';
            app.TextArea_1 = uitextarea(app.IKtoKv42Tab);
            app.TextArea_1.Value = {'IKto: Transient outward K+ current '};
            app.HighlightShowEquationsButton_1 = uibutton(app.IKtoKv42Tab, 'push');
            app.HighlightShowEquationsButton_1.ButtonPushedFcn = createCallbackFcn(app, @HighlightShowEquationsButton_1_1Pushed, true);
            app.HighlightShowEquationsButton_1.Text = 'Highlight & Show Equations';
            app.ResetButton_1 = uibutton(app.IKtoKv42Tab, 'push');
            app.ResetButton_1.ButtonPushedFcn = createCallbackFcn(app, @ResetButton_1_1Pushed, true);
            app.ResetButton_1.Text = 'Reset';
            app.fKtoKnobLabel = uilabel(app.IKtoKv42Tab);
            app.fKtoKnobLabel.Text = 'fKto';
            app.fKtoKnob = uiknob(app.IKtoKv42Tab, 'continuous');
            app.fKtoKnob.Limits = [0 1];
            app.fKtoKnob.ValueChangingFcn = createCallbackFcn(app, @fKtoKnobValueChanging, true);
            app.fKtoKnob.Value = 0.2087704319;
            app.GKtoKnobLabel = uilabel(app.IKtoKv42Tab);
            app.GKtoKnobLabel.Text = 'GKto';
            app.GKtoKnob = uiknob(app.IKtoKv42Tab, 'continuous');
            app.GKtoKnob.Limits = [0 1];
            app.GKtoKnob.ValueChangingFcn = createCallbackFcn(app, @GKtoKnobValueChanging2, true);
            app.GKtoKnob.Value = 0.14067;
            app.GKtopKnobLabel = uilabel(app.IKtoKv42Tab);
            app.GKtopKnobLabel.Text = 'GKto,p';
            app.GKtopKnob = uiknob(app.IKtoKv42Tab, 'continuous');
            app.GKtopKnob.Limits = [0 1];
            app.GKtopKnob.ValueChangingFcn = createCallbackFcn(app, @GKtopKnobValueChanging, true);
            app.GKtopKnob.Value = 0.387;
            app.p1Knob_6Label = uilabel(app.IKtoKv42Tab);
            app.p1Knob_6Label.Text = 'p1';
            app.p1Knob_1 = uiknob(app.IKtoKv42Tab, 'continuous');
            app.p1Knob_1.Limits = [-50 70];
            app.p1Knob_1.ValueChangingFcn = createCallbackFcn(app, @p1Knob_1ValueChanging, true);
            app.p1Knob_1.Value = 33;
            app.p2Knob_6Label = uilabel(app.IKtoKv42Tab);
            app.p2Knob_6Label.Text = 'p2';
            app.p2Knob_1 = uiknob(app.IKtoKv42Tab, 'continuous');
            app.p2Knob_1.Limits = [-50 50];
            app.p2Knob_1.ValueChangingFcn = createCallbackFcn(app, @p2Knob_1ValueChanging, true);
            app.p2Knob_1.Value = 15.5;
            app.p3Knob_6Label = uilabel(app.IKtoKv42Tab);
            app.p3Knob_6Label.Text = 'p3';
            app.p3Knob_1 = uiknob(app.IKtoKv42Tab, 'continuous');
            app.p3Knob_1.Limits = [-50 50];
            app.p3Knob_1.ValueChangingFcn = createCallbackFcn(app, @p3Knob_1ValueChanging, true);
            app.p3Knob_1.Value = 20;
            app.p4Knob_6Label = uilabel(app.IKtoKv42Tab);
            app.p4Knob_6Label.Text = 'p4';
            app.p4Knob_1 = uiknob(app.IKtoKv42Tab, 'continuous');
            app.p4Knob_1.Limits = [-10 40];
            app.p4Knob_1.ValueChangingFcn = createCallbackFcn(app, @p4Knob_1ValueChanging, true);
            app.p4Knob_1.Value = 16;
            app.p5Knob_6Label = uilabel(app.IKtoKv42Tab);
            app.p5Knob_6Label.Text = 'p5';
            app.p5Knob_1 = uiknob(app.IKtoKv42Tab, 'continuous');
            app.p5Knob_1.Limits = [0 50];
            app.p5Knob_1.ValueChangingFcn = createCallbackFcn(app, @p5Knob_1ValueChanging, true);
            app.p5Knob_1.Value = 8;
            app.p6Knob_5Label = uilabel(app.IKtoKv42Tab);
            app.p6Knob_5Label.Text = 'p6';
            app.p6Knob_1 = uiknob(app.IKtoKv42Tab, 'continuous');
            app.p6Knob_1.Limits = [0 30];
            app.p6Knob_1.ValueChangingFcn = createCallbackFcn(app, @p6Knob_1ValueChanging, true);
            app.p6Knob_1.Value = 7;
            app.p7Knob_5Label = uilabel(app.IKtoKv42Tab);
            app.p7Knob_5Label.Text = 'p7';
            app.p7Knob_1 = uiknob(app.IKtoKv42Tab, 'continuous');
            app.p7Knob_1.Limits = [0 1];
            app.p7Knob_1.ValueChangingFcn = createCallbackFcn(app, @p7Knob_1ValueChanging, true);
            app.p7Knob_1.Value = 0.03577;
            app.p8Knob_5Label = uilabel(app.IKtoKv42Tab);
            app.p8Knob_5Label.Text = 'p8';
            app.p8Knob_1 = uiknob(app.IKtoKv42Tab, 'continuous');
            app.p8Knob_1.Limits = [0 1];
            app.p8Knob_1.ValueChangingFcn = createCallbackFcn(app, @p8Knob_1ValueChanging, true);
            app.p8Knob_1.Value = 0.06237;
            app.p9Knob_5Label = uilabel(app.IKtoKv42Tab);
            app.p9Knob_5Label.Text = 'p9';
            app.p9Knob_1 = uiknob(app.IKtoKv42Tab, 'continuous');
            app.p9Knob_1.Limits = [0 1];
            app.p9Knob_1.ValueChangingFcn = createCallbackFcn(app, @p9Knob_1ValueChanging, true);
            app.p9Knob_1.Value = 0.18064;
            app.p10KnobLabel = uilabel(app.IKtoKv42Tab);
            app.p10KnobLabel.Text = 'p10';
            app.p10Knob_1 = uiknob(app.IKtoKv42Tab, 'continuous');
            app.p10Knob_1.Limits = [0 10];
            app.p10Knob_1.ValueChangingFcn = createCallbackFcn(app, @p10Knob_1ValueChanging, true);
            app.p10Knob_1.Value = 0.3956;
            app.p11KnobLabel = uilabel(app.IKtoKv42Tab);
            app.p11KnobLabel.Text = 'p11';
            app.p11Knob_1 = uiknob(app.IKtoKv42Tab, 'continuous');
            app.p11Knob_1.Limits = [0 0.005];
            app.p11Knob_1.ValueChangingFcn = createCallbackFcn(app, @p11Knob_1ValueChanging, true);
            app.p11Knob_1.Value = 0.000152;
            app.p12KnobLabel = uilabel(app.IKtoKv42Tab);
            app.p12KnobLabel.Text = 'p12';
            app.p12Knob_1 = uiknob(app.IKtoKv42Tab, 'continuous');
            app.p12Knob_1.Limits = [0 0.3];
            app.p12Knob_1.ValueChangingFcn = createCallbackFcn(app, @p12Knob_1ValueChanging, true);
            app.p12Knob_1.Value = 0.067083;
            app.p13KnobLabel = uilabel(app.IKtoKv42Tab);
            app.p13KnobLabel.Text = 'p13';
            app.p13Knob_1 = uiknob(app.IKtoKv42Tab, 'continuous');
            app.p13Knob_1.Limits = [0 0.005];
            app.p13Knob_1.ValueChangingFcn = createCallbackFcn(app, @p13Knob_1ValueChanging, true);
            app.p13Knob_1.Value = 0.00095;
            app.p14KnobLabel = uilabel(app.IKtoKv42Tab);
            app.p14KnobLabel.Text = 'p14';
            app.p14Knob_1 = uiknob(app.IKtoKv42Tab, 'continuous');
            app.p14Knob_1.Limits = [0 0.5];
            app.p14Knob_1.ValueChangingFcn = createCallbackFcn(app, @p14Knob_1ValueChanging, true);
            app.p14Knob_1.Value = 0.051335;
            app.IKslow1Kv15Tab = uitab(app.TabGroup);
            app.IKslow1Kv15Tab.Title = 'IKslow1 (Kv 1.5)';
            app.TextArea_2 = uitextarea(app.IKslow1Kv15Tab);
            app.TextArea_2.Value = {'IKslow1: Rapidly activating, slowly inactivating K+ current'};
            app.HighlightShowEquationsButton_2 = uibutton(app.IKslow1Kv15Tab, 'push');
            app.HighlightShowEquationsButton_2.ButtonPushedFcn = createCallbackFcn(app, @HighlightShowEquationsButton_2Pushed, true);
            app.HighlightShowEquationsButton_2.Text = 'Highlight & Show Equations';
            app.ResetButton_2 = uibutton(app.IKslow1Kv15Tab, 'push');
            app.ResetButton_2.ButtonPushedFcn = createCallbackFcn(app, @ResetButton_2Pushed, true);
            app.ResetButton_2.Text = 'Reset';
            app.fKslow1KnobLabel = uilabel(app.IKslow1Kv15Tab);
            app.fKslow1KnobLabel.Text = 'fKslow1';
            app.fKslow1Knob = uiknob(app.IKslow1Kv15Tab, 'continuous');
            app.fKslow1Knob.Limits = [0 1];
            app.fKslow1Knob.ValueChangingFcn = createCallbackFcn(app, @fKslow1KnobValueChanging, true);
            app.fKslow1Knob.Value = 0.9214774521;
            app.GKslow1Label = uilabel(app.IKslow1Kv15Tab);
            app.GKslow1Label.Text = 'GKslow1';
            app.GKslow1Knob = uiknob(app.IKslow1Kv15Tab, 'continuous');
            app.GKslow1Knob.Limits = [0 0.5];
            app.GKslow1Knob.ValueChangingFcn = createCallbackFcn(app, @GKslow1KnobValueChanging, true);
            app.GKslow1Knob.Value = 0.05766;
            app.GKslo1pLabel = uilabel(app.IKslow1Kv15Tab);
            app.GKslo1pLabel.Text = 'GKslo1,p';
            app.GKslow1pKnob = uiknob(app.IKslow1Kv15Tab, 'continuous');
            app.GKslow1pKnob.Limits = [0 0.5];
            app.GKslow1pKnob.ValueChangingFcn = createCallbackFcn(app, @GKslow1pKnobValueChanging, true);
            app.GKslow1pKnob.Value = 0.07496;
            app.p1Knob_2Label = uilabel(app.IKslow1Kv15Tab);
            app.p1Knob_2Label.Text = 'p1';
            app.p1Knob_2 = uiknob(app.IKslow1Kv15Tab, 'continuous');
            app.p1Knob_2.Limits = [-70 50];
            app.p1Knob_2.ValueChangingFcn = createCallbackFcn(app, @p1Knob_2ValueChanging, true);
            app.p1Knob_2.Value = 22.5;
            app.p2Knob_2Label = uilabel(app.IKslow1Kv15Tab);
            app.p2Knob_2Label.Text = 'p2';
            app.p2Knob_2 = uiknob(app.IKslow1Kv15Tab, 'continuous');
            app.p2Knob_2.Limits = [-70 50];
            app.p2Knob_2.ValueChangingFcn = createCallbackFcn(app, @p2Knob_2ValueChanging, true);
            app.p2Knob_2.Value = 45.2;
            app.p3Knob_2Label = uilabel(app.IKslow1Kv15Tab);
            app.p3Knob_2Label.Text = 'p3';
            app.p3Knob_2 = uiknob(app.IKslow1Kv15Tab, 'continuous');
            app.p3Knob_2.Limits = [-70 50];
            app.p3Knob_2.ValueChangingFcn = createCallbackFcn(app, @p3Knob_2ValueChanging, true);
            app.p3Knob_2.Value = 40;
            app.p4Knob_2Label = uilabel(app.IKslow1Kv15Tab);
            app.p4Knob_2Label.Text = 'p4';
            app.p4Knob_2 = uiknob(app.IKslow1Kv15Tab, 'continuous');
            app.p4Knob_2.Limits = [0 10];
            app.p4Knob_2.ValueChangingFcn = createCallbackFcn(app, @p4Knob_2ValueChanging, true);
            app.p4Knob_2.Value = 7.7;
            app.p5Knob_2Label = uilabel(app.IKslow1Kv15Tab);
            app.p5Knob_2Label.Text = 'p5';
            app.p5Knob_2 = uiknob(app.IKslow1Kv15Tab, 'continuous');
            app.p5Knob_2.Limits = [0 50];
            app.p5Knob_2.ValueChangingFcn = createCallbackFcn(app, @p5Knob_2ValueChanging, true);
            app.p5Knob_2.Value = 5.7;
            app.p6Knob_2Label = uilabel(app.IKslow1Kv15Tab);
            app.p6Knob_2Label.Text = 'p6';
            app.p6Knob_2 = uiknob(app.IKslow1Kv15Tab, 'continuous');
            app.p6Knob_2.Limits = [0 50];
            app.p6Knob_2.ValueChangingFcn = createCallbackFcn(app, @p6Knob_2ValueChanging, true);
            app.p6Knob_2.Value = 6.1;
            app.p7Knob_2Label = uilabel(app.IKslow1Kv15Tab);
            app.p7Knob_2Label.Text = 'p7';
            app.p7Knob_2 = uiknob(app.IKslow1Kv15Tab, 'continuous');
            app.p7Knob_2.Limits = [0 1];
            app.p7Knob_2.ValueChangingFcn = createCallbackFcn(app, @p7Knob_2ValueChanging, true);
            app.p7Knob_2.Value = 0.0629;
            app.p8Knob_2Label = uilabel(app.IKslow1Kv15Tab);
            app.p8Knob_2Label.Text = 'p8';
            app.p8Knob_2 = uiknob(app.IKslow1Kv15Tab, 'continuous');
            app.p8Knob_2.ValueChangingFcn = createCallbackFcn(app, @p8Knob_2ValueChanging, true);
            app.p8Knob_2.Value = 2.058;
            app.p9Knob_2Label = uilabel(app.IKslow1Kv15Tab);
            app.p9Knob_2Label.Text = 'p9';
            app.p9Knob_2 = uiknob(app.IKslow1Kv15Tab, 'continuous');
            app.p9Knob_2.Limits = [0 1000];
            app.p9Knob_2.ValueChangingFcn = createCallbackFcn(app, @p9Knob_2ValueChanging, true);
            app.p9Knob_2.Value = 803;
            app.p10Knob_2Label = uilabel(app.IKslow1Kv15Tab);
            app.p10Knob_2Label.Text = 'p10';
            app.p10Knob_2 = uiknob(app.IKslow1Kv15Tab, 'continuous');
            app.p10Knob_2.Limits = [0 50];
            app.p10Knob_2.ValueChangingFcn = createCallbackFcn(app, @p10Knob_2ValueChanging, true);
            app.p10Knob_2.Value = 18;
            app.IKslow2Kv21Tab = uitab(app.TabGroup);
            app.IKslow2Kv21Tab.Title = 'IKslow2 (Kv 2.1)';
            app.TextArea_3 = uitextarea(app.IKslow2Kv21Tab);
            app.TextArea_3.Value = {'IKslow2: Rapidly activating, slowly inactivating K+ current'};
            app.HighlightShowEquationsButton_3 = uibutton(app.IKslow2Kv21Tab, 'push');
            app.HighlightShowEquationsButton_3.ButtonPushedFcn = createCallbackFcn(app, @HighlightShowEquationsButton_3Pushed, true);
            app.HighlightShowEquationsButton_3.Text = 'Highlight & Show Equations';
            app.ResetButton_3 = uibutton(app.IKslow2Kv21Tab, 'push');
            app.ResetButton_3.ButtonPushedFcn = createCallbackFcn(app, @ResetButton_3Pushed, true);
            app.ResetButton_3.Text = 'Reset';
            app.GKslow2Label = uilabel(app.IKslow2Kv21Tab);
            app.GKslow2Label.Text = 'GKslow2';
            app.GKslow2Knob = uiknob(app.IKslow2Kv21Tab, 'continuous');
            app.GKslow2Knob.Limits = [0 0.5];
            app.GKslow2Knob.ValueChangingFcn = createCallbackFcn(app, @GKslow2KnobValueChanging, true);
            app.GKslow2Knob.Value = 0.05766;
            app.p1Knob_3Label = uilabel(app.IKslow2Kv21Tab);
            app.p1Knob_3Label.Text = 'p1';
            app.p1Knob_3 = uiknob(app.IKslow2Kv21Tab, 'continuous');
            app.p1Knob_3.Limits = [-70 50];
            app.p1Knob_3.ValueChangingFcn = createCallbackFcn(app, @p1Knob_3ValueChanging, true);
            app.p1Knob_3.Value = 22.5;
            app.p2Knob_3Label = uilabel(app.IKslow2Kv21Tab);
            app.p2Knob_3Label.Text = 'p2';
            app.p2Knob_3 = uiknob(app.IKslow2Kv21Tab, 'continuous');
            app.p2Knob_3.Limits = [-70 50];
            app.p2Knob_3.ValueChangingFcn = createCallbackFcn(app, @p2Knob_3ValueChanging, true);
            app.p2Knob_3.Value = 45.2;
            app.p3Knob_3Label = uilabel(app.IKslow2Kv21Tab);
            app.p3Knob_3Label.Text = 'p3';
            app.p3Knob_3 = uiknob(app.IKslow2Kv21Tab, 'continuous');
            app.p3Knob_3.Limits = [-70 50];
            app.p3Knob_3.ValueChangingFcn = createCallbackFcn(app, @p3Knob_3ValueChanging, true);
            app.p3Knob_3.Value = 40;
            app.p4Knob_3Label = uilabel(app.IKslow2Kv21Tab);
            app.p4Knob_3Label.Text = 'p4';
            app.p4Knob_3 = uiknob(app.IKslow2Kv21Tab, 'continuous');
            app.p4Knob_3.Limits = [0 10];
            app.p4Knob_3.ValueChangingFcn = createCallbackFcn(app, @p4Knob_3ValueChanging, true);
            app.p4Knob_3.Value = 7.7;
            app.p5Knob_3Label = uilabel(app.IKslow2Kv21Tab);
            app.p5Knob_3Label.Text = 'p5';
            app.p5Knob_3 = uiknob(app.IKslow2Kv21Tab, 'continuous');
            app.p5Knob_3.Limits = [0 50];
            app.p5Knob_3.ValueChangingFcn = createCallbackFcn(app, @p5Knob_3ValueChanging, true);
            app.p5Knob_3.Value = 5.7;
            app.p6Knob_3Label = uilabel(app.IKslow2Kv21Tab);
            app.p6Knob_3Label.Text = 'p6';
            app.p6Knob_3 = uiknob(app.IKslow2Kv21Tab, 'continuous');
            app.p6Knob_3.Limits = [0 50];
            app.p6Knob_3.ValueChangingFcn = createCallbackFcn(app, @p6Knob_3ValueChanging, true);
            app.p6Knob_3.Value = 6.1;
            app.p7Knob_3Label = uilabel(app.IKslow2Kv21Tab);
            app.p7Knob_3Label.Text = 'p7';
            app.p7Knob_3 = uiknob(app.IKslow2Kv21Tab, 'continuous');
            app.p7Knob_3.Limits = [0 1];
            app.p7Knob_3.ValueChangingFcn = createCallbackFcn(app, @p7Knob_3ValueChanging, true);
            app.p7Knob_3.Value = 0.0629;
            app.p8Knob_3Label = uilabel(app.IKslow2Kv21Tab);
            app.p8Knob_3Label.Text = 'p8';
            app.p8Knob_3 = uiknob(app.IKslow2Kv21Tab, 'continuous');
            app.p8Knob_3.ValueChangingFcn = createCallbackFcn(app, @p8Knob_3ValueChanging, true);
            app.p8Knob_3.Value = 2.058;
            app.p9Knob_3Label = uilabel(app.IKslow2Kv21Tab);
            app.p9Knob_3Label.Text = 'p9';
            app.p9Knob_3 = uiknob(app.IKslow2Kv21Tab, 'continuous');
            app.p9Knob_3.Limits = [5000 10000];
            app.p9Knob_3.ValueChangingFcn = createCallbackFcn(app, @p9Knob_3ValueChanging, true);
            app.p9Knob_3.Value = 5334;
            app.p10Knob_3Label = uilabel(app.IKslow2Kv21Tab);
            app.p10Knob_3Label.Text = 'p10';
            app.p10Knob_3 = uiknob(app.IKslow2Kv21Tab, 'continuous');
            app.p10Knob_3.Limits = [0 10000];
            app.p10Knob_3.ValueChangingFcn = createCallbackFcn(app, @p10Knob_3ValueChanging, true);
            app.p10Knob_3.Value = 4912;
            app.IKssTab = uitab(app.TabGroup);
            app.IKssTab.Title = 'IKss';
            app.ResetButton_4 = uibutton(app.IKssTab, 'push');
            app.ResetButton_4.ButtonPushedFcn = createCallbackFcn(app, @ResetButton_4Pushed, true);
            app.ResetButton_4.Text = 'Reset';
            app.HightlightButton_4 = uibutton(app.IKssTab, 'push');
            app.HightlightButton_4.ButtonPushedFcn = createCallbackFcn(app, @HightlightButton_4Pushed, true);
            app.HightlightButton_4.Text = 'Hightlight';
            app.TextArea_4 = uitextarea(app.IKssTab);
            app.TextArea_4.Value = {'IKss: Noninactivating steady-state K+ current'};
            app.GKssLabel = uilabel(app.IKssTab);
            app.GKssLabel.Text = 'GKss';
            app.GKss = uiknob(app.IKssTab, 'continuous');
            app.GKss.Limits = [0 0.5];
            app.GKss.ValueChangingFcn = createCallbackFcn(app, @GKssValueChanging, true);
            app.GKss.Value = 0.0428;
            app.p1Knob_4Label = uilabel(app.IKssTab);
            app.p1Knob_4Label.Text = 'p1';
            app.p1Knob_4 = uiknob(app.IKssTab, 'continuous');
            app.p1Knob_4.Limits = [-70 50];
            app.p1Knob_4.ValueChangingFcn = createCallbackFcn(app, @p1Knob_4ValueChanging, true);
            app.p1Knob_4.Value = 22.5;
            app.p2Knob_4Label = uilabel(app.IKssTab);
            app.p2Knob_4Label.Text = 'p2';
            app.p2Knob_4 = uiknob(app.IKssTab, 'continuous');
            app.p2Knob_4.Limits = [-70 50];
            app.p2Knob_4.ValueChangingFcn = createCallbackFcn(app, @p2Knob_4ValueChanging, true);
            app.p2Knob_4.Value = 40;
            app.p3Knob_4Label = uilabel(app.IKssTab);
            app.p3Knob_4Label.Text = 'p3';
            app.p3Knob_4 = uiknob(app.IKssTab, 'continuous');
            app.p3Knob_4.Limits = [0 10];
            app.p3Knob_4.ValueChangingFcn = createCallbackFcn(app, @p3Knob_4ValueChanging, true);
            app.p3Knob_4.Value = 7.7;
            app.p4Knob_4Label = uilabel(app.IKssTab);
            app.p4Knob_4Label.Text = 'p4';
            app.p4Knob_4 = uiknob(app.IKssTab, 'continuous');
            app.p4Knob_4.Limits = [0 1];
            app.p4Knob_4.ValueChangingFcn = createCallbackFcn(app, @p4Knob_4ValueChanging, true);
            app.p4Knob_4.Value = 0.0862;
            app.p5Knob_4Label = uilabel(app.IKssTab);
            app.p5Knob_4Label.Text = 'p5';
            app.p5Knob_4 = uiknob(app.IKssTab, 'continuous');
            app.p5Knob_4.Limits = [0 2000];
            app.p5Knob_4.ValueChangingFcn = createCallbackFcn(app, @p5Knob_4ValueChanging, true);
            app.p5Knob_4.Value = 1235.5;
            app.p6Knob_4Label = uilabel(app.IKssTab);
            app.p6Knob_4Label.Text = 'p6';
            app.p6Knob_4 = uiknob(app.IKssTab, 'continuous');
            app.p6Knob_4.ValueChangingFcn = createCallbackFcn(app, @p6Knob_4ValueChanging, true);
            app.p6Knob_4.Value = 13.17;
            app.RightPanel = uipanel(app.GridLayout);
            app.SelectVoltageDropDownLabel = uilabel(app.RightPanel);
            app.SelectVoltageDropDownLabel.Text = 'Select Voltage';
            app.SelectVoltageDropDown = uidropdown(app.RightPanel);
            app.SelectVoltageDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectVoltageDropDownValueChanged, true);
            app.UIAxesMiddleRight = uiaxes(app.RightPanel);
            title(app.UIAxesMiddleRight, 'Individual K^{+} Currents of Selected Voltage')
            xlabel(app.UIAxesMiddleRight, 'Time (ms)')
            ylabel(app.UIAxesMiddleRight, 'Current (pA/pF)')
            zlabel(app.UIAxesMiddleRight, 'Z')
            app.UIAxesTopRight = uiaxes(app.RightPanel);
            title(app.UIAxesTopRight, 'Sum of K^{+} Currents')
            xlabel(app.UIAxesTopRight, 'Time (ms)')
            ylabel(app.UIAxesTopRight, 'Current (pA/pF)')
            zlabel(app.UIAxesTopRight, 'Z')
            app.UIAxesBottomRight = uiaxes(app.RightPanel);
            title(app.UIAxesBottomRight, 'Sum of K^{+} Current of Selected Voltage')
            xlabel(app.UIAxesBottomRight, 'Time (ms)')
            ylabel(app.UIAxesBottomRight, 'Current (pA/pF)')
            zlabel(app.UIAxesBottomRight, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Kv

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end