classdef calib_window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        SelectVoltageDropDownLabel   matlab.ui.control.Label
        SelectVoltageDropDown        matlab.ui.control.DropDown
        MaxstepEditFieldLabel        matlab.ui.control.Label
        MaxstepEditField             matlab.ui.control.NumericEditField
        NumberofiterationsEditFieldLabel  matlab.ui.control.Label
        NumberofiterationsEditField  matlab.ui.control.NumericEditField
        RunButton                    matlab.ui.control.Button
        UIAxesAll                    matlab.ui.control.UIAxes
        UIAxesIndv                   matlab.ui.control.UIAxes
    end

    properties (Access = private)
        MainApp
        time_space
        best_sol
    end
    
    methods (Access = private)
        function plot_compare_onevolt(app, volt_idx)
            [~, ~, ~, ~, yksum_hat] = kcurrent_model(app.best_sol, app.MainApp.HoldingpotentialmVEditField.Value, ...
                app.MainApp.volts(volt_idx), app.time_space, app.MainApp.NernstpotentialEkmVEditField.Value);
            
            plot(app.UIAxesIndv, app.time_space{1}, app.MainApp.exp_yksum_set(:, volt_idx), "LineWidth",2)
            hold(app.UIAxesIndv,"on")
            plot(app.UIAxesIndv, app.time_space{1}, yksum_hat, "LineWidth",2, "Color","red")
            hold(app.UIAxesIndv,"off")
            axis(app.UIAxesIndv,"tight")
            legend(app.UIAxesIndv, "Experimental Data","Model Prediction", "Location","northeast")
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.MainApp = mainapp;

            % estimate holding time
            [~, ideal_hold_idx] = min(abs(app.MainApp.exp_t - app.MainApp.HoldingtimemsEditField.Value));
            
            % generate time space
            app.time_space = cell(1,3);
            app.time_space{1} = app.MainApp.exp_t;
            app.time_space{2} = app.MainApp.exp_t(1:ideal_hold_idx);
            pulse_t = app.MainApp.exp_t(ideal_hold_idx+1:end);
            pulse_t_adj = pulse_t - pulse_t(1);
            app.time_space{3} = pulse_t_adj;            
            
            % voltage drop-down menu
            volt_dd_items = strings(1, app.MainApp.num_volts);
            for i=1:app.MainApp.num_volts
                volt_dd_items(i) = strcat(num2str(app.MainApp.volts(i)), " mV");
            end
            app.SelectVoltageDropDown.Items = volt_dd_items;
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            % Store original button text
            orig_button_text = app.RunButton.Text;

            % When the function ends, return the original button state
            cleanup = onCleanup(@()set(app.RunButton, 'Text',orig_button_text, 'Icon',''));

            % Change button name to "Processing"
            app.RunButton.Text = 'Processing...';
            
            % Put text on top of icon
            app.RunButton.IconAlignment = 'bottom';

            % Create waitbar with same color as button
            wbar = permute(repmat(app.RunButton.BackgroundColor,15,1,1000),[1,3,2]);

            % Black frame around waitbar
            wbar([1,end],:,:) = 0;
            wbar(:,[1,end],:) = 0;

            % Load the empty waitbar to the button
            app.RunButton.Icon = wbar;
            
            pause(0.1)
            
            % Run calibration
            % optimization constraints 
            A = [];
            b = [];
            Aeq = [];
            beq = [];
            lb = [];
            ub = [];
            nonlcon = [];
            
            p0 = [33, 15.5, 20, 8, 7, 0.3956, 0.00095, 0.051335, 0.14067, 0.387, ...
                22.5, 45.2, 40, 5.7, 2.058, 803, 0.05766, 0.07496, ...
                5334, 0.05766 ...
                13.17, 0.0428];
            lb = [-50, -50, -50, eps, eps, eps, eps, eps, eps, eps, ...
                -70, -70, -70, eps, eps, eps, eps, eps, ...
                5000, eps, ...
                eps, eps];
            ub = [70, 50, 50, 50, 30, 10, 0.005, 0.5, 1, 1, ...
                50, 50, 50, 50, 100, 1000, 0.5, 0.5, ...
                10000, 0.5, ...
                100, 0.5];
            
            % define optimization objective
            options = optimoptions('fmincon', 'MaxFunctionEvaluations',app.MaxstepEditField.Value);
            opt_fun = @(p) obj_fn(p, app.MainApp.HoldingpotentialmVEditField.Value, app.MainApp.volts, ...
                app.time_space, app.MainApp.exp_yksum_set, app.MainApp.NernstpotentialEkmVEditField.Value, false);
            
            num_iters = app.NumberofiterationsEditField.Value;
            rmse_list = zeros(num_iters, 1);
            sol_list = cell(num_iters, 1);
            
            % first run with p0 and update progress bar 
            [sol, fval] = fmincon(opt_fun, p0, A, b, Aeq, beq, lb, ub, nonlcon, options);
            sol_list{1} = sol;
            rmse_list(1) = fval;

            current_prog = min(round((size(wbar,2)-2)*(1/num_iters)), size(wbar,2)-2); 
            app.RunButton.Icon(2:end-1, 2:current_prog+1, 1) = 0.25391; % (royalblue)
            app.RunButton.Icon(2:end-1, 2:current_prog+1, 2) = 0.41016;
            app.RunButton.Icon(2:end-1, 2:current_prog+1, 3) = 0.87891;
            pause(0.1)

            for i = 2:num_iters
                % random intialization
                running_p0 = zeros(length(p0), 1);
                for j = 1:length(p0)
                    unif_dist = makedist('Uniform', 'lower',lb(j), 'upper',ub(j));
                    running_p0(j) = random(unif_dist, 1);
                end
                
                % optimization
                [sol, fval] = fmincon(opt_fun, running_p0, A, b, Aeq, beq, lb, ub, nonlcon, options);
                sol_list{i} = sol;
                rmse_list(i) = fval;
                
                % update progress bar
                current_prog = min(round((size(wbar,2)-2)*(i/num_iters)), size(wbar,2)-2); 
                app.RunButton.Icon(2:end-1, 2:current_prog+1, 1) = 0.25391; % (royalblue)
                app.RunButton.Icon(2:end-1, 2:current_prog+1, 2) = 0.41016;
                app.RunButton.Icon(2:end-1, 2:current_prog+1, 3) = 0.87891;
                pause(0.1)                
            end
            
            % select best solution
            [~, best_fit_idx] = min(rmse_list);
            app.best_sol = sol_list{best_fit_idx};
            
            % plot model calibration results - 1
            [~, ~, ~, ~, yksum_hat] = kcurrent_model(app.best_sol, app.MainApp.HoldingpotentialmVEditField.Value, ...
                app.MainApp.volts(1), app.time_space, app.MainApp.NernstpotentialEkmVEditField.Value);
            plot(app.UIAxesAll, app.time_space{1}, yksum_hat)
            hold(app.UIAxesAll,"on")
            for i = 2:app.MainApp.num_volts
                [~, ~, ~, ~, yksum_hat] = kcurrent_model(app.best_sol, app.MainApp.HoldingpotentialmVEditField.Value, ...
                    app.MainApp.volts(i), app.time_space, app.MainApp.NernstpotentialEkmVEditField.Value);
                plot(app.UIAxesAll, app.time_space{1}, yksum_hat)                
            end
            hold(app.UIAxesAll,"off")
            axis(app.UIAxesAll,"tight")
            
            % plot model calibration results - 2
            selected_volt_value = app.SelectVoltageDropDown.Value;
            volt = split(selected_volt_value, " ");
            volt = str2double(volt(1));
            volt_idx = find(app.MainApp.volts == volt);
            
            plot_compare_onevolt(app, volt_idx);
            
            % export the results
            update_calib_window(app.MainApp, app.best_sol);
        end

        % Value changed function: SelectVoltageDropDown
        function SelectVoltageDropDownValueChanged(app, event)
            value = app.SelectVoltageDropDown.Value;
            volt = split(value, " ");
            volt = str2double(volt(1));
            volt_idx = find(app.MainApp.volts == volt);
            
            plot_compare_onevolt(app, volt_idx);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.SelectVoltageDropDownLabel = uilabel(app.UIFigure);
            app.SelectVoltageDropDownLabel.Text = 'Select Voltage';
            app.SelectVoltageDropDown = uidropdown(app.UIFigure);
            app.SelectVoltageDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectVoltageDropDownValueChanged, true);
            app.MaxstepEditFieldLabel = uilabel(app.UIFigure);
            app.MaxstepEditFieldLabel.Text = 'Max step';
            app.MaxstepEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxstepEditField.Value = 1000000;
            app.NumberofiterationsEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofiterationsEditFieldLabel.Text = 'Number of iterations';
            app.NumberofiterationsEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofiterationsEditField.Value = 15;
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.UIAxesAll = uiaxes(app.UIFigure);
            title(app.UIAxesAll, {'Model Calibration Results'; ''})
            xlabel(app.UIAxesAll, 'Time (ms)')
            ylabel(app.UIAxesAll, 'Current (pA/pF)')
            zlabel(app.UIAxesAll, 'Z')
            app.UIAxesIndv = uiaxes(app.UIFigure);
            title(app.UIAxesIndv, 'Model Calibration Results - Comparison')
            xlabel(app.UIAxesIndv, 'Time (ms)')
            ylabel(app.UIAxesIndv, 'Current (pA/pF)')
            zlabel(app.UIAxesIndv, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = calib_window(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end