classdef warn_message_exp_volt < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        MaxmVEditField        matlab.ui.control.NumericEditField
        MaxmVEditFieldLabel   matlab.ui.control.Label
        MinmVEditField        matlab.ui.control.NumericEditField
        MinmVEditFieldLabel   matlab.ui.control.Label
        StepmVEditField       matlab.ui.control.NumericEditField
        StepmVEditFieldLabel  matlab.ui.control.Label
        TextArea              matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        MainApp
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.MainApp = mainapp;
            
            min_volt = app.MainApp.exp_volts(1);
            step_volt = app.MainApp.ClampingvoltagestepmVEditField.Value;
            max_volt = app.MainApp.exp_volts(end);
            
            app.MinmVEditField.Value = min_volt;
            app.StepmVEditField.Value = step_volt;
            app.MaxmVEditField.Value = max_volt;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'WARNING'; 'Selected voltage is out of the range estimated from the experimental data:'};
            app.StepmVEditFieldLabel = uilabel(app.UIFigure);
            app.StepmVEditFieldLabel.Text = 'Step (mV)';
            app.StepmVEditField = uieditfield(app.UIFigure, 'numeric');
            app.MinmVEditFieldLabel = uilabel(app.UIFigure);
            app.MinmVEditFieldLabel.Text = 'Min (mV)';
            app.MinmVEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxmVEditFieldLabel = uilabel(app.UIFigure);
            app.MaxmVEditFieldLabel.Text = 'Max (mV)';
            app.MaxmVEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = warn_message_exp_volt(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end