classdef Kv < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        TabGroup                      matlab.ui.container.TabGroup
        ProtocolTab                   matlab.ui.container.Tab
        HoldingtimemsEditFieldLabel   matlab.ui.control.Label
        HoldingtimemsEditField        matlab.ui.control.NumericEditField
        HoldingpotentialmVEditFieldLabel  matlab.ui.control.Label
        HoldingpotentialmVEditField   matlab.ui.control.NumericEditField
        ClampvoltagemVEditFieldLabel  matlab.ui.control.Label
        ClampvoltagemVEditField       matlab.ui.control.NumericEditField
        VoltagetimemsEditFieldLabel   matlab.ui.control.Label
        VoltagetimemsEditField        matlab.ui.control.NumericEditField
        EnterButton                   matlab.ui.control.Button
        EkEditFieldLabel              matlab.ui.control.Label
        EkEditField                   matlab.ui.control.NumericEditField
        Switch                        matlab.ui.control.ToggleSwitch
        UIAxes                        matlab.ui.control.UIAxes
        IKtofTab                      matlab.ui.container.Tab
        p1KnobLabel                   matlab.ui.control.Label
        p1Knob                        matlab.ui.control.Knob
        p2KnobLabel                   matlab.ui.control.Label
        p2Knob                        matlab.ui.control.Knob
        p3KnobLabel                   matlab.ui.control.Label
        p3Knob                        matlab.ui.control.Knob
        p4KnobLabel                   matlab.ui.control.Label
        p4Knob                        matlab.ui.control.Knob
        p5KnobLabel                   matlab.ui.control.Label
        p5Knob                        matlab.ui.control.Knob
        p6KnobLabel                   matlab.ui.control.Label
        p6Knob                        matlab.ui.control.Knob
        p7KnobLabel                   matlab.ui.control.Label
        p7Knob                        matlab.ui.control.Knob
        p8KnobLabel                   matlab.ui.control.Label
        p8Knob                        matlab.ui.control.Knob
        p9KnobLabel                   matlab.ui.control.Label
        p9Knob                        matlab.ui.control.Knob
        p10KnobLabel                  matlab.ui.control.Label
        p10Knob                       matlab.ui.control.Knob
        p11KnobLabel                  matlab.ui.control.Label
        p11Knob                       matlab.ui.control.Knob
        p12KnobLabel                  matlab.ui.control.Label
        p12Knob                       matlab.ui.control.Knob
        GKtofKnobLabel                matlab.ui.control.Label
        GKtofKnob                     matlab.ui.control.Knob
        TextArea                      matlab.ui.control.TextArea
        PlotButton                    matlab.ui.control.Button
        p1EditFieldLabel              matlab.ui.control.Label
        p1EditField                   matlab.ui.control.NumericEditField
        p2EditFieldLabel              matlab.ui.control.Label
        p2EditField                   matlab.ui.control.NumericEditField
        p3EditFieldLabel              matlab.ui.control.Label
        p3EditField                   matlab.ui.control.NumericEditField
        p4EditFieldLabel              matlab.ui.control.Label
        p4EditField                   matlab.ui.control.NumericEditField
        p5EditFieldLabel              matlab.ui.control.Label
        p5EditField                   matlab.ui.control.NumericEditField
        p6EditFieldLabel              matlab.ui.control.Label
        p6EditField                   matlab.ui.control.NumericEditField
        p7EditFieldLabel              matlab.ui.control.Label
        p7EditField                   matlab.ui.control.NumericEditField
        p8EditFieldLabel              matlab.ui.control.Label
        p8EditField                   matlab.ui.control.NumericEditField
        p9EditFieldLabel              matlab.ui.control.Label
        p9EditField                   matlab.ui.control.NumericEditField
        p10EditFieldLabel             matlab.ui.control.Label
        p10EditField                  matlab.ui.control.NumericEditField
        p11EditFieldLabel             matlab.ui.control.Label
        p11EditField                  matlab.ui.control.NumericEditField
        p12EditFieldLabel             matlab.ui.control.Label
        p12EditField                  matlab.ui.control.NumericEditField
        GKtofEditFieldLabel           matlab.ui.control.Label
        GKtofEditField                matlab.ui.control.NumericEditField
        IKtosTab                      matlab.ui.container.Tab
        IKITab                        matlab.ui.container.Tab
        TextArea_2                    matlab.ui.control.TextArea
        PlotButton_2                  matlab.ui.control.Button
        p1Knob_6Label                 matlab.ui.control.Label
        p1Knob_2                      matlab.ui.control.Knob
        p2Knob_6Label                 matlab.ui.control.Label
        p2Knob_2                      matlab.ui.control.Knob
        p3Knob_6Label                 matlab.ui.control.Label
        p3Knob_2                      matlab.ui.control.Knob
        KoKnobLabel                   matlab.ui.control.Label
        KoKnob                        matlab.ui.control.Knob
        p1EditField_6Label            matlab.ui.control.Label
        p1EditField_2                 matlab.ui.control.NumericEditField
        p2EditField_6Label            matlab.ui.control.Label
        p2EditField_2                 matlab.ui.control.NumericEditField
        p3EditField_6Label            matlab.ui.control.Label
        p3EditField_2                 matlab.ui.control.NumericEditField
        KoEditFieldLabel              matlab.ui.control.Label
        KoEditField                   matlab.ui.control.NumericEditField
        IKsTab                        matlab.ui.container.Tab
        p1Knob_7Label                 matlab.ui.control.Label
        p1Knob_3                      matlab.ui.control.Knob
        p2Knob_7Label                 matlab.ui.control.Label
        p2Knob_3                      matlab.ui.control.Knob
        p3Knob_7Label                 matlab.ui.control.Label
        p3Knob_3                      matlab.ui.control.Knob
        p4Knob_6Label                 matlab.ui.control.Label
        p4Knob_3                      matlab.ui.control.Knob
        p5Knob_6Label                 matlab.ui.control.Label
        p5Knob_3                      matlab.ui.control.Knob
        TextArea_3                    matlab.ui.control.TextArea
        GKsKnobLabel                  matlab.ui.control.Label
        GKsKnob                       matlab.ui.control.Knob
        PlotButton_3                  matlab.ui.control.Button
        p1EditField_7Label            matlab.ui.control.Label
        p1EditField_3                 matlab.ui.control.NumericEditField
        p2EditField_7Label            matlab.ui.control.Label
        p2EditField_3                 matlab.ui.control.NumericEditField
        p3EditField_7Label            matlab.ui.control.Label
        p3EditField_3                 matlab.ui.control.NumericEditField
        p4EditField_6Label            matlab.ui.control.Label
        p4EditField_3                 matlab.ui.control.NumericEditField
        p5EditField_6Label            matlab.ui.control.Label
        p5EditField_3                 matlab.ui.control.NumericEditField
        GKsEditFieldLabel             matlab.ui.control.Label
        GKsEditField                  matlab.ui.control.NumericEditField
        IKurTab                       matlab.ui.container.Tab
        TextArea_4                    matlab.ui.control.TextArea
        PlotButton_4                  matlab.ui.control.Button
        p1Knob_2Label                 matlab.ui.control.Label
        p1Knob_4                      matlab.ui.control.Knob
        p2Knob_2Label                 matlab.ui.control.Label
        p2Knob_4                      matlab.ui.control.Knob
        p3Knob_2Label                 matlab.ui.control.Label
        p3Knob_4                      matlab.ui.control.Knob
        p4Knob_2Label                 matlab.ui.control.Label
        p4Knob_4                      matlab.ui.control.Knob
        p5Knob_2Label                 matlab.ui.control.Label
        p5Knob_4                      matlab.ui.control.Knob
        p6Knob_2Label                 matlab.ui.control.Label
        p6Knob_4                      matlab.ui.control.Knob
        p7Knob_2Label                 matlab.ui.control.Label
        p7Knob_4                      matlab.ui.control.Knob
        p8Knob_2Label                 matlab.ui.control.Label
        p8Knob_4                      matlab.ui.control.Knob
        p9Knob_2Label                 matlab.ui.control.Label
        p9Knob_4                      matlab.ui.control.Knob
        p10Knob_2Label                matlab.ui.control.Label
        p10Knob_4                     matlab.ui.control.Knob
        p11Knob_2Label                matlab.ui.control.Label
        p11Knob_4                     matlab.ui.control.Knob
        GKurKnobLabel                 matlab.ui.control.Label
        GKurKnob                      matlab.ui.control.Knob
        p1EditField_2Label            matlab.ui.control.Label
        p1EditField_4                 matlab.ui.control.NumericEditField
        p2EditField_2Label            matlab.ui.control.Label
        p2EditField_4                 matlab.ui.control.NumericEditField
        p3EditField_2Label            matlab.ui.control.Label
        p3EditField_4                 matlab.ui.control.NumericEditField
        p4EditField_2Label            matlab.ui.control.Label
        p4EditField_4                 matlab.ui.control.NumericEditField
        p5EditField_2Label            matlab.ui.control.Label
        p5EditField_4                 matlab.ui.control.NumericEditField
        p6EditField_2Label            matlab.ui.control.Label
        p6EditField_4                 matlab.ui.control.NumericEditField
        p7EditField_2Label            matlab.ui.control.Label
        p7EditField_4                 matlab.ui.control.NumericEditField
        p8EditField_2Label            matlab.ui.control.Label
        p8EditField_4                 matlab.ui.control.NumericEditField
        p9EditField_2Label            matlab.ui.control.Label
        p9EditField_4                 matlab.ui.control.NumericEditField
        p10EditField_2Label           matlab.ui.control.Label
        p10EditField_4                matlab.ui.control.NumericEditField
        p11EditField_2Label           matlab.ui.control.Label
        p11EditField_4                matlab.ui.control.NumericEditField
        GKurEditFieldLabel            matlab.ui.control.Label
        GKurEditField                 matlab.ui.control.NumericEditField
        IKssTab                       matlab.ui.container.Tab
        Image6                        matlab.ui.control.Image
        Image5                        matlab.ui.control.Image
        Image2                        matlab.ui.control.Image
        Image4                        matlab.ui.control.Image
        TextArea_5                    matlab.ui.control.TextArea
        GKssEditField                 matlab.ui.control.NumericEditField
        GKssKnobLabel                 matlab.ui.control.Label
        GKssKnob                      matlab.ui.control.Knob
        p1Knob_5Label                 matlab.ui.control.Label
        p1Knob_5                      matlab.ui.control.Knob
        p2Knob_5Label                 matlab.ui.control.Label
        p2Knob_5                      matlab.ui.control.Knob
        p3Knob_5Label                 matlab.ui.control.Label
        p3Knob_5                      matlab.ui.control.Knob
        p4Knob_5Label                 matlab.ui.control.Label
        p4Knob_5                      matlab.ui.control.Knob
        p5Knob_5Label                 matlab.ui.control.Label
        p5Knob_5                      matlab.ui.control.Knob
        p1EditField_5Label            matlab.ui.control.Label
        p1EditField_5                 matlab.ui.control.NumericEditField
        p2EditField_5Label            matlab.ui.control.Label
        p2EditField_5                 matlab.ui.control.NumericEditField
        p3EditField_5Label            matlab.ui.control.Label
        p3EditField_5                 matlab.ui.control.NumericEditField
        p4EditField_5Label            matlab.ui.control.Label
        p4EditField_5                 matlab.ui.control.NumericEditField
        p5EditField_5Label            matlab.ui.control.Label
        p5EditField_5                 matlab.ui.control.NumericEditField
        PlotButton_5                  matlab.ui.control.Button
        Image3                        matlab.ui.control.Image
        GKssEditFieldLabel            matlab.ui.control.Label
        IKrTab                        matlab.ui.container.Tab
        RightPanel                    matlab.ui.container.Panel
        UIAxesTopRight                matlab.ui.control.UIAxes
        UIAxesBottomRight             matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    properties (Access = private)
        % time space
        tH % holding time
        tP % voltage-clamp time
        t % entire time
        
        % currents
        yKsum
        yKtof
        yKI
        yKs
        yKur
        yKss
        yKr
        yNaK
    end
    
    methods (Access = private)
        function [tH, tP, t] = time_space(app)
            tH = 0:app.HoldingtimemsEditField.Value;
            tP = 0:app.VoltagetimemsEditField.Value;
            tP_shift = tP + app.HoldingtimemsEditField.Value + 1;
            t = [tH, tP_shift];
        end
        
        function [current_trc] = gen_IKtof(app)
            param = zeros(14, 1);
            param(1) = app.p1Knob.Value;
            param(2) = app.p2Knob.Value;
            param(3) = app.p3Knob.Value;
            param(4) = app.p4Knob.Value;
            param(5) = app.p5Knob.Value;
            param(6) = app.p6Knob.Value;
            param(7) = app.p7Knob.Value;
            param(8) = app.p8Knob.Value;
            param(9) = app.p9Knob.Value;
            param(10) = app.p10Knob.Value;
            param(11) = app.p11Knob.Value;
            param(12) = app.p12Knob.Value;
            param(13) = app.GKtofKnob.Value;
            param(14) = app.EkEditField.Value;
            
            tt = cell(1, 3);
            tt{1} = app.t;
            tt{2} = app.tH;
            tt{3} = app.tP;
            current_trc = IKtof(param, app.HoldingpotentialmVEditField.Value, app.ClampvoltagemVEditField.Value, tt);
        end

        function [current_trc] = gen_IKI(app)
            param = zeros(5, 1);
            param(1) = app.p1Knob_2.Value;
            param(2) = app.p2Knob_2.Value;
            param(3) = app.p3Knob_2.Value;
            param(4) = app.KoKnob.Value;
            param(5) = app.EkEditField.Value;
            
            tt = cell(1, 3);
            tt{1} = app.t;
            tt{2} = app.tH;
            tt{3} = app.tP;
            current_trc = IKI(param, app.HoldingpotentialmVEditField.Value, app.ClampvoltagemVEditField.Value, tt);
        end
        
        function [current_trc] = gen_IKs(app)
            param = zeros(7, 1);
            param(1) = app.p1Knob_3.Value;
            param(2) = app.p2Knob_3.Value;
            param(3) = app.p3Knob_3.Value;
            param(4) = app.p4Knob_3.Value;
            param(5) = app.p5Knob_3.Value;
            param(6) = app.GKsKnob.Value;
            param(7) = app.EkEditField.Value;
            
            tt = cell(1, 3);
            tt{1} = app.t;
            tt{2} = app.tH;
            tt{3} = app.tP;
            current_trc = IKs(param, app.HoldingpotentialmVEditField.Value, app.ClampvoltagemVEditField.Value, tt);            
        end

        function [current_trc] = gen_IKur(app)
            param = zeros(13, 1);
            param(1) = app.p1Knob_4.Value;
            param(2) = app.p2Knob_4.Value;
            param(3) = app.p3Knob_4.Value;
            param(4) = app.p4Knob_4.Value;
            param(5) = app.p5Knob_4.Value;
            param(6) = app.p6Knob_4.Value;
            param(7) = app.p7Knob_4.Value;
            param(8) = app.p8Knob_4.Value;
            param(9) = app.p9Knob_4.Value;
            param(10) = app.p10Knob_4.Value;
            param(11) = app.p11Knob_4.Value;
            param(12) = app.GKurKnob.Value;
            param(13) = app.EkEditField.Value;
            
            tt = cell(1, 3);
            tt{1} = app.t;
            tt{2} = app.tH;
            tt{3} = app.tP;
            current_trc = IKur(param, app.HoldingpotentialmVEditField.Value, app.ClampvoltagemVEditField.Value, tt);
        end
        
        function [current_trc] = gen_Iss(app)
            param = zeros(7, 1);
            param(1) = app.p1Knob_5.Value;
            param(2) = app.p2Knob_5.Value;
            param(3) = app.p3Knob_5.Value;
            param(4) = app.p4Knob_5.Value;
            param(5) = app.p5Knob_5.Value;            
            param(6) = app.GKssKnob.Value;
            param(7) = app.EkEditField.Value;
            
            tt = cell(1, 3);
            tt{1} = app.t;
            tt{2} = app.tH;
            tt{3} = app.tP;
            current_trc = IKss(param, app.HoldingpotentialmVEditField.Value, app.ClampvoltagemVEditField.Value, tt);
        end
        
        function plot_protocol(app)            
            yH = ones(1, length(app.tH))*app.HoldingpotentialmVEditField.Value;
            yP = ones(1, length(app.tP))*app.ClampvoltagemVEditField.Value;
            y = [yH, yP];
            
            plot(app.UIAxes, app.t, y, 'LineWidth',1.5)
        end
        
        function plot_currents(app, highlight_idx)
            % top-right axes; IKsum
            plot(app.UIAxesTopRight, app.t, app.yKsum, "Color","red", "LineWidth",2)
            
            % top-bottom axes; individual K+ currents
            l(1) = plot(app.UIAxesBottomRight, app.t, app.yKtof);
            
            hold(app.UIAxesBottomRight, "on")
            l(2) = plot(app.UIAxesBottomRight, app.t, app.yKI);
            l(3) = plot(app.UIAxesBottomRight, app.t, app.yKs);
            l(4) = plot(app.UIAxesBottomRight, app.t, app.yKur);
            l(5) = plot(app.UIAxesBottomRight, app.t, app.yKss);
            hold(app.UIAxesBottomRight, "off")
            
            switch highlight_idx
                case 1
                    legend(app.UIAxesBottomRight, l(highlight_idx), "I_{Ktof}")
                case 2
                    legend(app.UIAxesBottomRight, l(highlight_idx), "I_{KI}")
                case 3
                    legend(app.UIAxesBottomRight, l(highlight_idx), "I_{Ks}")
                case 4
                    legend(app.UIAxesBottomRight, l(highlight_idx), "I_{Kur}")
                case 5
                    legend(app.UIAxesBottomRight, l(highlight_idx), "I_{Kss}")
            end            
            set(l(highlight_idx), "LineWidth",2)
        end
    end    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % generate time space
            [app.tH, app.tP, app.t] = time_space(app);
            
            % individual currents
            app.yKtof = gen_IKtof(app);
            app.yKI = gen_IKI(app);
            app.yKs = gen_IKs(app);
            app.yKur = gen_IKur(app);
            app.yKss = gen_Iss(app);
            
            % IKsum
            app.yKsum = app.yKtof + app.yKI + app.yKs + app.yKur + app.yKss;
            
            % plot default voltage clamp protocol
            plot_protocol(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {630, 630};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {595, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: EnterButton
        function EnterButtonPushed(app, event)
            % update time space
            [app.tH, app.tP, app.t] = time_space(app);
                        
            % update currents
            app.yKtof = gen_IKtof(app);
            app.yKI = gen_IKI(app);
            app.yKs = gen_IKs(app);
            app.yKur = gen_IKur(app);
            app.yKss = gen_Iss(app);
            app.yKsum = app.yKtof + app.yKI + app.yKs + app.yKur + app.yKss;
            
            plot_protocol(app);
            
            plot(app.UIAxesTopRight, app.t, app.yKsum, "Color","red", "LineWidth",2)

            plot(app.UIAxesBottomRight, app.t, app.yKtof)
            hold(app.UIAxesBottomRight, "on")
            plot(app.UIAxesBottomRight, app.t, app.yKI)
            plot(app.UIAxesBottomRight, app.t, app.yKs)
            plot(app.UIAxesBottomRight, app.t, app.yKur)
            plot(app.UIAxesBottomRight, app.t, app.yKss)
            hold(app.UIAxesBottomRight, "off")
            
            legend(app.UIAxesBottomRight, "I_{Ktof}", "I_{KI}", "I_{Ks}", "I_{Kur}", "I_{Kss}")
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            yKsum_sub = app.yKsum - app.yKtof;
            app.yKtof = gen_IKtof(app);
            app.yKsum = yKsum_sub + app.yKtof; 
            
            plot_currents(app, 1);
        end

        % Button pushed function: PlotButton_2
        function PlotButton_2Pushed(app, event)
            yKsum_sub = app.yKsum - app.yKI;
            app.yKI = gen_IKI(app);
            app.yKsum = yKsum_sub + app.yKI;
            
            plot_currents(app, 2)
        end

        % Button pushed function: PlotButton_3
        function PlotButton_3Pushed(app, event)
            yKsum_sub = app.yKsum - app.yKs;
            app.yKs = gen_IKs(app);
            app.yKsum = yKsum_sub + app.yKs;
            
            plot_currents(app, 3)
        end

        % Button pushed function: PlotButton_4
        function PlotButton_4Pushed(app, event)
            yKsum_sub = app.yKsum - app.yKur;
            app.yKur = gen_IKur(app);
            app.yKsum = yKsum_sub + app.yKur;
            
            plot_currents(app, 4);
        end

        % Button pushed function: PlotButton_5
        function PlotButton_5Pushed(app, event)
            yKsum_sub = app.yKsum - app.yKss;
            app.yKss = gen_Iss(app);
            app.yKsum = yKsum_sub + app.yKss;
            
            plot_currents(app, 5)
        end

        % Callback function: GKtofEditField, GKtofKnob
        function GKtofKnobValueChanging(app, event)
            changingValue = event.Value;
            app.GKtofKnob.Value = changingValue;
            app.GKtofEditField.Value = changingValue;          
            
            yKsum_sub = app.yKsum - app.yKtof;
            app.yKtof = gen_IKtof(app);
            app.yKsum =yKsum_sub + app.yKtof; 
            
            plot_currents(app, 1);
        end

        % Callback function: p1EditField, p1Knob
        function p1KnobValueChanging(app, event)
            changingValue = event.Value;
            app.p1Knob.Value = changingValue;
            app.p1EditField.Value = changingValue;            
            
            yKsum_sub = app.yKsum - app.yKtof;
            app.yKtof = gen_IKtof(app);
            app.yKsum =yKsum_sub + app.yKtof; 
            
            plot_currents(app, 1);
        end

        % Callback function: p2EditField, p2Knob
        function p2KnobValueChanging(app, event)
            changingValue = event.Value;
            app.p2Knob.Value = changingValue;
            app.p2EditField.Value = changingValue;
            
            yKsum_sub = app.yKsum - app.yKtof;
            app.yKtof = gen_IKtof(app);
            app.yKsum =yKsum_sub + app.yKtof; 
            
            plot_currents(app, 1);
        end

        % Callback function: p3EditField, p3Knob
        function p3KnobValueChanging(app, event)
            changingValue = event.Value;
            app.p3Knob.Value = changingValue;
            app.p3EditField.Value = changingValue;
            
            yKsum_sub = app.yKsum - app.yKtof;
            app.yKtof = gen_IKtof(app);
            app.yKsum =yKsum_sub + app.yKtof; 
            
            plot_currents(app, 1);
        end

        % Callback function: p4EditField, p4Knob
        function p4KnobValueChanging(app, event)
            changingValue = event.Value;
            app.p4Knob.Value = changingValue;
            app.p4EditField.Value = changingValue;
            
            yKsum_sub = app.yKsum - app.yKtof;
            app.yKtof = gen_IKtof(app);
            app.yKsum =yKsum_sub + app.yKtof; 
            
            plot_currents(app, 1);
        end

        % Callback function: p5EditField, p5Knob
        function p5KnobValueChanging(app, event)
            changingValue = event.Value;
            app.p5Knob.Value = changingValue;
            app.p5EditField.Value = changingValue;
            
            yKsum_sub = app.yKsum - app.yKtof;
            app.yKtof = gen_IKtof(app);
            app.yKsum =yKsum_sub + app.yKtof; 
            
            plot_currents(app, 1);
        end

        % Callback function: p6EditField, p6Knob
        function p6KnobValueChanging(app, event)
            changingValue = event.Value;
            app.p6Knob.Value = changingValue;
            app.p6EditField.Value = changingValue;
            
            yKsum_sub = app.yKsum - app.yKtof;
            app.yKtof = gen_IKtof(app);
            app.yKsum =yKsum_sub + app.yKtof; 
            
            plot_currents(app, 1);
        end

        % Callback function: p7EditField, p7Knob
        function p7KnobValueChanging(app, event)
            changingValue = event.Value;
            app.p7Knob.Value = changingValue;
            app.p7EditField.Value = changingValue;
            
            yKsum_sub = app.yKsum - app.yKtof;
            app.yKtof = gen_IKtof(app);
            app.yKsum =yKsum_sub + app.yKtof; 
            
            plot_currents(app, 1);
        end

        % Callback function: p8EditField, p8Knob
        function p8KnobValueChanging(app, event)
            changingValue = event.Value;
            app.p8Knob.Value = changingValue;
            app.p8EditField.Value = changingValue;
            
            yKsum_sub = app.yKsum - app.yKtof;
            app.yKtof = gen_IKtof(app);
            app.yKsum =yKsum_sub + app.yKtof; 
            
            plot_currents(app, 1);
        end

        % Callback function: p9EditField, p9Knob
        function p9KnobValueChanging(app, event)
            changingValue = event.Value;
            app.p9Knob.Value = changingValue;
            app.p9EditField.Value = changingValue;
            
            yKsum_sub = app.yKsum - app.yKtof;
            app.yKtof = gen_IKtof(app);
            app.yKsum =yKsum_sub + app.yKtof; 
            
            plot_currents(app, 1);
        end

        % Callback function: p10EditField, p10Knob
        function p10KnobValueChanging(app, event)
            changingValue = event.Value;
            app.p10Knob.Value = changingValue;
            app.p10EditField.Value = changingValue;
            
            yKsum_sub = app.yKsum - app.yKtof;
            app.yKtof = gen_IKtof(app);
            app.yKsum =yKsum_sub + app.yKtof; 
            
            plot_currents(app, 1);
        end

        % Callback function: p11EditField, p11Knob
        function p11KnobValueChanging(app, event)
            changingValue = event.Value;
            app.p11Knob.Value = changingValue;
            app.p11EditField.Value = changingValue;
            
            yKsum_sub = app.yKsum - app.yKtof;
            app.yKtof = gen_IKtof(app);
            app.yKsum =yKsum_sub + app.yKtof; 
            
            plot_currents(app, 1);
        end

        % Callback function: p12EditField, p12Knob
        function p12KnobValueChanging(app, event)
            changingValue = event.Value;
            app.p12Knob.Value = changingValue;
            app.p12EditField.Value = changingValue;
            
            yKsum_sub = app.yKsum - app.yKtof;
            app.yKtof = gen_IKtof(app);
            app.yKsum =yKsum_sub + app.yKtof; 
            
            plot_currents(app, 1);
        end

        % Callback function: KoEditField, KoKnob
        function KoKnobValueChanging(app, event)
            changingValue = event.Value;
            app.KoKnob.Value = changingValue;
            app.KoEditField.Value = changingValue;
            
            yKsum_sub = app.yKsum - app.yKI;
            app.yKI = gen_IKI(app);
            app.yKsum = yKsum_sub + app.yKI;
            
            plot_currents(app, 2);
        end

        % Callback function: p1EditField_2, p1Knob_2
        function p1Knob_2ValueChanging(app, event)
            changingValue = event.Value;
            app.p1Knob_2.Value = changingValue;
            app.p1EditField_2.Value = changingValue;
            
            yKsum_sub = app.yKsum - app.yKI;
            app.yKI = gen_IKI(app);
            app.yKsum = yKsum_sub + app.yKI;
            
            plot_currents(app, 2);
        end

        % Callback function: p2EditField_2, p2Knob_2
        function p2Knob_2ValueChanging(app, event)
            changingValue = event.Value;
            app.p2Knob_2.Value = changingValue;
            app.p2EditField_2.Value = changingValue;
            
            yKsum_sub = app.yKsum - app.yKI;
            app.yKI = gen_IKI(app);
            app.yKsum = yKsum_sub + app.yKI;
            
            plot_currents(app, 2);
        end

        % Callback function: p3EditField_2, p3Knob_2
        function p3Knob_2ValueChanging(app, event)
            changingValue = event.Value;
            app.p3Knob_2.Value = changingValue;
            app.p3EditField_2.Value = changingValue;
            
            yKsum_sub = app.yKsum - app.yKI;
            app.yKI = gen_IKI(app);
            app.yKsum = yKsum_sub + app.yKI;
            
            plot_currents(app, 2);
        end

        % Callback function: GKsEditField, GKsKnob
        function GKsKnobValueChanging(app, event)
            changingValue = event.Value;
            app.GKsKnob.Value = changingValue;
            app.GKsEditField.Value = changingValue;
            
            yKsum_sub = app.yKsum - app.yKs;
            app.yKs = gen_IKs(app);
            app.yKsum = yKsum_sub + app.yKs;
            
            plot_currents(app, 3)
        end

        % Callback function: p1EditField_3, p1Knob_3
        function p1Knob_3ValueChanging(app, event)
            changingValue = event.Value;
            app.p1Knob_3.Value = changingValue;
            app.p1EditField.Value = changingValue;
            
            yKsum_sub = app.yKsum - app.yKs;
            app.yKs = gen_IKs(app);
            app.yKsum = yKsum_sub + app.yKs;
            
            plot_currents(app, 3)
        end

        % Callback function: p2EditField_3, p2Knob_3
        function p2Knob_3ValueChanging(app, event)
            changingValue = event.Value;
            app.p2Knob_3.Value = changingValue;
            app.p2EditField.Value = changingValue;
            
            yKsum_sub = app.yKsum - app.yKs;
            app.yKs = gen_IKs(app);
            app.yKsum = yKsum_sub + app.yKs;
            
            plot_currents(app, 3)
        end

        % Callback function: p3EditField_3, p3Knob_3
        function p3Knob_3ValueChanging(app, event)
            changingValue = event.Value;
            app.p3Knob_3.Value = changingValue;
            app.p3EditField.Value = changingValue;
            
            yKsum_sub = app.yKsum - app.yKs;
            app.yKs = gen_IKs(app);
            app.yKsum = yKsum_sub + app.yKs;
            
            plot_currents(app, 3)
        end

        % Callback function: p4EditField_3, p4Knob_3
        function p4Knob_3ValueChanging(app, event)
            changingValue = event.Value;
            app.p4Knob_3.Value = changingValue;
            app.p4EditField.Value = changingValue;
            
            yKsum_sub = app.yKsum - app.yKs;
            app.yKs = gen_IKs(app);
            app.yKsum = yKsum_sub + app.yKs;
            
            plot_currents(app, 3)
        end

        % Callback function: p5EditField_3, p5Knob_3
        function p5Knob_3ValueChanging(app, event)
            changingValue = event.Value;
            app.p5Knob_3.Value = changingValue;
            app.p5EditField.Value = changingValue;
            
            yKsum_sub = app.yKsum - app.yKs;
            app.yKs = gen_IKs(app);
            app.yKsum = yKsum_sub + app.yKs;
            
            plot_currents(app, 3)
        end

        % Callback function: GKurEditField, GKurKnob
        function GKurKnobValueChanging(app, event)
            changingValue = event.Value;
            app.GKurKnob.Value = changingValue;
            app.GKurEditField.Value = changingValue;
            
            yKsum_sub = app.yKsum - app.yKur;
            app.yKur = gen_IKur(app);
            app.yKsum = yKsum_sub + app.yKur;
            
            plot_currents(app, 4);
        end

        % Callback function: p1EditField_4, p1Knob_4
        function p1Knob_4ValueChanging(app, event)
            changingValue = event.Value;
            app.p1Knob_4.Value = changingValue;
            app.p1EditField_4.Value = changingValue;
            
            yKsum_sub = app.yKsum - app.yKur;
            app.yKur = gen_IKur(app);
            app.yKsum = yKsum_sub + app.yKur;
            
            plot_currents(app, 4);
        end

        % Callback function: p2EditField_4, p2Knob_4
        function p2Knob_4ValueChanging(app, event)
            changingValue = event.Value;
            app.p2Knob_4.Value = changingValue;
            app.p2EditField_4.Value = changingValue;
            
            yKsum_sub = app.yKsum - app.yKur;
            app.yKur = gen_IKur(app);
            app.yKsum = yKsum_sub + app.yKur;
            
            plot_currents(app, 4);
        end

        % Callback function: p3EditField_4, p3Knob_4
        function p3Knob_4ValueChanging(app, event)
            changingValue = event.Value;
            app.p3Knob_4.Value = changingValue;
            app.p3EditField_4.Value = changingValue;
            
            yKsum_sub = app.yKsum - app.yKur;
            app.yKur = gen_IKur(app);
            app.yKsum = yKsum_sub + app.yKur;
            
            plot_currents(app, 4);
        end

        % Callback function: p4EditField_4, p4Knob_4
        function p4Knob_4ValueChanging(app, event)
            changingValue = event.Value;
            app.p4Knob_4.Value = changingValue;
            app.p4EditField_4.Value = changingValue;
            
            yKsum_sub = app.yKsum - app.yKur;
            app.yKur = gen_IKur(app);
            app.yKsum = yKsum_sub + app.yKur;
            
            plot_currents(app, 4);
        end

        % Callback function: p5EditField_4, p5Knob_4
        function p5Knob_4ValueChanging(app, event)
            changingValue = event.Value;
            app.p5Knob_4.Value = changingValue;
            app.p5EditField_4.Value = changingValue;
            
            yKsum_sub = app.yKsum - app.yKur;
            app.yKur = gen_IKur(app);
            app.yKsum = yKsum_sub + app.yKur;
            
            plot_currents(app, 4);
        end

        % Callback function: p6EditField_4, p6Knob_4
        function p6Knob_4ValueChanging(app, event)
            changingValue = event.Value;
            app.p6Knob_4.Value = changingValue;
            app.p6EditField_4.Value = changingValue;
            
            yKsum_sub = app.yKsum - app.yKur;
            app.yKur = gen_IKur(app);
            app.yKsum = yKsum_sub + app.yKur;
            
            plot_currents(app, 4);
        end

        % Callback function: p7EditField_4, p7Knob_4
        function p7Knob_4ValueChanging(app, event)
            changingValue = event.Value;
            app.p7Knob_4.Value = changingValue;
            app.p7EditField_4.Value = changingValue;
            
            yKsum_sub = app.yKsum - app.yKur;
            app.yKur = gen_IKur(app);
            app.yKsum = yKsum_sub + app.yKur;
            
            plot_currents(app, 4);
        end

        % Callback function: p8EditField_4, p8Knob_4
        function p8Knob_4ValueChanging(app, event)
            changingValue = event.Value;
            app.p8Knob_4.Value = changingValue;
            app.p8EditField_4.Value = changingValue;
            
            yKsum_sub = app.yKsum - app.yKur;
            app.yKur = gen_IKur(app);
            app.yKsum = yKsum_sub + app.yKur;
            
            plot_currents(app, 4);
        end

        % Callback function: p9EditField_4, p9Knob_4
        function p9Knob_4ValueChanging(app, event)
            changingValue = event.Value;
            app.p9Knob_4.Value = changingValue;
            app.p9EditField_4.Value = changingValue;
            
            yKsum_sub = app.yKsum - app.yKur;
            app.yKur = gen_IKur(app);
            app.yKsum = yKsum_sub + app.yKur;
            
            plot_currents(app, 4);
        end

        % Callback function: p10EditField_4, p10Knob_4
        function p10Knob_4ValueChanging(app, event)
            changingValue = event.Value;
            app.p10Knob_4.Value = changingValue;
            app.p10EditField_4.Value = changingValue;
            
            yKsum_sub = app.yKsum - app.yKur;
            app.yKur = gen_IKur(app);
            app.yKsum = yKsum_sub + app.yKur;
            
            plot_currents(app, 4);
        end

        % Callback function: p11EditField_4, p11Knob_4
        function p11Knob_4ValueChanging(app, event)
            changingValue = event.Value;
            app.p11Knob_4.Value = changingValue;
            app.p11EditField_4.Value = changingValue;
            
            yKsum_sub = app.yKsum - app.yKur;
            app.yKur = gen_IKur(app);
            app.yKsum = yKsum_sub + app.yKur;
            
            plot_currents(app, 4);
        end

        % Callback function: GKssEditField, GKssKnob
        function GKssKnobValueChanging(app, event)
            changingValue = event.Value;
            app.GKssKnob.Value = changingValue;
            app.GKssEditField.Value = changingValue;
            
            yKsum_sub = app.yKsum - app.yKss;
            app.yKss = gen_Iss(app);
            app.yKsum = yKsum_sub + app.yKss;
            
            plot_currents(app, 5);            
        end

        % Callback function: p1EditField_5, p1Knob_5
        function p1Knob_5ValueChanging(app, event)
            changingValue = event.Value;
            app.p1Knob_5.Value = changingValue;
            app.p1EditField_5.Value = changingValue;
            
            yKsum_sub = app.yKsum - app.yKss;
            app.yKss = gen_Iss(app);
            app.yKsum = yKsum_sub + app.yKss;
            
            plot_currents(app, 5);            
        end

        % Callback function: p2EditField_5, p2Knob_5
        function p2Knob_5ValueChanging(app, event)
            changingValue = event.Value;
            app.p2Knob_5.Value = changingValue;
            app.p2EditField_5.Value = changingValue;
            
            yKsum_sub = app.yKsum - app.yKss;
            app.yKss = gen_Iss(app);
            app.yKsum = yKsum_sub + app.yKss;
            
            plot_currents(app, 5);            
        end

        % Callback function: p3EditField_5, p3Knob_5
        function p3Knob_5ValueChanging(app, event)
            changingValue = event.Value;
            app.p3Knob_5.Value = changingValue;
            app.p3EditField_5.Value = changingValue;
            
            yKsum_sub = app.yKsum - app.yKss;
            app.yKss = gen_Iss(app);
            app.yKsum = yKsum_sub + app.yKss;
            
            plot_currents(app, 5);
        end

        % Callback function: p4EditField_5, p4Knob_5
        function p4Knob_5ValueChanging(app, event)
            changingValue = event.Value;
            app.p4Knob_5.Value = changingValue;
            app.p4EditField_5.Value = changingValue;
            
            yKsum_sub = app.yKsum - app.yKss;
            app.yKss = gen_Iss(app);
            app.yKsum = yKsum_sub + app.yKss;
            
            plot_currents(app, 5);            
        end

        % Callback function: p5EditField_5, p5Knob_5
        function p5Knob_5ValueChanging(app, event)
            changingValue = event.Value;
            app.p5Knob_5.Value = changingValue;
            app.p5EditField_5.Value = changingValue;

            yKsum_sub = app.yKsum - app.yKss;
            app.yKss = gen_Iss(app);
            app.yKsum = yKsum_sub + app.yKss;
            
            plot_currents(app, 5);            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.ProtocolTab = uitab(app.TabGroup);
            app.ProtocolTab.Title = 'Protocol';
            app.HoldingtimemsEditFieldLabel = uilabel(app.ProtocolTab);
            app.HoldingtimemsEditFieldLabel.Text = 'Holding time (ms)';
            app.HoldingtimemsEditField = uieditfield(app.ProtocolTab, 'numeric');
            app.HoldingtimemsEditField.Value = 100;
            app.HoldingpotentialmVEditFieldLabel = uilabel(app.ProtocolTab);
            app.HoldingpotentialmVEditFieldLabel.Text = 'Holding potential (mV)';
            app.HoldingpotentialmVEditField = uieditfield(app.ProtocolTab, 'numeric');
            app.HoldingpotentialmVEditField.Value = -70;
            app.ClampvoltagemVEditFieldLabel = uilabel(app.ProtocolTab);
            app.ClampvoltagemVEditFieldLabel.Text = 'Clamp voltage (mV)';
            app.ClampvoltagemVEditField = uieditfield(app.ProtocolTab, 'numeric');
            app.ClampvoltagemVEditField.Value = 50;
            app.VoltagetimemsEditFieldLabel = uilabel(app.ProtocolTab);
            app.VoltagetimemsEditFieldLabel.Text = 'Voltage time (ms)';
            app.VoltagetimemsEditField = uieditfield(app.ProtocolTab, 'numeric');
            app.VoltagetimemsEditField.Value = 4500;
            app.EnterButton = uibutton(app.ProtocolTab, 'push');
            app.EnterButton.ButtonPushedFcn = createCallbackFcn(app, @EnterButtonPushed, true);
            app.EnterButton.Text = 'Enter';
            app.EkEditFieldLabel = uilabel(app.ProtocolTab);
            app.EkEditFieldLabel.Text = 'Ek';
            app.EkEditField = uieditfield(app.ProtocolTab, 'numeric');
            app.EkEditField.Value = -91.1;
            app.Switch = uiswitch(app.ProtocolTab, 'toggle');
            app.Switch.Items = {'Septum', 'Apex'};
            app.Switch.Value = 'Septum';
            app.UIAxes = uiaxes(app.ProtocolTab);
            title(app.UIAxes, 'Voltage Clamp Protocol')
            xlabel(app.UIAxes, 'ms')
            ylabel(app.UIAxes, 'mV')
            zlabel(app.UIAxes, 'Z')
            app.IKtofTab = uitab(app.TabGroup);
            app.IKtofTab.Title = 'IKtof';
            app.p1KnobLabel = uilabel(app.IKtofTab);
            app.p1KnobLabel.Text = 'p1';
            app.p1Knob = uiknob(app.IKtofTab, 'continuous');
            app.p1Knob.Limits = [-60 60];
            app.p1Knob.ValueChangingFcn = createCallbackFcn(app, @p1KnobValueChanging, true);
            app.p1Knob.Value = 30;
            app.p2KnobLabel = uilabel(app.IKtofTab);
            app.p2KnobLabel.Text = 'p2';
            app.p2Knob = uiknob(app.IKtofTab, 'continuous');
            app.p2Knob.Limits = [-60 60];
            app.p2Knob.ValueChangingFcn = createCallbackFcn(app, @p2KnobValueChanging, true);
            app.p2Knob.Value = 13.5;
            app.p3KnobLabel = uilabel(app.IKtofTab);
            app.p3KnobLabel.Text = 'p3';
            app.p3Knob = uiknob(app.IKtofTab, 'continuous');
            app.p3Knob.Limits = [-60 80];
            app.p3Knob.ValueChangingFcn = createCallbackFcn(app, @p3KnobValueChanging, true);
            app.p3Knob.Value = 33.5;
            app.p4KnobLabel = uilabel(app.IKtofTab);
            app.p4KnobLabel.Text = 'p4';
            app.p4Knob = uiknob(app.IKtofTab, 'continuous');
            app.p4Knob.Limits = [0 15];
            app.p4Knob.ValueChangingFcn = createCallbackFcn(app, @p4KnobValueChanging, true);
            app.p4Knob.Value = 7;
            app.p5KnobLabel = uilabel(app.IKtofTab);
            app.p5KnobLabel.Text = 'p5';
            app.p5Knob = uiknob(app.IKtofTab, 'continuous');
            app.p5Knob.Limits = [0 1.5];
            app.p5Knob.ValueChangingFcn = createCallbackFcn(app, @p5KnobValueChanging, true);
            app.p5Knob.Value = 0.18064;
            app.p6KnobLabel = uilabel(app.IKtofTab);
            app.p6KnobLabel.Text = 'p6';
            app.p6Knob = uiknob(app.IKtofTab, 'continuous');
            app.p6Knob.Limits = [0 0.5];
            app.p6Knob.ValueChangingFcn = createCallbackFcn(app, @p6KnobValueChanging, true);
            app.p6Knob.Value = 0.03577;
            app.p7KnobLabel = uilabel(app.IKtofTab);
            app.p7KnobLabel.Text = 'p7';
            app.p7Knob = uiknob(app.IKtofTab, 'continuous');
            app.p7Knob.Limits = [0 1.5];
            app.p7Knob.ValueChangingFcn = createCallbackFcn(app, @p7KnobValueChanging, true);
            app.p7Knob.Value = 0.3956;
            app.p8KnobLabel = uilabel(app.IKtofTab);
            app.p8KnobLabel.Text = 'p8';
            app.p8Knob = uiknob(app.IKtofTab, 'continuous');
            app.p8Knob.Limits = [-0.5 0.5];
            app.p8Knob.ValueChangingFcn = createCallbackFcn(app, @p8KnobValueChanging, true);
            app.p8Knob.Value = 0.06237;
            app.p9KnobLabel = uilabel(app.IKtofTab);
            app.p9KnobLabel.Text = 'p9';
            app.p9Knob = uiknob(app.IKtofTab, 'continuous');
            app.p9Knob.Limits = [0 0.001];
            app.p9Knob.ValueChangingFcn = createCallbackFcn(app, @p9KnobValueChanging, true);
            app.p9Knob.Value = 0.000152;
            app.p10KnobLabel = uilabel(app.IKtofTab);
            app.p10KnobLabel.Text = 'p10';
            app.p10Knob = uiknob(app.IKtofTab, 'continuous');
            app.p10Knob.Limits = [0 0.15];
            app.p10Knob.ValueChangingFcn = createCallbackFcn(app, @p10KnobValueChanging, true);
            app.p10Knob.Value = 0.067083;
            app.p11KnobLabel = uilabel(app.IKtofTab);
            app.p11KnobLabel.Text = 'p11';
            app.p11Knob = uiknob(app.IKtofTab, 'continuous');
            app.p11Knob.Limits = [0 0.002];
            app.p11Knob.ValueChangingFcn = createCallbackFcn(app, @p11KnobValueChanging, true);
            app.p11Knob.Value = 0.00095;
            app.p12KnobLabel = uilabel(app.IKtofTab);
            app.p12KnobLabel.Text = 'p12';
            app.p12Knob = uiknob(app.IKtofTab, 'continuous');
            app.p12Knob.Limits = [0 0.1];
            app.p12Knob.ValueChangingFcn = createCallbackFcn(app, @p12KnobValueChanging, true);
            app.p12Knob.Value = 0.051335;
            app.GKtofKnobLabel = uilabel(app.IKtofTab);
            app.GKtofKnobLabel.Text = 'GKtof';
            app.GKtofKnob = uiknob(app.IKtofTab, 'continuous');
            app.GKtofKnob.Limits = [0 2];
            app.GKtofKnob.ValueChangingFcn = createCallbackFcn(app, @GKtofKnobValueChanging, true);
            app.GKtofKnob.Value = 0.4067;
            app.TextArea = uitextarea(app.IKtofTab);
            app.TextArea.Value = {'IKtof'; 'Transient outward'; 'K+ current '};
            app.PlotButton = uibutton(app.IKtofTab, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.p1EditFieldLabel = uilabel(app.IKtofTab);
            app.p1EditFieldLabel.Text = 'p1';
            app.p1EditField = uieditfield(app.IKtofTab, 'numeric');
            app.p1EditField.ValueChangedFcn = createCallbackFcn(app, @p1KnobValueChanging, true);
            app.p1EditField.Value = 30;
            app.p2EditFieldLabel = uilabel(app.IKtofTab);
            app.p2EditFieldLabel.Text = 'p2';
            app.p2EditField = uieditfield(app.IKtofTab, 'numeric');
            app.p2EditField.ValueChangedFcn = createCallbackFcn(app, @p2KnobValueChanging, true);
            app.p2EditField.Value = 13.5;
            app.p3EditFieldLabel = uilabel(app.IKtofTab);
            app.p3EditFieldLabel.Text = 'p3';
            app.p3EditField = uieditfield(app.IKtofTab, 'numeric');
            app.p3EditField.ValueChangedFcn = createCallbackFcn(app, @p3KnobValueChanging, true);
            app.p3EditField.Value = 33.5;
            app.p4EditFieldLabel = uilabel(app.IKtofTab);
            app.p4EditFieldLabel.Text = 'p4';
            app.p4EditField = uieditfield(app.IKtofTab, 'numeric');
            app.p4EditField.ValueChangedFcn = createCallbackFcn(app, @p4KnobValueChanging, true);
            app.p4EditField.Value = 7;
            app.p5EditFieldLabel = uilabel(app.IKtofTab);
            app.p5EditFieldLabel.Text = 'p5';
            app.p5EditField = uieditfield(app.IKtofTab, 'numeric');
            app.p5EditField.ValueChangedFcn = createCallbackFcn(app, @p5KnobValueChanging, true);
            app.p5EditField.Value = 0.18064;
            app.p6EditFieldLabel = uilabel(app.IKtofTab);
            app.p6EditFieldLabel.Text = 'p6';
            app.p6EditField = uieditfield(app.IKtofTab, 'numeric');
            app.p6EditField.ValueChangedFcn = createCallbackFcn(app, @p6KnobValueChanging, true);
            app.p6EditField.Value = 0.03577;
            app.p7EditFieldLabel = uilabel(app.IKtofTab);
            app.p7EditFieldLabel.Text = 'p7';
            app.p7EditField = uieditfield(app.IKtofTab, 'numeric');
            app.p7EditField.ValueChangedFcn = createCallbackFcn(app, @p7KnobValueChanging, true);
            app.p7EditField.Value = 0.3956;
            app.p8EditFieldLabel = uilabel(app.IKtofTab);
            app.p8EditFieldLabel.Text = 'p8';
            app.p8EditField = uieditfield(app.IKtofTab, 'numeric');
            app.p8EditField.ValueChangedFcn = createCallbackFcn(app, @p8KnobValueChanging, true);
            app.p8EditField.Value = 0.06237;
            app.p9EditFieldLabel = uilabel(app.IKtofTab);
            app.p9EditFieldLabel.Text = 'p9';
            app.p9EditField = uieditfield(app.IKtofTab, 'numeric');
            app.p9EditField.ValueChangedFcn = createCallbackFcn(app, @p9KnobValueChanging, true);
            app.p9EditField.Value = 0.000152;
            app.p10EditFieldLabel = uilabel(app.IKtofTab);
            app.p10EditFieldLabel.Text = 'p10';
            app.p10EditField = uieditfield(app.IKtofTab, 'numeric');
            app.p10EditField.ValueChangedFcn = createCallbackFcn(app, @p10KnobValueChanging, true);
            app.p10EditField.Value = 0.067083;
            app.p11EditFieldLabel = uilabel(app.IKtofTab);
            app.p11EditFieldLabel.Text = 'p11';
            app.p11EditField = uieditfield(app.IKtofTab, 'numeric');
            app.p11EditField.ValueChangedFcn = createCallbackFcn(app, @p11KnobValueChanging, true);
            app.p11EditField.Value = 0.00095;
            app.p12EditFieldLabel = uilabel(app.IKtofTab);
            app.p12EditFieldLabel.Text = 'p12';
            app.p12EditField = uieditfield(app.IKtofTab, 'numeric');
            app.p12EditField.ValueChangedFcn = createCallbackFcn(app, @p12KnobValueChanging, true);
            app.p12EditField.Value = 0.051335;
            app.GKtofEditFieldLabel = uilabel(app.IKtofTab);
            app.GKtofEditFieldLabel.Text = 'GKtof';
            app.GKtofEditField = uieditfield(app.IKtofTab, 'numeric');
            app.GKtofEditField.ValueChangedFcn = createCallbackFcn(app, @GKtofKnobValueChanging, true);
            app.GKtofEditField.Value = 0.4067;
            app.IKtosTab = uitab(app.TabGroup);
            app.IKtosTab.Title = 'IKtos';
            app.IKITab = uitab(app.TabGroup);
            app.IKITab.Title = 'IKI';
            app.TextArea_2 = uitextarea(app.IKITab);
            app.TextArea_2.Value = {'IKI: Time-independent K+ current'};
            app.PlotButton_2 = uibutton(app.IKITab, 'push');
            app.PlotButton_2.ButtonPushedFcn = createCallbackFcn(app, @PlotButton_2Pushed, true);
            app.PlotButton_2.Text = 'Plot';
            app.p1Knob_6Label = uilabel(app.IKITab);
            app.p1Knob_6Label.Text = 'p1';
            app.p1Knob_2 = uiknob(app.IKITab, 'continuous');
            app.p1Knob_2.Limits = [0 1.5];
            app.p1Knob_2.ValueChangingFcn = createCallbackFcn(app, @p1Knob_2ValueChanging, true);
            app.p1Knob_2.Value = 0.2938;
            app.p2Knob_6Label = uilabel(app.IKITab);
            app.p2Knob_6Label.Text = 'p2';
            app.p2Knob_2 = uiknob(app.IKITab, 'continuous');
            app.p2Knob_2.Limits = [-200 600];
            app.p2Knob_2.ValueChangingFcn = createCallbackFcn(app, @p2Knob_2ValueChanging, true);
            app.p2Knob_2.Value = 210;
            app.p3Knob_6Label = uilabel(app.IKITab);
            app.p3Knob_6Label.Text = 'p3';
            app.p3Knob_2 = uiknob(app.IKITab, 'continuous');
            app.p3Knob_2.Limits = [0 0.5];
            app.p3Knob_2.ValueChangingFcn = createCallbackFcn(app, @p3Knob_2ValueChanging, true);
            app.p3Knob_2.Value = 0.0896;
            app.KoKnobLabel = uilabel(app.IKITab);
            app.KoKnobLabel.Text = '[Ko]';
            app.KoKnob = uiknob(app.IKITab, 'continuous');
            app.KoKnob.Limits = [0 10000];
            app.KoKnob.ValueChangingFcn = createCallbackFcn(app, @KoKnobValueChanging, true);
            app.KoKnob.Value = 5400;
            app.p1EditField_6Label = uilabel(app.IKITab);
            app.p1EditField_6Label.Text = 'p1';
            app.p1EditField_2 = uieditfield(app.IKITab, 'numeric');
            app.p1EditField_2.ValueChangedFcn = createCallbackFcn(app, @p1Knob_2ValueChanging, true);
            app.p1EditField_2.Value = 0.2938;
            app.p2EditField_6Label = uilabel(app.IKITab);
            app.p2EditField_6Label.Text = 'p2';
            app.p2EditField_2 = uieditfield(app.IKITab, 'numeric');
            app.p2EditField_2.ValueChangedFcn = createCallbackFcn(app, @p2Knob_2ValueChanging, true);
            app.p2EditField_2.Value = 210;
            app.p3EditField_6Label = uilabel(app.IKITab);
            app.p3EditField_6Label.Text = 'p3';
            app.p3EditField_2 = uieditfield(app.IKITab, 'numeric');
            app.p3EditField_2.ValueChangedFcn = createCallbackFcn(app, @p3Knob_2ValueChanging, true);
            app.p3EditField_2.Value = 0.0896;
            app.KoEditFieldLabel = uilabel(app.IKITab);
            app.KoEditFieldLabel.Text = '[Ko]';
            app.KoEditField = uieditfield(app.IKITab, 'numeric');
            app.KoEditField.ValueChangedFcn = createCallbackFcn(app, @KoKnobValueChanging, true);
            app.KoEditField.Value = 5400;
            app.IKsTab = uitab(app.TabGroup);
            app.IKsTab.Title = 'IKs';
            app.p1Knob_7Label = uilabel(app.IKsTab);
            app.p1Knob_7Label.Text = 'p1';
            app.p1Knob_3 = uiknob(app.IKsTab, 'continuous');
            app.p1Knob_3.Limits = [-50 100];
            app.p1Knob_3.ValueChangingFcn = createCallbackFcn(app, @p1Knob_3ValueChanging, true);
            app.p1Knob_3.Value = 26.5;
            app.p2Knob_7Label = uilabel(app.IKsTab);
            app.p2Knob_7Label.Text = 'p2';
            app.p2Knob_3 = uiknob(app.IKsTab, 'continuous');
            app.p2Knob_3.Limits = [0 0.5];
            app.p2Knob_3.ValueChangingFcn = createCallbackFcn(app, @p2Knob_3ValueChanging, true);
            app.p2Knob_3.Value = 0.128;
            app.p3Knob_7Label = uilabel(app.IKsTab);
            app.p3Knob_7Label.Text = 'p3';
            app.p3Knob_3 = uiknob(app.IKsTab, 'continuous');
            app.p3Knob_3.Limits = [0 0.5];
            app.p3Knob_3.ValueChangingFcn = createCallbackFcn(app, @p3Knob_3ValueChanging, true);
            app.p3Knob_3.Value = 0.038;
            app.p4Knob_6Label = uilabel(app.IKsTab);
            app.p4Knob_6Label.Text = 'p4';
            app.p4Knob_3 = uiknob(app.IKsTab, 'continuous');
            app.p4Knob_3.Limits = [0 1e-05];
            app.p4Knob_3.ValueChangingFcn = createCallbackFcn(app, @p4Knob_3ValueChanging, true);
            app.p4Knob_3.Value = 4.81333e-06;
            app.p5Knob_6Label = uilabel(app.IKsTab);
            app.p5Knob_6Label.Text = 'p5';
            app.p5Knob_3 = uiknob(app.IKsTab, 'continuous');
            app.p5Knob_3.Limits = [0 0.0005];
            app.p5Knob_3.ValueChangingFcn = createCallbackFcn(app, @p5Knob_3ValueChanging, true);
            app.p5Knob_3.Value = 9.53333e-05;
            app.TextArea_3 = uitextarea(app.IKsTab);
            app.TextArea_3.Value = {'IKs: Slow delayed rectifier K+ current'};
            app.GKsKnobLabel = uilabel(app.IKsTab);
            app.GKsKnobLabel.Text = 'GKs';
            app.GKsKnob = uiknob(app.IKsTab, 'continuous');
            app.GKsKnob.Limits = [0 0.015];
            app.GKsKnob.ValueChangingFcn = createCallbackFcn(app, @GKsKnobValueChanging, true);
            app.GKsKnob.Value = 0.00575;
            app.PlotButton_3 = uibutton(app.IKsTab, 'push');
            app.PlotButton_3.ButtonPushedFcn = createCallbackFcn(app, @PlotButton_3Pushed, true);
            app.PlotButton_3.Text = 'Plot';
            app.p1EditField_7Label = uilabel(app.IKsTab);
            app.p1EditField_7Label.Text = 'p1';
            app.p1EditField_3 = uieditfield(app.IKsTab, 'numeric');
            app.p1EditField_3.ValueChangedFcn = createCallbackFcn(app, @p1Knob_3ValueChanging, true);
            app.p1EditField_3.Value = 26.5;
            app.p2EditField_7Label = uilabel(app.IKsTab);
            app.p2EditField_7Label.Text = 'p2';
            app.p2EditField_3 = uieditfield(app.IKsTab, 'numeric');
            app.p2EditField_3.ValueChangedFcn = createCallbackFcn(app, @p2Knob_3ValueChanging, true);
            app.p2EditField_3.Value = 0.128;
            app.p3EditField_7Label = uilabel(app.IKsTab);
            app.p3EditField_7Label.Text = 'p3';
            app.p3EditField_3 = uieditfield(app.IKsTab, 'numeric');
            app.p3EditField_3.ValueChangedFcn = createCallbackFcn(app, @p3Knob_3ValueChanging, true);
            app.p3EditField_3.Value = 0.038;
            app.p4EditField_6Label = uilabel(app.IKsTab);
            app.p4EditField_6Label.Text = 'p4';
            app.p4EditField_3 = uieditfield(app.IKsTab, 'numeric');
            app.p4EditField_3.ValueChangedFcn = createCallbackFcn(app, @p4Knob_3ValueChanging, true);
            app.p4EditField_3.Value = 4.81333e-06;
            app.p5EditField_6Label = uilabel(app.IKsTab);
            app.p5EditField_6Label.Text = 'p5';
            app.p5EditField_3 = uieditfield(app.IKsTab, 'numeric');
            app.p5EditField_3.ValueChangedFcn = createCallbackFcn(app, @p5Knob_3ValueChanging, true);
            app.p5EditField_3.Value = 9.53333e-05;
            app.GKsEditFieldLabel = uilabel(app.IKsTab);
            app.GKsEditFieldLabel.Text = 'GKs';
            app.GKsEditField = uieditfield(app.IKsTab, 'numeric');
            app.GKsEditField.ValueChangedFcn = createCallbackFcn(app, @GKsKnobValueChanging, true);
            app.GKsEditField.Value = 0.00575;
            app.IKurTab = uitab(app.TabGroup);
            app.IKurTab.Title = 'IKur';
            app.TextArea_4 = uitextarea(app.IKurTab);
            app.TextArea_4.Value = {'IKur'; 'Ultrarapidly activating delayed rectifier K+ current'};
            app.PlotButton_4 = uibutton(app.IKurTab, 'push');
            app.PlotButton_4.ButtonPushedFcn = createCallbackFcn(app, @PlotButton_4Pushed, true);
            app.PlotButton_4.Text = 'Plot';
            app.p1Knob_2Label = uilabel(app.IKurTab);
            app.p1Knob_2Label.Text = 'p1';
            app.p1Knob_4 = uiknob(app.IKurTab, 'continuous');
            app.p1Knob_4.Limits = [-60 60];
            app.p1Knob_4.ValueChangingFcn = createCallbackFcn(app, @p1Knob_4ValueChanging, true);
            app.p1Knob_4.Value = 22.5;
            app.p2Knob_2Label = uilabel(app.IKurTab);
            app.p2Knob_2Label.Text = 'p2';
            app.p2Knob_4 = uiknob(app.IKurTab, 'continuous');
            app.p2Knob_4.Limits = [0 20];
            app.p2Knob_4.ValueChangingFcn = createCallbackFcn(app, @p2Knob_4ValueChanging, true);
            app.p2Knob_4.Value = 7.7;
            app.p3Knob_2Label = uilabel(app.IKurTab);
            app.p3Knob_2Label.Text = 'p3';
            app.p3Knob_4 = uiknob(app.IKurTab, 'continuous');
            app.p3Knob_4.Limits = [0 2];
            app.p3Knob_4.ValueChangingFcn = createCallbackFcn(app, @p3Knob_4ValueChanging, true);
            app.p3Knob_4.Value = 0.493;
            app.p4Knob_2Label = uilabel(app.IKurTab);
            app.p4Knob_2Label.Text = 'p4';
            app.p4Knob_4 = uiknob(app.IKurTab, 'continuous');
            app.p4Knob_4.Limits = [0 0.5];
            app.p4Knob_4.ValueChangingFcn = createCallbackFcn(app, @p4Knob_4ValueChanging, true);
            app.p4Knob_4.Value = 0.0629;
            app.p5Knob_2Label = uilabel(app.IKurTab);
            app.p5Knob_2Label.Text = 'p5';
            app.p5Knob_4 = uiknob(app.IKurTab, 'continuous');
            app.p5Knob_4.Limits = [-10 10];
            app.p5Knob_4.ValueChangingFcn = createCallbackFcn(app, @p5Knob_4ValueChanging, true);
            app.p5Knob_4.Value = 2.058;
            app.p6Knob_2Label = uilabel(app.IKurTab);
            app.p6Knob_2Label.Text = 'p6';
            app.p6Knob_4 = uiknob(app.IKurTab, 'continuous');
            app.p6Knob_4.Limits = [-50 100];
            app.p6Knob_4.ValueChangingFcn = createCallbackFcn(app, @p6Knob_4ValueChanging, true);
            app.p6Knob_4.Value = 45.2;
            app.p7Knob_2Label = uilabel(app.IKurTab);
            app.p7Knob_2Label.Text = 'p7';
            app.p7Knob_4 = uiknob(app.IKurTab, 'continuous');
            app.p7Knob_4.Limits = [0 20];
            app.p7Knob_4.ValueChangingFcn = createCallbackFcn(app, @p7Knob_4ValueChanging, true);
            app.p7Knob_4.Value = 5.7;
            app.p8Knob_2Label = uilabel(app.IKurTab);
            app.p8Knob_2Label.Text = 'p8';
            app.p8Knob_4 = uiknob(app.IKurTab, 'continuous');
            app.p8Knob_4.Limits = [0 5000];
            app.p8Knob_4.ValueChangingFcn = createCallbackFcn(app, @p8Knob_4ValueChanging, true);
            app.p8Knob_4.Value = 1200;
            app.p9Knob_2Label = uilabel(app.IKurTab);
            app.p9Knob_2Label.Text = 'p9';
            app.p9Knob_4 = uiknob(app.IKurTab, 'continuous');
            app.p9Knob_4.Limits = [0 500];
            app.p9Knob_4.ValueChangingFcn = createCallbackFcn(app, @p9Knob_4ValueChanging, true);
            app.p9Knob_4.Value = 170;
            app.p10Knob_2Label = uilabel(app.IKurTab);
            app.p10Knob_2Label.Text = 'p10';
            app.p10Knob_4 = uiknob(app.IKurTab, 'continuous');
            app.p10Knob_4.Limits = [-50 100];
            app.p10Knob_4.ValueChangingFcn = createCallbackFcn(app, @p10Knob_4ValueChanging, true);
            app.p10Knob_4.Value = 45.2;
            app.p11Knob_2Label = uilabel(app.IKurTab);
            app.p11Knob_2Label.Text = 'p11';
            app.p11Knob_4 = uiknob(app.IKurTab, 'continuous');
            app.p11Knob_4.Limits = [0 20];
            app.p11Knob_4.ValueChangingFcn = createCallbackFcn(app, @p11Knob_4ValueChanging, true);
            app.p11Knob_4.Value = 5.7;
            app.GKurKnobLabel = uilabel(app.IKurTab);
            app.GKurKnobLabel.Text = 'GKur';
            app.GKurKnob = uiknob(app.IKurTab, 'continuous');
            app.GKurKnob.Limits = [0 2];
            app.GKurKnob.ValueChangingFcn = createCallbackFcn(app, @GKurKnobValueChanging, true);
            app.GKurKnob.Value = 0.16;
            app.p1EditField_2Label = uilabel(app.IKurTab);
            app.p1EditField_2Label.Text = 'p1';
            app.p1EditField_4 = uieditfield(app.IKurTab, 'numeric');
            app.p1EditField_4.ValueChangedFcn = createCallbackFcn(app, @p1Knob_4ValueChanging, true);
            app.p1EditField_4.Value = 22.5;
            app.p2EditField_2Label = uilabel(app.IKurTab);
            app.p2EditField_2Label.Text = 'p2';
            app.p2EditField_4 = uieditfield(app.IKurTab, 'numeric');
            app.p2EditField_4.ValueChangedFcn = createCallbackFcn(app, @p2Knob_4ValueChanging, true);
            app.p2EditField_4.Value = 7.7;
            app.p3EditField_2Label = uilabel(app.IKurTab);
            app.p3EditField_2Label.Text = 'p3';
            app.p3EditField_4 = uieditfield(app.IKurTab, 'numeric');
            app.p3EditField_4.ValueChangedFcn = createCallbackFcn(app, @p3Knob_4ValueChanging, true);
            app.p3EditField_4.Value = 0.493;
            app.p4EditField_2Label = uilabel(app.IKurTab);
            app.p4EditField_2Label.Text = 'p4';
            app.p4EditField_4 = uieditfield(app.IKurTab, 'numeric');
            app.p4EditField_4.ValueChangedFcn = createCallbackFcn(app, @p4Knob_4ValueChanging, true);
            app.p4EditField_4.Value = 0.0629;
            app.p5EditField_2Label = uilabel(app.IKurTab);
            app.p5EditField_2Label.Text = 'p5';
            app.p5EditField_4 = uieditfield(app.IKurTab, 'numeric');
            app.p5EditField_4.ValueChangedFcn = createCallbackFcn(app, @p5Knob_4ValueChanging, true);
            app.p5EditField_4.Value = 2.058;
            app.p6EditField_2Label = uilabel(app.IKurTab);
            app.p6EditField_2Label.Text = 'p6';
            app.p6EditField_4 = uieditfield(app.IKurTab, 'numeric');
            app.p6EditField_4.ValueChangedFcn = createCallbackFcn(app, @p6Knob_4ValueChanging, true);
            app.p6EditField_4.Value = 45.2;
            app.p7EditField_2Label = uilabel(app.IKurTab);
            app.p7EditField_2Label.Text = 'p7';
            app.p7EditField_4 = uieditfield(app.IKurTab, 'numeric');
            app.p7EditField_4.ValueChangedFcn = createCallbackFcn(app, @p7Knob_4ValueChanging, true);
            app.p7EditField_4.Value = 5.7;
            app.p8EditField_2Label = uilabel(app.IKurTab);
            app.p8EditField_2Label.Text = 'p8';
            app.p8EditField_4 = uieditfield(app.IKurTab, 'numeric');
            app.p8EditField_4.ValueChangedFcn = createCallbackFcn(app, @p8Knob_4ValueChanging, true);
            app.p8EditField_4.Value = 1200;
            app.p9EditField_2Label = uilabel(app.IKurTab);
            app.p9EditField_2Label.Text = 'p9';
            app.p9EditField_4 = uieditfield(app.IKurTab, 'numeric');
            app.p9EditField_4.ValueChangedFcn = createCallbackFcn(app, @p9Knob_4ValueChanging, true);
            app.p9EditField_4.Value = 170;
            app.p10EditField_2Label = uilabel(app.IKurTab);
            app.p10EditField_2Label.Text = 'p10';
            app.p10EditField_4 = uieditfield(app.IKurTab, 'numeric');
            app.p10EditField_4.ValueChangedFcn = createCallbackFcn(app, @p10Knob_4ValueChanging, true);
            app.p10EditField_4.Value = 45.2;
            app.p11EditField_2Label = uilabel(app.IKurTab);
            app.p11EditField_2Label.Text = 'p11';
            app.p11EditField_4 = uieditfield(app.IKurTab, 'numeric');
            app.p11EditField_4.ValueChangedFcn = createCallbackFcn(app, @p11Knob_4ValueChanging, true);
            app.p11EditField_4.Value = 5.7;
            app.GKurEditFieldLabel = uilabel(app.IKurTab);
            app.GKurEditFieldLabel.Text = 'GKur';
            app.GKurEditField = uieditfield(app.IKurTab, 'numeric');
            app.GKurEditField.ValueChangedFcn = createCallbackFcn(app, @GKurKnobValueChanging, true);
            app.GKurEditField.Value = 0.16;
            app.IKssTab = uitab(app.TabGroup);
            app.IKssTab.Title = 'IKss';
            app.Image6 = uiimage(app.IKssTab);
            app.Image6.ImageSource = 'a95.PNG';
            app.Image5 = uiimage(app.IKssTab);
            app.Image5.ImageSource = 'a78.PNG';
            app.Image2 = uiimage(app.IKssTab);
            app.Image2.ImageSource = 'a93.PNG';
            app.Image4 = uiimage(app.IKssTab);
            app.Image4.ImageSource = 'a92.PNG';
            app.TextArea_5 = uitextarea(app.IKssTab);
            app.TextArea_5.Value = {'IKss: Noninactivating steady-state K+ current'};
            app.GKssEditField = uieditfield(app.IKssTab, 'numeric');
            app.GKssEditField.ValueChangedFcn = createCallbackFcn(app, @GKssKnobValueChanging, true);
            app.GKssEditField.Value = 0.05;
            app.GKssKnobLabel = uilabel(app.IKssTab);
            app.GKssKnobLabel.Text = 'GKss';
            app.GKssKnob = uiknob(app.IKssTab, 'continuous');
            app.GKssKnob.Limits = [0 2];
            app.GKssKnob.ValueChangingFcn = createCallbackFcn(app, @GKssKnobValueChanging, true);
            app.GKssKnob.Value = 0.16;
            app.p1Knob_5Label = uilabel(app.IKssTab);
            app.p1Knob_5Label.Text = 'p1';
            app.p1Knob_5 = uiknob(app.IKssTab, 'continuous');
            app.p1Knob_5.Limits = [-60 60];
            app.p1Knob_5.ValueChangingFcn = createCallbackFcn(app, @p1Knob_5ValueChanging, true);
            app.p1Knob_5.Value = 22.5;
            app.p2Knob_5Label = uilabel(app.IKssTab);
            app.p2Knob_5Label.Text = 'p2';
            app.p2Knob_5 = uiknob(app.IKssTab, 'continuous');
            app.p2Knob_5.Limits = [0 20];
            app.p2Knob_5.ValueChangingFcn = createCallbackFcn(app, @p2Knob_5ValueChanging, true);
            app.p2Knob_5.Value = 7.7;
            app.p3Knob_5Label = uilabel(app.IKssTab);
            app.p3Knob_5Label.Text = 'p3';
            app.p3Knob_5 = uiknob(app.IKssTab, 'continuous');
            app.p3Knob_5.Limits = [0 90];
            app.p3Knob_5.ValueChangingFcn = createCallbackFcn(app, @p3Knob_5ValueChanging, true);
            app.p3Knob_5.Value = 39.3;
            app.p4Knob_5Label = uilabel(app.IKssTab);
            app.p4Knob_5Label.Text = 'p4';
            app.p4Knob_5 = uiknob(app.IKssTab, 'continuous');
            app.p4Knob_5.Limits = [0 0.5];
            app.p4Knob_5.ValueChangingFcn = createCallbackFcn(app, @p4Knob_5ValueChanging, true);
            app.p4Knob_5.Value = 0.0862;
            app.p5Knob_5Label = uilabel(app.IKssTab);
            app.p5Knob_5Label.Text = 'p5';
            app.p5Knob_5 = uiknob(app.IKssTab, 'continuous');
            app.p5Knob_5.Limits = [-60 60];
            app.p5Knob_5.ValueChangingFcn = createCallbackFcn(app, @p5Knob_5ValueChanging, true);
            app.p5Knob_5.Value = 13.17;
            app.p1EditField_5Label = uilabel(app.IKssTab);
            app.p1EditField_5Label.Text = 'p1';
            app.p1EditField_5 = uieditfield(app.IKssTab, 'numeric');
            app.p1EditField_5.ValueChangedFcn = createCallbackFcn(app, @p1Knob_5ValueChanging, true);
            app.p1EditField_5.Value = 22.5;
            app.p2EditField_5Label = uilabel(app.IKssTab);
            app.p2EditField_5Label.Text = 'p2';
            app.p2EditField_5 = uieditfield(app.IKssTab, 'numeric');
            app.p2EditField_5.ValueChangedFcn = createCallbackFcn(app, @p2Knob_5ValueChanging, true);
            app.p2EditField_5.Value = 7.7;
            app.p3EditField_5Label = uilabel(app.IKssTab);
            app.p3EditField_5Label.Text = 'p3';
            app.p3EditField_5 = uieditfield(app.IKssTab, 'numeric');
            app.p3EditField_5.ValueChangedFcn = createCallbackFcn(app, @p3Knob_5ValueChanging, true);
            app.p3EditField_5.Value = 39.3;
            app.p4EditField_5Label = uilabel(app.IKssTab);
            app.p4EditField_5Label.Text = 'p4';
            app.p4EditField_5 = uieditfield(app.IKssTab, 'numeric');
            app.p4EditField_5.ValueChangedFcn = createCallbackFcn(app, @p4Knob_5ValueChanging, true);
            app.p4EditField_5.Value = 0.0862;
            app.p5EditField_5Label = uilabel(app.IKssTab);
            app.p5EditField_5Label.Text = 'p5';
            app.p5EditField_5 = uieditfield(app.IKssTab, 'numeric');
            app.p5EditField_5.ValueChangedFcn = createCallbackFcn(app, @p5Knob_5ValueChanging, true);
            app.p5EditField_5.Value = 13.17;
            app.PlotButton_5 = uibutton(app.IKssTab, 'push');
            app.PlotButton_5.ButtonPushedFcn = createCallbackFcn(app, @PlotButton_5Pushed, true);
            app.PlotButton_5.Text = 'Plot';
            app.Image3 = uiimage(app.IKssTab);
            app.Image3.ImageSource = 'a94.PNG';
            app.GKssEditFieldLabel = uilabel(app.IKssTab);
            app.GKssEditFieldLabel.Text = 'GKss';
            app.IKrTab = uitab(app.TabGroup);
            app.IKrTab.Title = 'IKr';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxesTopRight = uiaxes(app.RightPanel);
            title(app.UIAxesTopRight, 'IKsum')
            xlabel(app.UIAxesTopRight, 'Time (ms)')
            ylabel(app.UIAxesTopRight, 'pA/pF')
            app.UIAxesBottomRight = uiaxes(app.RightPanel);
            xlabel(app.UIAxesBottomRight, 'Time (ms)')
            ylabel(app.UIAxesBottomRight, 'pA/pF')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Kv

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end